classdef FACETCamerasBetter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        TabGroup       matlab.ui.container.TabGroup
        S10LaserTab    matlab.ui.container.Tab
        S10InjTab      matlab.ui.container.Tab
        S11LinacTab    matlab.ui.container.Tab
        S14LinacTab    matlab.ui.container.Tab
        S15LinacTab    matlab.ui.container.Tab
        S20ChicaneTab  matlab.ui.container.Tab
        S20ExpTab      matlab.ui.container.Tab
        S20LaserTab    matlab.ui.container.Tab
        S20sCMOSTab    matlab.ui.container.Tab
    end

    
    properties (Access = private)
        CamObjs Camera
        CamWatch
        Listeners = event.listener.empty;
        CamGrids matlab.ui.container.GridLayout
    end
    
    methods (Access = private)
        
        function updateFields(~,src,~,camArrayRate,camAcquisition,camTrigger,camCNCT,camState)
            camArrayRate.Value = src.pvs.ArrayRate.val{1};
            camAcquisition.Text = src.pvs.Acquisition.val{1};
            camTrigger.Text = src.pvs.TriggerMode.val{1};
            
            if strcmp(src.pvs.Connection.val{1},'Connect')
                camCNCT.Color = 'green';
            else
                camCNCT.Color = 'red';
            end
            
            if src.pvs.State.val{1} == 0
                camState.Color = 'green';
            elseif src.pvs.State.val{1} == 1
                camState.Color = 'red';
            else
                camState.Color = 'yellow';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             camCheck = F2_CamCheck();
%             nameList = camCheck.camera_info;
%             
%             for i = 1:size(nameList,1)
%                 if ~isempty(nameList{i,7}) % skip cameras without POE
%                     if ismember(nameList{i,2},camCheck.badCams)
%                         app.CamObjs = [app.CamObjs Camera(nameList{i,2},false)];
%                     else
%                         app.CamObjs = [app.CamObjs Camera(nameList{i,2},true)];
%                     end
%                 else
%                     disp(['Could not create Camera object for ' nameList{i,2} '. Missing POE.'])
%                 end
%             end
%             
%             for i = 1:numel(app.CamObjs)
%                 if app.CamObjs(i).IsSIOCgood &&...
%                         (lcaGetSmart(app.CamObjs(i).CameraPV+':Acquisition.SEVR',0,'DBF_ENUM') == 0)
%                     app.CamObjs(i).Alarm = false;
%                 end
%             end
            
            app.CamWatch = CameraWatchdog(app);
            app.CamObjs = app.CamWatch.CameraObjs;

            % Create a grid and top labels for each tab
            for i = 1:numel(app.TabGroup.Children)
                tab = app.TabGroup.Children(i);
                
                app.CamGrids(i) = uigridlayout(tab,[30 6]);
                app.CamGrids(i).ColumnWidth = ["2x","1x","2x","2x","1x","1x"];
                
                NameLabel = uilabel(app.CamGrids(i),'Text',"Name",...
                    'FontWeight','bold','VerticalAlignment','top');
                FPSLabel = uilabel(app.CamGrids(i),'Text',"FPS",...
                    'FontWeight','bold','VerticalAlignment','top',...
                    'HorizontalAlignment','center');
                AcquisitionLabel = uilabel(app.CamGrids(i),'Text',"Status",...
                    'FontWeight','bold','VerticalAlignment','top',...
                    'HorizontalAlignment','center');
                TriggerLabel = uilabel(app.CamGrids(i),'Text',"Trigger",...
                    'FontWeight','bold','VerticalAlignment','top',...
                    'HorizontalAlignment','center');
                CNCTLabel = uilabel(app.CamGrids(i),'Text',"CNCT",...
                    'FontWeight','bold','VerticalAlignment','top',...
                    'HorizontalAlignment','center');
                StateLabel = uilabel(app.CamGrids(i),'Text',"State",...
                    'FontWeight','bold','VerticalAlignment','top',...
                    'HorizontalAlignment','center');
            end
            
            % Place cameras in their correct tab
            for i = 1:numel(app.CamObjs)
                camObj = app.CamObjs(i);
                
                % Loop through tabs to find correct tab for the camera
                for j = 1:numel(app.TabGroup.Children)
                    tab = app.TabGroup.Children(j);
                    if strcmp(camObj.CamLocation,tab.Title)
                        camName = uilabel(app.CamGrids(j));
                        if camObj.IsSIOCgood && ~isempty(camObj.Name)
                            camName.Text = camObj.Name;
                        else
                            camName.Text = '';
                        end
                        
                        camArrayRate = uieditfield(app.CamGrids(j),'numeric',...
                            'HorizontalAlignment','center','Editable','off');
                        if camObj.IsSIOCgood && ~isempty(camObj.pvs.ArrayRate.val)
                            camArrayRate.Value = camObj.pvs.ArrayRate.val{1};
                        else
                            camArrayRate.Value = 0;
                        end
                        
                        camAcquisition = uilabel(app.CamGrids(j),'HorizontalAlignment','center');
                        if camObj.IsSIOCgood && ~isempty(camObj.pvs.Acquisition.val)
                            camAcquisition.Text = camObj.pvs.Acquisition.val{1};
                        else
                            camAcquisition.Text = '';
                        end
                        
                        camTrigger = uilabel(app.CamGrids(j),'HorizontalAlignment','center');
                        if camObj.IsSIOCgood && ~isempty(camObj.pvs.TriggerMode.val)
                            camTrigger.Text = camObj.pvs.TriggerMode.val{1};
                        else
                            camTrigger.Text = '';
                        end
                        
                        camCNCT = uilamp(app.CamGrids(j));
                        if camObj.IsSIOCgood && ~isempty(camObj.pvs.Connection.val) && strcmp(camObj.pvs.Connection.val{1},'Connect')
                            camCNCT.Color = 'green';
                        elseif camObj.IsSIOCgood && ~isempty(camObj.pvs.Connection.val) && strcmp(camObj.pvs.Connection.val{1},'Disconnect')
                            camCNCT.Color = 'red';
                        else
                            camCNCT.Color = 'white';
                        end
                        
                        camState = uilamp(app.CamGrids(j));
                        if camObj.IsSIOCgood && ~isempty(camObj.pvs.State.val) && camObj.pvs.State.val{1} == 0
                            camState.Color = 'green';
                        elseif camObj.IsSIOCgood && ~isempty(camObj.pvs.State.val) && camObj.pvs.State.val{1} == 1
                            camState.Color = 'red';
                        elseif camObj.IsSIOCgood && ~isempty(camObj.pvs.State.val)
                            camState.Color = 'yellow';
                        else
                            camState.Color = 'white';
                        end
                    end
                end
                
                % Start listener for each Camera
                app.Listeners(i) = addlistener(camObj,'PVUpdated',...
                    @(src,evnt)app.updateFields(camObj,evnt,camArrayRate,camAcquisition,camTrigger,camCNCT,camState));
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            for i = 1:numel(app.CamObjs)
                stop_PV(app.CamObjs(i));
                delete(app.CamObjs(i))
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.S10LaserTab = uitab(app.TabGroup);
            app.S10LaserTab.Title = 'S10 Laser';
            app.S10InjTab = uitab(app.TabGroup);
            app.S10InjTab.Title = 'S10 Inj';
            app.S11LinacTab = uitab(app.TabGroup);
            app.S11LinacTab.Title = 'S11 Linac';
            app.S14LinacTab = uitab(app.TabGroup);
            app.S14LinacTab.Title = 'S14 Linac';
            app.S15LinacTab = uitab(app.TabGroup);
            app.S15LinacTab.Title = 'S15 Linac';
            app.S20ChicaneTab = uitab(app.TabGroup);
            app.S20ChicaneTab.Title = 'S20 Chicane';
            app.S20ExpTab = uitab(app.TabGroup);
            app.S20ExpTab.Title = 'S20 Exp';
            app.S20LaserTab = uitab(app.TabGroup);
            app.S20LaserTab.Title = 'S20 Laser';
            app.S20sCMOSTab = uitab(app.TabGroup);
            app.S20sCMOSTab.Title = 'S20 sCMOS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FACETCamerasBetter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end