classdef Example1 < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                       matlab.ui.Figure
    LampLabel                      matlab.ui.control.Label
    Lamp                           matlab.ui.control.Lamp
    ToggleSwitchLabel              matlab.ui.control.Label
    ToggleSwitch                   matlab.ui.control.ToggleSwitch
    SwitchLabel                    matlab.ui.control.Label
    Switch                         matlab.ui.control.Switch
    RockerSwitchLabel              matlab.ui.control.Label
    RockerSwitch                   matlab.ui.control.RockerSwitch
    NumericEditFieldLabel          matlab.ui.control.Label
    NumericEditField               matlab.ui.control.NumericEditField
    LinearGaugeLabel               matlab.ui.control.Label
    LinearGauge                    matlab.ui.control.LinearGauge
    GaugeLabel                     matlab.ui.control.Label
    Gauge                          matlab.ui.control.Gauge
    NinetyDegreegaugeGaugeLabel    matlab.ui.control.Label
    NinetyDegreegaugeGauge         matlab.ui.control.NinetyDegreeGauge
    WritableNumericEditFieldLabel  matlab.ui.control.Label
    WritableNumericEditField       matlab.ui.control.NumericEditField
    SemiCircularGaugeLabel         matlab.ui.control.Label
    SemiCircularGauge              matlab.ui.control.SemicircularGauge
    StateButton                    matlab.ui.control.StateButton
    PVClassSupportedAppDesignerElementsLabel  matlab.ui.control.Label
    write0or1intoeditfieldtotoggleLampandLabel  matlab.ui.control.Label
  end

  
  properties (Access = public)
    pvlist % Description
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, arg1)
      % generate a (java) context object, required by the PV class to perform read/write operations to EPICS PV channels using a java CA client
      context = PV.Initialize(PVtype.EPICS) ;
      % Generate list of PV objects
      %  'name' field is user-supplied name to refer to this PV channel locally
      %  'pvname' field should be EPICS PV name
      %  'monitor' field should be set to true to automatically update the local PV values (starts when you call the run() method)
      %  'guihan' field is the App Designer component to associate with a given PV channel
      %  'mode' field should be set to "rw" if you want to be able to write to this PV
      app.pvlist = [ PV(context,'name',"LampPV",'pvname',"SIOC:SYS1:ML00:AO956",'monitor',true,'guihan',app.Lamp);
                 PV(context,'name',"ToggleSwitchPV",'pvname',"SIOC:SYS1:ML00:AO956",'monitor',true,'guihan',app.ToggleSwitch);
                 PV(context,'name',"SwitchPV",'pvname',"SIOC:SYS1:ML00:AO956",'monitor',true,'guihan',app.Switch);
                 PV(context,'name',"RockerSwitchPV",'pvname',"SIOC:SYS1:ML00:AO956",'monitor',true,'guihan',app.RockerSwitch);
                 PV(context,'name',"NumericEditFieldPV",'pvname',"SIOC:SYS1:ML00:AO956",'monitor',true,'guihan',app.NumericEditField);
                 PV(context,'name',"WriteableNumericEditFieldPV",'pvname',"SIOC:SYS1:ML00:AO956",'monitor',true,'guihan',app.WritableNumericEditField,'mode',"rw");
                 PV(context,'name',"LinearGaugePV",'pvname',"SIOC:SYS1:ML00:AO953",'monitor',true,'guihan',app.LinearGauge);
                 PV(context,'name',"GaugePV",'pvname',"SIOC:SYS1:ML00:AO953",'monitor',true,'guihan',app.Gauge);
                 PV(context,'name',"NinetyDegreeGaugePV",'pvname',"SIOC:SYS1:ML00:AO953",'monitor',true,'guihan',app.NinetyDegreegaugeGauge);
                 PV(context,'name',"SemicircularGaugePV",'pvname',"SIOC:SYS1:ML00:AO953",'monitor',true,'guihan',app.SemiCircularGauge);
                 PV(context,'name',"StateButtonPV",'pvname',"SIOC:SYS1:ML00:AO956",'monitor',true,'guihan',app.StateButton) ] ;
       pset(app.pvlist,'debug',0) ; % Set debug level to 0 to enable read/write operations (make PV objects live)
       % start timer which keeps local values of PV data updated and updates GUI field. async=true option uses asnchronous get methods (non blocking)
       run(app.pvlist,true,0.02); % (async, polltime) - set polling time to a value (s) less than the fastest rate at which you expect PV values to be changing
    end

    % Close request function: UIFigure
    function UIFigureCloseRequest(app, event)
      stop(app.pvlist); % Stop timers used to auto update PV fields
      Cleanup(app.pvlist); % Cleanup java objects used for PV access
      delete(app)
      
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
      app.LampLabel = uilabel(app.UIFigure);
      app.LampLabel.Text = 'Lamp';
      app.Lamp = uilamp(app.UIFigure);
      app.ToggleSwitchLabel = uilabel(app.UIFigure);
      app.ToggleSwitchLabel.Text = 'Toggle Switch';
      app.ToggleSwitch = uiswitch(app.UIFigure, 'toggle');
      app.ToggleSwitch.ItemsData = {'0', '1'};
      app.ToggleSwitch.Value = '0';
      app.SwitchLabel = uilabel(app.UIFigure);
      app.SwitchLabel.Text = 'Switch';
      app.Switch = uiswitch(app.UIFigure, 'slider');
      app.Switch.ItemsData = {'0', '1'};
      app.Switch.Value = '0';
      app.RockerSwitchLabel = uilabel(app.UIFigure);
      app.RockerSwitchLabel.Text = 'Rocker Switch';
      app.RockerSwitch = uiswitch(app.UIFigure, 'rocker');
      app.RockerSwitch.ItemsData = {'0', '1'};
      app.RockerSwitch.Value = '0';
      app.NumericEditFieldLabel = uilabel(app.UIFigure);
      app.NumericEditFieldLabel.Text = 'Numeric Edit Field';
      app.NumericEditField = uieditfield(app.UIFigure, 'numeric');
      app.LinearGaugeLabel = uilabel(app.UIFigure);
      app.LinearGaugeLabel.Text = 'Linear Gauge';
      app.LinearGauge = uigauge(app.UIFigure, 'linear');
      app.LinearGauge.Limits = [-3 3];
      app.GaugeLabel = uilabel(app.UIFigure);
      app.GaugeLabel.Text = 'Gauge';
      app.Gauge = uigauge(app.UIFigure, 'circular');
      app.Gauge.Limits = [-3 3];
      app.NinetyDegreegaugeGaugeLabel = uilabel(app.UIFigure);
      app.NinetyDegreegaugeGaugeLabel.Text = 'Ninety Degree gauge';
      app.NinetyDegreegaugeGauge = uigauge(app.UIFigure, 'ninetydegree');
      app.NinetyDegreegaugeGauge.Limits = [-3 3];
      app.WritableNumericEditFieldLabel = uilabel(app.UIFigure);
      app.WritableNumericEditFieldLabel.Text = 'Writable Numeric Edit Field';
      app.WritableNumericEditField = uieditfield(app.UIFigure, 'numeric');
      app.SemiCircularGaugeLabel = uilabel(app.UIFigure);
      app.SemiCircularGaugeLabel.Text = 'Semi Circular Gauge';
      app.SemiCircularGauge = uigauge(app.UIFigure, 'semicircular');
      app.SemiCircularGauge.Limits = [-3 3];
      app.StateButton = uibutton(app.UIFigure, 'state');
      app.PVClassSupportedAppDesignerElementsLabel = uilabel(app.UIFigure);
      app.PVClassSupportedAppDesignerElementsLabel.Text = 'PV Class Supported App Designer Elements';
      app.write0or1intoeditfieldtotoggleLampandLabel = uilabel(app.UIFigure);
      app.write0or1intoeditfieldtotoggleLampandLabel.Text = {'(write "0" or "1" into edit field to toggle Lamp and'; 'switch & button states - NB the switches have no put capability here)'};
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = Example1(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end