classdef BPM_Orbit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BPMOrbitUIFigure        matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        UIAxes                  matlab.ui.control.UIAxes
        PulseSlider             matlab.ui.control.Slider
        CloseButton             matlab.ui.control.Button
        GridLayout2             matlab.ui.container.GridLayout
        PulseNumEditFieldLabel  matlab.ui.control.Label
        PulseNumEditField       matlab.ui.control.NumericEditField
        NextPulse               matlab.ui.control.Button
        LastPulse               matlab.ui.control.Button
        Play                    matlab.ui.control.Button
        Pause                   matlab.ui.control.Button
        GridLayout3             matlab.ui.container.GridLayout
        ButtonGroup             matlab.ui.container.ButtonGroup
        XButton                 matlab.ui.control.ToggleButton
        YButton                 matlab.ui.control.ToggleButton
        TMITButton              matlab.ui.control.ToggleButton
        ShowToolsButton         matlab.ui.control.Button
        LogbookButton           matlab.ui.control.Button
        ShowAverageCheckBox     matlab.ui.control.CheckBox
        TakeDiffOrbitButton     matlab.ui.control.Button
    end

    
    properties (Access = public)
        BG_mdl % BSA_GUI model object holding app data and state
        orbit_mdl % BPM_Orbit model object holding app data and state
        play % 1 if 'play' is on, 0 if static
        play_rate % rate at which orbit plot changes
        orbitPlot % plot object for primary orbit plot
        orbitPlot_2 % plot object for plot using asterisks for points
        current_coor = 'X' % currently selected plot coordinate ('X', 'Y', or 'TMIT')
        max_xy = 10
        max_tmit = 3e9
        toolsWindow
        
        STDERR = 2
        
        % Listeners
        plotOrbitListener
        newOrbitListener
    end
    
    methods (Access = private)
        
        function errorMessage(app, ex, callbackMessage)
            err = ex.stack(1);
            file = err.file; funcname = err.name; linenum = num2str(err.line);
            file = strsplit(file, '/'); file = file{end};
            loc = sprintf('File: %s   Function: %s   Line: %s', file, funcname, linenum);
            uiwait(errordlg(...
                    lprintf(app.STDERR, '%s%c%s%c%s', callbackMessage, newline, ex.message, newline, loc)));
        end
        
        function onNewOrbit(app)
            % set slider and pulse label
            numPulses = sum(app.orbit_mdl.currentOrbit.pulses);
            if numPulses > 1
                app.PulseSlider.Enable = 'on';
                app.PulseSlider.Limits=[1,numPulses];
            else
                app.pulseSlider.Enable = 'off';
            end
                
            app.PulseNumEditField.Limits=app.PulseSlider.Limits;
            app.PulseSlider.Value = app.orbit_mdl.currentOrbit.pulsenum;
            app.PulseNumEditField.Value = app.orbit_mdl.currentOrbit.pulsenum;
            plot_orbit(app);
        end
        
        function pulseChanged(app, pulsenum)
            app.PulseNumEditField.Value = pulsenum;
            app.PulseSlider.Value = pulsenum;
            updatePulseNum(app.orbit_mdl, pulsenum);
        end
        
        function plot_orbit(app)
            % Create the initial orbit plot. This function is called on
            % initialization, and changes in the beam line or the plot
            % coordinate
            
            if app.orbit_mdl.orbitIdx == 0
                app.TakeDiffOrbitButton.Enable = 0;
            else
                app.TakeDiffOrbitButton.Enable = 1;
            end
            
            % Determine selected coordinate and retrieve the appropriate
            % data struct
            app.current_coor = app.ButtonGroup.SelectedObject.Text;
            bpms = app.orbit_mdl.currentOrbit.(app.current_coor);
            %bpm_idx = bpms.bpm_idx;
            bpm_names = bpms.bpm_names;
            bpm_data = bpms.bpm_data;
            z_pos = reshape(app.orbit_mdl.currentOrbit.Z, size(bpm_names)); % force size matching for line below
            use_bpms = contains(bpm_names, app.orbit_mdl.enabled) .* z_pos ~= 0;
            %bpm_num=1:length(bpm_idx);
            
            % Determine selected pulse num
            if app.orbit_mdl.showAve
                pulse_data = bpms.mean(use_bpms);
                pulsenum = 1;
            else
                pulsenum=round(app.PulseSlider.Value);
                pulse_data=bpm_data(use_bpms, pulsenum);
            end
            if strcmp(bpms.label,'TMIT')
                cutoff = app.max_tmit;
            else
                cutoff = app.max_xy;
            end
            pulse_data(abs(pulse_data) > cutoff) = 0; % remove wacky points
            
            hold(app.UIAxes,'off')
            app.orbitPlot = plot(app.UIAxes, z_pos(use_bpms), pulse_data,'-');
            hold(app.UIAxes)
            if app.orbit_mdl.showAve
                app.orbitPlot_2 = errorbar(z_pos(use_bpms), pulse_data, bpms.rms_error(use_bpms), '*','Parent', app.UIAxes);
            else
                app.orbitPlot_2 = plot(app.UIAxes, z_pos(use_bpms), pulse_data, '*');
            end
            
            app.UIAxes.YLabel.String = bpms.label;
            
            % Determine what sort of time stamp is available. This accounts
            % for different BSA data formats over the years. All new BSA
            % files should have a full time_stamps array
            try
               ts = app.orbit_mdl.currentOrbit.time_stamps(pulsenum);
            catch
               ts = app.orbit_mdl.currentOrbit.time_stamps(1);
            end
            try
                ts = datetime(ts, 'ConvertFrom', 'posixtime');
                if app.orbit_mdl.orbitIdx == 0
                    titleStr = sprintf('Difference Orbit to %s', app.orbit_mdl.diffOrbitName);
                else
                    titleStr = sprintf('Orbit - %s', string(ts));
                end
                app.UIAxes.Title.String = titleStr;
            catch
                % If no time stamp is available, set the plot title to the
                % name of the loaded file
                s = app.BG_mdl.fileName;
                app.UIAxes.Title.String = ['Orbit - ', s(10:length(s)-4)];
            end
            
            dt=app.orbitPlot_2.DataTipTemplate;
            dt.DataTipRows(1).Value = bpm_names;
            dt.DataTipRows(1).Label = '';
            lim = min(max(abs(pulse_data),[],'all'), 2e9);
            if strcmp(bpms.label,'TMIT') % TMIT limits and labels different than X/Y
                dt.DataTipRows(2).Label = 'TMIT';
                if lim > 0
                    app.UIAxes.YLim = [0,lim];
                end
            else
                lim = max(max(abs(pulse_data)) * 1.1, 1);
                dt.DataTipRows(2).Label='Pos: ';
                app.UIAxes.YLim = [-lim,lim];
            end
            
            drawnow();
        end

        function update_orbit(app)
            % Update the orbit plot with the new pulse value. This is
            % called when there is no change in beam line or plot variable,
            % only the pulse num
            
            % Get data for current coordinate
            bpms = app.orbit_mdl.currentOrbit.(app.current_coor);
            bpm_data = bpms.bpm_data;
            z_pos = reshape(app.orbit_mdl.currentOrbit.Z, size(bpms.bpm_names));
            use_bpms = contains(bpms.bpm_names, app.orbit_mdl.enabled) & z_pos ~= 0;
            if sum(use_bpms) ~= length(app.orbitPlot.XData)
                plot_orbit(app);
                return
            end
            %bpm_idx = bpms.bpm_idx;
            %bpm_names = bpms.bpm_names;
            %bpm_num=1:length(bpm_idx);
            
            % Determine pulse num based on slider value
            pulsenum = app.orbit_mdl.currentOrbit.pulsenum;
            pulse_data = bpm_data(use_bpms, pulsenum);
            active_bpms = ~isnan(pulse_data);         
            % Same as in 'plot_orbit' function, some gymnastics required
            % for the different BSA file formats of versions past
            try
                ts = app.orbit_mdl.currentOrbit.time_stamps(pulsenum);
            catch
                ts = app.orbit_mdl.currentOrbit.time_stamps;
            end
            try
                ts = datetime(ts, 'ConvertFrom', 'posixtime');
                if isempty(ts)
                    s = app.BG_mdl.fileName;
                    titleStr = ['Orbit - ', s(10:length(s)-4)];
                else
                    titleStr = strcat('Orbit - ', string(ts));
                end
                if app.orbit_mdl.orbitIdx == 0
                    titleStr = sprintf('Difference Orbit to %s', app.orbit_mdl.diffOrbitName);
                end
                app.UIAxes.Title.String = titleStr;
            catch 
            end
            
            % update the values for the plot
            app.orbitPlot.XData = z_pos(active_bpms);
            app.orbitPlot_2.XData = z_pos(active_bpms);
            app.orbitPlot.YData = pulse_data(active_bpms);
            app.orbitPlot_2.YData = pulse_data(active_bpms);
            
            drawnow();
        end
        
        function fig = appToFig(app)
            fig = figure;
            fig.Visible = 'off';
            fig_ax = axes(fig);
            app_ax = app.UIAxes;
            copyobj(app_ax.Children, fig_ax, 'legacy');
            
            fig_ax.XLabel.String = app_ax.XLabel.String;
            fig_ax.YLabel.String = app_ax.YLabel.String;
            fig_ax.Title.String = app_ax.Title.String;
            fig_ax.XLim = app_ax.XLim;
            fig_ax.YLim = app_ax.YLim;
            fig.Position = app_ax.Position;

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, BG_mdl)
            app.BG_mdl = BG_mdl;
            app.orbit_mdl = BSA_GUI_orbitmodel(app, app.BG_mdl);
            
            app.plotOrbitListener = addlistener(app.orbit_mdl, 'plotOrbit', @(~,~)app.update_orbit);
            app.newOrbitListener = addlistener(app.orbit_mdl, 'newOrbit', @(~,~)app.onNewOrbit);
            
            orbitInit(app.orbit_mdl);
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            try
                plot_orbit(app);
            catch ex
                errorMessage(app, ex, 'Error plotting orbit');
            end
        end

        % Value changed function: PulseSlider
        function PulseSliderValueChanged(app, event)
            old_pulse = app.PulseNumEditField.Value;
            try
                new_pulse = round(app.PulseSlider.Value);
                pulseChanged(app, new_pulse)
                %update_orbit(app) % change plot to new pulse
            catch ex
                errorMessage(app, ex, 'Error changing pulse.')
                app.PulseSlider.Value = old_pulse;
                app.PulseNumEditField.Value = old_pulse;
            end
        end

        % Value changed function: PulseNumEditField
        function PulseNumEditFieldValueChanged(app, event)
            old_pulse = app.PulseSlider.Value;
            try
                new_pulse = round(app.PulseSlider.Value);
                pulseChanged(app, new_pulse)
                %update_orbit(app) % change plot to new pulse
            catch ex
                errorMessage(app, ex, 'Error changing pulse.')
                app.PulseSlider.Value = old_pulse;
                app.PulseNumEditField.Value = old_pulse;
            end
        end

        % Button pushed function: NextPulse
        function NextPulseButtonPushed(app, event)
            % when play button is hselectedit, the next button becomes the increase
            % rate button
            try
                if app.play
                    app.orbit_mdl.play_rate = app.orbit_mdl.play_rate - app.orbit_mdl.play_rate/10; % arbitrary rate change per button selection
                    return
                end
            catch ex
                errorMessage(app, ex, 'Error increasing play rate.');
            end
            
            next_pulse = app.PulseNumEditField.Value + 1;
            
            % ensure next pulse is still within limits, update slider and
            % edit field, plot the new orbit
            try
                if next_pulse <= app.PulseNumEditField.Limits(2)
                    pulseChanged(app, next_pulse);
                    %update_orbit(app);
                end
            catch ex
                errorMessage(app, ex, 'Error incrementing pulse.')
                pulseChanged(app, next_pulse - 1);
            end
        end

        % Button pushed function: LastPulse
        function LastPulseButtonPushed(app, event)
            % when play button is selected, the last button becomes the
            % decrease rate button
            try
                if app.play
                    app.orbit_mdl.play_rate = app.orbit_mdl.play_rate + app.orbit_mdl.play_rate/10; % arbitrary rate change per button selection
                    return
                end
            catch ex
                errorMessage(app, ex, 'Error decreasing play rate.');
            end
            
            last_pulse = app.PulseNumEditField.Value - 1;
            
            % ensure next pulse is still within limits, update slider and
            % edit field, plot the new orbit
            try
                if last_pulse >= app.PulseNumEditField.Limits(1)
                    pulseChanged(app, last_pulse);
                    %pdate_orbit(app);
                end
            catch ex
                errorMessage(app, ex, 'Error decrementing pulse.')
                pulseChanged(app, next_pulse - 1);
            end
        end

        % Button pushed function: Play
        function PlayButtonPushed(app, event)
            % execute 'play' of orbit over time
            app.play=1;
            
            % change 'Next' and 'Last' buttons to 'Faster' and 'Slower'
            app.NextPulse.Text = 'Faster';
            app.LastPulse.Text = 'Slower';
            
            % While still within limits, update the plot to the next pulse 
            try
                while app.play
                    update_orbit(app)
                    drawnow limitrate;
                    pause(app.orbit_mdl.play_rate) % modulate rate at which next orbit is plotted
                    pulse = app.PulseNumEditField.Value;
                    if pulse == app.PulseSlider.Limits(2)
                        app.play = 0;
                        break
                    end
                    pulseChanged(app, pulse+1);
                end
            catch ex
                errorMessage(app, ex, 'Error displaying orbit time series.');
            end
            
            % reset buttons
            app.NextPulse.Text = '+';
            app.LastPulse.Text = '-';
        end

        % Button pushed function: Pause
        function PauseButtonPushed(app, event)
            app.play = 0;
        end

        % Button pushed function: ShowToolsButton
        function ShowToolsButtonPushed(app, event)
            try
                app.toolsWindow = BPM_orbit_tools(app.orbit_mdl);
            catch ex
                errorMessage(app, ex, 'Error launching tools menu.');
            end
        end

        % Button pushed function: LogbookButton
        function LogbookButtonPushed(app, event)
            try
                fig = appToFig(app);
                util_printLog_wComments(fig, 'Matlab', 'Orbit from BSA GUI', '');
                delete(fig);
                saveOrbit(app.orbit_mdl, 0);
                saveData(app.BG_mdl, 0);
            catch ex
                errorMessage(app, ex, 'Error printing to logbook.');
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            BPMOrbitUIFigureCloseRequest(app, event)
        end

        % Close request function: BPMOrbitUIFigure
        function BPMOrbitUIFigureCloseRequest(app, event)
            try
                if ~isempty(app.toolsWindow)
                    delete(app.toolsWindow);
                end
                delete(app);
            catch ex
                answer = questdlg(sprintf('%s%c%cWould you like to force quit?', lprintf(app.STDERR, 'Error quitting app. %s',ex.message), newline, newline),...
                    'Delete App',...
                    'Yes', 'No', 'No');
                if strcmp(answer, 'Yes')
                    delete(app);
                end
            end
            
        end

        % Value changed function: ShowAverageCheckBox
        function ShowAverageCheckBoxValueChanged(app, event)
            try
                showAve = app.ShowAverageCheckBox.Value;
                app.orbit_mdl.showAve = showAve;
                app.NextPulse.Enable = ~showAve;
                app.LastPulse.Enable = ~showAve;
                app.PulseNumEditField.Enable = ~showAve;
                app.PulseSlider.Enable = ~showAve;
                app.Play.Enable = ~showAve;
                app.Pause.Enable = ~showAve;
                plot_orbit(app);
            catch ME
                errorMessage(app, ME, 'Error toggling show average option.');
            end
        end

        % Button pushed function: TakeDiffOrbitButton
        function TakeDiffOrbitButtonPushed(app, event)
            try
                diffFromCurrent(app.orbit_mdl);
            catch ME
                errorMessage(app, ME, 'Error creating difference orbit'); 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BPMOrbitUIFigure = uifigure('Visible', 'off');
            app.BPMOrbitUIFigure.Name = 'BPM Orbit';
            app.BPMOrbitUIFigure.CloseRequestFcn = createCallbackFcn(app, @BPMOrbitUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.BPMOrbitUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Orbit')
            xlabel(app.UIAxes, 'Z [m]')
            ylabel(app.UIAxes, '')
            app.PulseSlider = uislider(app.GridLayout);
            app.PulseSlider.ValueChangedFcn = createCallbackFcn(app, @PulseSliderValueChanged, true);
            app.PulseSlider.Value = 1;
            app.CloseButton = uibutton(app.GridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.PulseNumEditFieldLabel = uilabel(app.GridLayout);
            app.PulseNumEditFieldLabel.Text = 'Pulse Num:';
            app.PulseNumEditField = uieditfield(app.GridLayout, 'numeric');
            app.PulseNumEditField.ValueChangedFcn = createCallbackFcn(app, @PulseNumEditFieldValueChanged, true);
            app.PulseNumEditField.Value = 1;
            app.NextPulse = uibutton(app.GridLayout, 'push');
            app.NextPulse.ButtonPushedFcn = createCallbackFcn(app, @NextPulseButtonPushed, true);
            app.NextPulse.Text = '+';
            app.LastPulse = uibutton(app.GridLayout, 'push');
            app.LastPulse.ButtonPushedFcn = createCallbackFcn(app, @LastPulseButtonPushed, true);
            app.LastPulse.Text = '-';
            app.Play = uibutton(app.GridLayout, 'push');
            app.Play.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.Play.Text = 'Play';
            app.Pause = uibutton(app.GridLayout, 'push');
            app.Pause.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.Pause.Text = 'Pause';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ButtonGroup = uibuttongroup(app.GridLayout3);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.XButton = uitogglebutton(app.ButtonGroup);
            app.XButton.Text = 'X';
            app.XButton.Value = true;
            app.YButton = uitogglebutton(app.ButtonGroup);
            app.YButton.Text = 'Y';
            app.TMITButton = uitogglebutton(app.ButtonGroup);
            app.TMITButton.Text = 'TMIT';
            app.ShowToolsButton = uibutton(app.GridLayout, 'push');
            app.ShowToolsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowToolsButtonPushed, true);
            app.ShowToolsButton.Text = 'Show Tools';
            app.LogbookButton = uibutton(app.GridLayout, 'push');
            app.LogbookButton.ButtonPushedFcn = createCallbackFcn(app, @LogbookButtonPushed, true);
            app.LogbookButton.Text = 'Logbook';
            app.ShowAverageCheckBox = uicheckbox(app.GridLayout);
            app.ShowAverageCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowAverageCheckBoxValueChanged, true);
            app.ShowAverageCheckBox.Text = 'Show Average';
            app.TakeDiffOrbitButton = uibutton(app.GridLayout, 'push');
            app.TakeDiffOrbitButton.ButtonPushedFcn = createCallbackFcn(app, @TakeDiffOrbitButtonPushed, true);
            app.TakeDiffOrbitButton.Visible = 'off';
            app.TakeDiffOrbitButton.Text = 'Take Diff Orbit';
            % Show the figure after all components are created
            app.BPMOrbitUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BPM_Orbit(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BPMOrbitUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BPMOrbitUIFigure)
        end
    end
end