classdef bsagui_AdvancedOptions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BSAGUIOptionsUIFigure        matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        TabGroup                     matlab.ui.container.TabGroup
        MultiplotTab                 matlab.ui.container.Tab
        GridLayout_2                 matlab.ui.container.GridLayout
        GridLayout3                  matlab.ui.container.GridLayout
        MultiplotOptionsButtonGroup  matlab.ui.container.ButtonGroup
        SeparateFigure               matlab.ui.control.RadioButton
        SameFigure                   matlab.ui.control.RadioButton
        GridLayout4                  matlab.ui.container.GridLayout
        ExportdatatoworkspaceButton  matlab.ui.control.Button
        AllZTab                      matlab.ui.container.Tab
        GridLayout5                  matlab.ui.container.GridLayout
        AllZOptionsPanel             matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        AllBSACorrCheckBox           matlab.ui.control.CheckBox
        HorzNormRMSCheckBox          matlab.ui.control.CheckBox
        XBPMCorrCheckBox             matlab.ui.control.CheckBox
        VertNormRMSCheckBox          matlab.ui.control.CheckBox
        YBPMCorrCheckBox             matlab.ui.control.CheckBox
        XYFactorCheckBox             matlab.ui.control.CheckBox
        DispersionCheckBox           matlab.ui.control.CheckBox
        HorzRMSCheckBox              matlab.ui.control.CheckBox
        VertRMSCheckBox              matlab.ui.control.CheckBox
        ModelBeamSigmaCheckBox       matlab.ui.control.CheckBox
        HorzNoDispersionCheckBox     matlab.ui.control.CheckBox
        ModelBeamEnergyCheckBox      matlab.ui.control.CheckBox
        NewModelCheckBox             matlab.ui.control.CheckBox
        SCPAcqTab                    matlab.ui.container.Tab
        GridLayout6                  matlab.ui.container.GridLayout
        AcquireSCPCheckBox           matlab.ui.control.CheckBox
        AcquireBPMSCheckBox          matlab.ui.control.CheckBox
        AcquireRFCheckBox            matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        mdl % BSA_GUI app
        options % struct of selected options
        STDERR = 2
        
        AcqSCPListener
    end
    
    methods (Access = private)
        
        function onAcqSCPChanged(app)
            app.AcquireSCPCheckBox.Value = app.mdl.acqSCP;
            app.AcquireBPMSCheckBox.Enable = app.mdl.acqSCP;
            app.AcquireRFCheckBox.Enable = app.mdl.acqSCP;
            app.AcquireBPMSCheckBox.Value = app.mdl.scpNames.getBPMS;
            app.AcquireRFCheckBox.Value = app.mdl.scpNames.getRF;
        end
        
        function errorMessage(app, ex, callbackMessage)
            err = ex.stack(1);
            file = err.file; funcname = err.name; linenum = num2str(err.line);
            file = strsplit(file, '/'); file = file{length(file)};
            loc = sprintf('File: %s   Function: %s   Line: %s', file, funcname, linenum);
            uiwait(errordlg(...
                    lprintf(app.STDERR, '%s%c%s%c%s', callbackMessage, newline, ex.message, newline, loc)));
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mdl)
            app.mdl = mdl;
            
            app.AcqSCPListener = addlistener(app.mdl, 'AcqSCPChanged', @(~,~)app.AcquireSCPCheckBoxValueChanged);
            
            app.options = app.mdl.z_options;
            if app.mdl.facet
                app.NewModelCheckBox.Enable = 'off';
                
                %app.AllBSACorrCheckBox.Enable = 'off';
                %app.XBPMCorrCheckBox.Enable = 'off';
                %app.YBPMCorrCheckBox.Enable = 'off';
                %app.XYFactorCheckBox.Enable = 'off';
                app.AllBSACorrCheckBox.Value = app.options.AllBSA;
                app.XBPMCorrCheckBox.Value = app.options.XBPMCorr;
                app.YBPMCorrCheckBox.Value = app.options.YBPMCorr;
                app.XYFactorCheckBox.Value = app.options.XYFactor; 
                app.DispersionCheckBox.Enable = 'off';
                app.HorzNoDispersionCheckBox.Enable = 'off';
                app.HorzNormRMSCheckBox.Enable = 'off';
                app.VertNormRMSCheckBox.Enable = 'off';
                app.HorzRMSCheckBox.Enable = 'off';
                app.VertRMSCheckBox.Enable = 'off';
                app.ModelBeamSigmaCheckBox.Enable = 'off';
                app.ModelBeamEnergyCheckBox.Enable = 'off';
                
            elseif ~isempty(app.mdl.z_options)
                app.NewModelCheckBox.Value = app.options.NewModel;
            
                app.AllBSACorrCheckBox.Value = app.options.AllBSA;
                app.XBPMCorrCheckBox.Value = app.options.XBPMCorr;
                app.YBPMCorrCheckBox.Value = app.options.YBPMCorr;
                app.XYFactorCheckBox.Value = app.options.XYFactor;            
                app.DispersionCheckBox.Value = app.options.Dispersion;
                app.HorzNoDispersionCheckBox.Value = app.options.HorzNoDispersion;
                app.HorzNormRMSCheckBox.Value = app.options.HorzNormRMS;
                app.VertNormRMSCheckBox.Value = app.options.VertNormRMS;
                app.HorzRMSCheckBox.Value = app.options.HorzRMS;
                app.VertRMSCheckBox.Value = app.options.VertRMS;
                app.ModelBeamSigmaCheckBox.Value = app.options.ModelBeamSigma;
                app.ModelBeamEnergyCheckBox.Value = app.options.ModelBeamEnergy;
                
            end
            
            onAcqSCPChanged(app);
            app.SameFigure.Value = app.mdl.multiplot_same;
        end

        % Callback function
        function MultiplotOptionsButtonGroupSelectionChanged(app, event)
            try
                selectedButton = app.MultiplotOptionsButtonGroup.SelectedObject;
                multiplotOptionChanged(app.mdl, selectedButton);
            catch ME
                errorMessage(app, ME, 'Error changing multiplot option'); 
            end
        end

        % Callback function
        function ExportdatatoworkspaceButtonPushed(app, event)
            try
                exportToWorkspace(app.mdl);
            catch ME
                errorMessage(app, ME, 'Error exporting to workspace'); 
            end
        end

        % Callback function
        function ZOptValueChanged(app, event)
            try
                value = event.Source.Value;
                field = event.Source.Tag;
                updateZOptions(app.mdl, value, field);
            catch ME
                errorMessage(app, ME, 'Error changing Z option'); 
            end
        end

        % Value changed function: AcquireBPMSCheckBox, 
        % AcquireRFCheckBox, AcquireSCPCheckBox
        function AcquireSCPCheckBoxValueChanged(app, event)
            acqSCP = app.AcquireSCPCheckBox.Value;
            acqBPMS = app.AcquireBPMSCheckBox.Value;
            acqRF = app.AcquireRFCheckBox.Value;
            try
                setSCPAcq(app.mdl, acqSCP, acqBPMS, acqRF);
                onAcqSCPChanged(app);
            catch ME
                errorMessage(app, ME, 'Error changing acquire SCP option'); 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BSAGUIOptionsUIFigure = uifigure('Visible', 'off');
            app.BSAGUIOptionsUIFigure.Name = 'BSA GUI Options';
            app.GridLayout = uigridlayout(app.BSAGUIOptionsUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.MultiplotTab = uitab(app.TabGroup);
            app.MultiplotTab.Title = 'Multiplot';
            app.GridLayout_2 = uigridlayout(app.MultiplotTab);
            app.GridLayout3 = uigridlayout(app.GridLayout_2);
            app.MultiplotOptionsButtonGroup = uibuttongroup(app.GridLayout3);
            app.MultiplotOptionsButtonGroup.Title = 'Multiplot Options';
            app.SeparateFigure = uiradiobutton(app.MultiplotOptionsButtonGroup);
            app.SeparateFigure.Tag = 'separate';
            app.SeparateFigure.Text = 'Plot traces on separate figures';
            app.SeparateFigure.Value = true;
            app.SameFigure = uiradiobutton(app.MultiplotOptionsButtonGroup);
            app.SameFigure.Tag = 'same';
            app.SameFigure.Text = 'Plot traces on same figure';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.ExportdatatoworkspaceButton = uibutton(app.GridLayout4, 'push');
            app.ExportdatatoworkspaceButton.Text = 'Export data to workspace';
            app.AllZTab = uitab(app.TabGroup);
            app.AllZTab.Title = 'All Z';
            app.GridLayout5 = uigridlayout(app.AllZTab);
            app.AllZOptionsPanel = uipanel(app.GridLayout5);
            app.AllZOptionsPanel.Title = 'All Z Options';
            app.GridLayout2 = uigridlayout(app.AllZOptionsPanel);
            app.AllBSACorrCheckBox = uicheckbox(app.GridLayout2);
            app.AllBSACorrCheckBox.Tag = 'AllBSA';
            app.AllBSACorrCheckBox.Text = 'All BSA Corr';
            app.AllBSACorrCheckBox.Value = true;
            app.HorzNormRMSCheckBox = uicheckbox(app.GridLayout2);
            app.HorzNormRMSCheckBox.Tag = 'HorzNormRMS';
            app.HorzNormRMSCheckBox.Text = 'Horz Norm RMS';
            app.HorzNormRMSCheckBox.Value = true;
            app.XBPMCorrCheckBox = uicheckbox(app.GridLayout2);
            app.XBPMCorrCheckBox.Tag = 'XPMBCorr';
            app.XBPMCorrCheckBox.Text = 'X BPM Corr';
            app.XBPMCorrCheckBox.Value = true;
            app.VertNormRMSCheckBox = uicheckbox(app.GridLayout2);
            app.VertNormRMSCheckBox.Tag = 'VertNormRMS';
            app.VertNormRMSCheckBox.Text = 'Vert Norm RMS';
            app.VertNormRMSCheckBox.Value = true;
            app.YBPMCorrCheckBox = uicheckbox(app.GridLayout2);
            app.YBPMCorrCheckBox.Tag = 'YBPMCorr';
            app.YBPMCorrCheckBox.Text = 'Y BPM Corr';
            app.YBPMCorrCheckBox.Value = true;
            app.XYFactorCheckBox = uicheckbox(app.GridLayout2);
            app.XYFactorCheckBox.Tag = 'XYFactor';
            app.XYFactorCheckBox.Text = 'XY Factor';
            app.XYFactorCheckBox.Value = true;
            app.DispersionCheckBox = uicheckbox(app.GridLayout2);
            app.DispersionCheckBox.Tag = 'Dispersion';
            app.DispersionCheckBox.Text = 'Dispersion';
            app.DispersionCheckBox.Value = true;
            app.HorzRMSCheckBox = uicheckbox(app.GridLayout2);
            app.HorzRMSCheckBox.Tag = 'HorzRMS';
            app.HorzRMSCheckBox.Text = 'Horz RMS';
            app.HorzRMSCheckBox.Value = true;
            app.VertRMSCheckBox = uicheckbox(app.GridLayout2);
            app.VertRMSCheckBox.Tag = 'VertRMS';
            app.VertRMSCheckBox.Text = 'Vert RMS';
            app.VertRMSCheckBox.Value = true;
            app.ModelBeamSigmaCheckBox = uicheckbox(app.GridLayout2);
            app.ModelBeamSigmaCheckBox.Tag = 'ModelBeamSigma';
            app.ModelBeamSigmaCheckBox.Text = 'Model Beam Sigma';
            app.ModelBeamSigmaCheckBox.Value = true;
            app.HorzNoDispersionCheckBox = uicheckbox(app.GridLayout2);
            app.HorzNoDispersionCheckBox.Tag = 'HorzNoDispersion';
            app.HorzNoDispersionCheckBox.Text = 'Horz No Dispersion';
            app.HorzNoDispersionCheckBox.Value = true;
            app.ModelBeamEnergyCheckBox = uicheckbox(app.GridLayout2);
            app.ModelBeamEnergyCheckBox.Tag = 'ModelBeamEnergy';
            app.ModelBeamEnergyCheckBox.Text = 'Model Beam Energy';
            app.ModelBeamEnergyCheckBox.Value = true;
            app.NewModelCheckBox = uicheckbox(app.GridLayout2);
            app.NewModelCheckBox.Tag = 'NewModel';
            app.NewModelCheckBox.Text = 'New Model';
            app.SCPAcqTab = uitab(app.TabGroup);
            app.SCPAcqTab.Title = 'SCP Acq';
            app.GridLayout6 = uigridlayout(app.SCPAcqTab);
            app.AcquireSCPCheckBox = uicheckbox(app.GridLayout6);
            app.AcquireSCPCheckBox.ValueChangedFcn = createCallbackFcn(app, @AcquireSCPCheckBoxValueChanged, true);
            app.AcquireSCPCheckBox.Text = 'Acquire SCP';
            app.AcquireSCPCheckBox.Value = true;
            app.AcquireBPMSCheckBox = uicheckbox(app.GridLayout6);
            app.AcquireBPMSCheckBox.ValueChangedFcn = createCallbackFcn(app, @AcquireSCPCheckBoxValueChanged, true);
            app.AcquireBPMSCheckBox.Text = 'Acquire BPMS';
            app.AcquireBPMSCheckBox.Value = true;
            app.AcquireRFCheckBox = uicheckbox(app.GridLayout6);
            app.AcquireRFCheckBox.ValueChangedFcn = createCallbackFcn(app, @AcquireSCPCheckBoxValueChanged, true);
            app.AcquireRFCheckBox.Text = 'Acquire RF';
            app.AcquireRFCheckBox.Value = true;
            % Show the figure after all components are created
            app.BSAGUIOptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bsagui_AdvancedOptions(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BSAGUIOptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BSAGUIOptionsUIFigure)
        end
    end
end