classdef bsagui_BPMOrbitTools < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OrbitToolsUIFigure           matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        TabGroup                     matlab.ui.container.TabGroup
        ReferenceOrbitTab            matlab.ui.container.Tab
        GridLayout2                  matlab.ui.container.GridLayout
        OrbitsPanel                  matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        OrbitListBox                 matlab.ui.control.ListBox
        LoadReferenceOrbitButton     matlab.ui.control.Button
        SaveReferenceOrbitButton     matlab.ui.control.Button
        OrbitOptionsButtonGroup      matlab.ui.container.ButtonGroup
        SingleOrbitButton            matlab.ui.control.RadioButton
        DifferenceOrbitButton        matlab.ui.control.RadioButton
        PrimaryOrbitDropDownLabel    matlab.ui.control.Label
        PrimaryOrbitDropDown         matlab.ui.control.DropDown
        ReferenceOrbitDropDownLabel  matlab.ui.control.Label
        ReferenceOrbitDropDown       matlab.ui.control.DropDown
        SaveAsCheckBox               matlab.ui.control.CheckBox
        DisableBPMSTab               matlab.ui.container.Tab
        GridLayout5                  matlab.ui.container.GridLayout
        EnabledListBoxLabel          matlab.ui.control.Label
        EnabledListBox               matlab.ui.control.ListBox
        DisabledListBoxLabel         matlab.ui.control.Label
        DisabledListBox              matlab.ui.control.ListBox
        DisableButton                matlab.ui.control.Button
        EnableButton                 matlab.ui.control.Button
        DisableAllDownstreamButton   matlab.ui.control.Button
        EnableAllDownstreamButton    matlab.ui.control.Button
        SearchEnabledEditField       matlab.ui.control.EditField
        SearchDisabledEditField      matlab.ui.control.EditField
        ContextMenu                  matlab.ui.container.ContextMenu
        EditNameMenu                 matlab.ui.container.Menu
    end

    
    properties (Access = private)
        orbit_mdl % Description
        
        STDERR = 2
        
        BPMSChangedListener
        newOrbitListener
        orbitLoadedListener
        orbitChangedListener
    end
    
    methods (Access = private)
        
        function errorMessage(app, ex, callbackMessage)
            err = ex.stack(1);
            file = err.file; funcname = err.name; linenum = num2str(err.line);
            file = strsplit(file, '/'); file = file{end};
            loc = sprintf('File: %s   Function: %s   Line: %s', file, funcname, linenum);
            uiwait(errordlg(...
                    lprintf(app.STDERR, '%s%c%s%c%s', callbackMessage, newline, ex.message, newline, loc)));
        end
        
        function orbitListUpdated(app)
            % populate the orbit lists
            app.PrimaryOrbitDropDown.Items = app.orbit_mdl.orbitNameList;
            app.ReferenceOrbitDropDown.Items = app.orbit_mdl.orbitNameList;
            app.OrbitListBox.Items = app.orbit_mdl.orbitNameList;
            app.OrbitOptionsButtonGroup.SelectedObject = app.OrbitOptionsButtonGroup.Children((app.orbit_mdl.orbitIdx ~= 0) + 1);
            if app.orbit_mdl.orbitIdx == 0
                app.ReferenceOrbitDropDown.Value = app.orbit_mdl.diffOrbitName;
            end
        end
        
        function onBPMSChanged(app)
            % update the BPMS lists
            if isvalid(app)
                app.EnabledListBox.Items = app.orbit_mdl.enabled;
                app.EnabledListBox.Value = {};
                app.DisabledListBox.Items = app.orbit_mdl.disabled;
                app.DisabledListBox.Value = {};
            end
        end
        
        function onEnabledSearch(app, searchString)
            % update PV list based on search string
            strs = split(searchString, '*');
            if length(strs) > 1
                str1 = strs(1); str2 = strs(2);
            else
                str1 = strs(1); str2 = str1;
            end
            pvList = app.orbit_mdl.enabled;
            pvList = pvList(contains(pvList, str1) & contains(pvList, str2));
            if length(pvList) == 1 % if only 1 options, set that PV
                app.EnabledListBox.Value = pvList{1};
            end
            app.EnabledListBox.Items = pvList;
        end
        
        function onDisabledSearch(app, searchString)
            % update PV list based on search string
            strs = split(searchString, '*');
            if length(strs) > 1
                str1 = strs(1); str2 = strs(2);
            else
                str1 = strs(1); str2 = str1;
            end
            pvList = app.orbit_mdl.disabled;
            pvList = pvList(contains(pvList, str1) & contains(pvList, str2));
            if length(pvList) == 1 % if only 1 options, set that PV
                app.DisabledListBox.Value = pvList{1};
            end
            app.DisabledListBox.Items = pvList;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, orbit_mdl)
            app.orbit_mdl = orbit_mdl;
            
            onBPMSChanged(app);
            
            app.BPMSChangedListener = addlistener(app.orbit_mdl, 'BPMSChanged', @(~,~)app.onBPMSChanged);
            app.orbitLoadedListener = addlistener(app.orbit_mdl, 'orbitLoaded', @(~,~)app.orbitListUpdated);
            app.orbitChangedListener = addlistener(app.orbit_mdl, 'newOrbit', @(~,~)app.orbitListUpdated);
            
            orbitListUpdated(app);
            
            app.OrbitOptionsButtonGroup.SelectedObject = app.OrbitOptionsButtonGroup.Children((app.orbit_mdl.orbitIdx ~= 0) + 1);
            if app.orbit_mdl.orbitIdx == 0
                app.ReferenceOrbitDropDown.Value = app.orbit_mdl.diffOrbitName;
            end
        end

        % Close request function: OrbitToolsUIFigure
        function OrbitToolsUIFigureCloseRequest(app, event)
            app.orbit_mdl.toolsWindow = [];
            delete(app.BPMSChangedListener);
            delete(app.newOrbitListener);
            delete(app.orbitLoadedListener);
            delete(app.orbitChangedListener);
            delete(app);
        end

        % Button pushed function: DisableButton
        function DisableButtonPushed(app, event)
            try
                pvs = app.EnabledListBox.Value;
                disableBPMS(app.orbit_mdl, pvs);
            catch ME
                errorMessage(app, ME, 'Error disabling BPMS');
            end
        end

        % Button pushed function: EnableButton
        function EnableButtonPushed(app, event)
            try
                pvs = app.DisabledListBox.Value;
                enableBPMS(app.orbit_mdl, pvs);
            catch ME
                errorMessage(app, ME, 'Error enabling BPMS');
            end
        end

        % Button pushed function: DisableAllDownstreamButton
        function DisableAllDownstreamButtonPushed(app, event)
            try
                pvs = app.EnabledListBox.Value;
                idx = find(contains(app.EnabledListBox.Items, pvs(length(pvs))));
                pvs = app.EnabledListBox.Items(idx:length(app.EnabledListBox.Items));
                disableBPMS(app.orbit_mdl, pvs);
            catch ME
                errorMessage(app, ME, 'Error disabling downstream BPMS');
            end
        end

        % Button pushed function: EnableAllDownstreamButton
        function EnableAllDownstreamButtonPushed(app, event)
            try
                pvs = app.DisabledListBox.Value;
                idx = find(contains(app.DisabledListBox.Items, pvs(length(pvs))));
                pvs = app.DisabledListBox.Items(idx:length(app.DisabledListBox.Items));
                enableBPMS(app.orbit_mdl, pvs);
            catch ME
                errorMessage(app, ME, 'Error dienablingsabling downstream BPMS');
            end
        end

        % Button pushed function: LoadReferenceOrbitButton
        function LoadReferenceOrbitButtonPushed(app, event)
            try
               loadOrbit(app.orbit_mdl); 
            catch ex
                errorMessage(app, ex, 'Error loading file.');
            end
        end

        % Value changed function: PrimaryOrbitDropDown
        function PrimaryOrbitDropDownValueChanged(app, event)
            currentOrbit = app.PrimaryOrbitDropDown.Value;
            if app.SingleOrbitButton.Value
                changeOrbit(app.orbit_mdl, currentOrbit);
            else
                differenceOrbit(app.orbit_mdl, currentOrbit, app.ReferenceOrbitDropDown.Value)
            end
        end

        % Value changed function: ReferenceOrbitDropDown
        function ReferenceOrbitDropDownValueChanged(app, event)
            refOrbit = app.ReferenceOrbitDropDown.Value;
            if app.DifferenceOrbitButton.Value
                differenceOrbit(app.orbit_mdl, app.PrimaryOrbitDropDown.Value, refOrbit);
            end
        end

        % Selection changed function: OrbitOptionsButtonGroup
        function OrbitOptionsButtonGroupSelectionChanged(app, event)
            orbitType = app.OrbitOptionsButtonGroup.SelectedObject.Tag;
            switch orbitType
                case 'single'
                    orbitA = app.PrimaryOrbitDropDown.Value;
                    changeOrbit(app.orbit_mdl, orbitA);
                case 'diff'
                    if length(app.orbit_mdl.orbitNameList) == 1
                        app.DifferenceOrbitButton.Value = 0;
                        app.SingleOrbitButton.Value = 1;
                        return
                    end
                    orbitA = app.PrimaryOrbitDropDown.Value;
                    orbitB = app.ReferenceOrbitDropDown.Value;
                    differenceOrbit(app.orbit_mdl, orbitA, orbitB)
            end
        end

        % Button pushed function: SaveReferenceOrbitButton
        function SaveReferenceOrbitButtonPushed(app, event)
            try
                saveOrbit(app.orbit_mdl, app.SaveAsCheckBox.Value);
                saveData(app.orbit_mdl.BSAGUI, app.SaveAsCheckBox.Value);
            catch ME
                errorMessage(app, ME, 'Error saving reference orbit.');
            end
        end

        % Value changing function: SearchEnabledEditField
        function SearchEnabledEditFieldValueChanging(app, event)
            try
                searchString = upper(event.Value);
                onEnabledSearch(app, searchString);
            catch ME
                errorMessage(app, ME, 'Error searching enabled list');
            end
        end

        % Value changing function: SearchDisabledEditField
        function SearchDisabledEditFieldValueChanging(app, event)
        try
            searchString = upper(event.Value);
            onDisabledSearch(app, searchString);
        catch ME
            errorMessage(app, ME, 'Error searching disabled list');
        end
        end

        % Menu selected function: EditNameMenu
        function EditNameMenuSelected(app, event)
            try
                orbitName = app.OrbitListBox.Value;
                changeOrbitName(app.orbit_mdl, orbitName);
            catch ME
                errorMessage(app, ME, 'Error changing orbit name');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OrbitToolsUIFigure = uifigure('Visible', 'off');
            app.OrbitToolsUIFigure.Name = 'Orbit Tools';
            app.OrbitToolsUIFigure.CloseRequestFcn = createCallbackFcn(app, @OrbitToolsUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.OrbitToolsUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ReferenceOrbitTab = uitab(app.TabGroup);
            app.ReferenceOrbitTab.Title = 'Reference Orbit';
            app.GridLayout2 = uigridlayout(app.ReferenceOrbitTab);
            app.OrbitsPanel = uipanel(app.GridLayout2);
            app.OrbitsPanel.Title = 'Orbits';
            app.GridLayout3 = uigridlayout(app.OrbitsPanel);
            app.OrbitListBox = uilistbox(app.GridLayout3);
            app.OrbitListBox.Items = {'BSA GUI Orbit'};
            app.OrbitListBox.Value = 'BSA GUI Orbit';
            app.LoadReferenceOrbitButton = uibutton(app.GridLayout2, 'push');
            app.LoadReferenceOrbitButton.ButtonPushedFcn = createCallbackFcn(app, @LoadReferenceOrbitButtonPushed, true);
            app.LoadReferenceOrbitButton.Text = 'Load Reference Orbit';
            app.SaveReferenceOrbitButton = uibutton(app.GridLayout2, 'push');
            app.SaveReferenceOrbitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveReferenceOrbitButtonPushed, true);
            app.SaveReferenceOrbitButton.Text = 'Save Reference Orbit';
            app.OrbitOptionsButtonGroup = uibuttongroup(app.GridLayout2);
            app.OrbitOptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OrbitOptionsButtonGroupSelectionChanged, true);
            app.OrbitOptionsButtonGroup.Title = 'Orbit Options';
            app.SingleOrbitButton = uiradiobutton(app.OrbitOptionsButtonGroup);
            app.SingleOrbitButton.Tag = 'single';
            app.SingleOrbitButton.Text = 'Single orbit';
            app.SingleOrbitButton.Value = true;
            app.DifferenceOrbitButton = uiradiobutton(app.OrbitOptionsButtonGroup);
            app.DifferenceOrbitButton.Tag = 'diff';
            app.DifferenceOrbitButton.Text = 'Difference orbit from selections in list';
            app.PrimaryOrbitDropDownLabel = uilabel(app.GridLayout2);
            app.PrimaryOrbitDropDownLabel.Text = 'Primary Orbit';
            app.PrimaryOrbitDropDown = uidropdown(app.GridLayout2);
            app.PrimaryOrbitDropDown.Items = {};
            app.PrimaryOrbitDropDown.ValueChangedFcn = createCallbackFcn(app, @PrimaryOrbitDropDownValueChanged, true);
            app.PrimaryOrbitDropDown.Value = {};
            app.ReferenceOrbitDropDownLabel = uilabel(app.GridLayout2);
            app.ReferenceOrbitDropDownLabel.Text = 'Reference Orbit';
            app.ReferenceOrbitDropDown = uidropdown(app.GridLayout2);
            app.ReferenceOrbitDropDown.Items = {};
            app.ReferenceOrbitDropDown.ValueChangedFcn = createCallbackFcn(app, @ReferenceOrbitDropDownValueChanged, true);
            app.ReferenceOrbitDropDown.Value = {};
            app.SaveAsCheckBox = uicheckbox(app.GridLayout2);
            app.SaveAsCheckBox.Text = 'Save As';
            app.DisableBPMSTab = uitab(app.TabGroup);
            app.DisableBPMSTab.Title = 'Disable BPMS';
            app.GridLayout5 = uigridlayout(app.DisableBPMSTab);
            app.EnabledListBoxLabel = uilabel(app.GridLayout5);
            app.EnabledListBoxLabel.Text = 'Enabled';
            app.EnabledListBox = uilistbox(app.GridLayout5);
            app.EnabledListBox.Items = {};
            app.EnabledListBox.Multiselect = 'on';
            app.EnabledListBox.Value = {};
            app.DisabledListBoxLabel = uilabel(app.GridLayout5);
            app.DisabledListBoxLabel.Text = 'Disabled';
            app.DisabledListBox = uilistbox(app.GridLayout5);
            app.DisabledListBox.Items = {'', ''};
            app.DisabledListBox.Multiselect = 'on';
            app.DisabledListBox.Value = {''};
            app.DisableButton = uibutton(app.GridLayout5, 'push');
            app.DisableButton.ButtonPushedFcn = createCallbackFcn(app, @DisableButtonPushed, true);
            app.DisableButton.Text = 'Disable';
            app.EnableButton = uibutton(app.GridLayout5, 'push');
            app.EnableButton.ButtonPushedFcn = createCallbackFcn(app, @EnableButtonPushed, true);
            app.EnableButton.Text = 'Enable';
            app.DisableAllDownstreamButton = uibutton(app.GridLayout5, 'push');
            app.DisableAllDownstreamButton.ButtonPushedFcn = createCallbackFcn(app, @DisableAllDownstreamButtonPushed, true);
            app.DisableAllDownstreamButton.Text = 'Disable All Downstream';
            app.EnableAllDownstreamButton = uibutton(app.GridLayout5, 'push');
            app.EnableAllDownstreamButton.ButtonPushedFcn = createCallbackFcn(app, @EnableAllDownstreamButtonPushed, true);
            app.EnableAllDownstreamButton.Text = 'Enable All Downstream';
            app.SearchEnabledEditField = uieditfield(app.GridLayout5, 'text');
            app.SearchEnabledEditField.ValueChangingFcn = createCallbackFcn(app, @SearchEnabledEditFieldValueChanging, true);
            app.SearchDisabledEditField = uieditfield(app.GridLayout5, 'text');
            app.SearchDisabledEditField.ValueChangingFcn = createCallbackFcn(app, @SearchDisabledEditFieldValueChanging, true);
            app.ContextMenu = uicontextmenu(app.OrbitToolsUIFigure);
            % Assign app.ContextMenu
            app.OrbitListBox.ContextMenu = app.ContextMenu;
            app.EditNameMenu = uimenu(app.ContextMenu);
            app.EditNameMenu.MenuSelectedFcn = createCallbackFcn(app, @EditNameMenuSelected, true);
            app.EditNameMenu.Text = 'Edit Name';
            % Show the figure after all components are created
            app.OrbitToolsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bsagui_BPMOrbitTools(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OrbitToolsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OrbitToolsUIFigure)
        end
    end
end