classdef F2_CameraSwapSummary < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UITable                      matlab.ui.control.Table
        UIAxes                       matlab.ui.control.UIAxes
        CameraSwaps202324Label       matlab.ui.control.Label
        UITable2                     matlab.ui.control.Table
        UIAxes2                      matlab.ui.control.UIAxes
        November92023May222024Label  matlab.ui.control.Label
        PerformanceofEachCameraModelLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            data1 = load('Camera_Swap.mat','Camera_Swap');
            Camera_Swap = data1.Camera_Swap;
            app.UITable.Data = Camera_Swap;
            data2 = load('replacement_summary.mat','replacement_summary');
            replacement_summary = data2.replacement_summary;
            data3 = load('model_summary.mat','model_summary');
            model_summary = data3.model_summary;
            app.UITable2.Data = model_summary;
            bar(app.UIAxes, replacement_summary.Replacements);
            app.UIAxes.XTickLabel = replacement_summary.Name;
            title(app.UIAxes, 'Replacement Summary')
            xlabel(app.UIAxes,'Camera Name');
            ylabel(app.UIAxes,'Replacement Counts');
            bar(app.UIAxes2, model_summary.Replacements);
            app.UIAxes2.XTickLabel = model_summary.Model;
            title(app.UIAxes2, 'Model Replacement Summary')
            xlabel(app.UIAxes2,'Camera Model'); 
            ylabel(app.UIAxes2,'Replacements');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Date'; 'Camera Name'; 'Model'; 'Old SN'; 'New SN'; 'Old Reboot Count'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CameraSwaps202324Label = uilabel(app.UIFigure);
            app.CameraSwaps202324Label.Text = 'Camera Swaps 2023-24';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Model Name'; 'Replacements'};
            app.UITable2.RowName = {};
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.November92023May222024Label = uilabel(app.UIFigure);
            app.November92023May222024Label.Text = 'November 9, 2023 - May 22, 2024';
            app.PerformanceofEachCameraModelLabel = uilabel(app.UIFigure);
            app.PerformanceofEachCameraModelLabel.Text = 'Performance of Each Camera Model';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = F2_CameraSwapSummary

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end