classdef corrplot_metaData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        MetadataPVsPanel  matlab.ui.container.Panel
        GridLayout2       matlab.ui.container.GridLayout
        PVs               matlab.ui.control.TextArea
        ConfigureButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        mdl corrPlot_mdl % Description
    end
    
    methods (Access = private)
        
        function errorMessage(app, ex, callbackMessage)
            % create a warning dialogue box with the location of the
            % exception
            err = ex.stack(1);
            file = err.file; funcname = err.name; linenum = num2str(err.line);
            file = strsplit(file, '/'); file = file{length(file)};
            loc = sprintf('File: %s   Function: %s   Line: %s', file, funcname, linenum);
            uiwait(errordlg(...
                    lprintf(app.STDERR, '%s%c%s%c%s', callbackMessage, newline, ex.message, newline, loc)));
        end
        
        function activate(app)
            %Correct bug with edit fields on window open
            app.UIFigure.WindowState='minimized';
            drawnow();
            app.UIFigure.WindowState='normal';
            drawnow();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mdl)
            app.mdl = mdl;
            if ~isempty(app.mdl.nameList.metaDataPV)
                app.PVs.Value = app.mdl.nameList.metaDataPV;
            end
            
            % Time activate function to handle edit field glitch
             t = timer('TimerFcn',@(~,~)activate(app),'StartDelay',1,'Name','activator');
             start(t);
        end

        % Button pushed function: ConfigureButton
        function ConfigureButtonPushed(app, event)
             try
                metaDataPVs = app.PVs.Value;
                % clear white space
                metaDataPVs(cellfun(@isempty, metaDataPVs)) = [];
                setMetaData(app.mdl, metaDataPVs);
                delete(app);
            catch ME
                errorMessage(app, ME, 'Error updating metadata PVs');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MetadataPVsPanel = uipanel(app.GridLayout);
            app.MetadataPVsPanel.Title = 'Metadata PVs';
            app.GridLayout2 = uigridlayout(app.MetadataPVsPanel);
            app.PVs = uitextarea(app.GridLayout2);
            app.ConfigureButton = uibutton(app.GridLayout, 'push');
            app.ConfigureButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureButtonPushed, true);
            app.ConfigureButton.Tooltip = {'Select configure to confirm metadata PVs'};
            app.ConfigureButton.Text = 'Configure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = corrplot_metaData(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end