classdef DAQMonitorapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        DAQRunningStatusLampLabel  matlab.ui.control.Label
        DAQRunningStatusLamp       matlab.ui.control.Lamp
        DAQMonitorAppLabel         matlab.ui.control.Label
        EnabledLampLabel           matlab.ui.control.Label
        EnabledLamp                matlab.ui.control.Lamp
        ResettoZeroLamp_2Label     matlab.ui.control.Label
        ResettoZeroLamp            matlab.ui.control.Lamp
        PVsMonitoredLabel          matlab.ui.control.Label
        UpdateStatusButton         matlab.ui.control.Button
        EditField                  matlab.ui.control.EditField
        LegendLabel_2              matlab.ui.control.Label
        DAQNotRunningLamp_2Label   matlab.ui.control.Label
        DAQNotRunningLamp_2        matlab.ui.control.Lamp
        NotEnabledLampLabel        matlab.ui.control.Label
        NotEnabledLamp             matlab.ui.control.Lamp
        NotResettoZeroLampLabel    matlab.ui.control.Label
        NotResettoZeroLamp         matlab.ui.control.Lamp
        Enable214Button            matlab.ui.control.Button
        ResettoZeroButton          matlab.ui.control.Button
        ResetBothButton            matlab.ui.control.Button
        RunTestDAQButton           matlab.ui.control.Button
        ResetDAQButton             matlab.ui.control.Button
        AbortDAQButton             matlab.ui.control.Button
        DAQRunningLamp_3Label      matlab.ui.control.Label
        DAQRunningLamp_3           matlab.ui.control.Lamp
        EnabledLamp_3Label         matlab.ui.control.Label
        EnabledLamp_3              matlab.ui.control.Lamp
        ResettoZeroLamp_3Label     matlab.ui.control.Label
        ResettoZeroLamp_3          matlab.ui.control.Lamp
        DAQlabel                   matlab.ui.control.Label
        UITable                    matlab.ui.control.Table
        CloseButton                matlab.ui.control.Button
        UpdateLabel                matlab.ui.control.Label
        AbortLabel                 matlab.ui.control.Label
        ResetLabel                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        aobj % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.aobj = DAQMonitorScript(app);
            app.aobj.UpdateTable();
        end

        % Button pushed function: ResetBothButton
        function ResetBothButtonPushed(app, event)
            app.aobj.ResetPVs();
        end

        % Button pushed function: Enable214Button
        function Enable214ButtonPushed(app, event)
            app.aobj.Enable();
        end

        % Button pushed function: ResettoZeroButton
        function ResettoZeroButtonPushed(app, event)
            app.aobj.ResetZero();
        end

        % Button pushed function: UpdateStatusButton
        function UpdateStatusButtonPushed(app, event)
            app.aobj.UpdateStatusButton();
        end

        % Callback function: RunTestDAQButton, UIFigure
        function RunDAQGUIButtonPushed(app, event)
            app.aobj.RunDAQ();
        end

        % Button pushed function: AbortDAQButton
        function AbortDAQButtonPushed(app, event)
            app.aobj.AbortDAQ();
        end

        % Button pushed function: ResetDAQButton
        function ResetDAQButtonPushed(app, event)
            app.aobj.ResetDAQ();
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @RunDAQGUIButtonPushed, true);
            app.DAQRunningStatusLampLabel = uilabel(app.UIFigure);
            app.DAQRunningStatusLampLabel.Text = 'DAQ Running Status';
            app.DAQRunningStatusLamp = uilamp(app.UIFigure);
            app.DAQMonitorAppLabel = uilabel(app.UIFigure);
            app.DAQMonitorAppLabel.Text = 'DAQ Monitor App';
            app.EnabledLampLabel = uilabel(app.UIFigure);
            app.EnabledLampLabel.Text = '214 Enabled';
            app.EnabledLamp = uilamp(app.UIFigure);
            app.ResettoZeroLamp_2Label = uilabel(app.UIFigure);
            app.ResettoZeroLamp_2Label.Text = 'Reset to Zero';
            app.ResettoZeroLamp = uilamp(app.UIFigure);
            app.PVsMonitoredLabel = uilabel(app.UIFigure);
            app.PVsMonitoredLabel.Text = 'PVs Monitored';
            app.UpdateStatusButton = uibutton(app.UIFigure, 'push');
            app.UpdateStatusButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateStatusButtonPushed, true);
            app.UpdateStatusButton.Text = 'Update Status';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.LegendLabel_2 = uilabel(app.UIFigure);
            app.LegendLabel_2.Text = 'Legend';
            app.DAQNotRunningLamp_2Label = uilabel(app.UIFigure);
            app.DAQNotRunningLamp_2Label.Text = 'DAQ Not Running';
            app.DAQNotRunningLamp_2 = uilamp(app.UIFigure);
            app.NotEnabledLampLabel = uilabel(app.UIFigure);
            app.NotEnabledLampLabel.Text = 'Not Enabled';
            app.NotEnabledLamp = uilamp(app.UIFigure);
            app.NotResettoZeroLampLabel = uilabel(app.UIFigure);
            app.NotResettoZeroLampLabel.Text = 'Not Reset to Zero';
            app.NotResettoZeroLamp = uilamp(app.UIFigure);
            app.Enable214Button = uibutton(app.UIFigure, 'push');
            app.Enable214Button.ButtonPushedFcn = createCallbackFcn(app, @Enable214ButtonPushed, true);
            app.Enable214Button.Text = 'Enable 214';
            app.ResettoZeroButton = uibutton(app.UIFigure, 'push');
            app.ResettoZeroButton.ButtonPushedFcn = createCallbackFcn(app, @ResettoZeroButtonPushed, true);
            app.ResettoZeroButton.Text = 'Reset to Zero';
            app.ResetBothButton = uibutton(app.UIFigure, 'push');
            app.ResetBothButton.ButtonPushedFcn = createCallbackFcn(app, @ResetBothButtonPushed, true);
            app.ResetBothButton.Text = 'Reset Both';
            app.RunTestDAQButton = uibutton(app.UIFigure, 'push');
            app.RunTestDAQButton.ButtonPushedFcn = createCallbackFcn(app, @RunDAQGUIButtonPushed, true);
            app.RunTestDAQButton.Text = 'Run Test DAQ';
            app.ResetDAQButton = uibutton(app.UIFigure, 'push');
            app.ResetDAQButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDAQButtonPushed, true);
            app.ResetDAQButton.Text = 'Reset DAQ';
            app.AbortDAQButton = uibutton(app.UIFigure, 'push');
            app.AbortDAQButton.ButtonPushedFcn = createCallbackFcn(app, @AbortDAQButtonPushed, true);
            app.AbortDAQButton.Text = 'Abort DAQ';
            app.DAQRunningLamp_3Label = uilabel(app.UIFigure);
            app.DAQRunningLamp_3Label.Text = 'DAQ Running';
            app.DAQRunningLamp_3 = uilamp(app.UIFigure);
            app.EnabledLamp_3Label = uilabel(app.UIFigure);
            app.EnabledLamp_3Label.Text = '214 Enabled';
            app.EnabledLamp_3 = uilamp(app.UIFigure);
            app.ResettoZeroLamp_3Label = uilabel(app.UIFigure);
            app.ResettoZeroLamp_3Label.Text = 'Reset to Zero';
            app.ResettoZeroLamp_3 = uilamp(app.UIFigure);
            app.DAQlabel = uilabel(app.UIFigure);
            app.DAQlabel.Text = '';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Camera Name'; 'Shots Captured'};
            app.UITable.RowName = {};
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.UpdateLabel = uilabel(app.UIFigure);
            app.UpdateLabel.Text = '';
            app.AbortLabel = uilabel(app.UIFigure);
            app.AbortLabel.Text = '';
            app.ResetLabel = uilabel(app.UIFigure);
            app.ResetLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DAQMonitorapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end