classdef BC11E_Settings < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    FeedbackSettingsUIFigure  matlab.ui.Figure
    GridLayout                matlab.ui.container.GridLayout
    LeftPanel                 matlab.ui.container.Panel
    GainEditFieldLabel        matlab.ui.control.Label
    GainEditField             matlab.ui.control.NumericEditField
    BC11EnergyFeedbackSettingsLabel  matlab.ui.control.Label
    ControlVariableL1EVernierLimitsPanel  matlab.ui.container.Panel
    EditField                 matlab.ui.control.NumericEditField
    EditField_2               matlab.ui.control.NumericEditField
    LowLabel                  matlab.ui.control.Label
    HighLabel                 matlab.ui.control.Label
    SetpointBPM11333SaturationLimitsmmPanel  matlab.ui.container.Panel
    EditField_3               matlab.ui.control.NumericEditField
    EditField_4               matlab.ui.control.NumericEditField
    LowLabel_2                matlab.ui.control.Label
    HighLabel_2               matlab.ui.control.Label
    SetpointFilterFreqHz0OFFEditFieldLabel  matlab.ui.control.Label
    FilterFreqEditField       matlab.ui.control.NumericEditField
    SetpointBPM11333DeadbandLimitsmmPanel  matlab.ui.container.Panel
    EditField_5               matlab.ui.control.NumericEditField
    EditField_6               matlab.ui.control.NumericEditField
    LowLabel_3                matlab.ui.control.Label
    HighLabel_3               matlab.ui.control.Label
    SetpointBPM11333TMITLimitsNe9Panel  matlab.ui.container.Panel
    EditField_7               matlab.ui.control.NumericEditField
    EditField_8               matlab.ui.control.NumericEditField
    LowLabel_4                matlab.ui.control.Label
    HighLabel_4               matlab.ui.control.Label
    RightPanel                matlab.ui.container.Panel
    UIAxes                    matlab.ui.control.UIAxes
  end

  % Properties that correspond to apps with auto-reflow
  properties (Access = private)
    onePanelWidth = 576;
  end

  
  properties (Access = private)
    aobj % Feedback application object
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, FeedbackApp)
      app.aobj = FeedbackApp ;
      app.aobj.SettingsGui = app ;
      app.aobj.SettingsGui_whichFeedback = 3 ;
      app.aobj.Feedbacks(3).SetpointVar.axhan=app.UIAxes;
      app.aobj.Feedbacks(3).SetpointVar.StripPlot=true;
      gh.BC11E_Gain = app.GainEditField ;
      gh.BC11E_ControlLimitLo = app.EditField ;
      gh.BC11E_ControlLimitHi = app.EditField_2 ;
      gh.BC11E_SetpointLimitLo = app.EditField_3 ;
      gh.BC11E_SetpointLimitHi = app.EditField_4 ;
      gh.BC11E_SetpointFilterFreq = app.FilterFreqEditField ;
      gh.BC11E_SetpointDeadbandLo = app.EditField_5 ;
      gh.BC11E_SetpointDeadbandHi = app.EditField_6 ;
      gh.BC11E_TMITLo = app.EditField_7 ;
      gh.BC11E_TMITHi = app.EditField_8 ;
      app.aobj.SettingsGuiLink(gh,"Attach");
      fn=fieldnames(gh);
      for ifn=1:length(fn)
        gh.(fn{ifn}).Value = app.aobj.pvs.(fn{ifn}).val{1} ;
      end
    end

    % Close request function: FeedbackSettingsUIFigure
    function FeedbackSettingsUIFigureCloseRequest(app, event)
      gh.BC11E_Gain = app.GainEditField ;
      gh.BC11E_ControlLimitLo = app.EditField ;
      gh.BC11E_ControlLimitHi = app.EditField_2 ;
      gh.BC11E_SetpointLimitLo = app.EditField_3 ;
      gh.BC11E_SetpointLimitHi = app.EditField_4 ;
      gh.BC11E_SetpointFilterFreq = app.FilterFreqEditField ;
      gh.BC11E_SetpointDeadbandLo = app.EditField_5 ;
      gh.BC11E_SetpointDeadbandHi = app.EditField_6 ;
      gh.BC11E_TMITLo = app.EditField_7 ;
      gh.BC11E_TMITHi = app.EditField_8 ;
      app.aobj.SettingsGuiLink(gh,"Detach");
      app.aobj.Feedbacks(3).SetpointVar.StripPlot=false;
      app.aobj.Feedbacks(3).SetpointVar.axhan=[];
      app.aobj.SettingsGui=[];
      app.aobj.SettingsGui_whichFeedback = 0 ;
      app.aobj.guihan.BC11EnergyFeedbackMenu.Enable = true ;
      delete(app)
    end

    % Changes arrangement of the app based on UIFigure width
    function updateAppLayout(app, event)
            currentFigureWidth = app.FeedbackSettingsUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {484, 484};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {293, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.FeedbackSettingsUIFigure = uifigure('Visible', 'off');
      app.FeedbackSettingsUIFigure.Name = 'Feedback Settings';
      app.FeedbackSettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @FeedbackSettingsUIFigureCloseRequest, true);
      app.FeedbackSettingsUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
      app.GridLayout = uigridlayout(app.FeedbackSettingsUIFigure);
      app.LeftPanel = uipanel(app.GridLayout);
      app.GainEditFieldLabel = uilabel(app.LeftPanel);
      app.GainEditFieldLabel.Text = 'Gain';
      app.GainEditField = uieditfield(app.LeftPanel, 'numeric');
      app.GainEditField.Value = 1;
      app.BC11EnergyFeedbackSettingsLabel = uilabel(app.LeftPanel);
      app.BC11EnergyFeedbackSettingsLabel.Text = 'BC11 Energy Feedback Settings';
      app.ControlVariableL1EVernierLimitsPanel = uipanel(app.LeftPanel);
      app.ControlVariableL1EVernierLimitsPanel.Title = 'Control Variable: L1 E Vernier Limits';
      app.EditField = uieditfield(app.ControlVariableL1EVernierLimitsPanel, 'numeric');
      app.EditField.Value = 5;
      app.EditField_2 = uieditfield(app.ControlVariableL1EVernierLimitsPanel, 'numeric');
      app.EditField_2.Value = 60;
      app.LowLabel = uilabel(app.ControlVariableL1EVernierLimitsPanel);
      app.LowLabel.Text = 'Low';
      app.HighLabel = uilabel(app.ControlVariableL1EVernierLimitsPanel);
      app.HighLabel.Text = 'High';
      app.SetpointBPM11333SaturationLimitsmmPanel = uipanel(app.LeftPanel);
      app.SetpointBPM11333SaturationLimitsmmPanel.Title = 'Setpoint: BPM11333 Saturation Limits [mm]';
      app.EditField_3 = uieditfield(app.SetpointBPM11333SaturationLimitsmmPanel, 'numeric');
      app.EditField_3.Value = -10;
      app.EditField_4 = uieditfield(app.SetpointBPM11333SaturationLimitsmmPanel, 'numeric');
      app.EditField_4.Value = 10;
      app.LowLabel_2 = uilabel(app.SetpointBPM11333SaturationLimitsmmPanel);
      app.LowLabel_2.Text = 'Low';
      app.HighLabel_2 = uilabel(app.SetpointBPM11333SaturationLimitsmmPanel);
      app.HighLabel_2.Text = 'High';
      app.SetpointFilterFreqHz0OFFEditFieldLabel = uilabel(app.LeftPanel);
      app.SetpointFilterFreqHz0OFFEditFieldLabel.Text = {'Setpoint Filter Freq. [Hz]:'; '(0 = OFF)'};
      app.FilterFreqEditField = uieditfield(app.LeftPanel, 'numeric');
      app.FilterFreqEditField.Value = 0.1;
      app.SetpointBPM11333DeadbandLimitsmmPanel = uipanel(app.LeftPanel);
      app.SetpointBPM11333DeadbandLimitsmmPanel.Title = 'Setpoint: BPM11333 Deadband Limits [mm]';
      app.EditField_5 = uieditfield(app.SetpointBPM11333DeadbandLimitsmmPanel, 'numeric');
      app.EditField_5.Value = -0.2;
      app.EditField_6 = uieditfield(app.SetpointBPM11333DeadbandLimitsmmPanel, 'numeric');
      app.EditField_6.Value = 0.2;
      app.LowLabel_3 = uilabel(app.SetpointBPM11333DeadbandLimitsmmPanel);
      app.LowLabel_3.Text = 'Low';
      app.HighLabel_3 = uilabel(app.SetpointBPM11333DeadbandLimitsmmPanel);
      app.HighLabel_3.Text = 'High';
      app.SetpointBPM11333TMITLimitsNe9Panel = uipanel(app.LeftPanel);
      app.SetpointBPM11333TMITLimitsNe9Panel.Title = 'Setpoint: BPM11333 TMIT Limits [Ne^9]';
      app.EditField_7 = uieditfield(app.SetpointBPM11333TMITLimitsNe9Panel, 'numeric');
      app.EditField_7.Value = 0.5;
      app.EditField_8 = uieditfield(app.SetpointBPM11333TMITLimitsNe9Panel, 'numeric');
      app.EditField_8.Value = 50;
      app.LowLabel_4 = uilabel(app.SetpointBPM11333TMITLimitsNe9Panel);
      app.LowLabel_4.Text = 'Low';
      app.HighLabel_4 = uilabel(app.SetpointBPM11333TMITLimitsNe9Panel);
      app.HighLabel_4.Text = 'High';
      app.RightPanel = uipanel(app.GridLayout);
      app.UIAxes = uiaxes(app.RightPanel);
      title(app.UIAxes, 'BPM11333')
      xlabel(app.UIAxes, 'time [s]')
      ylabel(app.UIAxes, 'X [mm]')
      % Show the figure after all components are created
      app.FeedbackSettingsUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = BC11E_Settings(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.FeedbackSettingsUIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.FeedbackSettingsUIFigure)
    end
  end
end