classdef F2_Feedback < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    FACETIIFeedbackUIFigure       matlab.ui.Figure
    StripchartsMenu               matlab.ui.container.Menu
    DL1EnergyMenu                 matlab.ui.container.Menu
    BC11EnergyMenu                matlab.ui.container.Menu
    BC14EnergyMenu                matlab.ui.container.Menu
    SettingsMenu                  matlab.ui.container.Menu
    DL1EnergyFeedbackMenu         matlab.ui.container.Menu
    BC11EnergyFeedbackMenu        matlab.ui.container.Menu
    BC11BLENFeedbackMenu          matlab.ui.container.Menu
    BC14EnergyFeedbackMenu        matlab.ui.container.Menu
    DisplayEnergyUnitsMenu        matlab.ui.container.Menu
    JitterTimeoutMenu             matlab.ui.container.Menu
    DL10EnergyFeedbackPanel       matlab.ui.container.Panel
    SetpointEditField             matlab.ui.control.NumericEditField
    mmLabel_5                     matlab.ui.control.Label
    Gauge                         matlab.ui.control.LinearGauge
    StatusLamp                    matlab.ui.control.Lamp
    Switch                        matlab.ui.control.Switch
    Gauge_3                       matlab.ui.control.LinearGauge
    KLYSIN1041SFB_ADESLabel       matlab.ui.control.Label
    BPMSIN10731X1HLabel           matlab.ui.control.Label
    EditField                     matlab.ui.control.NumericEditField
    EditField_2                   matlab.ui.control.NumericEditField
    NotRunningButton              matlab.ui.control.Button
    FeedbackJitterButton          matlab.ui.control.StateButton
    SetpointJitterAmplitudeEditFieldLabel  matlab.ui.control.Label
    SetpointJitterAmplitudeEditField  matlab.ui.control.NumericEditField
    mmLabel_8                     matlab.ui.control.Label
    BC14EnergyFeedbackPanel       matlab.ui.container.Panel
    SetpointEditField_2           matlab.ui.control.NumericEditField
    mmLabel_4                     matlab.ui.control.Label
    Switch_2                      matlab.ui.control.Switch
    StatusLamp_2                  matlab.ui.control.Lamp
    BC14KLYS1Label                matlab.ui.control.Label
    BC14KLYS2Label                matlab.ui.control.Label
    BPMSLI14801X1HLabel           matlab.ui.control.Label
    EditField_5                   matlab.ui.control.NumericEditField
    EditField_6                   matlab.ui.control.NumericEditField
    NotRunningButton_5            matlab.ui.control.Button
    Gauge_16                      matlab.ui.control.NinetyDegreeGauge
    Gauge_17                      matlab.ui.control.NinetyDegreeGauge
    Gauge_6                       matlab.ui.control.LinearGauge
    BC20EnergyFeedbackPanel       matlab.ui.container.Panel
    SetpointEditField_4           matlab.ui.control.NumericEditField
    mmLabel_7                     matlab.ui.control.Label
    Switch_4                      matlab.ui.control.Switch
    StatusLamp_4                  matlab.ui.control.Lamp
    Gauge_7                       matlab.ui.control.LinearGauge
    BPMSLI202445X1HLabel          matlab.ui.control.Label
    EditField_8                   matlab.ui.control.NumericEditField
    EditField_9                   matlab.ui.control.NumericEditField
    NotRunningButton_6            matlab.ui.control.Button
    BC14KLYS1Label_2              matlab.ui.control.Label
    BC14KLYS2Label_2              matlab.ui.control.Label
    Gauge_18                      matlab.ui.control.NinetyDegreeGauge
    Gauge_19                      matlab.ui.control.NinetyDegreeGauge
    BC11EnergyFeedbackPanel       matlab.ui.container.Panel
    SetpointEditField_5           matlab.ui.control.NumericEditField
    mmLabel_6                     matlab.ui.control.Label
    StatusLamp_5                  matlab.ui.control.Lamp
    Switch_5                      matlab.ui.control.Switch
    Gauge_9                       matlab.ui.control.LinearGauge
    SIOCSYS1ML01AO231Label        matlab.ui.control.Label
    BPMSLI11333X1HLabel           matlab.ui.control.Label
    EditField_11                  matlab.ui.control.NumericEditField
    EditField_12                  matlab.ui.control.NumericEditField
    NotRunningButton_3            matlab.ui.control.Button
    Gauge_8                       matlab.ui.control.LinearGauge
    BC11BunchLengthFeedbackPanel  matlab.ui.container.Panel
    SetpointEditField_6           matlab.ui.control.NumericEditField
    eguLabel                      matlab.ui.control.Label
    Gauge_10                      matlab.ui.control.LinearGauge
    StatusLamp_6                  matlab.ui.control.Lamp
    Switch_6                      matlab.ui.control.Switch
    Gauge_11                      matlab.ui.control.LinearGauge
    SIOCSYS1ML01AO232Label        matlab.ui.control.Label
    BLENLI11359Label              matlab.ui.control.Label
    EditField_13                  matlab.ui.control.NumericEditField
    EditField_14                  matlab.ui.control.NumericEditField
    NotRunningButton_4            matlab.ui.control.Button
    BC14BunchLengthFeedbackPanel  matlab.ui.container.Panel
    SetpointEditField_7           matlab.ui.control.NumericEditField
    mmLabel_3                     matlab.ui.control.Label
    Gauge_12                      matlab.ui.control.LinearGauge
    Gauge_13                      matlab.ui.control.LinearGauge
    L2PHASELabel                  matlab.ui.control.Label
    BL11359Label                  matlab.ui.control.Label
    EditField_15                  matlab.ui.control.NumericEditField
    EditField_16                  matlab.ui.control.NumericEditField
    StatusLamp_7                  matlab.ui.control.Lamp
    NotRunningButton_7            matlab.ui.control.Button
    FeedbackWatcherProcessStatusPanel  matlab.ui.container.Panel
    fbstat                        matlab.ui.control.Label
    L1OrbitFeedbackPanel          matlab.ui.container.Panel
    Switch_7                      matlab.ui.control.Switch
    StatusLamp_8                  matlab.ui.control.Lamp
    NotRunningButton_8            matlab.ui.control.Button
    Gauge_20                      matlab.ui.control.LinearGauge
    Gauge_21                      matlab.ui.control.LinearGauge
    XCORIN10761Label              matlab.ui.control.Label
    BPMSLI11201X1HLabel           matlab.ui.control.Label
    EditField_17                  matlab.ui.control.NumericEditField
    EditField_18                  matlab.ui.control.NumericEditField
    Gauge_22                      matlab.ui.control.LinearGauge
    Gauge_23                      matlab.ui.control.LinearGauge
    XCORLI11202Label              matlab.ui.control.Label
    BPMSLI11312X1HLabel           matlab.ui.control.Label
    EditField_19                  matlab.ui.control.NumericEditField
    EditField_20                  matlab.ui.control.NumericEditField
    Gauge_24                      matlab.ui.control.LinearGauge
    Gauge_25                      matlab.ui.control.LinearGauge
    YCORIN10761Label              matlab.ui.control.Label
    BPMSLI11201Y1HLabel           matlab.ui.control.Label
    EditField_21                  matlab.ui.control.NumericEditField
    EditField_22                  matlab.ui.control.NumericEditField
    Gauge_26                      matlab.ui.control.LinearGauge
    Gauge_27                      matlab.ui.control.LinearGauge
    YCORLI11201Label              matlab.ui.control.Label
    BPMSLI11312Y1HLabel           matlab.ui.control.Label
    EditField_23                  matlab.ui.control.NumericEditField
    EditField_24                  matlab.ui.control.NumericEditField
    XmmEditFieldLabel             matlab.ui.control.Label
    XmmEditField                  matlab.ui.control.NumericEditField
    XANGmradEditFieldLabel        matlab.ui.control.Label
    XANGmradEditField             matlab.ui.control.NumericEditField
    YmmEditFieldLabel             matlab.ui.control.Label
    YmmEditField                  matlab.ui.control.NumericEditField
    YANGmradEditFieldLabel        matlab.ui.control.Label
    YANGmradEditField             matlab.ui.control.NumericEditField
  end

  
  properties (Access = public)
    aobj % Helper App object
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, arg1)
      app.aobj = F2_FeedbackApp(app) ; % generate helper object
    end

    % Value changed function: Switch
    function SwitchValueChanged(app, event)
      disp('Setting DL FB Enable...');
      value = string(app.Switch.Value) == "On" ;
      caput(app.aobj.pvs.FeedbackEnable,double(bitset(app.aobj.Enabled,1,double(value))));
      disp('Done.');
    end

    % Menu selected function: DL1EnergyMenu
    function DL1EnergyMenuSelected(app, event)
      !StripTool /u1/facet/tools/StripTool/config/FB_DL1_E.stp &
    end

    % Menu selected function: DL1EnergyFeedbackMenu
    function DL1EnergyFeedbackMenuSelected(app, event)
      app.DL1EnergyFeedbackMenu.Enable = false ;
      DL1E_Settings(app.aobj);
      drawnow;
    end

    % Value changed function: SetpointEditField
    function SetpointEditFieldValueChanged(app, event)
      value = app.SetpointEditField.Value;
      if app.aobj.GuiEnergyUnits
        value = (value - app.aobj.SetpointConversion{1}(1)) / app.aobj.SetpointConversion{1}(2) ;
      end
      caput(app.aobj.pvs.DL1E_Offset,value) ;
      app.aobj.SetpointOffsets(1) = value ;
    end

    % Menu selected function: DisplayEnergyUnitsMenu
    function DisplayEnergyUnitsMenuSelected(app, event)
      if app.DisplayEnergyUnitsMenu.Checked
        app.DisplayEnergyUnitsMenu.Checked = false ;
        app.aobj.GuiEnergyUnits = false ;
      else
        app.DisplayEnergyUnitsMenu.Checked = true ;
        app.aobj.GuiEnergyUnits = true ;
      end
      notify(app.aobj,'PVUpdated');
    end

    % Close request function: FACETIIFeedbackUIFigure
    function FACETIIFeedbackUIFigureCloseRequest(app, event)
      app.aobj.shutdown;
      delete(app)
    end

    % Value changed function: SetpointEditField_2
    function SetpointEditField_2ValueChanged(app, event)
      value = app.SetpointEditField_2.Value;
      if app.aobj.GuiEnergyUnits
        value = (value - app.aobj.SetpointConversion{2}(1)) / app.aobj.SetpointConversion{2}(2) ;
      end
      caput(app.aobj.pvs.BC14E_Offset,value) ;
      app.aobj.SetpointOffsets(2) = value ;
    end

    % Menu selected function: BC14EnergyMenu
    function BC14EnergyMenuSelected(app, event)
      !StripTool /u1/facet/tools/StripTool/config/FB_BC14_E.stp &
    end

    % Menu selected function: BC14EnergyFeedbackMenu
    function BC14EnergyFeedbackMenuSelected(app, event)
      app.BC14EnergyFeedbackMenu.Enable = false ;
      BC14E_Settings(app.aobj);
      drawnow
    end

    % Value changed function: Switch_2
    function Switch_2ValueChanged(app, event)
      value = string(app.Switch_2.Value) == "On" ;
      caput(app.aobj.pvs.FeedbackEnable,double(bitset(app.aobj.Enabled,2,value)));
    end

    % Menu selected function: BC11EnergyFeedbackMenu
    function BC11EnergyFeedbackMenuSelected(app, event)
      app.BC11EnergyFeedbackMenu.Enable = false ;
      BC11E_Settings(app.aobj);
      drawnow
    end

    % Value changed function: Switch_5
    function Switch_5ValueChanged(app, event)
      value = string(app.Switch_5.Value) == "On" ;
      caput(app.aobj.pvs.FeedbackEnable,double(bitset(app.aobj.Enabled,3,value)));
    end

    % Value changed function: SetpointEditField_5
    function SetpointEditField_5ValueChanged(app, event)
      value = app.SetpointEditField_5.Value;
      if app.aobj.GuiEnergyUnits
        value = (value - app.aobj.SetpointConversion{3}(1)) / app.aobj.SetpointConversion{3}(2) ;
      end
      caput(app.aobj.pvs.BC11E_Offset,value) ;
      app.aobj.SetpointOffsets(3) = value ;
    end

    % Menu selected function: BC11EnergyMenu
    function BC11EnergyMenuSelected(app, event)
      !StripTool /u1/facet/tools/StripTool/config/FB_BC11_E.stp &
    end

    % Menu selected function: JitterTimeoutMenu
    function JitterTimeoutMenuSelected(app, event)
      resp=str2double(inputdlg('Enter Jitter Timeout (min)','Jitter Timeout',1,{'2'}));
      if ~isempty(resp)
        app.JitterTimeoutMenu.Text = sprintf('Jitter Timeout = %d min',round(resp)) ;
        caput(app.aobj.pvs.FB_JitterOnTime,resp);
      end
    end

    % Value changed function: FeedbackJitterButton
    function FeedbackJitterButtonValueChanged(app, event)
      value = app.FeedbackJitterButton.Value;
      caput(app.aobj.pvs.DL1E_JitterON,double(value));
    end

    % Value changed function: SetpointJitterAmplitudeEditField
    function SetpointJitterAmplitudeEditFieldValueChanged(app, event)
      value = app.SetpointJitterAmplitudeEditField.Value;
      caput(app.aobj.pvs.DL1E_JitterAMP,double(value));
    end

    % Value changed function: Switch_6
    function Switch_6ValueChanged(app, event)
      value = string(app.Switch_6.Value) == "On" ;
      caput(app.aobj.pvs.FeedbackEnable,double(bitset(app.aobj.Enabled,4,value)));
    end

    % Menu selected function: BC11BLENFeedbackMenu
    function BC11BLENFeedbackMenuSelected(app, event)
      app.BC11BLENFeedbackMenu.Enable = false ;
      BC11BL_Settings(app.aobj);
      drawnow
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.FACETIIFeedbackUIFigure = uifigure('Visible', 'off');
      app.FACETIIFeedbackUIFigure.Name = 'FACET-II Feedback';
      app.FACETIIFeedbackUIFigure.CloseRequestFcn = createCallbackFcn(app, @FACETIIFeedbackUIFigureCloseRequest, true);
      app.StripchartsMenu = uimenu(app.FACETIIFeedbackUIFigure);
      app.StripchartsMenu.Text = 'Stripcharts';
      app.DL1EnergyMenu = uimenu(app.StripchartsMenu);
      app.DL1EnergyMenu.MenuSelectedFcn = createCallbackFcn(app, @DL1EnergyMenuSelected, true);
      app.DL1EnergyMenu.Text = 'DL1 Energy';
      app.BC11EnergyMenu = uimenu(app.StripchartsMenu);
      app.BC11EnergyMenu.MenuSelectedFcn = createCallbackFcn(app, @BC11EnergyMenuSelected, true);
      app.BC11EnergyMenu.Text = 'BC11 Energy';
      app.BC14EnergyMenu = uimenu(app.StripchartsMenu);
      app.BC14EnergyMenu.MenuSelectedFcn = createCallbackFcn(app, @BC14EnergyMenuSelected, true);
      app.BC14EnergyMenu.Text = 'BC14 Energy';
      app.SettingsMenu = uimenu(app.FACETIIFeedbackUIFigure);
      app.SettingsMenu.Text = 'Settings';
      app.DL1EnergyFeedbackMenu = uimenu(app.SettingsMenu);
      app.DL1EnergyFeedbackMenu.MenuSelectedFcn = createCallbackFcn(app, @DL1EnergyFeedbackMenuSelected, true);
      app.DL1EnergyFeedbackMenu.Text = 'DL1 Energy Feedback ...';
      app.BC11EnergyFeedbackMenu = uimenu(app.SettingsMenu);
      app.BC11EnergyFeedbackMenu.MenuSelectedFcn = createCallbackFcn(app, @BC11EnergyFeedbackMenuSelected, true);
      app.BC11EnergyFeedbackMenu.Text = 'BC11 Energy Feedback...';
      app.BC11BLENFeedbackMenu = uimenu(app.SettingsMenu);
      app.BC11BLENFeedbackMenu.MenuSelectedFcn = createCallbackFcn(app, @BC11BLENFeedbackMenuSelected, true);
      app.BC11BLENFeedbackMenu.Text = 'BC11 BLEN Feedback...';
      app.BC14EnergyFeedbackMenu = uimenu(app.SettingsMenu);
      app.BC14EnergyFeedbackMenu.MenuSelectedFcn = createCallbackFcn(app, @BC14EnergyFeedbackMenuSelected, true);
      app.BC14EnergyFeedbackMenu.Text = 'BC14 Energy Feedback ...';
      app.DisplayEnergyUnitsMenu = uimenu(app.SettingsMenu);
      app.DisplayEnergyUnitsMenu.MenuSelectedFcn = createCallbackFcn(app, @DisplayEnergyUnitsMenuSelected, true);
      app.DisplayEnergyUnitsMenu.Text = 'Display Energy Units';
      app.JitterTimeoutMenu = uimenu(app.SettingsMenu);
      app.JitterTimeoutMenu.MenuSelectedFcn = createCallbackFcn(app, @JitterTimeoutMenuSelected, true);
      app.JitterTimeoutMenu.Text = 'Jitter Timeout = 2 min';
      app.DL10EnergyFeedbackPanel = uipanel(app.FACETIIFeedbackUIFigure);
      app.DL10EnergyFeedbackPanel.Title = 'DL10 Energy Feedback';
      app.SetpointEditField = uieditfield(app.DL10EnergyFeedbackPanel, 'numeric');
      app.SetpointEditField.ValueChangedFcn = createCallbackFcn(app, @SetpointEditFieldValueChanged, true);
      app.mmLabel_5 = uilabel(app.DL10EnergyFeedbackPanel);
      app.mmLabel_5.Text = 'mm';
      app.Gauge = uigauge(app.DL10EnergyFeedbackPanel, 'linear');
      app.Gauge.Limits = [-100 100];
      app.Gauge.MajorTicks = [-100 -60 -20 20 60 100];
      app.Gauge.MajorTickLabels = {''};
      app.Gauge.MinorTicks = [-100 -92 -84 -76 -68 -60 -52 -44 -36 -28 -20 -12 -4 4 12 20 28 36 44 52 60 68 76 84 92 100];
      app.StatusLamp = uilamp(app.DL10EnergyFeedbackPanel);
      app.Switch = uiswitch(app.DL10EnergyFeedbackPanel, 'slider');
      app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
      app.Gauge_3 = uigauge(app.DL10EnergyFeedbackPanel, 'linear');
      app.Gauge_3.Value = 40;
      app.KLYSIN1041SFB_ADESLabel = uilabel(app.DL10EnergyFeedbackPanel);
      app.KLYSIN1041SFB_ADESLabel.Text = 'KLYS:IN10:41:SFB_ADES';
      app.BPMSIN10731X1HLabel = uilabel(app.DL10EnergyFeedbackPanel);
      app.BPMSIN10731X1HLabel.Text = 'BPMS:IN10:731:X1H';
      app.EditField = uieditfield(app.DL10EnergyFeedbackPanel, 'numeric');
      app.EditField.Editable = 'off';
      app.EditField_2 = uieditfield(app.DL10EnergyFeedbackPanel, 'numeric');
      app.EditField_2.ValueDisplayFormat = '%11.6g';
      app.EditField_2.Editable = 'off';
      app.NotRunningButton = uibutton(app.DL10EnergyFeedbackPanel, 'push');
      app.NotRunningButton.Text = 'Not Running';
      app.FeedbackJitterButton = uibutton(app.DL10EnergyFeedbackPanel, 'state');
      app.FeedbackJitterButton.ValueChangedFcn = createCallbackFcn(app, @FeedbackJitterButtonValueChanged, true);
      app.FeedbackJitterButton.Text = 'Feedback Jitter OFF';
      app.SetpointJitterAmplitudeEditFieldLabel = uilabel(app.DL10EnergyFeedbackPanel);
      app.SetpointJitterAmplitudeEditFieldLabel.Text = 'Setpoint Jitter Amplitude:';
      app.SetpointJitterAmplitudeEditField = uieditfield(app.DL10EnergyFeedbackPanel, 'numeric');
      app.SetpointJitterAmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @SetpointJitterAmplitudeEditFieldValueChanged, true);
      app.SetpointJitterAmplitudeEditField.Value = 0.5;
      app.mmLabel_8 = uilabel(app.DL10EnergyFeedbackPanel);
      app.mmLabel_8.Text = 'mm';
      app.BC14EnergyFeedbackPanel = uipanel(app.FACETIIFeedbackUIFigure);
      app.BC14EnergyFeedbackPanel.Title = 'BC14 Energy Feedback';
      app.SetpointEditField_2 = uieditfield(app.BC14EnergyFeedbackPanel, 'numeric');
      app.SetpointEditField_2.ValueChangedFcn = createCallbackFcn(app, @SetpointEditField_2ValueChanged, true);
      app.mmLabel_4 = uilabel(app.BC14EnergyFeedbackPanel);
      app.mmLabel_4.Text = 'mm';
      app.Switch_2 = uiswitch(app.BC14EnergyFeedbackPanel, 'slider');
      app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
      app.StatusLamp_2 = uilamp(app.BC14EnergyFeedbackPanel);
      app.BC14KLYS1Label = uilabel(app.BC14EnergyFeedbackPanel);
      app.BC14KLYS1Label.Text = 'KLYS:LI14:41:PDES';
      app.BC14KLYS2Label = uilabel(app.BC14EnergyFeedbackPanel);
      app.BC14KLYS2Label.Text = 'KLYS:LI14:51:PDES';
      app.BPMSLI14801X1HLabel = uilabel(app.BC14EnergyFeedbackPanel);
      app.BPMSLI14801X1HLabel.Text = 'BPMS:LI14:801:X1H';
      app.EditField_5 = uieditfield(app.BC14EnergyFeedbackPanel, 'numeric');
      app.EditField_5.Editable = 'off';
      app.EditField_6 = uieditfield(app.BC14EnergyFeedbackPanel, 'numeric');
      app.EditField_6.Editable = 'off';
      app.NotRunningButton_5 = uibutton(app.BC14EnergyFeedbackPanel, 'push');
      app.NotRunningButton_5.Text = 'Not Running';
      app.Gauge_16 = uigauge(app.BC14EnergyFeedbackPanel, 'ninetydegree');
      app.Gauge_16.Limits = [-180 0];
      app.Gauge_16.ScaleDirection = 'counterclockwise';
      app.Gauge_16.Value = -60;
      app.Gauge_17 = uigauge(app.BC14EnergyFeedbackPanel, 'ninetydegree');
      app.Gauge_17.Limits = [0 180];
      app.Gauge_17.ScaleDirection = 'counterclockwise';
      app.Gauge_17.Value = 60;
      app.Gauge_6 = uigauge(app.BC14EnergyFeedbackPanel, 'linear');
      app.Gauge_6.Limits = [-100 100];
      app.Gauge_6.MajorTicks = [-100 -60 -20 20 60 100];
      app.Gauge_6.MajorTickLabels = {''};
      app.Gauge_6.MinorTicks = [-100 -92 -84 -76 -68 -60 -52 -44 -36 -28 -20 -12 -4 4 12 20 28 36 44 52 60 68 76 84 92 100];
      app.BC20EnergyFeedbackPanel = uipanel(app.FACETIIFeedbackUIFigure);
      app.BC20EnergyFeedbackPanel.Title = 'BC20 Energy Feedback';
      app.SetpointEditField_4 = uieditfield(app.BC20EnergyFeedbackPanel, 'numeric');
      app.mmLabel_7 = uilabel(app.BC20EnergyFeedbackPanel);
      app.mmLabel_7.Text = 'mm';
      app.Switch_4 = uiswitch(app.BC20EnergyFeedbackPanel, 'slider');
      app.Switch_4.Enable = 'off';
      app.StatusLamp_4 = uilamp(app.BC20EnergyFeedbackPanel);
      app.Gauge_7 = uigauge(app.BC20EnergyFeedbackPanel, 'linear');
      app.Gauge_7.Limits = [-100 100];
      app.BPMSLI202445X1HLabel = uilabel(app.BC20EnergyFeedbackPanel);
      app.BPMSLI202445X1HLabel.Text = 'BPMS:LI20:2445:X1H';
      app.EditField_8 = uieditfield(app.BC20EnergyFeedbackPanel, 'numeric');
      app.EditField_8.Editable = 'off';
      app.EditField_9 = uieditfield(app.BC20EnergyFeedbackPanel, 'numeric');
      app.EditField_9.Editable = 'off';
      app.NotRunningButton_6 = uibutton(app.BC20EnergyFeedbackPanel, 'push');
      app.NotRunningButton_6.Text = 'Not Running';
      app.BC14KLYS1Label_2 = uilabel(app.BC20EnergyFeedbackPanel);
      app.BC14KLYS1Label_2.Text = 'KLYS:LI18:31+41:PDES';
      app.BC14KLYS2Label_2 = uilabel(app.BC20EnergyFeedbackPanel);
      app.BC14KLYS2Label_2.Text = 'KLYS:LI18:51+61:PDES';
      app.Gauge_18 = uigauge(app.BC20EnergyFeedbackPanel, 'ninetydegree');
      app.Gauge_18.Limits = [0 90];
      app.Gauge_18.Value = 60;
      app.Gauge_19 = uigauge(app.BC20EnergyFeedbackPanel, 'ninetydegree');
      app.Gauge_19.Limits = [-90 0];
      app.Gauge_19.Value = -60;
      app.BC11EnergyFeedbackPanel = uipanel(app.FACETIIFeedbackUIFigure);
      app.BC11EnergyFeedbackPanel.Title = 'BC11 Energy Feedback';
      app.SetpointEditField_5 = uieditfield(app.BC11EnergyFeedbackPanel, 'numeric');
      app.SetpointEditField_5.ValueChangedFcn = createCallbackFcn(app, @SetpointEditField_5ValueChanged, true);
      app.mmLabel_6 = uilabel(app.BC11EnergyFeedbackPanel);
      app.mmLabel_6.Text = 'mm';
      app.StatusLamp_5 = uilamp(app.BC11EnergyFeedbackPanel);
      app.Switch_5 = uiswitch(app.BC11EnergyFeedbackPanel, 'slider');
      app.Switch_5.ValueChangedFcn = createCallbackFcn(app, @Switch_5ValueChanged, true);
      app.Gauge_9 = uigauge(app.BC11EnergyFeedbackPanel, 'linear');
      app.Gauge_9.Value = 40;
      app.SIOCSYS1ML01AO231Label = uilabel(app.BC11EnergyFeedbackPanel);
      app.SIOCSYS1ML01AO231Label.Text = 'SIOC:SYS1:ML01:AO231';
      app.BPMSLI11333X1HLabel = uilabel(app.BC11EnergyFeedbackPanel);
      app.BPMSLI11333X1HLabel.Text = 'BPMS:LI11:333:X1H';
      app.EditField_11 = uieditfield(app.BC11EnergyFeedbackPanel, 'numeric');
      app.EditField_11.Editable = 'off';
      app.EditField_12 = uieditfield(app.BC11EnergyFeedbackPanel, 'numeric');
      app.EditField_12.Editable = 'off';
      app.NotRunningButton_3 = uibutton(app.BC11EnergyFeedbackPanel, 'push');
      app.NotRunningButton_3.Text = 'Not Running';
      app.Gauge_8 = uigauge(app.BC11EnergyFeedbackPanel, 'linear');
      app.Gauge_8.Limits = [-100 100];
      app.Gauge_8.MajorTicks = [-100 -60 -20 20 60 100];
      app.Gauge_8.MajorTickLabels = {''};
      app.Gauge_8.MinorTicks = [-100 -92 -84 -76 -68 -60 -52 -44 -36 -28 -20 -12 -4 4 12 20 28 36 44 52 60 68 76 84 92 100];
      app.BC11BunchLengthFeedbackPanel = uipanel(app.FACETIIFeedbackUIFigure);
      app.BC11BunchLengthFeedbackPanel.Title = 'BC11 Bunch Length Feedback';
      app.SetpointEditField_6 = uieditfield(app.BC11BunchLengthFeedbackPanel, 'numeric');
      app.SetpointEditField_6.Value = 0.4;
      app.eguLabel = uilabel(app.BC11BunchLengthFeedbackPanel);
      app.eguLabel.Text = 'egu';
      app.Gauge_10 = uigauge(app.BC11BunchLengthFeedbackPanel, 'linear');
      app.Gauge_10.Limits = [0.1 1];
      app.Gauge_10.Value = 0.4;
      app.StatusLamp_6 = uilamp(app.BC11BunchLengthFeedbackPanel);
      app.Switch_6 = uiswitch(app.BC11BunchLengthFeedbackPanel, 'slider');
      app.Switch_6.ValueChangedFcn = createCallbackFcn(app, @Switch_6ValueChanged, true);
      app.Gauge_11 = uigauge(app.BC11BunchLengthFeedbackPanel, 'linear');
      app.Gauge_11.Limits = [0 10];
      app.SIOCSYS1ML01AO232Label = uilabel(app.BC11BunchLengthFeedbackPanel);
      app.SIOCSYS1ML01AO232Label.Text = 'SIOC:SYS1:ML01:AO232';
      app.BLENLI11359Label = uilabel(app.BC11BunchLengthFeedbackPanel);
      app.BLENLI11359Label.Text = 'BLEN:LI11:359';
      app.EditField_13 = uieditfield(app.BC11BunchLengthFeedbackPanel, 'numeric');
      app.EditField_13.Editable = 'off';
      app.EditField_14 = uieditfield(app.BC11BunchLengthFeedbackPanel, 'numeric');
      app.EditField_14.Editable = 'off';
      app.NotRunningButton_4 = uibutton(app.BC11BunchLengthFeedbackPanel, 'push');
      app.NotRunningButton_4.Text = 'Not Running';
      app.BC14BunchLengthFeedbackPanel = uipanel(app.FACETIIFeedbackUIFigure);
      app.BC14BunchLengthFeedbackPanel.Title = 'BC14 Bunch Length Feedback';
      app.SetpointEditField_7 = uieditfield(app.BC14BunchLengthFeedbackPanel, 'numeric');
      app.mmLabel_3 = uilabel(app.BC14BunchLengthFeedbackPanel);
      app.mmLabel_3.Text = 'mm';
      app.Gauge_12 = uigauge(app.BC14BunchLengthFeedbackPanel, 'linear');
      app.Gauge_12.Limits = [0.01 1];
      app.Gauge_12.Value = 0.1;
      app.Gauge_13 = uigauge(app.BC14BunchLengthFeedbackPanel, 'linear');
      app.Gauge_13.Limits = [-180 180];
      app.L2PHASELabel = uilabel(app.BC14BunchLengthFeedbackPanel);
      app.L2PHASELabel.Text = 'L2 PHASE';
      app.BL11359Label = uilabel(app.BC14BunchLengthFeedbackPanel);
      app.BL11359Label.Text = 'BL11359';
      app.EditField_15 = uieditfield(app.BC14BunchLengthFeedbackPanel, 'numeric');
      app.EditField_15.Editable = 'off';
      app.EditField_16 = uieditfield(app.BC14BunchLengthFeedbackPanel, 'numeric');
      app.EditField_16.Editable = 'off';
      app.StatusLamp_7 = uilamp(app.BC14BunchLengthFeedbackPanel);
      app.NotRunningButton_7 = uibutton(app.BC14BunchLengthFeedbackPanel, 'push');
      app.NotRunningButton_7.Text = 'Not Running';
      app.FeedbackWatcherProcessStatusPanel = uipanel(app.FACETIIFeedbackUIFigure);
      app.FeedbackWatcherProcessStatusPanel.Title = 'Feedback Watcher Process Status';
      app.fbstat = uilabel(app.FeedbackWatcherProcessStatusPanel);
      app.fbstat.Text = 'MATLAB Watcher Process STOPPED - All Feedbacks OFF';
      app.L1OrbitFeedbackPanel = uipanel(app.FACETIIFeedbackUIFigure);
      app.L1OrbitFeedbackPanel.Title = 'L1 Orbit Feedback';
      app.Switch_7 = uiswitch(app.L1OrbitFeedbackPanel, 'slider');
      app.Switch_7.Enable = 'off';
      app.StatusLamp_8 = uilamp(app.L1OrbitFeedbackPanel);
      app.NotRunningButton_8 = uibutton(app.L1OrbitFeedbackPanel, 'push');
      app.NotRunningButton_8.Text = 'Not Running';
      app.Gauge_20 = uigauge(app.L1OrbitFeedbackPanel, 'linear');
      app.Gauge_20.Limits = [-100 100];
      app.Gauge_20.MajorTicks = [-100 -60 -20 20 60 100];
      app.Gauge_20.MajorTickLabels = {''};
      app.Gauge_20.MinorTicks = [-100 -92 -84 -76 -68 -60 -52 -44 -36 -28 -20 -12 -4 4 12 20 28 36 44 52 60 68 76 84 92 100];
      app.Gauge_21 = uigauge(app.L1OrbitFeedbackPanel, 'linear');
      app.Gauge_21.Value = 40;
      app.XCORIN10761Label = uilabel(app.L1OrbitFeedbackPanel);
      app.XCORIN10761Label.Text = 'XCOR:IN10:761';
      app.BPMSLI11201X1HLabel = uilabel(app.L1OrbitFeedbackPanel);
      app.BPMSLI11201X1HLabel.Text = 'BPMS:LI11:201:X1H';
      app.EditField_17 = uieditfield(app.L1OrbitFeedbackPanel, 'numeric');
      app.EditField_17.Editable = 'off';
      app.EditField_18 = uieditfield(app.L1OrbitFeedbackPanel, 'numeric');
      app.EditField_18.ValueDisplayFormat = '%11.6g';
      app.EditField_18.Editable = 'off';
      app.Gauge_22 = uigauge(app.L1OrbitFeedbackPanel, 'linear');
      app.Gauge_22.Limits = [-100 100];
      app.Gauge_22.MajorTicks = [-100 -60 -20 20 60 100];
      app.Gauge_22.MajorTickLabels = {''};
      app.Gauge_22.MinorTicks = [-100 -92 -84 -76 -68 -60 -52 -44 -36 -28 -20 -12 -4 4 12 20 28 36 44 52 60 68 76 84 92 100];
      app.Gauge_23 = uigauge(app.L1OrbitFeedbackPanel, 'linear');
      app.Gauge_23.Value = 40;
      app.XCORLI11202Label = uilabel(app.L1OrbitFeedbackPanel);
      app.XCORLI11202Label.Text = 'XCOR:LI11:202';
      app.BPMSLI11312X1HLabel = uilabel(app.L1OrbitFeedbackPanel);
      app.BPMSLI11312X1HLabel.Text = 'BPMS:LI11:312:X1H';
      app.EditField_19 = uieditfield(app.L1OrbitFeedbackPanel, 'numeric');
      app.EditField_19.Editable = 'off';
      app.EditField_20 = uieditfield(app.L1OrbitFeedbackPanel, 'numeric');
      app.EditField_20.ValueDisplayFormat = '%11.6g';
      app.EditField_20.Editable = 'off';
      app.Gauge_24 = uigauge(app.L1OrbitFeedbackPanel, 'linear');
      app.Gauge_24.Limits = [-100 100];
      app.Gauge_24.MajorTicks = [-100 -60 -20 20 60 100];
      app.Gauge_24.MajorTickLabels = {''};
      app.Gauge_24.MinorTicks = [-100 -92 -84 -76 -68 -60 -52 -44 -36 -28 -20 -12 -4 4 12 20 28 36 44 52 60 68 76 84 92 100];
      app.Gauge_25 = uigauge(app.L1OrbitFeedbackPanel, 'linear');
      app.Gauge_25.Value = 40;
      app.YCORIN10761Label = uilabel(app.L1OrbitFeedbackPanel);
      app.YCORIN10761Label.Text = 'YCOR:IN10:761';
      app.BPMSLI11201Y1HLabel = uilabel(app.L1OrbitFeedbackPanel);
      app.BPMSLI11201Y1HLabel.Text = 'BPMS:LI11:201:Y1H';
      app.EditField_21 = uieditfield(app.L1OrbitFeedbackPanel, 'numeric');
      app.EditField_21.Editable = 'off';
      app.EditField_22 = uieditfield(app.L1OrbitFeedbackPanel, 'numeric');
      app.EditField_22.ValueDisplayFormat = '%11.6g';
      app.EditField_22.Editable = 'off';
      app.Gauge_26 = uigauge(app.L1OrbitFeedbackPanel, 'linear');
      app.Gauge_26.Limits = [-100 100];
      app.Gauge_26.MajorTicks = [-100 -60 -20 20 60 100];
      app.Gauge_26.MajorTickLabels = {''};
      app.Gauge_26.MinorTicks = [-100 -92 -84 -76 -68 -60 -52 -44 -36 -28 -20 -12 -4 4 12 20 28 36 44 52 60 68 76 84 92 100];
      app.Gauge_27 = uigauge(app.L1OrbitFeedbackPanel, 'linear');
      app.Gauge_27.Value = 40;
      app.YCORLI11201Label = uilabel(app.L1OrbitFeedbackPanel);
      app.YCORLI11201Label.Text = 'YCOR:LI11:201';
      app.BPMSLI11312Y1HLabel = uilabel(app.L1OrbitFeedbackPanel);
      app.BPMSLI11312Y1HLabel.Text = 'BPMS:LI11:312:Y1H';
      app.EditField_23 = uieditfield(app.L1OrbitFeedbackPanel, 'numeric');
      app.EditField_23.Editable = 'off';
      app.EditField_24 = uieditfield(app.L1OrbitFeedbackPanel, 'numeric');
      app.EditField_24.ValueDisplayFormat = '%11.6g';
      app.EditField_24.Editable = 'off';
      app.XmmEditFieldLabel = uilabel(app.L1OrbitFeedbackPanel);
      app.XmmEditFieldLabel.Text = 'X (mm)';
      app.XmmEditField = uieditfield(app.L1OrbitFeedbackPanel, 'numeric');
      app.XANGmradEditFieldLabel = uilabel(app.L1OrbitFeedbackPanel);
      app.XANGmradEditFieldLabel.Text = 'XANG (mrad)';
      app.XANGmradEditField = uieditfield(app.L1OrbitFeedbackPanel, 'numeric');
      app.YmmEditFieldLabel = uilabel(app.L1OrbitFeedbackPanel);
      app.YmmEditFieldLabel.Text = 'Y (mm)';
      app.YmmEditField = uieditfield(app.L1OrbitFeedbackPanel, 'numeric');
      app.YANGmradEditFieldLabel = uilabel(app.L1OrbitFeedbackPanel);
      app.YANGmradEditFieldLabel.Text = 'YANG (mrad)';
      app.YANGmradEditField = uieditfield(app.L1OrbitFeedbackPanel, 'numeric');
      % Show the figure after all components are created
      app.FACETIIFeedbackUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = F2_Feedback(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.FACETIIFeedbackUIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.FACETIIFeedbackUIFigure)
    end
  end
end