classdef FFAPP_Newest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SaveasFBPICtxtButton    matlab.ui.control.Button
        SaveasQuickPicH5Button  matlab.ui.control.Button
        SaveasHiPaceH5Button    matlab.ui.control.Button
        OptionDropDown          matlab.ui.control.DropDown
        OptionDropDownLabel     matlab.ui.control.Label
        BeamLineSlider          matlab.ui.control.Slider
        BeamLineSliderLabel     matlab.ui.control.Label
        UITable                 matlab.ui.control.Table
        FileTextArea            matlab.ui.control.TextArea
        FileLabel               matlab.ui.control.Label
        ProcessBeamLIneButton   matlab.ui.control.Button
        LoadFile                matlab.ui.control.Button
        CloseAppButton          matlab.ui.control.Button
        BeamLineAxes            matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        aobj %  Support Object
    end


    methods (Access = private)
        
        function results = plotOpt(app)
            dropVal = app.OptionDropDown.Value;
            slideVal = app.BeamLineSlider.Value;

            dist =0;

            if (slideVal>11)
                dist = slideVal-11;
                slideVal = 11;
            end

            components = app.UITable.Data;
            totLen = sum(components(:,1:2), "all");


            switch dropVal
                case 'Phase X'
                    plot(app.UIAxes2, app.aobj.data.xA(slideVal+1,:), app.aobj.data.thtxA(slideVal+1,:),'b.')
                    title(app.UIAxes2, "\theta_x vs x")
                    xlabel(app.UIAxes2, "x [m]")
                    ylabel(app.UIAxes2, "\theta_x [rads]")

                case 'Phase Y'
                    plot(app.UIAxes2, app.aobj.data.yA(slideVal+1,:), app.aobj.data.thtyA(slideVal+1,:),'b.')
                    title(app.UIAxes2, "\theta_y vs y")
                    xlabel(app.UIAxes2, "y [m]")
                    ylabel(app.UIAxes2, "\theta_y [rads]")

                case 'Phase Longitudinal'
                    plot(app.UIAxes2, app.aobj.data.zA(slideVal+1,:), app.aobj.data.gamma, 'b.')
                    title(app.UIAxes2, "\gamma vs z")
                    xlabel(app.UIAxes2, "z [m]")
                    ylabel(app.UIAxes2, "\gamma")

                case 'Transverse Distribution'
                    plot(app.UIAxes2, app.aobj.data.xA(slideVal+1,:) + app.aobj.data.thtxA(slideVal+1,:)*totLen*dist/12, app.aobj.data.yA(slideVal+1,:) + app.aobj.data.thtyA(slideVal+1,:)*totLen*dist/12, 'b.')
                    title(app.UIAxes2, "Particle Distribution")
                    xlabel(app.UIAxes2, "x [m]")
                    ylabel(app.UIAxes2, "y [m]")

            end 
        end

        function results = plotBeamLine(app)

            cla(app.BeamLineAxes)

            slideVal = app.BeamLineSlider.Value;
            components = app.UITable.Data;

            positions = app.aobj.data.lengths;

            totLen = sum(components(:,1:2), "all");

            domain = linspace(-totLen,0, 1000);

            plot(app.BeamLineAxes, domain, ones(1,1000)*5, 'k')
            axis(app.BeamLineAxes, [-totLen 0 0 10])

            hold(app.BeamLineAxes, "on")

            for ii = 1:6
                offset = 0;
                if (components(ii,3) < 0)
                    offset = -2.5;
                end

                rectangle(app.BeamLineAxes, "Position", [positions(2*ii-1) 5+offset components(ii,2) 2.5], "FaceColor", "r")
            end

            if (slideVal > 11)
                plot(app.BeamLineAxes, positions(1) + (slideVal)*totLen/12, 5, 'b.', 'MarkerSize', 25)
            else
                plot(app.BeamLineAxes, positions(slideVal+1), 5, 'b.', 'MarkerSize', 25)
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.aobj = AppSupportTemplate(app);

            ncols = numel(app.UITable.ColumnName);

            app.UITable.Data = [1.66961700000011 0.195699999999880 5.68109973794447	0.864839999999850 0.863899999999950	1.63443413822842; 0.460800000000000	0.714200000000000 0.714200000000000	0.714200000000000 0.714200000000000	0.714200000000000; -15.5381944444444 -11.2643517222067 13.8826659199104	17.6421170540465 -32.7919350322039 17.6421170540465;]';
            app.UITable.ColumnEditable = true(1,ncols);
        end

        % Button pushed function: CloseAppButton
        function CloseAppButtonPushed(app, event)
            app.UIFigureCloseRequest();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Callback function: FileTextArea, LoadFile
        function LoadFileButtonPushed(app, event)
            [file,path] = uigetfile('*.mat')
            
            app.aobj.inFile = file;
            
            app.FileTextArea.Value = file;
        end

        % Button pushed function: ProcessBeamLIneButton
        function ProcessBeamLIneButtonPushed(app, event)
            app.aobj.componentParams = app.UITable.Data;
            app.aobj.processFile();

            cla(app.UIAxes)

            plot(app.UIAxes,app.aobj.data.lengths,app.aobj.data.sigxA, 'b-o')
            hold(app.UIAxes, 'on')
            plot(app.UIAxes,app.aobj.data.lengths,app.aobj.data.sigyA, 'r-o')

            xlabel(app.UIAxes, 'z [m]')
            ylabel(app.UIAxes, '\sigma [m]')

            legend(app.UIAxes, 'x', 'y', 'Location', 'northwest')

            app.plotOpt();
            app.plotBeamLine();


        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: OptionDropDown
        function OptionDropDownValueChanged(app, event)
            value = app.OptionDropDown.Value;
            
            app.plotOpt();
        end

        % Value changed function: BeamLineSlider
        function BeamLineSliderValueChanged(app, event)
            value = app.BeamLineSlider.Value;

            if (value<=11)
                [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
                event.Source.Value = event.Source.MajorTicks(minIdx);
                value = event.Source.MajorTicks(minIdx);
            end
            
            app.plotOpt();
            app.plotBeamLine();
        end

        % Button pushed function: SaveasHiPaceH5Button
        function SaveasHiPaceH5ButtonPushed(app, event)
            [file,path] = uiputfile('*.H5');

            app.aobj.Out2QuickPIC(path, file);

        end

        % Button pushed function: SaveasQuickPicH5Button
        function SaveasQuickPicH5ButtonPushed(app, event)
            [file,path] = uiputfile('*.H5');

            app.aobj.Out2HiPace(path, file);
        end

        % Button pushed function: SaveasFBPICtxtButton
        function SaveasFBPICtxtButtonPushed(app, event)
            [file,path] = uiputfile('*.txt');

            app.aobj.Out2FBPIC(path, file);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Beam Size')
            xlabel(app.UIAxes, 'z [m]')
            ylabel(app.UIAxes, '\sigma')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.BeamLineAxes = uiaxes(app.UIFigure);
            title(app.BeamLineAxes, 'Beam Line')
            xlabel(app.BeamLineAxes, 'z [m]')
            zlabel(app.BeamLineAxes, 'Z')
            app.BeamLineAxes.YTick = [0 5 10];
            app.BeamLineAxes.YTickLabel = '';
            app.CloseAppButton = uibutton(app.UIFigure, 'push');
            app.CloseAppButton.ButtonPushedFcn = createCallbackFcn(app, @CloseAppButtonPushed, true);
            app.CloseAppButton.Text = 'Close App';
            app.LoadFile = uibutton(app.UIFigure, 'push');
            app.LoadFile.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFile.Text = 'Load Input File';
            app.ProcessBeamLIneButton = uibutton(app.UIFigure, 'push');
            app.ProcessBeamLIneButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessBeamLIneButtonPushed, true);
            app.ProcessBeamLIneButton.Text = 'Process Beam LIne';
            app.FileLabel = uilabel(app.UIFigure);
            app.FileLabel.Text = 'File:';
            app.FileTextArea = uitextarea(app.UIFigure);
            app.FileTextArea.ValueChangingFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.FileTextArea.Value = {'None'};
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Drift Lengths'; 'Quad Lengths'; 'Quad Strengths'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.BeamLineSliderLabel = uilabel(app.UIFigure);
            app.BeamLineSliderLabel.Text = {'BeamLine '; 'Slider'};
            app.BeamLineSlider = uislider(app.UIFigure);
            app.BeamLineSlider.Limits = [0 12];
            app.BeamLineSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.BeamLineSlider.ValueChangedFcn = createCallbackFcn(app, @BeamLineSliderValueChanged, true);
            app.BeamLineSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.OptionDropDownLabel = uilabel(app.UIFigure);
            app.OptionDropDownLabel.Text = 'Option';
            app.OptionDropDown = uidropdown(app.UIFigure);
            app.OptionDropDown.Items = {'Phase X', 'Phase Y', 'Phase Longitudinal', 'Transverse Distribution'};
            app.OptionDropDown.ValueChangedFcn = createCallbackFcn(app, @OptionDropDownValueChanged, true);
            app.OptionDropDown.Value = 'Phase X';
            app.SaveasHiPaceH5Button = uibutton(app.UIFigure, 'push');
            app.SaveasHiPaceH5Button.ButtonPushedFcn = createCallbackFcn(app, @SaveasHiPaceH5ButtonPushed, true);
            app.SaveasHiPaceH5Button.Text = 'Save as HiPace H5';
            app.SaveasQuickPicH5Button = uibutton(app.UIFigure, 'push');
            app.SaveasQuickPicH5Button.ButtonPushedFcn = createCallbackFcn(app, @SaveasQuickPicH5ButtonPushed, true);
            app.SaveasQuickPicH5Button.Text = 'Save as QuickPic H5';
            app.SaveasFBPICtxtButton = uibutton(app.UIFigure, 'push');
            app.SaveasFBPICtxtButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasFBPICtxtButtonPushed, true);
            app.SaveasFBPICtxtButton.Text = 'Save as FBPIC txt';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FFAPP_Newest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end