classdef IRmode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SetupforIRalignmentmodePanel    matlab.ui.container.Panel
        SetB0B1exposureButton           matlab.ui.control.Button
        BlockHeNeButton                 matlab.ui.control.Button
        UnblockEPSShutterButton         matlab.ui.control.Button
        CloseIrisButton                 matlab.ui.control.Button
        SetHWPButton                    matlab.ui.control.Button
        PerformIRalignmentPanel         matlab.ui.container.Panel
        StartIRalignmentButton          matlab.ui.control.Button
        PauseIRalignmentButton          matlab.ui.control.Button
        AbortIRalignmentButton          matlab.ui.control.Button
        ReturnsettingstoHeNealignmentmodePanel  matlab.ui.container.Panel
        SetB0B1exposureButton_2         matlab.ui.control.Button
        UnblockHeNeButton               matlab.ui.control.Button
        BlockEPSShutterButton           matlab.ui.control.Button
        SetHWPButton_2                  matlab.ui.control.Button
        OpenIrisButton                  matlab.ui.control.Button
        LogTextAreaLabel                matlab.ui.control.Label
        LogTextArea                     matlab.ui.control.TextArea
        GrabIRreferenceparametersPanel  matlab.ui.container.Panel
        ClearIRreferencesButton         matlab.ui.control.Button
        GrabIRReferencesButton          matlab.ui.control.Button
        AlignmentStatusLampLabel        matlab.ui.control.Label
        AlignmentStatusLamp             matlab.ui.control.Lamp
        HeNestatusLampLabel             matlab.ui.control.Label
        HeNestatusLamp                  matlab.ui.control.Lamp
        EPSshutterLampLabel             matlab.ui.control.Label
        EPSshutterLamp                  matlab.ui.control.Lamp
        IRGainEditFieldLabel            matlab.ui.control.Label
        IRGainEditField                 matlab.ui.control.NumericEditField
        ClearLogButton                  matlab.ui.control.Button
        MisalignmentTolerancepixelsEditFieldLabel  matlab.ui.control.Label
        MisalignmentTolerancepixelsEditField  matlab.ui.control.NumericEditField
        CurrentirissizeLabel            matlab.ui.control.Label
        IrisSizeL                       matlab.ui.control.Label
        HWProtLabel                     matlab.ui.control.Label
        HWProtL                         matlab.ui.control.Label
    end

    
    properties (Access = public)
        camerapvs =  {'CAMR:LT20:0101','CAMR:LT20:0102'};% B0 and B1
            
        motorpvs = {'MOTR:LI20:MC06:S1'};%S20 Motor PVs M4 and M5
        
        feedbackOnPVs = {'SIOC:SYS1:ML01:AO181','SIOC:SYS1:ML01:AO181'};
        feedbackPausePV = 'SIOC:SYS1:ML01:AO182';
        feedbackExitPV = 'SIOC:SYS1:ML01:AO183';
        
        calibrationMatrices = [];
        calibrationMatrixFile = '/home/fphysics/cemma/S20Laser/S20LaserAlignmentFeedback/cal_matrix_S20TransportB0B1IR11_30_2021_18_22.mat';
        umPerPixel ;
        setPointOption = 1; % Steer to the HeNe target position on B0 and B1 
        
        refCamSettings = [];
        refSumCts = []; 
        refRMSVals = [];
        requestedSetpoints = [];
        IRgainPV = {'SIOC:SYS1:ML01:AO198'};
        k_p;
        k_i = 0.0;
        HeNeBlockTRAPV = 'XPS:LA20:LS24:M3';
        HeNeBlockOut = 8;
        HeNeBlockIn = 10;
        
        IrisPV = 'XPS:LA20:LS24:M4';
        HWProtPV = 'XPS:LA20:LS24:M2';
        
        EPSShutterPV = 'DO:LA20:10:Bo1';
        EPSShutterBlocked = 'High';
        EPSShutterOpen = 'Low';
        
        maxMisalignmentTolerance
        maxMisalignmentTolerancePV = 'SIOC:SYS1:ML01:AO188';
    end
    
    methods (Access = private)
        
        function checkBool = performPreAlignmentChecks(app)
            checkBool = 1;
%             data = app.UITable.Data;
%             sectionBools = double(table2array(data(:,2)));
%              if sum(sectionBools)==0
%               app.LogTextArea.Value =['Error - No laser cameras selected for alignment ',...
%                  app.LogTextArea.Value(:)']  ;
%              checkBool = 0;  
%             end
            if isempty(app.refCamSettings) || isempty(app.refSumCts)% Exit if no references grabbed
              app.LogTextArea.Value = ...
                  ['Error - no laser references grabbed.',...
                 ' Grab references before starting auto-alignment ',...
                 app.LogTextArea.Value(:)']  ;
             checkBool = 0;
            end
            if isempty(app.requestedSetpoints)% Exit if no setpoints selected
                   app.LogTextArea.Value = ...
                  ['Error - no laser target positions selected.',...
                 ' Select target centroid positions before starting auto-alignment ',...
                 app.LogTextArea.Value(:)']  ;
             checkBool = 0;
            end
        end
        
        function initiateShutterPanel(app)
            app.setHeNeStatus();
            app.setEPSShutterStatus();
        end
        
        function setHeNeStatus(app)
            HeNeVal = lcaGetSmart([app.HeNeBlockTRAPV,'.RBV']);
            
            if abs(HeNeVal - app.HeNeBlockOut) < 0.001
                app.HeNestatusLamp.Color = [0, 1, 0];
                app.HeNestatusLamp.Tooltip = 'HeNe block is out, HeNe is being sent';
                %app.BlockUnblockButton.Text = 'Block';
            elseif  abs(HeNeVal - app.HeNeBlockIn) < 0.001
                app.HeNestatusLamp.Color = [1, 0, 0];
                app.HeNestatusLamp.Tooltip = 'HeNe block is in, HeNe is being blocked';
                %app.BlockUnblockButton.Text = 'Unblock';
            else
                app.HeNestatusLamp.Color = [1, 1, 0];
                app.HeNestatusLamp.Tooltip = 'Status unknown';
                %app.BlockUnblockButton.Text = 'Block';
            end
        end
        
        function flipEPSShutter(app)
            ShutterVal = lcaGetSmart(app.EPSShutterPV);
            
            if strcmp(ShutterVal, app.EPSShutterBlocked) 
                lcaPutSmart(app.EPSShutterPV, app.EPSShutterOpen);
            elseif strcmp(ShutterVal, app.EPSShutterOpen)
                lcaPutSmart(app.EPSShutterPV, app.EPSShutterBlocked);
            end
            
            app.setEPSShutterStatus();
        end
        
        function setEPSShutterStatus(app)
            ShutterVal = lcaGetSmart(app.EPSShutterPV);
            
            
            if strcmp(ShutterVal,'High')
                app.EPSshutterLamp.Color = [1, 0, 0];
                app.EPSshutterLamp.Tooltip = 'EPS shutter is in';
                %app.BlockUnblockButton_2.Text = 'Unblock';
            elseif strcmp(ShutterVal,'Low')
                app.EPSshutterLamp.Color = [0, 1, 0];
                app.EPSshutterLamp.Tooltip = 'EPS shutter is out';
                %app.BlockUnblockButton_2.Text = 'Block';
            end

        end
        
        function moveStepperMotor(app, motorPV, motorName, target)
            
            lcaPutSmart(motorPV, target);
            motorVal = lcaGetSmart([motorPV,'.RBV']);
            app.appendMessage(['Moving motor: ', motorName]);
            
            while ~(abs(motorVal - target) < 0.01)
                motorVal = lcaGetSmart([motorPV,'.RBV']);
                app.updateStatusLamps();
            end
            
            app.appendMessage(['Finished moving motor: ', motorName]);
            
        end
        
        function appendMessage(app, msg)
            app.LogTextArea.Value = ...
                  [msg, ...
                 app.LogTextArea.Value(:)'] ;
            pause(1e-2);
        end
        
        function updateStatusLamps(app)
            app.setHeNeStatus();
            app.setEPSShutterStatus();
            app.setIrisStatus();
            app.setHWPStatus();
            
            drawnow();
        end
        
        function setIrisStatus(app)
            ISize = lcaGetSmart([app.IrisPV,'.RBV']);
            app.IrisSizeL.Text = sprintf('%2.1f',-ISize);
        end
        function setHWPStatus(app)
            HWProt = lcaGetSmart([app.HWProtPV,'.RBV']);
            app.HWProtL.Text = sprintf('%3.1f',HWProt);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.calibrationMatrices{1} = importdata(app.calibrationMatrixFile);
            app.LogTextArea.Value = ['Imported IR calibration matrix from ',app.calibrationMatrixFile,...
            app.LogTextArea.Value(:)'];
            
            app.IRGainEditField.Value = lcaGetSmart(app.IRgainPV);
            
            app.initiateShutterPanel();
            app.maxMisalignmentTolerance = lcaGetSmart(app.maxMisalignmentTolerancePV);
            app.updateStatusLamps();
        end

        % Button pushed function: BlockHeNeButton
        function BlockHeNeButtonPushed(app, event)
                        % Blocks/unblocks the HeNe
            
            HeNeVal = lcaGetSmart(app.HeNeBlockTRAPV);
            
            app.HeNestatusLamp.Color = [1, 1, 0];
            app.HeNestatusLamp.Tooltip = 'Status unknown';
            drawnow();
            
            if abs(HeNeVal - app.HeNeBlockIn) < 0.001
                target = app.HeNeBlockOut;
            else
                target = app.HeNeBlockIn;
            end
                     
            app.moveStepperMotor(app.HeNeBlockTRAPV, 'HeNe block', target);
            app.LogTextArea.Value = ['He Ne Blocked...',...
            app.LogTextArea.Value(:)'];
            app.setHeNeStatus
            drawnow();
            

        end

        % Button pushed function: SetB0B1exposureButton
        function SetB0B1exposureButtonPushed(app, event)
            
            lcaPutSmart({[app.camerapvs{1},':AcquireTime'],...
                [app.camerapvs{2},':AcquireTime']},1.0);
                app.LogTextArea.Value = ['Set Exposure time to 1 s on ',...
                    lcaGetSmart([app.camerapvs{1},':NAME']),'and ',...
                    lcaGetSmart([app.camerapvs{2},':NAME']),...
                app.LogTextArea.Value(:)'];
            
        end

        % Button pushed function: GrabIRReferencesButton
        function GrabIRReferencesButtonPushed(app, event)
            
            app.LogTextArea.Value = ['Grabbing Reference Laser Parameters...',...
                app.LogTextArea.Value(:)'];
            drawnow()
            
            [app.refCamSettings,app.refSumCts,app.requestedSetpoints,app.refRMSVals] = ...
                defineFeedbackSetpoint(app);
            app.LogTextArea.Value = ...
                ['Grabbed Reference Laser Reference Parameters',...
                app.LogTextArea.Value(:)'];
            
        end

        % Button pushed function: ClearIRreferencesButton
        function ClearIRreferencesButtonPushed(app, event)
            
            app.refCamSettings = [];
            app.refSumCts = [];
            app.requestedSetpoints = [];
            app.LogTextArea.Value = ['Cleared Reference Laser Position and Sum Counts',app.LogTextArea.Value(:)'];
        end

        % Button pushed function: StartIRalignmentButton
        function StartIRalignmentButtonPushed(app, event)
            
            lcaPutSmart(app.feedbackExitPV,0);% Set exit PV to zero
            lcaPutSmart(app.feedbackPausePV,0);% Set pause PV to zero
            NLaserSections = 1;
            checkBool = performPreAlignmentChecks(app);
            if ~checkBool;return;end% Exit alignment if pre-checks are bad
            app.LogTextArea.Value = ['Starting Auto Alignment',app.LogTextArea.Value(:)'];
            app.AlignmentStatusLamp.Color = 'Green';
            drawnow()
            while lcaGetSmart(app.feedbackExitPV) ==0% Run the feedback             
                    app.k_p = lcaGetSmart(app.IRgainPV);% Update gain vals
                    if any(isnan(app.k_p))                     
                         app.LogTextArea.Value =  ['One or more invalid Gain values. Skipping alignment',...
                         app.LogTextArea.Value(:)'];
                     continue
                    end             
                for ij = 1:NLaserSections
                   
                   if lcaGetSmart(app.feedbackExitPV)==0;end
         
                   if lcaGetSmart(app.feedbackPausePV)==0
                    % Get setpoint and motor pv for the relevant cameras      
                    inputDataStruct.motorpvs = app.motorpvs{ij};                  
                    steeringSetpoint = app.requestedSetpoints(1+4*(ij-1):4*ij);
                    inputDataStruct.camerapvs = {app.camerapvs{2*ij-1},app.camerapvs{2*ij}};
                    inputDataStruct.channel_index = [1:4];
                      
                    % Evaluate the exit condition
                    if evaluateExitCondition(inputDataStruct,app.refSumCts,app.refCamSettings,app.feedbackOnPVs,ij,app)
                        continue;
                    else % Run the feedback
                     app.LogTextArea.Value =  ['Aligning on ',lcaGetSmart([inputDataStruct.camerapvs{1},':NAME']),...
                         app.LogTextArea.Value(:)'];
                      [~,~,~] = ...
                            alignLaserToSetpoint(inputDataStruct,steeringSetpoint,...
                            app.calibrationMatrices{ij},app.k_p(ij),app.k_i,app);    
                            app.LogTextArea.Value = ['Last move made ',...
                                datestr(now), app.LogTextArea.Value(:)'];               
                    end
                    else
                    lcaGetSmart(app.feedbackPausePV);                  
                    if lcaGetSmart(app.feedbackExitPV);break;end %Makes sure you don't get stuck on pause and exit PVs = 1
                   end 
                end

                drawnow()
            end 
            
        end

        % Button pushed function: PauseIRalignmentButton
        function PauseIRalignmentButtonPushed(app, event)
            if lcaGetSmart(app.feedbackExitPV)==1
                app.LogTextArea.Value = [' Cannot pause Auto-Alignment because it is already stopped',app.LogTextArea.Value(:)'];
            elseif lcaGetSmart(app.feedbackPausePV)==0
                lcaPutSmart(app.feedbackPausePV,1);
                app.LogTextArea.Value = ['Auto-Alignment Paused',app.LogTextArea.Value(:)'];
                app.AlignmentStatusLamp.Color = [1.0 0.41 0.16];
            else
                lcaPutSmart(app.feedbackPausePV,0);
                app.LogTextArea.Value = ['Auto-Alignment Un-Paused',app.LogTextArea.Value(:)'];
                app.AlignmentStatusLamp.Color = 'Green';
            end
        end

        % Button pushed function: AbortIRalignmentButton
        function AbortIRalignmentButtonPushed(app, event)
                        lcaPutSmart(app.feedbackExitPV,1)
            app.LogTextArea.Value = ['Auto-Alignment Stopped',app.LogTextArea.Value(:)'];
            app.AlignmentStatusLamp.Color = 'Red';
        end

        % Button pushed function: UnblockHeNeButton
        function UnblockHeNeButtonPushed(app, event)
                        % Blocks/unblocks the HeNe
            
            HeNeVal = lcaGetSmart(app.HeNeBlockTRAPV);
            
            app.HeNestatusLamp.Color = [1, 1, 0];
            app.HeNestatusLamp.Tooltip = 'Status unknown';
            drawnow();
            
            if abs(HeNeVal - app.HeNeBlockIn) < 0.001
                target = app.HeNeBlockOut;
            else
                target = app.HeNeBlockIn;
            end
                     
            app.moveStepperMotor(app.HeNeBlockTRAPV, 'HeNe block', target);
            app.LogTextArea.Value = ['He Ne Blocked...',...
            app.LogTextArea.Value(:)'];
            app.setHeNeStatus
            drawnow();
            
        end

        % Button pushed function: SetB0B1exposureButton_2
        function SetB0B1exposureButton_2Pushed(app, event)
            
           lcaPutSmart({[app.camerapvs{1},':AcquireTime'],...
               [app.camerapvs{2},':AcquireTime']},0.0005);
                app.LogTextArea.Value = ['Set Exposure time to 0.001 s on ',...
                    lcaGetSmart([app.camerapvs{1},':NAME']),'and ',...
                    lcaGetSmart([app.camerapvs{2},':NAME']),...
                app.LogTextArea.Value(:)'];  
            
        end

        % Value changed function: IRGainEditField
        function IRGainEditFieldValueChanged(app, event)
            value = app.IRGainEditField.Value;
            lcaPutSmart(app.IRgainPV,value);
        end

        % Button pushed function: UnblockEPSShutterButton
        function UnblockEPSShutterButtonPushed(app, event)
            app.flipEPSShutter();
        end

        % Button pushed function: BlockEPSShutterButton
        function BlockEPSShutterButtonPushed(app, event)
            app.flipEPSShutter();
        end

        % Button pushed function: ClearLogButton
        function ClearLogButtonPushed(app, event)
             app.LogTextArea.Value =  ' ';
        end

        % Value changed function: 
        % MisalignmentTolerancepixelsEditField
        function MisalignmentTolerancepixelsEditFieldValueChanged(app, event)
            value = app.MisalignmentTolerancepixelsEditField.Value;
            lcaPutSmart(app.maxMisalignmentTolerancePV,value);
        end

        % Button pushed function: OpenIrisButton
        function OpenIrisButtonPushed(app, event)
            % Opens iris to 40 mm aperture
            
            IrisVal = lcaGetSmart(app.IrisPV);
            
            app.moveStepperMotor(app.IrisPV, 'Iris size', -40);
            
            %app.HeNestatusLamp.Color = [1, 1, 0];
            %app.HeNestatusLamp.Tooltip = 'Status unknown';
            %app.BlockUnblockButton.Text = 'Block';
            %drawnow();
            
            %if abs(HeNeVal - app.HeNeBlockIn) < 0.001
            %    target = app.HeNeBlockOut;
            %else
            %    target = app.HeNeBlockIn;
            %end
            
            
            %app.setHeNeStatus
            drawnow();
        end

        % Button pushed function: CloseIrisButton
        function CloseIrisButtonPushed(app, event)
            % Closes iris to 5 mm apeture
            
            IrisVal = lcaGetSmart(app.IrisPV);
            
            app.moveStepperMotor(app.IrisPV, 'Iris size', -5);
            
            %app.HeNestatusLamp.Color = [1, 1, 0];
            %app.HeNestatusLamp.Tooltip = 'Status unknown';
            %app.BlockUnblockButton.Text = 'Block';
            %drawnow();
            
            %if abs(HeNeVal - app.HeNeBlockIn) < 0.001
            %    target = app.HeNeBlockOut;
            %else
            %    target = app.HeNeBlockIn;
            %end
            
            
            %app.setHeNeStatus
            drawnow();
        end

        % Button pushed function: SetHWPButton
        function SetHWPButtonPushed(app, event)
            HWPVal = lcaGetSmart(app.HWProtPV);
            app.moveStepperMotor(app.HWProtPV, 'HWP', 65);
            app.setHWPStatus();
        end

        % Button pushed function: SetHWPButton_2
        function SetHWPButton_2Pushed(app, event)
            HWPVal = lcaGetSmart(app.HWProtPV);
            app.moveStepperMotor(app.HWProtPV, 'HWP', 50);
            app.setHWPStatus();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SetupforIRalignmentmodePanel = uipanel(app.UIFigure);
            app.SetupforIRalignmentmodePanel.Title = '1. Set-up for IR alignment mode';
            app.SetB0B1exposureButton = uibutton(app.SetupforIRalignmentmodePanel, 'push');
            app.SetB0B1exposureButton.ButtonPushedFcn = createCallbackFcn(app, @SetB0B1exposureButtonPushed, true);
            app.SetB0B1exposureButton.Text = {'Set B0 B1 '; 'exposure'};
            app.BlockHeNeButton = uibutton(app.SetupforIRalignmentmodePanel, 'push');
            app.BlockHeNeButton.ButtonPushedFcn = createCallbackFcn(app, @BlockHeNeButtonPushed, true);
            app.BlockHeNeButton.Text = {'Block '; 'HeNe'};
            app.UnblockEPSShutterButton = uibutton(app.SetupforIRalignmentmodePanel, 'push');
            app.UnblockEPSShutterButton.ButtonPushedFcn = createCallbackFcn(app, @UnblockEPSShutterButtonPushed, true);
            app.UnblockEPSShutterButton.Text = {'Unblock '; 'EPS Shutter'};
            app.CloseIrisButton = uibutton(app.SetupforIRalignmentmodePanel, 'push');
            app.CloseIrisButton.ButtonPushedFcn = createCallbackFcn(app, @CloseIrisButtonPushed, true);
            app.CloseIrisButton.Text = {'Close'; 'Iris'};
            app.SetHWPButton = uibutton(app.SetupforIRalignmentmodePanel, 'push');
            app.SetHWPButton.ButtonPushedFcn = createCallbackFcn(app, @SetHWPButtonPushed, true);
            app.SetHWPButton.Text = {'Set '; 'HWP'};
            app.PerformIRalignmentPanel = uipanel(app.UIFigure);
            app.PerformIRalignmentPanel.Title = '2. Perform IR alignment';
            app.StartIRalignmentButton = uibutton(app.PerformIRalignmentPanel, 'push');
            app.StartIRalignmentButton.ButtonPushedFcn = createCallbackFcn(app, @StartIRalignmentButtonPushed, true);
            app.StartIRalignmentButton.Text = 'Start IR alignment';
            app.PauseIRalignmentButton = uibutton(app.PerformIRalignmentPanel, 'push');
            app.PauseIRalignmentButton.ButtonPushedFcn = createCallbackFcn(app, @PauseIRalignmentButtonPushed, true);
            app.PauseIRalignmentButton.Text = 'Pause IR alignment';
            app.AbortIRalignmentButton = uibutton(app.PerformIRalignmentPanel, 'push');
            app.AbortIRalignmentButton.ButtonPushedFcn = createCallbackFcn(app, @AbortIRalignmentButtonPushed, true);
            app.AbortIRalignmentButton.Text = 'Abort IR alignment';
            app.ReturnsettingstoHeNealignmentmodePanel = uipanel(app.UIFigure);
            app.ReturnsettingstoHeNealignmentmodePanel.Title = '3. Return settings to HeNe alignment mode';
            app.SetB0B1exposureButton_2 = uibutton(app.ReturnsettingstoHeNealignmentmodePanel, 'push');
            app.SetB0B1exposureButton_2.ButtonPushedFcn = createCallbackFcn(app, @SetB0B1exposureButton_2Pushed, true);
            app.SetB0B1exposureButton_2.Text = {'Set B0 B1 '; 'exposure'};
            app.UnblockHeNeButton = uibutton(app.ReturnsettingstoHeNealignmentmodePanel, 'push');
            app.UnblockHeNeButton.ButtonPushedFcn = createCallbackFcn(app, @UnblockHeNeButtonPushed, true);
            app.UnblockHeNeButton.Text = {'Unblock '; 'HeNe'};
            app.BlockEPSShutterButton = uibutton(app.ReturnsettingstoHeNealignmentmodePanel, 'push');
            app.BlockEPSShutterButton.ButtonPushedFcn = createCallbackFcn(app, @BlockEPSShutterButtonPushed, true);
            app.BlockEPSShutterButton.Text = {'Block '; 'EPS '; 'Shutter'};
            app.SetHWPButton_2 = uibutton(app.ReturnsettingstoHeNealignmentmodePanel, 'push');
            app.SetHWPButton_2.ButtonPushedFcn = createCallbackFcn(app, @SetHWPButton_2Pushed, true);
            app.SetHWPButton_2.Text = {'Set '; 'HWP'};
            app.OpenIrisButton = uibutton(app.ReturnsettingstoHeNealignmentmodePanel, 'push');
            app.OpenIrisButton.ButtonPushedFcn = createCallbackFcn(app, @OpenIrisButtonPushed, true);
            app.OpenIrisButton.Text = {'Open'; 'Iris'};
            app.LogTextAreaLabel = uilabel(app.UIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.UIFigure);
            app.GrabIRreferenceparametersPanel = uipanel(app.UIFigure);
            app.GrabIRreferenceparametersPanel.Title = '2. Grab IR reference parameters';
            app.ClearIRreferencesButton = uibutton(app.GrabIRreferenceparametersPanel, 'push');
            app.ClearIRreferencesButton.ButtonPushedFcn = createCallbackFcn(app, @ClearIRreferencesButtonPushed, true);
            app.ClearIRreferencesButton.Text = 'Clear IR references';
            app.GrabIRReferencesButton = uibutton(app.GrabIRreferenceparametersPanel, 'push');
            app.GrabIRReferencesButton.ButtonPushedFcn = createCallbackFcn(app, @GrabIRReferencesButtonPushed, true);
            app.GrabIRReferencesButton.Text = 'Grab IR References';
            app.AlignmentStatusLampLabel = uilabel(app.UIFigure);
            app.AlignmentStatusLampLabel.Text = 'Alignment Status';
            app.AlignmentStatusLamp = uilamp(app.UIFigure);
            app.HeNestatusLampLabel = uilabel(app.UIFigure);
            app.HeNestatusLampLabel.Text = 'HeNe status';
            app.HeNestatusLamp = uilamp(app.UIFigure);
            app.EPSshutterLampLabel = uilabel(app.UIFigure);
            app.EPSshutterLampLabel.Text = 'EPS shutter';
            app.EPSshutterLamp = uilamp(app.UIFigure);
            app.IRGainEditFieldLabel = uilabel(app.UIFigure);
            app.IRGainEditFieldLabel.Text = 'IR Gain';
            app.IRGainEditField = uieditfield(app.UIFigure, 'numeric');
            app.IRGainEditField.ValueChangedFcn = createCallbackFcn(app, @IRGainEditFieldValueChanged, true);
            app.ClearLogButton = uibutton(app.UIFigure, 'push');
            app.ClearLogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLogButtonPushed, true);
            app.ClearLogButton.Text = 'Clear Log';
            app.MisalignmentTolerancepixelsEditFieldLabel = uilabel(app.UIFigure);
            app.MisalignmentTolerancepixelsEditFieldLabel.Text = 'Misalignment Tolerance [pixels]';
            app.MisalignmentTolerancepixelsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MisalignmentTolerancepixelsEditField.ValueChangedFcn = createCallbackFcn(app, @MisalignmentTolerancepixelsEditFieldValueChanged, true);
            app.CurrentirissizeLabel = uilabel(app.UIFigure);
            app.CurrentirissizeLabel.Text = 'Current iris size:';
            app.IrisSizeL = uilabel(app.UIFigure);
            app.IrisSizeL.Text = 'init';
            app.HWProtLabel = uilabel(app.UIFigure);
            app.HWProtLabel.Text = 'HWP rot:';
            app.HWProtL = uilabel(app.UIFigure);
            app.HWProtL.Text = 'init';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IRmode

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end