classdef expertSettings_F2_LAA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UITable                      matlab.ui.control.Table
        Label                        matlab.ui.control.Label
        MaxoffsettolerancepixelsEditFieldLabel  matlab.ui.control.Label
        MaxoffsettolerancepixelsEditField  matlab.ui.control.NumericEditField
        MPANearFarMaxoffsettolpixEditFieldLabel  matlab.ui.control.Label
        MPANearFarMaxoffsettolpixEditField  matlab.ui.control.NumericEditField
        DisableTimestampCheckSwitchLabel  matlab.ui.control.Label
        DisableTimestampCheckSwitch  matlab.ui.control.Switch
        Label_2                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        sectionNames = {'Pulse Picker and Regen Out','Preamp Near and Far','MPA Near and Far',...
            'DM Near and Far','HeNe Near and Far','B0 and B1','B2 and B3','B4','B5','B6'} % Description
        gainPVs = {'SIOC:SYS1:ML01:AO190','SIOC:SYS1:ML01:AO191','SIOC:SYS1:ML01:AO199',...
            'SIOC:SYS1:ML01:AO186','SIOC:SYS1:ML01:AO192','SIOC:SYS1:ML01:AO193',...
            'SIOC:SYS1:ML01:AO194','SIOC:SYS1:ML01:AO195',...
            'SIOC:SYS1:ML01:AO196','SIOC:SYS1:ML01:AO197'}
        maxMisalignmentTolerancePV = 'SIOC:SYS1:ML01:AO200';
        MPANearFarmaxMisalignmentTolerancePV = 'SIOC:SYS1:ML01:AO189';
        disableTimestampCheckForCamerasPV = 'SIOC:SYS1:ML01:AO187';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            gainValues=lcaGetSmart(app.gainPVs);
            maxTol = lcaGetSmart(app.maxMisalignmentTolerancePV);
            app.UITable.Data = table(app.sectionNames',gainValues);
            app.MaxoffsettolerancepixelsEditField.Value = maxTol;
            app.MPANearFarMaxoffsettolpixEditField.Value = lcaGetSmart(app.MPANearFarmaxMisalignmentTolerancePV);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            disp(double(table2array(app.UITable.Data(:,2))))
            newGainValues = double(table2array(app.UITable.Data(:,2)));
            lcaPutSmart(app.gainPVs,newGainValues);
        end

        % Value changed function: MaxoffsettolerancepixelsEditField
        function MaxoffsettolerancepixelsEditFieldValueChanged(app, event)
            value = app.MaxoffsettolerancepixelsEditField.Value;
            lcaPutSmart(app.maxMisalignmentTolerancePV,double(value));
        end

        % Value changed function: MPANearFarMaxoffsettolpixEditField
        function MPANearFarMaxoffsettolpixEditFieldValueChanged(app, event)
            value = app.MPANearFarMaxoffsettolpixEditField.Value;
            lcaPutSmart(app.MPANearFarmaxMisalignmentTolerancePV,double(value));
        end

        % Value changed function: DisableTimestampCheckSwitch
        function DisableTimestampCheckSwitchValueChanged(app, event)
            value = app.DisableTimestampCheckSwitch.Value;
            switch value
                case 'Enabled'
                    pvval = 0;
                case 'Disabled'
                    pvval = 1;
            end
            lcaPutSmart(app.disableTimestampCheckForCamerasPV,pvval);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Laser Cameras'; 'Gain'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.MaxoffsettolerancepixelsEditFieldLabel = uilabel(app.UIFigure);
            app.MaxoffsettolerancepixelsEditFieldLabel.Text = 'Max offset tolerance [pixels]: ';
            app.MaxoffsettolerancepixelsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxoffsettolerancepixelsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxoffsettolerancepixelsEditFieldValueChanged, true);
            app.MPANearFarMaxoffsettolpixEditFieldLabel = uilabel(app.UIFigure);
            app.MPANearFarMaxoffsettolpixEditFieldLabel.Text = 'MPA Near/Far Max offset tol [pix]: ';
            app.MPANearFarMaxoffsettolpixEditField = uieditfield(app.UIFigure, 'numeric');
            app.MPANearFarMaxoffsettolpixEditField.ValueChangedFcn = createCallbackFcn(app, @MPANearFarMaxoffsettolpixEditFieldValueChanged, true);
            app.DisableTimestampCheckSwitchLabel = uilabel(app.UIFigure);
            app.DisableTimestampCheckSwitchLabel.Text = 'Disable Timestamp Check';
            app.DisableTimestampCheckSwitch = uiswitch(app.UIFigure, 'slider');
            app.DisableTimestampCheckSwitch.Items = {'Enabled', 'Disabled'};
            app.DisableTimestampCheckSwitch.ValueChangedFcn = createCallbackFcn(app, @DisableTimestampCheckSwitchValueChanged, true);
            app.DisableTimestampCheckSwitch.Value = 'Enabled';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'Warning -  do NOT disable '; 'unless laser is on'; 'local timing'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = expertSettings_F2_LAA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end