classdef F2_LEM < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    FLEMFACETIILEMUIFigure     matlab.ui.Figure
    FileMenu                   matlab.ui.container.Menu
    SetPrefMenu                matlab.ui.container.Menu
    SaveDataMenu               matlab.ui.container.Menu
    LoadDataMenu               matlab.ui.container.Menu
    GridLayout                 matlab.ui.container.GridLayout
    LeftPanel                  matlab.ui.container.Panel
    ScaleMagnetsButton         matlab.ui.control.Button
    UndoButton                 matlab.ui.control.Button
    TabGroup                   matlab.ui.container.TabGroup
    EREFSTab                   matlab.ui.container.Tab
    GunEditFieldLabel          matlab.ui.control.Label
    GunEref                    matlab.ui.control.NumericEditField
    DL1EditFieldLabel          matlab.ui.control.Label
    DL1Eref                    matlab.ui.control.NumericEditField
    BC11EditFieldLabel         matlab.ui.control.Label
    BC11Eref                   matlab.ui.control.NumericEditField
    ErefGeVLabel               matlab.ui.control.Label
    FudgeFactorLabel           matlab.ui.control.Label
    ExtantLabel                matlab.ui.control.Label
    RefLabel                   matlab.ui.control.Label
    L0Label                    matlab.ui.control.Label
    EditField                  matlab.ui.control.NumericEditField
    EditField_2                matlab.ui.control.NumericEditField
    L1Label                    matlab.ui.control.Label
    EditField_3                matlab.ui.control.NumericEditField
    EditField_4                matlab.ui.control.NumericEditField
    L2Label_2                  matlab.ui.control.Label
    EditField_5                matlab.ui.control.NumericEditField
    EditField_6                matlab.ui.control.NumericEditField
    L3Label_2                  matlab.ui.control.Label
    EditField_7                matlab.ui.control.NumericEditField
    EditField_8                matlab.ui.control.NumericEditField
    BC14Label                  matlab.ui.control.Label
    BC14Eref                   matlab.ui.control.NumericEditField
    BC20Label                  matlab.ui.control.Label
    BC20Eref                   matlab.ui.control.NumericEditField
    UseBendEDEFButton          matlab.ui.control.StateButton
    WakesTab                   matlab.ui.container.Tab
    BunchChargenCLabel         matlab.ui.control.Label
    L0EditFieldLabel           matlab.ui.control.Label
    L0EditField                matlab.ui.control.NumericEditField
    L1EditFieldLabel           matlab.ui.control.Label
    L1EditField                matlab.ui.control.NumericEditField
    L2EditFieldLabel           matlab.ui.control.Label
    L2EditField                matlab.ui.control.NumericEditField
    L3EditFieldLabel           matlab.ui.control.Label
    L3EditField                matlab.ui.control.NumericEditField
    EditField_175              matlab.ui.control.NumericEditField
    EditField_176              matlab.ui.control.NumericEditField
    EditField_177              matlab.ui.control.NumericEditField
    EditField_178              matlab.ui.control.NumericEditField
    rmsBunchLengthumLabel      matlab.ui.control.Label
    ElossMeVLabel              matlab.ui.control.Label
    EditField_179              matlab.ui.control.NumericEditField
    EditField_180              matlab.ui.control.NumericEditField
    EditField_181              matlab.ui.control.NumericEditField
    EditField_182              matlab.ui.control.NumericEditField
    UseMeasuredValuesButton    matlab.ui.control.StateButton
    RFTab                      matlab.ui.container.Tab
    L1Label_2                  matlab.ui.control.Label
    L2Label                    matlab.ui.control.Label
    L3Label_5                  matlab.ui.control.Label
    PhasedegLabel              matlab.ui.control.Label
    EGAINMeVLabel              matlab.ui.control.Label
    EditField_169              matlab.ui.control.EditField
    EditField_170              matlab.ui.control.EditField
    EditField_171              matlab.ui.control.EditField
    EditField_172              matlab.ui.control.EditField
    EditField_173              matlab.ui.control.EditField
    EditField_174              matlab.ui.control.EditField
    EditField_183              matlab.ui.control.EditField
    EditField_184              matlab.ui.control.EditField
    EditField_185              matlab.ui.control.EditField
    CHIRPMeVLabel              matlab.ui.control.Label
    GetPhasesfromSBSTsButton   matlab.ui.control.StateButton
    MagnetsTab_2               matlab.ui.container.Tab
    MagnetReferenceSourceButtonGroup  matlab.ui.container.ButtonGroup
    UseExtantStrengthsButton   matlab.ui.control.ToggleButton
    UseModelStrengthsButton    matlab.ui.control.ToggleButton
    L0CheckBox                 matlab.ui.control.CheckBox
    L1CheckBox                 matlab.ui.control.CheckBox
    L2CheckBox                 matlab.ui.control.CheckBox
    L3CheckBox                 matlab.ui.control.CheckBox
    ReadDataButton             matlab.ui.control.Button
    S20CheckBox                matlab.ui.control.CheckBox
    DataValidLampLabel         matlab.ui.control.Label
    DataValidLamp              matlab.ui.control.Lamp
    RightPanel                 matlab.ui.container.Panel
    TabGroup2                  matlab.ui.container.TabGroup
    EProfileTab                matlab.ui.container.Tab
    UIAxes                     matlab.ui.control.UIAxes
    MagnetsTab                 matlab.ui.container.Tab
    UIAxes2                    matlab.ui.control.UIAxes
    Table                      matlab.ui.container.Tab
    UITable                    matlab.ui.control.Table
    KlysEgainTab               matlab.ui.container.Tab
    GridLayout2                matlab.ui.container.GridLayout
    Label_10                   matlab.ui.control.Label
    Label_9                    matlab.ui.control.Label
    Label_8                    matlab.ui.control.Label
    Label_7                    matlab.ui.control.Label
    Label_6                    matlab.ui.control.Label
    Label_5                    matlab.ui.control.Label
    Label_4                    matlab.ui.control.Label
    Label_3                    matlab.ui.control.Label
    Label_2                    matlab.ui.control.Label
    Label                      matlab.ui.control.Label
    L0Label_2                  matlab.ui.control.Label
    L1L2Label                  matlab.ui.control.Label
    L3Label_3                  matlab.ui.control.Label
    EditField_9                matlab.ui.control.EditField
    EditField_10               matlab.ui.control.EditField
    EditField_11               matlab.ui.control.EditField
    EditField_12               matlab.ui.control.EditField
    EditField_13               matlab.ui.control.EditField
    EditField_14               matlab.ui.control.EditField
    EditField_15               matlab.ui.control.EditField
    EditField_16               matlab.ui.control.EditField
    EditField_17               matlab.ui.control.EditField
    EditField_18               matlab.ui.control.EditField
    EditField_19               matlab.ui.control.EditField
    EditField_20               matlab.ui.control.EditField
    EditField_21               matlab.ui.control.EditField
    EditField_22               matlab.ui.control.EditField
    EditField_23               matlab.ui.control.EditField
    EditField_24               matlab.ui.control.EditField
    EditField_25               matlab.ui.control.EditField
    EditField_26               matlab.ui.control.EditField
    EditField_27               matlab.ui.control.EditField
    EditField_28               matlab.ui.control.EditField
    EditField_29               matlab.ui.control.EditField
    EditField_30               matlab.ui.control.EditField
    EditField_31               matlab.ui.control.EditField
    EditField_32               matlab.ui.control.EditField
    EditField_33               matlab.ui.control.EditField
    EditField_34               matlab.ui.control.EditField
    EditField_35               matlab.ui.control.EditField
    EditField_36               matlab.ui.control.EditField
    EditField_37               matlab.ui.control.EditField
    EditField_38               matlab.ui.control.EditField
    EditField_39               matlab.ui.control.EditField
    EditField_40               matlab.ui.control.EditField
    EditField_41               matlab.ui.control.EditField
    EditField_42               matlab.ui.control.EditField
    EditField_43               matlab.ui.control.EditField
    EditField_44               matlab.ui.control.EditField
    EditField_45               matlab.ui.control.EditField
    EditField_46               matlab.ui.control.EditField
    EditField_47               matlab.ui.control.EditField
    EditField_48               matlab.ui.control.EditField
    EditField_49               matlab.ui.control.EditField
    EditField_50               matlab.ui.control.EditField
    EditField_51               matlab.ui.control.EditField
    EditField_52               matlab.ui.control.EditField
    EditField_53               matlab.ui.control.EditField
    EditField_54               matlab.ui.control.EditField
    EditField_55               matlab.ui.control.EditField
    EditField_56               matlab.ui.control.EditField
    EditField_57               matlab.ui.control.EditField
    EditField_58               matlab.ui.control.EditField
    EditField_59               matlab.ui.control.EditField
    EditField_60               matlab.ui.control.EditField
    EditField_61               matlab.ui.control.EditField
    EditField_62               matlab.ui.control.EditField
    EditField_63               matlab.ui.control.EditField
    EditField_64               matlab.ui.control.EditField
    EditField_65               matlab.ui.control.EditField
    EditField_66               matlab.ui.control.EditField
    EditField_67               matlab.ui.control.EditField
    EditField_68               matlab.ui.control.EditField
    EditField_69               matlab.ui.control.EditField
    EditField_70               matlab.ui.control.EditField
    EditField_71               matlab.ui.control.EditField
    EditField_72               matlab.ui.control.EditField
    EditField_73               matlab.ui.control.EditField
    EditField_74               matlab.ui.control.EditField
    EditField_75               matlab.ui.control.EditField
    EditField_76               matlab.ui.control.EditField
    EditField_77               matlab.ui.control.EditField
    EditField_78               matlab.ui.control.EditField
    EditField_79               matlab.ui.control.EditField
    EditField_80               matlab.ui.control.EditField
    EditField_81               matlab.ui.control.EditField
    EditField_82               matlab.ui.control.EditField
    EditField_83               matlab.ui.control.EditField
    EditField_84               matlab.ui.control.EditField
    EditField_85               matlab.ui.control.EditField
    EditField_86               matlab.ui.control.EditField
    EditField_87               matlab.ui.control.EditField
    EditField_88               matlab.ui.control.EditField
    KlysPhaseTab               matlab.ui.container.Tab
    GridLayout2_2              matlab.ui.container.GridLayout
    Label_11                   matlab.ui.control.Label
    Label_12                   matlab.ui.control.Label
    Label_13                   matlab.ui.control.Label
    Label_14                   matlab.ui.control.Label
    Label_15                   matlab.ui.control.Label
    Label_16                   matlab.ui.control.Label
    Label_17                   matlab.ui.control.Label
    Label_18                   matlab.ui.control.Label
    Label_19                   matlab.ui.control.Label
    Label_20                   matlab.ui.control.Label
    L0Label_3                  matlab.ui.control.Label
    L1L2Label_2                matlab.ui.control.Label
    L3Label_4                  matlab.ui.control.Label
    EditField_89               matlab.ui.control.EditField
    EditField_90               matlab.ui.control.EditField
    EditField_91               matlab.ui.control.EditField
    EditField_92               matlab.ui.control.EditField
    EditField_93               matlab.ui.control.EditField
    EditField_94               matlab.ui.control.EditField
    EditField_95               matlab.ui.control.EditField
    EditField_96               matlab.ui.control.EditField
    EditField_97               matlab.ui.control.EditField
    EditField_98               matlab.ui.control.EditField
    EditField_99               matlab.ui.control.EditField
    EditField_100              matlab.ui.control.EditField
    EditField_101              matlab.ui.control.EditField
    EditField_102              matlab.ui.control.EditField
    EditField_103              matlab.ui.control.EditField
    EditField_104              matlab.ui.control.EditField
    EditField_105              matlab.ui.control.EditField
    EditField_106              matlab.ui.control.EditField
    EditField_107              matlab.ui.control.EditField
    EditField_108              matlab.ui.control.EditField
    EditField_109              matlab.ui.control.EditField
    EditField_110              matlab.ui.control.EditField
    EditField_111              matlab.ui.control.EditField
    EditField_112              matlab.ui.control.EditField
    EditField_113              matlab.ui.control.EditField
    EditField_114              matlab.ui.control.EditField
    EditField_115              matlab.ui.control.EditField
    EditField_116              matlab.ui.control.EditField
    EditField_117              matlab.ui.control.EditField
    EditField_118              matlab.ui.control.EditField
    EditField_119              matlab.ui.control.EditField
    EditField_120              matlab.ui.control.EditField
    EditField_121              matlab.ui.control.EditField
    EditField_122              matlab.ui.control.EditField
    EditField_123              matlab.ui.control.EditField
    EditField_124              matlab.ui.control.EditField
    EditField_125              matlab.ui.control.EditField
    EditField_126              matlab.ui.control.EditField
    EditField_127              matlab.ui.control.EditField
    EditField_128              matlab.ui.control.EditField
    EditField_129              matlab.ui.control.EditField
    EditField_130              matlab.ui.control.EditField
    EditField_131              matlab.ui.control.EditField
    EditField_132              matlab.ui.control.EditField
    EditField_133              matlab.ui.control.EditField
    EditField_134              matlab.ui.control.EditField
    EditField_135              matlab.ui.control.EditField
    EditField_136              matlab.ui.control.EditField
    EditField_137              matlab.ui.control.EditField
    EditField_138              matlab.ui.control.EditField
    EditField_139              matlab.ui.control.EditField
    EditField_140              matlab.ui.control.EditField
    EditField_141              matlab.ui.control.EditField
    EditField_142              matlab.ui.control.EditField
    EditField_143              matlab.ui.control.EditField
    EditField_144              matlab.ui.control.EditField
    EditField_145              matlab.ui.control.EditField
    EditField_146              matlab.ui.control.EditField
    EditField_147              matlab.ui.control.EditField
    EditField_148              matlab.ui.control.EditField
    EditField_149              matlab.ui.control.EditField
    EditField_150              matlab.ui.control.EditField
    EditField_151              matlab.ui.control.EditField
    EditField_152              matlab.ui.control.EditField
    EditField_153              matlab.ui.control.EditField
    EditField_154              matlab.ui.control.EditField
    EditField_155              matlab.ui.control.EditField
    EditField_156              matlab.ui.control.EditField
    EditField_157              matlab.ui.control.EditField
    EditField_158              matlab.ui.control.EditField
    EditField_159              matlab.ui.control.EditField
    EditField_160              matlab.ui.control.EditField
    EditField_161              matlab.ui.control.EditField
    EditField_162              matlab.ui.control.EditField
    EditField_163              matlab.ui.control.EditField
    EditField_164              matlab.ui.control.EditField
    EditField_165              matlab.ui.control.EditField
    EditField_166              matlab.ui.control.EditField
    EditField_167              matlab.ui.control.EditField
    EditField_168              matlab.ui.control.EditField
    MessagesTab                matlab.ui.container.Tab
    TextArea                   matlab.ui.control.TextArea
    SettingsMenu               matlab.ui.container.Menu
    MagnetSetDestinationMenu   matlab.ui.container.Menu
    BDESMenu                   matlab.ui.container.Menu
    BCONMenu                   matlab.ui.container.Menu
    BDESBCONMenu               matlab.ui.container.Menu
    ForceallphasestozeroMenu   matlab.ui.container.Menu
    MultiknobMenu              matlab.ui.container.Menu
    AssignalltableentriesMenu  matlab.ui.container.Menu
    AssignSelectedrowsMenu     matlab.ui.container.Menu
    DisplayMenu                matlab.ui.container.Menu
    ShowlegendMenu             matlab.ui.container.Menu
    DetachplottableMenu        matlab.ui.container.Menu
    HelpMenu                   matlab.ui.container.Menu
    ThereisnohelpforyouMenu    matlab.ui.container.Menu
  end

  % Properties that correspond to apps with auto-reflow
  properties (Access = private)
    onePanelWidth = 576;
  end

  
  properties (Access = public)
    aobj % App object
  end
  
  methods (Access = public)
    
    function SetRegion(app,regsel)
      reghan1=[app.L0CheckBox app.L1CheckBox app.L2CheckBox app.L3CheckBox app.S20CheckBox];
      if ~any(regsel)
        app.L0CheckBox.Value=true;
        app.SetRegion([true false false false false]);
        return
      end
      app.aobj.linacsel = regsel ;
      reghan={[app.GunEref app.EditField app.EditField_2] [app.BC11Eref app.EditField_3 app.EditField_4] [app.BC14Eref app.EditField_5 app.EditField_6] [app.BC20Eref app.EditField_7 app.EditField_8] []};
      i1=find(regsel,1);
      i2=find(regsel,1,'last');
      for ireg=i1:i2
        if ireg>i1 && ireg<i2
          regsel(ireg)=true; % force continuous region selection
        end
      end
      for ireg=1:length(regsel)
        for iobj=1:length(reghan{ireg})
          set(reghan{ireg}(iobj),'Visible',true);
        end
      end
      if i1>1
        reghan{2}=[app.DL1Eref app.EditField_3 app.EditField_4] ;
%         reghan={[app.GunEref app.EditField app.EditField_2] [app.DL1Eref app.EditField_3 app.EditField_4] [app.BC11Eref app.EditField_5 app.EditField_6] [app.BC14Eref app.EditField_7 app.EditField_8] []};
      end
      if i1>2
        reghan{3}=[app.BC11Eref app.EditField_5 app.EditField_6];
      end
      if i2<4
        reghan{5}=app.BC20Eref;
        reghan{4}=[reghan{4} app.BC20Eref];
      end
      if i1==5
        reghan{4}=[reghan{4} app.BC14Eref];
        reghan{5}=app.BC20Eref;
      end
      for ireg=1:length(regsel)
        set(reghan1(ireg),'Value',regsel(ireg));
        for iobj=1:length(reghan{ireg})
          set(reghan{ireg}(iobj),'Visible',regsel(ireg));
        end
      end
    end
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, LLM)
      
      % Make sure start with Messages and EREFS tab visible
      app.TabGroup2.SelectedTab = app.MessagesTab ;
      app.TabGroup.SelectedTab = app.EREFSTab ;
      
      drawnow
      
      % Attach existing LEM object if supplied
      if exist("LLM","var")
        app.aobj=LLM.LEM;
        try
          app.ReadDataButton.Enable=false;
          app.aobj.attachGUI(app);
          app.DataValidLamp.Color='green';
        catch ME 
          app.ReadDataButton.Enable=true;
          app.DataValidLamp.Color='red';
          app.TextArea.Value=["!!!!!!! Error initializing LEM app: " + string(ME.message); string(app.TextArea.Value)];
          throw(ME);
        end
      end
      
      % Update data...
      app.ReadDataButtonPushed();
    end

    % Changes arrangement of the app based on UIFigure width
    function updateAppLayout(app, event)
            currentFigureWidth = app.FLEMFACETIILEMUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {458, 458};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {311, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
    end

    % Selection change function: TabGroup2
    function TabGroup2SelectionChanged(app, event)
      switch app.TabGroup2.SelectedTab % right side Tab group
        case app.Table
          app.AssignSelectedrowsMenu.Enable=true;
        otherwise
          app.AssignSelectedrowsMenu.Enable=false;
      end
      if isequal(app.DataValidLamp.Color,[0 1 0])
        app.aobj.UpdateGUI;
      end
    end

    % Button pushed function: ReadDataButton
    function ReadDataButtonPushed(app, event)
      app.DataValidLamp.Color='black';
      inittab = app.TabGroup2.SelectedTab ;
      app.TabGroup2.SelectedTab = app.MessagesTab ;
      if isempty(app.aobj) % Instantiate supporting app object
        try
          app.ReadDataButton.Enable=false;
          if app.ForceallphasestozeroMenu.Checked
            app.aobj = F2_LEMApp(app,true) ;
          else
            app.aobj = F2_LEMApp(app) ;
          end
          app.DataValidLamp.Color='green';
        catch ME 
          app.ReadDataButton.Enable=true;
          app.DataValidLamp.Color='red';
          app.TextArea.Value=["!!!!!!! Error initializing LEM app: " + string(ME.message); string(app.TextArea.Value)];
          throw(ME);
        end
      else
        app.aobj.UpdateModel;
      end
      app.ReadDataButton.Enable=true;
      app.TabGroup2.SelectedTab = inittab ;
      app.aobj.UpdateGUI;
    end

    % Selection change function: TabGroup
    function TabGroupSelectionChanged(app, event)
      if isequal(app.DataValidLamp.Color,[0 1 0])
        app.aobj.UpdateGUI;
      end
    end

    % Value changed function: GunEref
    function GunErefValueChanged(app, event)
      value = app.GunEref.Value;
      app.aobj.SetGunEref(value);
    end

    % Value changed function: DL1Eref
    function DL1ErefValueChanged(app, event)
      value = [app.DL1Eref.Value app.BC11Eref.Value app.BC14Eref.Value app.BC20Eref.Value] ;
      app.UseBendEDEFButton.Value = false ;
      app.aobj.UseBendEDEF=false;
      app.aobj.SetLinacEref(value) ;
    end

    % Value changed function: BC11Eref
    function BC11ErefValueChanged(app, event)
      value = [app.DL1Eref.Value app.BC11Eref.Value app.BC14Eref.Value app.BC20Eref.Value] ;
      app.UseBendEDEFButton.Value = false ;
      app.aobj.UseBendEDEF=false;
      app.aobj.SetLinacEref(value) ;
    end

    % Value changed function: BC14Eref
    function BC14ErefValueChanged(app, event)
      value = [app.DL1Eref.Value app.BC11Eref.Value app.BC14Eref.Value app.BC20Eref.Value] ;
      app.UseBendEDEFButton.Value = false ;
      app.aobj.UseBendEDEF=false;
      app.aobj.SetLinacEref(value) ;
    end

    % Value changed function: BC20Eref
    function BC20ErefValueChanged(app, event)
      value = [app.DL1Eref.Value app.BC11Eref.Value app.BC14Eref.Value app.BC20Eref.Value] ;
      app.UseBendEDEFButton.Value = false ;
      app.aobj.UseBendEDEF=false;
      app.aobj.SetLinacEref(value) ;
    end

    % Value changed function: L0EditField
    function L0EditFieldValueChanged(app, event)
      value = [app.L0EditField.Value app.L1EditField.Value app.L2EditField.Value app.L3EditField.Value];
      app.aobj.bq = value ;
      app.UseMeasuredValuesButton.Value=false;
      app.aobj.UpdateGUI;
    end

    % Value changed function: L1EditField
    function L1EditFieldValueChanged(app, event)
      value = [app.L0EditField.Value app.L1EditField.Value app.L2EditField.Value app.L3EditField.Value];
      app.aobj.bq = value ;
      app.UseMeasuredValuesButton.Value=false;
      app.aobj.UpdateGUI;
    end

    % Value changed function: L2EditField
    function L2EditFieldValueChanged(app, event)
      value = [app.L0EditField.Value app.L1EditField.Value app.L2EditField.Value app.L3EditField.Value];
      app.aobj.bq = value ;
      app.UseMeasuredValuesButton.Value=false;
      app.aobj.UpdateGUI;
    end

    % Value changed function: L3EditField
    function L3EditFieldValueChanged(app, event)
      value = [app.L0EditField.Value app.L1EditField.Value app.L2EditField.Value app.L3EditField.Value];
      app.aobj.bq = value ;
      app.UseMeasuredValuesButton.Value=false;
      app.aobj.UpdateGUI;
    end

    % Value changed function: EditField_175
    function EditField_175ValueChanged(app, event)
      value = [app.EditField_175.Value app.EditField_176.Value app.EditField_177.Value app.EditField_178.Value ] ;
      app.aobj.blen = value ;
      app.UseMeasuredValuesButton.Value=false;
      app.aobj.UpdateGUI;
    end

    % Value changed function: EditField_176
    function EditField_176ValueChanged(app, event)
      value = [app.EditField_175.Value app.EditField_176.Value app.EditField_177.Value app.EditField_178.Value ] ;
      app.aobj.blen = value ;
      app.UseMeasuredValuesButton.Value=false;
      app.aobj.UpdateGUI;
    end

    % Value changed function: EditField_177
    function EditField_177ValueChanged(app, event)
      value = [app.EditField_175.Value app.EditField_176.Value app.EditField_177.Value app.EditField_178.Value ] ;
      app.aobj.blen = value ;
      app.UseMeasuredValuesButton.Value=false;
      app.aobj.UpdateGUI;
    end

    % Value changed function: EditField_178
    function EditField_178ValueChanged(app, event)
      value = [app.EditField_175.Value app.EditField_176.Value app.EditField_177.Value app.EditField_178.Value ] ;
      app.aobj.blen = value ;
      app.UseMeasuredValuesButton.Value=false;
      app.aobj.UpdateGUI;
    end

    % Selection changed function: 
    % MagnetReferenceSourceButtonGroup
    function MagnetReferenceSourceButtonGroupSelectionChanged(app, event)
      selectedButton = app.MagnetReferenceSourceButtonGroup.SelectedObject;
      app.aobj.RescaleWithModel = selectedButton == app.UseModelStrengthsButton ;
      app.aobj.UpdateGUI;
    end

    % Value changed function: L0CheckBox
    function L0CheckBoxValueChanged(app, event)
      value = [app.L0CheckBox.Value app.L1CheckBox.Value app.L2CheckBox.Value app.L3CheckBox.Value app.S20CheckBox.Value] ;
      app.SetRegion(value);
    end

    % Value changed function: L1CheckBox
    function L1CheckBoxValueChanged(app, event)
      value = [app.L0CheckBox.Value app.L1CheckBox.Value app.L2CheckBox.Value app.L3CheckBox.Value app.S20CheckBox.Value] ;
      app.SetRegion(value);
    end

    % Value changed function: L2CheckBox
    function L2CheckBoxValueChanged(app, event)
      value = [app.L0CheckBox.Value app.L1CheckBox.Value app.L2CheckBox.Value app.L3CheckBox.Value app.S20CheckBox.Value] ;
      app.SetRegion(value);
    end

    % Value changed function: L3CheckBox
    function L3CheckBoxValueChanged(app, event)
      value = [app.L0CheckBox.Value app.L1CheckBox.Value app.L2CheckBox.Value app.L3CheckBox.Value app.S20CheckBox.Value] ;
      app.SetRegion(value);
    end

    % Value changed function: S20CheckBox
    function S20CheckBoxValueChanged(app, event)
      value = [app.L0CheckBox.Value app.L1CheckBox.Value app.L2CheckBox.Value app.L3CheckBox.Value app.S20CheckBox.Value] ;
      app.SetRegion(value);
    end

    % Button pushed function: ScaleMagnetsButton
    function ScaleMagnetsButtonPushed(app, event)
      app.DataValidLamp.Color = 'black' ;
      if app.TabGroup2.SelectedTab==app.Table % provide possibility of selecting magnets to scale
        resp=questdlg('Scale magnets from Eref to current energy profile based on Table selections or All magnets (with BERR flag set)?','Scale Magnets','All','Table Selection','Cancel','Cancel');
        if string(resp)=="All"
          app.aobj.DoMagnetScale;
        elseif string(resp)=="Table Selection"
          app.aobj.DoMagnetScale("tablesel");
        end
      else
        resp=questdlg('Scale All magnets from Eref to current energy profile (with BERR flag set)?','Scale Magnets','Scale Magnets','Cancel','Cancel');
        if string(resp)=="Scale Magnets"
          app.aobj.DoMagnetScale;
        end
      end
      app.aobj.UpdateGUI;
    end

    % Button pushed function: UndoButton
    function UndoButtonPushed(app, event)
      app.aobj.UndoMagnetScale;
    end

    % Callback function
    function SetFudgerefMenuSelected(app, event)
      
    end

    % Menu selected function: SetPrefMenu
    function SetPrefMenuSelected(app, event)
      if string(questdlg('Set in-memory momentum profile as energy profile reference and write to EPICS (without scaling magnets)?','Yes','No'))=="Yes"
        try
          app.aobj.SetPref;
        catch ME
          errordlg(sprintf('Error setting momentum profile reference:\n%s',ME.message),"PRED Set Error");
        end
      end
    end

    % Menu selected function: SaveDataMenu
    function SaveDataMenuSelected(app, event)
      [fname,fdir]=uiputfile(app.aobj.confdir+"/F2_LEM/refdata.mat","Save Model File");
      if fname
        app.aobj.SaveModel(fullfile(fdir,fname),true);
      end
    end

    % Callback function
    function LoadModelMenuSelected(app, event)
      [fname,fdir]=uigetfile(app.aobj.modeldir+"/FACET2e/*.mat","Load Model File");
      if fname
        try
          app.aobj.LoadModel(fullfile(fdir,fname),true);
        catch ME
          errordlg(sprintf('Error loading model:\n%s',ME.message));
        end
      end
    end

    % Menu selected function: LoadDataMenu
    function LoadDataMenuSelected(app, event)
      [fname,fdir]=uigetfile(app.aobj.confdir+"/F2_LEM/*.mat","Load Data File");
      if fname
        try
          app.aobj.LoadModel(fullfile(fdir,fname),true);
        catch ME
          errordlg(sprintf('Error loading data:\n%s',ME.message));
        end
      end
    end

    % Value changed function: UseBendEDEFButton
    function UseBendEDEFButtonValueChanged(app, event)
      app.aobj.UseBendEDEF = app.UseBendEDEFButton.Value;
      
    end

    % Cell edit callback: UITable
    function UITableCellEdit(app, event)
      indices = event.Indices;
      newData = event.NewData;
      app.aobj.tableCallback(indices,newData);
    end

    % Cell selection callback: UITable
    function UITableCellSelection(app, event)
      indices = event.Indices;
      app.aobj.tableCallback(indices);
    end

    % Menu selected function: DetachplottableMenu
    function DetachplottableMenuSelected(app, event)
      app.aobj.uidetachplot=true;
      app.aobj.UpdateGUI;
    end

    % Menu selected function: ShowlegendMenu
    function ShowlegendMenuSelected(app, event)
      if app.ShowlegendMenu.Checked
        app.ShowlegendMenu.Checked=false;
      else
        app.ShowlegendMenu.Checked=true;
      end
      app.aobj.uishowlegend = app.ShowlegendMenu.Checked ;
      app.aobj.UpdateGUI;
    end

    % Value changed function: UseMeasuredValuesButton
    function UseMeasuredValuesButtonValueChanged(app, event)
      value = app.UseMeasuredValuesButton.Value;
      if value
        app.aobj.ReadWakeMeasData;
        app.aobj.UpdateGUI;
      end
    end

    % Menu selected function: ForceallphasestozeroMenu
    function ForceallphasestozeroMenuSelected(app, event)
      app.DataValidLamp.Color='black';
      inittab = app.TabGroup2.SelectedTab ;
      app.TabGroup2.SelectedTab = app.MessagesTab ;
      app.ReadDataButton.Enable=false;
      if app.ForceallphasestozeroMenu.Checked
        app.ForceallphasestozeroMenu.Checked=false;
      else
        app.ForceallphasestozeroMenu.Checked=true;
      end
      if ~isempty(app.aobj)
        app.aobj.KlysZeroPhases = app.ForceallphasestozeroMenu.Checked ;
      end
      app.DataValidLamp.Color='green';
      app.ReadDataButton.Enable=true;
      app.TabGroup2.SelectedTab = inittab ;
      app.aobj.UpdateGUI;
    end

    % Close request function: FLEMFACETIILEMUIFigure
    function FLEMFACETIILEMUIFigureCloseRequest(app, event)
      delete(app)
%       exit
    end

    % Menu selected function: AssignalltableentriesMenu
    function AssignalltableentriesMenuSelected(app, event)
      app.aobj.LaunchMultiknob("Berr");
    end

    % Menu selected function: AssignSelectedrowsMenu
    function AssignSelectedrowsMenuSelected(app, event)
      app.aobj.LaunchMultiknob("TableSelect");
    end

    % Value changed function: EditField_10, EditField_11, 
    % EditField_12, EditField_13, EditField_14, EditField_15, 
    % EditField_16, EditField_17, EditField_18, EditField_20, 
    % EditField_21, EditField_22, EditField_23, EditField_24, 
    % EditField_25, EditField_26, EditField_27, EditField_28, 
    % EditField_29, EditField_31, EditField_32, EditField_33, 
    % EditField_34, EditField_35, EditField_36, EditField_37, 
    % EditField_38, EditField_39, EditField_40, EditField_41, 
    % EditField_42, EditField_43, EditField_44, EditField_45, 
    % EditField_46, EditField_47, EditField_48, EditField_50, 
    % EditField_51, EditField_52, EditField_53, EditField_54, 
    % EditField_55, EditField_56, EditField_57, EditField_58, 
    % EditField_60, EditField_61, EditField_62, EditField_63, 
    % EditField_64, EditField_65, EditField_66, EditField_67, 
    % EditField_68, EditField_70, EditField_71, EditField_72, 
    % EditField_74, EditField_75, EditField_76, EditField_77, 
    % EditField_80, EditField_81, EditField_82, EditField_84, 
    % EditField_85, EditField_86, EditField_87
    function EditField_29ValueChanged(app, event)
      % Setting overrides Egain values
      t=regexp(event.Source.Tag,'(\d+)_(\d+)','tokens','once');
      isec=str2double(t{1})-9; ikly=str2double(t{2});
      egain = str2double(event.Source.Value) ;
      phase = app.aobj.Klys.KlysPhase(ikly,isec) ;
      wake = app.aobj.klyswakeloss(ikly,isec) ;
      ampl = (egain + wake) / cosd(phase) ;
      app.aobj.Klys.KlysAmplOverride(ikly,isec) = ampl ;
      app.DataValidLamp.Color='red';
      if isnan(event.Source.Value)
        fprintf('%s: Disabling Klystron %d-%d Ampl Override\n',datestr(now),isec+9,ikly);
        event.Source.FontColor='black';
      else
        fprintf('%s: Overriding Klystron %d-%d Ampl = %g\n',datestr(now),isec+9,ikly,ampl);
        event.Source.FontColor='red';
      end
      app.aobj.SaveOverrides;
    end

    % Value changed function: EditField_100, EditField_101, 
    % EditField_102, EditField_103, EditField_104, 
    % EditField_105, EditField_106, EditField_107, 
    % EditField_108, EditField_109, EditField_111, 
    % EditField_112, EditField_113, EditField_114, 
    % EditField_115, EditField_116, EditField_117, 
    % EditField_118, EditField_119, EditField_120, 
    % EditField_121, EditField_122, EditField_123, 
    % EditField_124, EditField_125, EditField_126, 
    % EditField_127, EditField_128, EditField_130, 
    % EditField_131, EditField_132, EditField_133, 
    % EditField_134, EditField_135, EditField_136, 
    % EditField_137, EditField_138, EditField_140, 
    % EditField_141, EditField_142, EditField_143, 
    % EditField_144, EditField_145, EditField_146, 
    % EditField_147, EditField_148, EditField_150, 
    % EditField_151, EditField_152, EditField_154, 
    % EditField_155, EditField_156, EditField_157, 
    % EditField_160, EditField_161, EditField_162, 
    % EditField_164, EditField_165, EditField_166, 
    % EditField_167, EditField_90, EditField_91, EditField_92, 
    % EditField_93, EditField_94, EditField_95, EditField_96, 
    % EditField_97, EditField_98
    function EditField_109ValueChanged(app, event)
       % Setting overrides phase values
       t=regexp(event.Source.Tag,'(\d+)_(\d+)','tokens','once');
       isec=str2double(t{1})-9; ikly=str2double(t{2});
       phase = str2double(event.Source.Value) ;
       app.aobj.Klys.KlysPhaseOverride(ikly,isec) = phase ;
       app.DataValidLamp.Color='red';
       if isnan(event.Source.Value)
         fprintf('%s: Disabling Klystron %d-%d Phase Override\n',datestr(now),isec+9,ikly);
         event.Source.FontColor='black';
       else
         fprintf('%s: Overriding Klystron %d-%d Phase = %g\n',datestr(now),isec+9,ikly,phase);
         event.Source.FontColor='red';
       end
       app.aobj.SaveOverrides;
    end

    % Menu selected function: MagnetSetDestinationMenu
    function MagnetSetDestinationMenuSelected(app, event)
      
    end

    % Menu selected function: BDESMenu
    function BDESMenuSelected(app, event)
      app.aobj.WriteDest = "BDES" ;
      app.BDESMenu.Checked = true; app.BCONMenu.Checked = false; app.BDESBCONMenu.Checked = false ;
    end

    % Menu selected function: BCONMenu
    function BCONMenuSelected(app, event)
      app.aobj.WriteDest = "BCON" ;
      app.BDESMenu.Checked = false; app.BCONMenu.Checked = true; app.BDESBCONMenu.Checked = false ;
    end

    % Menu selected function: BDESBCONMenu
    function BDESBCONMenuSelected(app, event)
      app.aobj.WriteDest = "BDESCON" ;
      app.BDESMenu.Checked = false; app.BCONMenu.Checked = false; app.BDESBCONMenu.Checked = true ;
    end

    % Value changed function: EditField_169
    function EditField_169ValueChanged(app, event)
      value = str2double(app.EditField_169.Value);
      if ~isnan(value)
        app.aobj.DesignPhases(1)=value;
      end
    end

    % Value changed function: EditField_170
    function EditField_170ValueChanged(app, event)
      value = str2double(app.EditField_170.Value);
      if ~isnan(value)
        app.aobj.DesignPhases(2)=value;
      end
    end

    % Value changed function: EditField_171
    function EditField_171ValueChanged(app, event)
      value = str2double(app.EditField_171.Value);
      if ~isnan(value)
        app.aobj.DesignPhases(3)=value;
      end
    end

    % Value changed function: GetPhasesfromSBSTsButton
    function GetPhasesfromSBSTsButtonValueChanged(app, event)
      value = app.GetPhasesfromSBSTsButton.Value;
      app.aobj.UseDesignPhases = ~value ;
      app.aobj.UpdateGUI;
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.FLEMFACETIILEMUIFigure = uifigure('Visible', 'off');
      app.FLEMFACETIILEMUIFigure.Name = 'FLEM (FACET-II LEM)';
      app.FLEMFACETIILEMUIFigure.CloseRequestFcn = createCallbackFcn(app, @FLEMFACETIILEMUIFigureCloseRequest, true);
      app.FLEMFACETIILEMUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
      app.FileMenu = uimenu(app.FLEMFACETIILEMUIFigure);
      app.FileMenu.Text = 'File';
      app.SetPrefMenu = uimenu(app.FileMenu);
      app.SetPrefMenu.MenuSelectedFcn = createCallbackFcn(app, @SetPrefMenuSelected, true);
      app.SetPrefMenu.Text = 'Set P ref...';
      app.SaveDataMenu = uimenu(app.FileMenu);
      app.SaveDataMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveDataMenuSelected, true);
      app.SaveDataMenu.Text = 'Save Data...';
      app.LoadDataMenu = uimenu(app.FileMenu);
      app.LoadDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataMenuSelected, true);
      app.LoadDataMenu.Text = 'Load Data...';
      app.GridLayout = uigridlayout(app.FLEMFACETIILEMUIFigure);
      app.LeftPanel = uipanel(app.GridLayout);
      app.ScaleMagnetsButton = uibutton(app.LeftPanel, 'push');
      app.ScaleMagnetsButton.ButtonPushedFcn = createCallbackFcn(app, @ScaleMagnetsButtonPushed, true);
      app.ScaleMagnetsButton.Text = 'Scale Magnets';
      app.UndoButton = uibutton(app.LeftPanel, 'push');
      app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
      app.UndoButton.Enable = 'off';
      app.UndoButton.Text = 'Undo';
      app.TabGroup = uitabgroup(app.LeftPanel);
      app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
      app.EREFSTab = uitab(app.TabGroup);
      app.EREFSTab.Title = 'EREFS';
      app.GunEditFieldLabel = uilabel(app.EREFSTab);
      app.GunEditFieldLabel.Text = 'Gun';
      app.GunEref = uieditfield(app.EREFSTab, 'numeric');
      app.GunEref.ValueDisplayFormat = '%.4f';
      app.GunEref.ValueChangedFcn = createCallbackFcn(app, @GunErefValueChanged, true);
      app.GunEref.Value = 0.005;
      app.DL1EditFieldLabel = uilabel(app.EREFSTab);
      app.DL1EditFieldLabel.Text = 'DL1';
      app.DL1Eref = uieditfield(app.EREFSTab, 'numeric');
      app.DL1Eref.ValueDisplayFormat = '%.3f';
      app.DL1Eref.ValueChangedFcn = createCallbackFcn(app, @DL1ErefValueChanged, true);
      app.DL1Eref.Value = 0.135;
      app.BC11EditFieldLabel = uilabel(app.EREFSTab);
      app.BC11EditFieldLabel.Text = 'BC11';
      app.BC11Eref = uieditfield(app.EREFSTab, 'numeric');
      app.BC11Eref.ValueDisplayFormat = '%.3f';
      app.BC11Eref.ValueChangedFcn = createCallbackFcn(app, @BC11ErefValueChanged, true);
      app.BC11Eref.Value = 0.335;
      app.ErefGeVLabel = uilabel(app.EREFSTab);
      app.ErefGeVLabel.Text = 'Eref (GeV)';
      app.FudgeFactorLabel = uilabel(app.EREFSTab);
      app.FudgeFactorLabel.Text = 'Fudge Factor';
      app.ExtantLabel = uilabel(app.EREFSTab);
      app.ExtantLabel.Text = 'Extant';
      app.RefLabel = uilabel(app.EREFSTab);
      app.RefLabel.Text = 'Ref.';
      app.L0Label = uilabel(app.EREFSTab);
      app.L0Label.Text = 'L0';
      app.EditField = uieditfield(app.EREFSTab, 'numeric');
      app.EditField.ValueDisplayFormat = '%5.4f';
      app.EditField.Editable = 'off';
      app.EditField.Value = 1;
      app.EditField_2 = uieditfield(app.EREFSTab, 'numeric');
      app.EditField_2.ValueDisplayFormat = '%5.4f';
      app.EditField_2.Editable = 'off';
      app.EditField_2.Value = 1;
      app.L1Label = uilabel(app.EREFSTab);
      app.L1Label.Text = 'L1';
      app.EditField_3 = uieditfield(app.EREFSTab, 'numeric');
      app.EditField_3.ValueDisplayFormat = '%5.4f';
      app.EditField_3.Editable = 'off';
      app.EditField_3.Value = 1;
      app.EditField_4 = uieditfield(app.EREFSTab, 'numeric');
      app.EditField_4.ValueDisplayFormat = '%5.4f';
      app.EditField_4.Editable = 'off';
      app.EditField_4.Value = 1;
      app.L2Label_2 = uilabel(app.EREFSTab);
      app.L2Label_2.Text = 'L2';
      app.EditField_5 = uieditfield(app.EREFSTab, 'numeric');
      app.EditField_5.ValueDisplayFormat = '%5.4f';
      app.EditField_5.Editable = 'off';
      app.EditField_5.Value = 1;
      app.EditField_6 = uieditfield(app.EREFSTab, 'numeric');
      app.EditField_6.ValueDisplayFormat = '%5.4f';
      app.EditField_6.Editable = 'off';
      app.EditField_6.Value = 1;
      app.L3Label_2 = uilabel(app.EREFSTab);
      app.L3Label_2.Text = 'L3';
      app.EditField_7 = uieditfield(app.EREFSTab, 'numeric');
      app.EditField_7.ValueDisplayFormat = '%5.4f';
      app.EditField_7.Editable = 'off';
      app.EditField_7.Value = 1;
      app.EditField_8 = uieditfield(app.EREFSTab, 'numeric');
      app.EditField_8.ValueDisplayFormat = '%5.4f';
      app.EditField_8.Editable = 'off';
      app.EditField_8.Value = 1;
      app.BC14Label = uilabel(app.EREFSTab);
      app.BC14Label.Text = 'BC14';
      app.BC14Eref = uieditfield(app.EREFSTab, 'numeric');
      app.BC14Eref.ValueDisplayFormat = '%.3f';
      app.BC14Eref.ValueChangedFcn = createCallbackFcn(app, @BC14ErefValueChanged, true);
      app.BC14Eref.Value = 4.5;
      app.BC20Label = uilabel(app.EREFSTab);
      app.BC20Label.Text = 'BC20';
      app.BC20Eref = uieditfield(app.EREFSTab, 'numeric');
      app.BC20Eref.ValueDisplayFormat = '%.3f';
      app.BC20Eref.ValueChangedFcn = createCallbackFcn(app, @BC20ErefValueChanged, true);
      app.BC20Eref.Value = 10;
      app.UseBendEDEFButton = uibutton(app.EREFSTab, 'state');
      app.UseBendEDEFButton.ValueChangedFcn = createCallbackFcn(app, @UseBendEDEFButtonValueChanged, true);
      app.UseBendEDEFButton.Text = 'Use Bend EDEF';
      app.UseBendEDEFButton.Value = true;
      app.WakesTab = uitab(app.TabGroup);
      app.WakesTab.Title = 'Wakes';
      app.BunchChargenCLabel = uilabel(app.WakesTab);
      app.BunchChargenCLabel.Text = {'Bunch'; 'Charge (nC)'};
      app.L0EditFieldLabel = uilabel(app.WakesTab);
      app.L0EditFieldLabel.Text = 'L0';
      app.L0EditField = uieditfield(app.WakesTab, 'numeric');
      app.L0EditField.ValueChangedFcn = createCallbackFcn(app, @L0EditFieldValueChanged, true);
      app.L0EditField.Value = 2;
      app.L1EditFieldLabel = uilabel(app.WakesTab);
      app.L1EditFieldLabel.Text = 'L1';
      app.L1EditField = uieditfield(app.WakesTab, 'numeric');
      app.L1EditField.ValueChangedFcn = createCallbackFcn(app, @L1EditFieldValueChanged, true);
      app.L1EditField.Value = 2;
      app.L2EditFieldLabel = uilabel(app.WakesTab);
      app.L2EditFieldLabel.Text = 'L2';
      app.L2EditField = uieditfield(app.WakesTab, 'numeric');
      app.L2EditField.ValueChangedFcn = createCallbackFcn(app, @L2EditFieldValueChanged, true);
      app.L2EditField.Value = 2;
      app.L3EditFieldLabel = uilabel(app.WakesTab);
      app.L3EditFieldLabel.Text = 'L3';
      app.L3EditField = uieditfield(app.WakesTab, 'numeric');
      app.L3EditField.ValueChangedFcn = createCallbackFcn(app, @L3EditFieldValueChanged, true);
      app.L3EditField.Value = 2;
      app.EditField_175 = uieditfield(app.WakesTab, 'numeric');
      app.EditField_175.ValueChangedFcn = createCallbackFcn(app, @EditField_175ValueChanged, true);
      app.EditField_175.Value = 735;
      app.EditField_176 = uieditfield(app.WakesTab, 'numeric');
      app.EditField_176.ValueChangedFcn = createCallbackFcn(app, @EditField_176ValueChanged, true);
      app.EditField_176.Value = 735;
      app.EditField_177 = uieditfield(app.WakesTab, 'numeric');
      app.EditField_177.ValueChangedFcn = createCallbackFcn(app, @EditField_177ValueChanged, true);
      app.EditField_177.Value = 438;
      app.EditField_178 = uieditfield(app.WakesTab, 'numeric');
      app.EditField_178.ValueChangedFcn = createCallbackFcn(app, @EditField_178ValueChanged, true);
      app.EditField_178.Value = 90;
      app.rmsBunchLengthumLabel = uilabel(app.WakesTab);
      app.rmsBunchLengthumLabel.Text = {'rms Bunch'; 'Length (um)'};
      app.ElossMeVLabel = uilabel(app.WakesTab);
      app.ElossMeVLabel.Text = {'Eloss'; '(MeV)'};
      app.EditField_179 = uieditfield(app.WakesTab, 'numeric');
      app.EditField_179.ValueDisplayFormat = '%.1f';
      app.EditField_179.Editable = 'off';
      app.EditField_180 = uieditfield(app.WakesTab, 'numeric');
      app.EditField_180.ValueDisplayFormat = '%.1f';
      app.EditField_180.Editable = 'off';
      app.EditField_181 = uieditfield(app.WakesTab, 'numeric');
      app.EditField_181.ValueDisplayFormat = '%.1f';
      app.EditField_181.Editable = 'off';
      app.EditField_182 = uieditfield(app.WakesTab, 'numeric');
      app.EditField_182.ValueDisplayFormat = '%.1f';
      app.EditField_182.Editable = 'off';
      app.UseMeasuredValuesButton = uibutton(app.WakesTab, 'state');
      app.UseMeasuredValuesButton.ValueChangedFcn = createCallbackFcn(app, @UseMeasuredValuesButtonValueChanged, true);
      app.UseMeasuredValuesButton.Text = 'Use Measured Values';
      app.UseMeasuredValuesButton.Value = true;
      app.RFTab = uitab(app.TabGroup);
      app.RFTab.Title = 'RF';
      app.L1Label_2 = uilabel(app.RFTab);
      app.L1Label_2.Text = 'L1';
      app.L2Label = uilabel(app.RFTab);
      app.L2Label.Text = 'L2';
      app.L3Label_5 = uilabel(app.RFTab);
      app.L3Label_5.Text = 'L3';
      app.PhasedegLabel = uilabel(app.RFTab);
      app.PhasedegLabel.Text = {'Phase'; '(deg)'};
      app.EGAINMeVLabel = uilabel(app.RFTab);
      app.EGAINMeVLabel.Text = {'EGAIN'; '(MeV)'};
      app.EditField_169 = uieditfield(app.RFTab, 'text');
      app.EditField_169.ValueChangedFcn = createCallbackFcn(app, @EditField_169ValueChanged, true);
      app.EditField_169.Value = '0';
      app.EditField_170 = uieditfield(app.RFTab, 'text');
      app.EditField_170.ValueChangedFcn = createCallbackFcn(app, @EditField_170ValueChanged, true);
      app.EditField_170.Value = '0.000';
      app.EditField_171 = uieditfield(app.RFTab, 'text');
      app.EditField_171.ValueChangedFcn = createCallbackFcn(app, @EditField_171ValueChanged, true);
      app.EditField_171.Value = '0.000';
      app.EditField_172 = uieditfield(app.RFTab, 'text');
      app.EditField_172.Editable = 'off';
      app.EditField_172.Value = '0.000';
      app.EditField_173 = uieditfield(app.RFTab, 'text');
      app.EditField_173.Editable = 'off';
      app.EditField_173.Value = '0.000';
      app.EditField_174 = uieditfield(app.RFTab, 'text');
      app.EditField_174.Editable = 'off';
      app.EditField_174.Value = '0.000';
      app.EditField_183 = uieditfield(app.RFTab, 'text');
      app.EditField_183.Editable = 'off';
      app.EditField_183.Value = '0.000';
      app.EditField_184 = uieditfield(app.RFTab, 'text');
      app.EditField_184.Editable = 'off';
      app.EditField_184.Value = '0.000';
      app.EditField_185 = uieditfield(app.RFTab, 'text');
      app.EditField_185.Editable = 'off';
      app.EditField_185.Value = '0.000';
      app.CHIRPMeVLabel = uilabel(app.RFTab);
      app.CHIRPMeVLabel.Text = {'CHIRP'; '(MeV)'};
      app.GetPhasesfromSBSTsButton = uibutton(app.RFTab, 'state');
      app.GetPhasesfromSBSTsButton.ValueChangedFcn = createCallbackFcn(app, @GetPhasesfromSBSTsButtonValueChanged, true);
      app.GetPhasesfromSBSTsButton.Text = 'Get Phases from SBSTs';
      app.MagnetsTab_2 = uitab(app.TabGroup);
      app.MagnetsTab_2.Title = 'Magnets';
      app.MagnetReferenceSourceButtonGroup = uibuttongroup(app.MagnetsTab_2);
      app.MagnetReferenceSourceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MagnetReferenceSourceButtonGroupSelectionChanged, true);
      app.MagnetReferenceSourceButtonGroup.Title = 'Magnet Reference Source';
      app.UseExtantStrengthsButton = uitogglebutton(app.MagnetReferenceSourceButtonGroup);
      app.UseExtantStrengthsButton.Text = 'Use Extant Strengths';
      app.UseExtantStrengthsButton.Value = true;
      app.UseModelStrengthsButton = uitogglebutton(app.MagnetReferenceSourceButtonGroup);
      app.UseModelStrengthsButton.Text = 'Use Model Strengths';
      app.L0CheckBox = uicheckbox(app.LeftPanel);
      app.L0CheckBox.ValueChangedFcn = createCallbackFcn(app, @L0CheckBoxValueChanged, true);
      app.L0CheckBox.Enable = 'off';
      app.L0CheckBox.Text = 'L0';
      app.L0CheckBox.Value = true;
      app.L1CheckBox = uicheckbox(app.LeftPanel);
      app.L1CheckBox.ValueChangedFcn = createCallbackFcn(app, @L1CheckBoxValueChanged, true);
      app.L1CheckBox.Enable = 'off';
      app.L1CheckBox.Text = 'L1';
      app.L1CheckBox.Value = true;
      app.L2CheckBox = uicheckbox(app.LeftPanel);
      app.L2CheckBox.ValueChangedFcn = createCallbackFcn(app, @L2CheckBoxValueChanged, true);
      app.L2CheckBox.Enable = 'off';
      app.L2CheckBox.Text = 'L2';
      app.L2CheckBox.Value = true;
      app.L3CheckBox = uicheckbox(app.LeftPanel);
      app.L3CheckBox.ValueChangedFcn = createCallbackFcn(app, @L3CheckBoxValueChanged, true);
      app.L3CheckBox.Enable = 'off';
      app.L3CheckBox.Text = 'L3';
      app.L3CheckBox.Value = true;
      app.ReadDataButton = uibutton(app.LeftPanel, 'push');
      app.ReadDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReadDataButtonPushed, true);
      app.ReadDataButton.Text = 'Read Data';
      app.S20CheckBox = uicheckbox(app.LeftPanel);
      app.S20CheckBox.ValueChangedFcn = createCallbackFcn(app, @S20CheckBoxValueChanged, true);
      app.S20CheckBox.Enable = 'off';
      app.S20CheckBox.Text = 'S20';
      app.S20CheckBox.Value = true;
      app.DataValidLampLabel = uilabel(app.LeftPanel);
      app.DataValidLampLabel.Text = 'Data Valid?';
      app.DataValidLamp = uilamp(app.LeftPanel);
      app.RightPanel = uipanel(app.GridLayout);
      app.TabGroup2 = uitabgroup(app.RightPanel);
      app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
      app.EProfileTab = uitab(app.TabGroup2);
      app.EProfileTab.Title = 'E-Profile';
      app.UIAxes = uiaxes(app.EProfileTab);
      title(app.UIAxes, '')
      xlabel(app.UIAxes, 'X')
      ylabel(app.UIAxes, 'Y')
      zlabel(app.UIAxes, 'Z')
      app.MagnetsTab = uitab(app.TabGroup2);
      app.MagnetsTab.Title = 'Magnets';
      app.UIAxes2 = uiaxes(app.MagnetsTab);
      title(app.UIAxes2, '')
      xlabel(app.UIAxes2, 'X')
      ylabel(app.UIAxes2, 'Y')
      zlabel(app.UIAxes2, 'Z')
      app.Table = uitab(app.TabGroup2);
      app.Table.Title = 'Table';
      app.UITable = uitable(app.Table);
      app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
      app.UITable.RowName = {};
      app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
      app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
      app.KlysEgainTab = uitab(app.TabGroup2);
      app.KlysEgainTab.Title = 'Klys Egain';
      app.GridLayout2 = uigridlayout(app.KlysEgainTab);
      app.Label_10 = uilabel(app.GridLayout2);
      app.Label_10.Text = '19';
      app.Label_9 = uilabel(app.GridLayout2);
      app.Label_9.Text = '18';
      app.Label_8 = uilabel(app.GridLayout2);
      app.Label_8.Text = '17';
      app.Label_7 = uilabel(app.GridLayout2);
      app.Label_7.Text = '16';
      app.Label_6 = uilabel(app.GridLayout2);
      app.Label_6.Text = '15';
      app.Label_5 = uilabel(app.GridLayout2);
      app.Label_5.Text = '14';
      app.Label_4 = uilabel(app.GridLayout2);
      app.Label_4.Text = '13';
      app.Label_3 = uilabel(app.GridLayout2);
      app.Label_3.Text = '12';
      app.Label_2 = uilabel(app.GridLayout2);
      app.Label_2.Text = '11';
      app.Label = uilabel(app.GridLayout2);
      app.Label.Text = '10';
      app.L0Label_2 = uilabel(app.GridLayout2);
      app.L0Label_2.Text = 'L0';
      app.L1L2Label = uilabel(app.GridLayout2);
      app.L1L2Label.Text = 'L1 / L2';
      app.L3Label_3 = uilabel(app.GridLayout2);
      app.L3Label_3.Text = 'L3';
      app.EditField_9 = uieditfield(app.GridLayout2, 'text');
      app.EditField_9.Value = '0.000';
      app.EditField_10 = uieditfield(app.GridLayout2, 'text');
      app.EditField_10.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_10.Tag = '11_1';
      app.EditField_10.Value = '0.000';
      app.EditField_11 = uieditfield(app.GridLayout2, 'text');
      app.EditField_11.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_11.Tag = '12_1';
      app.EditField_11.Value = '0.000';
      app.EditField_12 = uieditfield(app.GridLayout2, 'text');
      app.EditField_12.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_12.Tag = '13_1';
      app.EditField_12.Value = '0.000';
      app.EditField_13 = uieditfield(app.GridLayout2, 'text');
      app.EditField_13.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_13.Tag = '14_1';
      app.EditField_13.Value = '0.000';
      app.EditField_14 = uieditfield(app.GridLayout2, 'text');
      app.EditField_14.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_14.Tag = '15_1';
      app.EditField_14.Value = '0.000';
      app.EditField_15 = uieditfield(app.GridLayout2, 'text');
      app.EditField_15.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_15.Tag = '16_1';
      app.EditField_15.Value = '0.000';
      app.EditField_16 = uieditfield(app.GridLayout2, 'text');
      app.EditField_16.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_16.Tag = '17_1';
      app.EditField_16.Value = '0.000';
      app.EditField_17 = uieditfield(app.GridLayout2, 'text');
      app.EditField_17.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_17.Tag = '18_1';
      app.EditField_17.Value = '0.000';
      app.EditField_18 = uieditfield(app.GridLayout2, 'text');
      app.EditField_18.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_18.Tag = '19_1';
      app.EditField_18.Value = '0.000';
      app.EditField_19 = uieditfield(app.GridLayout2, 'text');
      app.EditField_19.Value = '0.000';
      app.EditField_20 = uieditfield(app.GridLayout2, 'text');
      app.EditField_20.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_20.Tag = '11_2';
      app.EditField_20.Value = '0.000';
      app.EditField_21 = uieditfield(app.GridLayout2, 'text');
      app.EditField_21.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_21.Tag = '12_2';
      app.EditField_21.Value = '0.000';
      app.EditField_22 = uieditfield(app.GridLayout2, 'text');
      app.EditField_22.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_22.Tag = '13_2';
      app.EditField_22.Value = '0.000';
      app.EditField_23 = uieditfield(app.GridLayout2, 'text');
      app.EditField_23.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_23.Tag = '14_2';
      app.EditField_23.Value = '0.000';
      app.EditField_24 = uieditfield(app.GridLayout2, 'text');
      app.EditField_24.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_24.Tag = '15_2';
      app.EditField_24.Value = '0.000';
      app.EditField_25 = uieditfield(app.GridLayout2, 'text');
      app.EditField_25.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_25.Tag = '16_2';
      app.EditField_25.Value = '0.000';
      app.EditField_26 = uieditfield(app.GridLayout2, 'text');
      app.EditField_26.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_26.Tag = '17_2';
      app.EditField_26.Value = '0.000';
      app.EditField_27 = uieditfield(app.GridLayout2, 'text');
      app.EditField_27.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_27.Tag = '18_2';
      app.EditField_27.Value = '0.000';
      app.EditField_28 = uieditfield(app.GridLayout2, 'text');
      app.EditField_28.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_28.Tag = '19_2';
      app.EditField_28.Value = '0.000';
      app.EditField_29 = uieditfield(app.GridLayout2, 'text');
      app.EditField_29.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_29.Tag = '10_3';
      app.EditField_29.Value = '0.000';
      app.EditField_30 = uieditfield(app.GridLayout2, 'text');
      app.EditField_30.Value = '0.000';
      app.EditField_31 = uieditfield(app.GridLayout2, 'text');
      app.EditField_31.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_31.Tag = '12_3';
      app.EditField_31.Value = '0.000';
      app.EditField_32 = uieditfield(app.GridLayout2, 'text');
      app.EditField_32.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_32.Tag = '13_3';
      app.EditField_32.Value = '0.000';
      app.EditField_33 = uieditfield(app.GridLayout2, 'text');
      app.EditField_33.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_33.Tag = '14_3';
      app.EditField_33.Value = '0.000';
      app.EditField_34 = uieditfield(app.GridLayout2, 'text');
      app.EditField_34.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_34.Tag = '15_3';
      app.EditField_34.Value = '0.000';
      app.EditField_35 = uieditfield(app.GridLayout2, 'text');
      app.EditField_35.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_35.Tag = '16_3';
      app.EditField_35.Value = '0.000';
      app.EditField_36 = uieditfield(app.GridLayout2, 'text');
      app.EditField_36.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_36.Tag = '17_3';
      app.EditField_36.Value = '0.000';
      app.EditField_37 = uieditfield(app.GridLayout2, 'text');
      app.EditField_37.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_37.Tag = '18_3';
      app.EditField_37.Value = '0.000';
      app.EditField_38 = uieditfield(app.GridLayout2, 'text');
      app.EditField_38.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_38.Tag = '19_3';
      app.EditField_38.Value = '0.000';
      app.EditField_39 = uieditfield(app.GridLayout2, 'text');
      app.EditField_39.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_39.Tag = '10_4';
      app.EditField_39.Value = '0.000';
      app.EditField_40 = uieditfield(app.GridLayout2, 'text');
      app.EditField_40.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_40.Tag = '11_4';
      app.EditField_40.Value = '0.000';
      app.EditField_41 = uieditfield(app.GridLayout2, 'text');
      app.EditField_41.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_41.Tag = '12_4';
      app.EditField_41.Value = '0.000';
      app.EditField_42 = uieditfield(app.GridLayout2, 'text');
      app.EditField_42.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_42.Tag = '13_4';
      app.EditField_42.Value = '0.000';
      app.EditField_43 = uieditfield(app.GridLayout2, 'text');
      app.EditField_43.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_43.Tag = '14_4';
      app.EditField_43.Value = '0.000';
      app.EditField_44 = uieditfield(app.GridLayout2, 'text');
      app.EditField_44.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_44.Tag = '15_4';
      app.EditField_44.Value = '0.000';
      app.EditField_45 = uieditfield(app.GridLayout2, 'text');
      app.EditField_45.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_45.Tag = '16_4';
      app.EditField_45.Value = '0.000';
      app.EditField_46 = uieditfield(app.GridLayout2, 'text');
      app.EditField_46.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_46.Tag = '17_4';
      app.EditField_46.Value = '0.000';
      app.EditField_47 = uieditfield(app.GridLayout2, 'text');
      app.EditField_47.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_47.Tag = '18_4';
      app.EditField_47.Value = '0.000';
      app.EditField_48 = uieditfield(app.GridLayout2, 'text');
      app.EditField_48.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_48.Tag = '19_4';
      app.EditField_48.Value = '0.000';
      app.EditField_49 = uieditfield(app.GridLayout2, 'text');
      app.EditField_49.Value = '0.000';
      app.EditField_50 = uieditfield(app.GridLayout2, 'text');
      app.EditField_50.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_50.Tag = '11_5';
      app.EditField_50.Value = '0.000';
      app.EditField_51 = uieditfield(app.GridLayout2, 'text');
      app.EditField_51.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_51.Tag = '12_5';
      app.EditField_51.Value = '0.000';
      app.EditField_52 = uieditfield(app.GridLayout2, 'text');
      app.EditField_52.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_52.Tag = '13_5';
      app.EditField_52.Value = '0.000';
      app.EditField_53 = uieditfield(app.GridLayout2, 'text');
      app.EditField_53.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_53.Tag = '14_5';
      app.EditField_53.Value = '0.000';
      app.EditField_54 = uieditfield(app.GridLayout2, 'text');
      app.EditField_54.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_54.Tag = '15_5';
      app.EditField_54.Value = '0.000';
      app.EditField_55 = uieditfield(app.GridLayout2, 'text');
      app.EditField_55.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_55.Tag = '16_5';
      app.EditField_55.Value = '0.000';
      app.EditField_56 = uieditfield(app.GridLayout2, 'text');
      app.EditField_56.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_56.Tag = '17_5';
      app.EditField_56.Value = '0.000';
      app.EditField_57 = uieditfield(app.GridLayout2, 'text');
      app.EditField_57.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_57.Tag = '18_5';
      app.EditField_57.Value = '0.000';
      app.EditField_58 = uieditfield(app.GridLayout2, 'text');
      app.EditField_58.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_58.Tag = '19_5';
      app.EditField_58.Value = '0.000';
      app.EditField_59 = uieditfield(app.GridLayout2, 'text');
      app.EditField_59.Value = '0.000';
      app.EditField_60 = uieditfield(app.GridLayout2, 'text');
      app.EditField_60.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_60.Tag = '11_6';
      app.EditField_60.Value = '0.000';
      app.EditField_61 = uieditfield(app.GridLayout2, 'text');
      app.EditField_61.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_61.Tag = '12_6';
      app.EditField_61.Value = '0.000';
      app.EditField_62 = uieditfield(app.GridLayout2, 'text');
      app.EditField_62.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_62.Tag = '13_6';
      app.EditField_62.Value = '0.000';
      app.EditField_63 = uieditfield(app.GridLayout2, 'text');
      app.EditField_63.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_63.Tag = '14_6';
      app.EditField_63.Value = '0.000';
      app.EditField_64 = uieditfield(app.GridLayout2, 'text');
      app.EditField_64.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_64.Tag = '15_6';
      app.EditField_64.Value = '0.000';
      app.EditField_65 = uieditfield(app.GridLayout2, 'text');
      app.EditField_65.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_65.Tag = '16_6';
      app.EditField_65.Value = '0.000';
      app.EditField_66 = uieditfield(app.GridLayout2, 'text');
      app.EditField_66.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_66.Tag = '17_6';
      app.EditField_66.Value = '0.000';
      app.EditField_67 = uieditfield(app.GridLayout2, 'text');
      app.EditField_67.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_67.Tag = '18_6';
      app.EditField_67.Value = '0.000';
      app.EditField_68 = uieditfield(app.GridLayout2, 'text');
      app.EditField_68.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_68.Tag = '19_6';
      app.EditField_68.Value = '0.000';
      app.EditField_69 = uieditfield(app.GridLayout2, 'text');
      app.EditField_69.Value = '0.000';
      app.EditField_70 = uieditfield(app.GridLayout2, 'text');
      app.EditField_70.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_70.Tag = '11_7';
      app.EditField_70.Value = '0.000';
      app.EditField_71 = uieditfield(app.GridLayout2, 'text');
      app.EditField_71.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_71.Tag = '12_7';
      app.EditField_71.Value = '0.000';
      app.EditField_72 = uieditfield(app.GridLayout2, 'text');
      app.EditField_72.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_72.Tag = '13_7';
      app.EditField_72.Value = '0.000';
      app.EditField_73 = uieditfield(app.GridLayout2, 'text');
      app.EditField_73.Value = '0.000';
      app.EditField_74 = uieditfield(app.GridLayout2, 'text');
      app.EditField_74.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_74.Tag = '15_7';
      app.EditField_74.Value = '0.000';
      app.EditField_75 = uieditfield(app.GridLayout2, 'text');
      app.EditField_75.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_75.Tag = '16_7';
      app.EditField_75.Value = '0.000';
      app.EditField_76 = uieditfield(app.GridLayout2, 'text');
      app.EditField_76.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_76.Tag = '17_7';
      app.EditField_76.Value = '0.000';
      app.EditField_77 = uieditfield(app.GridLayout2, 'text');
      app.EditField_77.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_77.Tag = '18_7';
      app.EditField_77.Value = '0.000';
      app.EditField_78 = uieditfield(app.GridLayout2, 'text');
      app.EditField_78.Value = '0.000';
      app.EditField_79 = uieditfield(app.GridLayout2, 'text');
      app.EditField_79.Value = '0.000';
      app.EditField_80 = uieditfield(app.GridLayout2, 'text');
      app.EditField_80.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_80.Tag = '11_8';
      app.EditField_80.Value = '0.000';
      app.EditField_81 = uieditfield(app.GridLayout2, 'text');
      app.EditField_81.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_81.Tag = '12_8';
      app.EditField_81.Value = '0.000';
      app.EditField_82 = uieditfield(app.GridLayout2, 'text');
      app.EditField_82.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_82.Tag = '13_8';
      app.EditField_82.Value = '0.000';
      app.EditField_83 = uieditfield(app.GridLayout2, 'text');
      app.EditField_83.Value = '0.000';
      app.EditField_84 = uieditfield(app.GridLayout2, 'text');
      app.EditField_84.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_84.Tag = '15_8';
      app.EditField_84.Value = '0.000';
      app.EditField_85 = uieditfield(app.GridLayout2, 'text');
      app.EditField_85.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_85.Tag = '16_8';
      app.EditField_85.Value = '0.000';
      app.EditField_86 = uieditfield(app.GridLayout2, 'text');
      app.EditField_86.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_86.Tag = '17_8';
      app.EditField_86.Value = '0.000';
      app.EditField_87 = uieditfield(app.GridLayout2, 'text');
      app.EditField_87.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
      app.EditField_87.Tag = '18_8';
      app.EditField_87.Value = '0.000';
      app.EditField_88 = uieditfield(app.GridLayout2, 'text');
      app.EditField_88.Value = '0.000';
      app.KlysPhaseTab = uitab(app.TabGroup2);
      app.KlysPhaseTab.Title = 'Klys Phase';
      app.GridLayout2_2 = uigridlayout(app.KlysPhaseTab);
      app.Label_11 = uilabel(app.GridLayout2_2);
      app.Label_11.Text = '19';
      app.Label_12 = uilabel(app.GridLayout2_2);
      app.Label_12.Text = '18';
      app.Label_13 = uilabel(app.GridLayout2_2);
      app.Label_13.Text = '17';
      app.Label_14 = uilabel(app.GridLayout2_2);
      app.Label_14.Text = '16';
      app.Label_15 = uilabel(app.GridLayout2_2);
      app.Label_15.Text = '15';
      app.Label_16 = uilabel(app.GridLayout2_2);
      app.Label_16.Text = '14';
      app.Label_17 = uilabel(app.GridLayout2_2);
      app.Label_17.Text = '13';
      app.Label_18 = uilabel(app.GridLayout2_2);
      app.Label_18.Text = '12';
      app.Label_19 = uilabel(app.GridLayout2_2);
      app.Label_19.Text = '11';
      app.Label_20 = uilabel(app.GridLayout2_2);
      app.Label_20.Text = '10';
      app.L0Label_3 = uilabel(app.GridLayout2_2);
      app.L0Label_3.Text = 'L0';
      app.L1L2Label_2 = uilabel(app.GridLayout2_2);
      app.L1L2Label_2.Text = 'L1 / L2';
      app.L3Label_4 = uilabel(app.GridLayout2_2);
      app.L3Label_4.Text = 'L3';
      app.EditField_89 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_89.Value = '0.000';
      app.EditField_90 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_90.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_90.Tag = '11_1';
      app.EditField_90.Value = '0.000';
      app.EditField_91 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_91.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_91.Tag = '12_1';
      app.EditField_91.Value = '0.000';
      app.EditField_92 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_92.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_92.Tag = '13_1';
      app.EditField_92.Value = '0.000';
      app.EditField_93 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_93.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_93.Tag = '14_1';
      app.EditField_93.Value = '0.000';
      app.EditField_94 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_94.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_94.Tag = '15_1';
      app.EditField_94.Value = '0.000';
      app.EditField_95 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_95.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_95.Tag = '16_1';
      app.EditField_95.Value = '0.000';
      app.EditField_96 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_96.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_96.Tag = '17_1';
      app.EditField_96.Value = '0.000';
      app.EditField_97 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_97.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_97.Tag = '18_1';
      app.EditField_97.Value = '0.000';
      app.EditField_98 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_98.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_98.Tag = '19_1';
      app.EditField_98.Value = '0.000';
      app.EditField_99 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_99.Value = '0.000';
      app.EditField_100 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_100.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_100.Tag = '11_2';
      app.EditField_100.Value = '0.000';
      app.EditField_101 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_101.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_101.Tag = '12_2';
      app.EditField_101.Value = '0.000';
      app.EditField_102 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_102.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_102.Tag = '13_2';
      app.EditField_102.Value = '0.000';
      app.EditField_103 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_103.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_103.Tag = '14_2';
      app.EditField_103.Value = '0.000';
      app.EditField_104 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_104.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_104.Tag = '15_2';
      app.EditField_104.Value = '0.000';
      app.EditField_105 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_105.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_105.Tag = '16_2';
      app.EditField_105.Value = '0.000';
      app.EditField_106 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_106.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_106.Tag = '17_2';
      app.EditField_106.Value = '0.000';
      app.EditField_107 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_107.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_107.Tag = '18_2';
      app.EditField_107.Value = '0.000';
      app.EditField_108 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_108.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_108.Tag = '19_2';
      app.EditField_108.Value = '0.000';
      app.EditField_109 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_109.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_109.Tag = '10_3';
      app.EditField_109.Value = '0.000';
      app.EditField_110 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_110.Value = '0.000';
      app.EditField_111 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_111.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_111.Tag = '12_3';
      app.EditField_111.Value = '0.000';
      app.EditField_112 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_112.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_112.Tag = '13_3';
      app.EditField_112.Value = '0.000';
      app.EditField_113 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_113.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_113.Tag = '14_3';
      app.EditField_113.Value = '0.000';
      app.EditField_114 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_114.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_114.Tag = '15_3';
      app.EditField_114.Value = '0.000';
      app.EditField_115 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_115.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_115.Tag = '16_3';
      app.EditField_115.Value = '0.000';
      app.EditField_116 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_116.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_116.Tag = '17_3';
      app.EditField_116.Value = '0.000';
      app.EditField_117 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_117.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_117.Tag = '18_3';
      app.EditField_117.Value = '0.000';
      app.EditField_118 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_118.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_118.Tag = '19_3';
      app.EditField_118.Value = '0.000';
      app.EditField_119 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_119.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_119.Tag = '10_4';
      app.EditField_119.Value = '0.000';
      app.EditField_120 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_120.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_120.Tag = '11_4';
      app.EditField_120.Value = '0.000';
      app.EditField_121 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_121.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_121.Tag = '12_4';
      app.EditField_121.Value = '0.000';
      app.EditField_122 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_122.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_122.Tag = '13_4';
      app.EditField_122.Value = '0.000';
      app.EditField_123 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_123.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_123.Tag = '14_4';
      app.EditField_123.Value = '0.000';
      app.EditField_124 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_124.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_124.Tag = '15_4';
      app.EditField_124.Value = '0.000';
      app.EditField_125 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_125.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_125.Tag = '16_4';
      app.EditField_125.Value = '0.000';
      app.EditField_126 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_126.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_126.Tag = '17_4';
      app.EditField_126.Value = '0.000';
      app.EditField_127 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_127.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_127.Tag = '18_4';
      app.EditField_127.Value = '0.000';
      app.EditField_128 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_128.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_128.Tag = '19_4';
      app.EditField_128.Value = '0.000';
      app.EditField_129 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_129.Value = '0.000';
      app.EditField_130 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_130.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_130.Tag = '11_5';
      app.EditField_130.Value = '0.000';
      app.EditField_131 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_131.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_131.Tag = '12_5';
      app.EditField_131.Value = '0.000';
      app.EditField_132 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_132.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_132.Tag = '13_5';
      app.EditField_132.Value = '0.000';
      app.EditField_133 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_133.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_133.Tag = '14_5';
      app.EditField_133.Value = '0.000';
      app.EditField_134 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_134.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_134.Tag = '15_5';
      app.EditField_134.Value = '0.000';
      app.EditField_135 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_135.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_135.Tag = '16_5';
      app.EditField_135.Value = '0.000';
      app.EditField_136 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_136.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_136.Tag = '17_5';
      app.EditField_136.Value = '0.000';
      app.EditField_137 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_137.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_137.Tag = '18_5';
      app.EditField_137.Value = '0.000';
      app.EditField_138 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_138.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_138.Tag = '19_5';
      app.EditField_138.Value = '0.000';
      app.EditField_139 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_139.Value = '0.000';
      app.EditField_140 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_140.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_140.Tag = '11_6';
      app.EditField_140.Value = '0.000';
      app.EditField_141 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_141.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_141.Tag = '12_6';
      app.EditField_141.Value = '0.000';
      app.EditField_142 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_142.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_142.Tag = '13_6';
      app.EditField_142.Value = '0.000';
      app.EditField_143 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_143.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_143.Tag = '14_6';
      app.EditField_143.Value = '0.000';
      app.EditField_144 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_144.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_144.Tag = '15_6';
      app.EditField_144.Value = '0.000';
      app.EditField_145 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_145.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_145.Tag = '16_6';
      app.EditField_145.Value = '0.000';
      app.EditField_146 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_146.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_146.Tag = '17_6';
      app.EditField_146.Value = '0.000';
      app.EditField_147 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_147.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_147.Tag = '18_6';
      app.EditField_147.Value = '0.000';
      app.EditField_148 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_148.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_148.Tag = '19_6';
      app.EditField_148.Value = '0.000';
      app.EditField_149 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_149.Value = '0.000';
      app.EditField_150 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_150.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_150.Tag = '11_7';
      app.EditField_150.Value = '0.000';
      app.EditField_151 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_151.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_151.Tag = '12_7';
      app.EditField_151.Value = '0.000';
      app.EditField_152 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_152.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_152.Tag = '13_7';
      app.EditField_152.Value = '0.000';
      app.EditField_153 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_153.Value = '0.000';
      app.EditField_154 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_154.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_154.Tag = '15_7';
      app.EditField_154.Value = '0.000';
      app.EditField_155 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_155.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_155.Tag = '16_7';
      app.EditField_155.Value = '0.000';
      app.EditField_156 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_156.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_156.Tag = '17_7';
      app.EditField_156.Value = '0.000';
      app.EditField_157 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_157.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_157.Tag = '18_7';
      app.EditField_157.Value = '0.000';
      app.EditField_158 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_158.Value = '0.000';
      app.EditField_159 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_159.Value = '0.000';
      app.EditField_160 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_160.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_160.Tag = '11_8';
      app.EditField_160.Value = '0.000';
      app.EditField_161 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_161.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_161.Tag = '12_8';
      app.EditField_161.Value = '0.000';
      app.EditField_162 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_162.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_162.Tag = '13_8';
      app.EditField_162.Value = '0.000';
      app.EditField_163 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_163.Value = '0.000';
      app.EditField_164 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_164.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_164.Tag = '15_8';
      app.EditField_164.Value = '0.000';
      app.EditField_165 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_165.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_165.Tag = '16_8';
      app.EditField_165.Value = '0.000';
      app.EditField_166 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_166.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_166.Tag = '17_8';
      app.EditField_166.Value = '0.000';
      app.EditField_167 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_167.ValueChangedFcn = createCallbackFcn(app, @EditField_109ValueChanged, true);
      app.EditField_167.Tag = '18_8';
      app.EditField_167.Value = '0.000';
      app.EditField_168 = uieditfield(app.GridLayout2_2, 'text');
      app.EditField_168.Value = '0.000';
      app.MessagesTab = uitab(app.TabGroup2);
      app.MessagesTab.Title = 'Messages';
      app.TextArea = uitextarea(app.MessagesTab);
      app.TextArea.Editable = 'off';
      app.SettingsMenu = uimenu(app.FLEMFACETIILEMUIFigure);
      app.SettingsMenu.Text = 'Settings';
      app.MagnetSetDestinationMenu = uimenu(app.SettingsMenu);
      app.MagnetSetDestinationMenu.MenuSelectedFcn = createCallbackFcn(app, @MagnetSetDestinationMenuSelected, true);
      app.MagnetSetDestinationMenu.Text = 'Magnet Set Destination ';
      app.BDESMenu = uimenu(app.MagnetSetDestinationMenu);
      app.BDESMenu.MenuSelectedFcn = createCallbackFcn(app, @BDESMenuSelected, true);
      app.BDESMenu.Text = 'BDES';
      app.BCONMenu = uimenu(app.MagnetSetDestinationMenu);
      app.BCONMenu.MenuSelectedFcn = createCallbackFcn(app, @BCONMenuSelected, true);
      app.BCONMenu.Text = 'BCON';
      app.BDESBCONMenu = uimenu(app.MagnetSetDestinationMenu);
      app.BDESBCONMenu.MenuSelectedFcn = createCallbackFcn(app, @BDESBCONMenuSelected, true);
      app.BDESBCONMenu.Checked = 'on';
      app.BDESBCONMenu.Text = 'BDES+BCON';
      app.ForceallphasestozeroMenu = uimenu(app.SettingsMenu);
      app.ForceallphasestozeroMenu.MenuSelectedFcn = createCallbackFcn(app, @ForceallphasestozeroMenuSelected, true);
      app.ForceallphasestozeroMenu.Text = 'Force all phases to zero';
      app.MultiknobMenu = uimenu(app.FLEMFACETIILEMUIFigure);
      app.MultiknobMenu.Text = 'Multiknob';
      app.AssignalltableentriesMenu = uimenu(app.MultiknobMenu);
      app.AssignalltableentriesMenu.MenuSelectedFcn = createCallbackFcn(app, @AssignalltableentriesMenuSelected, true);
      app.AssignalltableentriesMenu.Text = 'Assign all table entries...';
      app.AssignSelectedrowsMenu = uimenu(app.MultiknobMenu);
      app.AssignSelectedrowsMenu.MenuSelectedFcn = createCallbackFcn(app, @AssignSelectedrowsMenuSelected, true);
      app.AssignSelectedrowsMenu.Enable = 'off';
      app.AssignSelectedrowsMenu.Text = 'Assign Selected rows...';
      app.DisplayMenu = uimenu(app.FLEMFACETIILEMUIFigure);
      app.DisplayMenu.Text = 'Display';
      app.ShowlegendMenu = uimenu(app.DisplayMenu);
      app.ShowlegendMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowlegendMenuSelected, true);
      app.ShowlegendMenu.Checked = 'on';
      app.ShowlegendMenu.Text = 'Show legend';
      app.DetachplottableMenu = uimenu(app.DisplayMenu);
      app.DetachplottableMenu.MenuSelectedFcn = createCallbackFcn(app, @DetachplottableMenuSelected, true);
      app.DetachplottableMenu.Text = 'Detach plot / table...';
      app.HelpMenu = uimenu(app.FLEMFACETIILEMUIFigure);
      app.HelpMenu.Text = 'Help';
      app.ThereisnohelpforyouMenu = uimenu(app.HelpMenu);
      app.ThereisnohelpforyouMenu.Text = 'There is no help for you...';
      % Show the figure after all components are created
      app.FLEMFACETIILEMUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = F2_LEM(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.FLEMFACETIILEMUIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.FLEMFACETIILEMUIFigure)
    end
  end
end