classdef laserStatsTable < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UITable                    matlab.ui.control.Table
        ShowLiveStatsButton        matlab.ui.control.Button
        PauseLiveStatsButton       matlab.ui.control.Button
        StatseLogButton            matlab.ui.control.Button
        ShowTargetPositionsButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
                cameraPVs  ; % Description
                umPerPixel = 1;
                cameraNames = {};
                statsBool = 1;
    end
    
    methods (Access = private)
            
            function [beamImageData,img] = GrabLaserBeamProperties(UserData,nCamera)
                        methods = {'Gaussian','Asym Gauss', 'Super Gauss','Raw RMS','RMS with peak cut',...
                'RMS area cut','RMS noise cut','4th ord. Gauss','Double Gauss','Double Asym Gauss'};
            if isfield(UserData,'umPerPixel');else;UserData.umPerPixel(nCamera) = 1;end
            if isfield(UserData,'fitMethod');opts=struct('usemethod',UserData.fitMethod);
            else opts = struct('usemethod',2);end % Set Asym Gaussian as default fit method
            %disp(strcat('Fit Method: ',methods{UserData.fitMethod}));
            camerapv = UserData.cameraPVs{nCamera};
            % Check that the camera is acquiring data (i.e. image array size > 0)
            if verLessThan('matlab','9.6')
            try lcaGetStatus(strcat(camerapv,':Image:ArraySize0_RBV'))
            catch
                warning(['Camera status warning - no data acquired for ',camerapv])
                beamImageData = eps*ones(10,1);
                img = [];
                return
            end
            end
            % Grab image
            data = profmon_grab(camerapv,0);     img = data.img;
            %[img,xsub,ysub,flag,bgs]=beamAnalysis_imgProc(data,opts);% Processed Image
            % Calculate beam statistics
             beamParams = beamAnalysis_beamParams(img, 1:size(img,2), 1:size(img,1),0,opts);
             
             if isfield(opts,'usemethod');stats = beamParams.stats; stats = [stats beamParams.xStat];
             else stats = beamParams(UserData.fitMethod).stats; stats = [stats beamParams(UserData.fitMethod).xStat]; end
            % Fit rms Ellipse to beam
            %opts=struct( 'figure',1,'axes',[],'ts',now,'doPlot',0);
                [ell, cross] = beamAnalysis_getEllipse(stats, 1,opts);
                
            % Calculate eccentricity
                a = [cross(1,end-1)-cross(1,end-2) cross(2,end-1)-cross(2,end-2)];
                b = [cross(1,2)-cross(1,1) cross(2,2)-cross(2,1)];    
                
            % Calculate tilt angle 
            ax1 = sqrt((cross(1,end-1)-cross(1,end-2)).^2+(cross(2,end-1)-cross(2,end-2)).^2);
            ax2 = sqrt((cross(1,2)-cross(1,1)).^2+(cross(2,2)-cross(2,1)).^2);
            if ax1>ax2; eccentricity = ax1/ax2; else eccentricity = ax2/ax1;end
            anglex1 = acosd(a(1,1)./ax1); anglex2 = acosd(b(1,1)./ax2);
            tilt = min(anglex1,anglex2);
            
            % Calculate the difference between the image and a 2d Gaussian
             xmean = stats(1);ymean = stats(2);xrms = stats(3);yrms = stats(4);
             [X,Y] = meshgrid( 1:size(img,2), 1:size(img,1));
             gaussDist = 1.0/sqrt(2*pi*xrms^2).*exp(-(X-xmean).^2/2/xrms^2)*1.0/sqrt(2*pi*yrms^2).*exp(-(Y-ymean).^2/2/yrms^2);
             gaussDist = gaussDist./max(max(gaussDist));% Normalize counts
            
            % Don't do the ROI calculation with inpolygon (it's too slow) 
            % Check  that the beam fits within an elliptical ROI - otherwise don't
            % calculate the homogeneity
             % Make the ideal 2d Gaussian
             gaussDist = gaussDist .* double(max(max(img)));
            
            % Calculate 2d correlation coefficient between image and 2d Gaussian
             corrCoeff = corr2(img,gaussDist);
             r_squared = corrCoeff^2; 
            % Calculate the difference image
            Diff = abs((double(img)-double(gaussDist)))./double(gaussDist);
            Diff_noWeight = abs((double(img)-double(gaussDist)))./double(max(max(img)));
            
            % Cut the diff image at +/- 2 sigma box (faster than inpolygon for live plotting)
            xv = [1:size(img,2)]-xmean; yv = [1:size(img,1)]-ymean;
            Diff = Diff(abs(yv)<2*yrms,:); % keep all the 
            Diff = Diff(:, abs(xv)<2*xrms);
            p2p_homogeneity = mean(mean(Diff));
            %p2p_homogeneity = (mean(abs(I(:))./double(gaussDist(:))))
            
            Diff_noWeight = Diff_noWeight(abs(yv)<2*yrms,:); % keep all the 
            Diff_noWeight = Diff_noWeight(:, abs(xv)<2*xrms);
            p2p_homogeneity_smooth = 100*mean(mean(Diff_noWeight));
            
            % Ignore the tilt angle and calculate the humidity in the room instead
            humidity = lcaGetSmart('HVAC:IN10:1:LSR_HMD');
            % For Brendan - calculate the sum counts within a +/- 2 sigma ROI box to see influence of background
            imgROI = img;
            imgROI = imgROI(abs(yv)<2*yrms,:);
            imgROI = imgROI(:,abs(xv)<2*xrms);
            sumInROI = sum(sum(imgROI))*1e-6;
            % Add ROI min to centroid finder
            stats(1) = stats(1) + data.roiX;
            stats(2) = stats(2) + data.roiY;
            beamImageData = [stats(1:4)*UserData.umPerPixel(nCamera),sum(sum(img))*1e-6,...
                humidity,eccentricity,r_squared,sumInROI,p2p_homogeneity_smooth];
            end
            
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                app.cameraPVs = evalin('base','cameraPVs');
            catch 
                warning('No cameras specified defaulting to S20 Laser Room')
            app.cameraPVs = {'CAMR:LT20:0001','CAMR:LT20:0002','CAMR:LT20:0003','CAMR:LT20:0004',...
            'CAMR:LT20:0005','CAMR:LT20:0006','CAMR:LT20:0007','CAMR:LT20:0008'};       
            end
            
                for ij = 1:length(app.cameraPVs)
                    a=lcaGetSmart([app.cameraPVs{ij},':NAME']);
                    app.cameraNames{ij} = a{:};
                    [bp(ij,:),img]=GrabLaserBeamProperties(app,ij);                  
                end
                bp(:,1:5) = round(bp(:,1:5),4,'significant');              
                 t = table(app.cameraNames',bp(:,1),bp(:,2),bp(:,3),bp(:,4),bp(:,5));
                    app.UITable.Data = t;
            
            
        end

        % Button pushed function: ShowLiveStatsButton
        function ShowLiveStatsButtonPushed(app, event)
                       app.statsBool = 1;
               while app.statsBool
               
                for ij = 1:length(app.cameraPVs)
                    a=lcaGetSmart([app.cameraPVs{ij},':NAME']);
                    app.cameraNames{ij} = a{:};
                [bp(ij,:),img]=GrabLaserBeamProperties(app,ij);
                end
                 bp(:,1:5) = round(bp(:,1:5),4,'significant');
                 t = table(app.cameraNames',bp(:,1),bp(:,2),bp(:,3),bp(:,4),bp(:,5));
                app.UITable.Data = t;
                pause(0.2)
               end
        end

        % Button pushed function: PauseLiveStatsButton
        function PauseLiveStatsButtonPushed(app, event)
            app.statsBool = 0;
        end

        % Button pushed function: StatseLogButton
        function StatseLogButtonPushed(app, event)
            addpath('/usr/local/facet/tools/matlabTNG/common/')
            info_str = sprintf(['| Camera Name | X mean [pix] | Y mean [pix] '...
                '| X rms [pix] | Y rms [pix] | Sum Cts [MCts] ' '\n']);
            for i = 1:height(app.UITable.Data)
             mat_line   = sprintf(['| ' app.cameraNames{i} ' | '  num2str(app.UITable.Data{i,2}) ' | '...
             num2str(app.UITable.Data{i,3}) ' | ' num2str(app.UITable.Data{i,4}) ' | ' num2str(app.UITable.Data{i,5}) ' | '...
             num2str(app.UITable.Data{i,6}) '\n']);
           info_str = [info_str mat_line];
            end
          
            info_str = sprintf([info_str '\n' '\n'])
            
            figure(99);
            ax = axes();
            set(ax, 'Visible', 'off');
            set(gcf,'Position',[10 10 10 10]);
            util_printLog2020(99,'title','S20 Laser Room Multi Profmon Stats',...
            'author','S20 Laser Room Multi Profmon GUI','text',info_str);
            clf(99), close(99);
        end

        % Button pushed function: ShowTargetPositionsButton
        function ShowTargetPositionsButtonPushed(app, event)
            s20LaserTargetPositionsTable
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Camera Name'; 'X Mean [pix]'; 'Y Mean [pix]'; 'X RMS [pix]'; 'Y RMS [pix]'; 'Sum Cts [MCts]'};
            app.UITable.RowName = {};
            app.ShowLiveStatsButton = uibutton(app.UIFigure, 'push');
            app.ShowLiveStatsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowLiveStatsButtonPushed, true);
            app.ShowLiveStatsButton.Text = 'Show Live Stats';
            app.PauseLiveStatsButton = uibutton(app.UIFigure, 'push');
            app.PauseLiveStatsButton.ButtonPushedFcn = createCallbackFcn(app, @PauseLiveStatsButtonPushed, true);
            app.PauseLiveStatsButton.Text = 'Pause Live Stats';
            app.StatseLogButton = uibutton(app.UIFigure, 'push');
            app.StatseLogButton.ButtonPushedFcn = createCallbackFcn(app, @StatseLogButtonPushed, true);
            app.StatseLogButton.Text = 'Stats -> eLog';
            app.ShowTargetPositionsButton = uibutton(app.UIFigure, 'push');
            app.ShowTargetPositionsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowTargetPositionsButtonPushed, true);
            app.ShowTargetPositionsButton.Text = 'Show Target Positions';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = laserStatsTable

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end