classdef Escan_bpmViewer < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    BPMViewerUIFigure             matlab.ui.Figure
    BPMResponsevsEnergyScanPanel  matlab.ui.container.Panel
    GridLayout                    matlab.ui.container.GridLayout
    XBPMAxes                      matlab.ui.control.UIAxes
    YBPMAxes                      matlab.ui.control.UIAxes
    DxmmEditFieldLabel            matlab.ui.control.Label
    DXEditField                   matlab.ui.control.NumericEditField
    D2xmm2EditFieldLabel          matlab.ui.control.Label
    D2XEditField                  matlab.ui.control.NumericEditField
    DymmEditFieldLabel            matlab.ui.control.Label
    DYEditField                   matlab.ui.control.NumericEditField
    D2ymm2EditFieldLabel          matlab.ui.control.Label
    D2YEditField                  matlab.ui.control.NumericEditField
    Label                         matlab.ui.control.Label
    DXErrEditField                matlab.ui.control.NumericEditField
    Label_3                       matlab.ui.control.Label
    D2XErrEditField               matlab.ui.control.NumericEditField
    Label_4                       matlab.ui.control.Label
    DYErrEditField                matlab.ui.control.NumericEditField
    Label_6                       matlab.ui.control.Label
    D2YErrEditField               matlab.ui.control.NumericEditField
    BPMNameLabel                  matlab.ui.control.Label
    PrevBPMButton                 matlab.ui.control.Button
    NextBPMButton                 matlab.ui.control.Button
  end

  
  properties (SetAccess = private)
    names string
    cnames string
    data % F2_Orbit escandata property
    ibpm = 1
    nbpm = 0
  end
  
  methods (Access = public)
    
    function LoadData(app,names,cnames,data)
      app.names = names ;
      app.cnames = cnames ;
      app.data=data;
      app.ibpm = 1;
      app.nbpm = sum(app.data{1,5}) ;
      app.PrevBPMButton.Enable = false;
      app.NextBPMButton.Enable = true ;
      app.PlotData;
    end
  end
  
  methods (Access = private)
    
    function PlotData(app)
      sz=size(app.data);
      if sz(2)==7
        app.PlotData0();
      else
        app.PlotData1();
      end
    end
    
    function PlotData0(app)
      %PLOTDATA0 used for F2_Orbit DispMeasMethod 0
      global BEAMLINE
      use=find(app.data{1,5});
      id=app.data{1,6};
      E0=BEAMLINE{id(app.ibpm)}.P*1000;
      app.BPMNameLabel.Text = "BPM = " + app.cnames(use(app.ibpm)) + " (" + app.names(use(app.ibpm)) + " )" ;
      dE = [app.data{:,7}] ; % Delta-E (MeV)
      X = arrayfun(@(x) app.data{x,1}(app.ibpm),1:length(dE)) ;
      Y = arrayfun(@(x) app.data{x,2}(app.ibpm),1:length(dE)) ;
      dX = arrayfun(@(x) app.data{x,3}(app.ibpm),1:length(dE)) ;
      dY = arrayfun(@(x) app.data{x,4}(app.ibpm),1:length(dE)) ;
      [P,S,MU]=polyfit(dE,X,2);
      xx=linspace(min(dE),max(dE),1000); [yy,dyy]=polyval(P,xx,S,MU);
      errorbar(app.XBPMAxes,dE,X,dX,'b.','MarkerSize',10);
      hold(app.XBPMAxes,'on');
      plot(app.XBPMAxes,xx,yy+dyy,'k--',xx,yy-dyy,'k--');
      plot(app.XBPMAxes,xx,yy,'k');
      hold(app.XBPMAxes,'off');
      xlabel(app.XBPMAxes,'dE [MeV]'); ylabel(app.XBPMAxes,'X [mm]') ;
      [q,dq]=noplot_polyfit(dE/E0,X,dX,2);
      app.DXEditField.Value = q(2); app.DXErrEditField.Value = dq(2) ;
      app.D2XEditField.Value = q(3); app.D2XErrEditField.Value = dq(3) ;
      [P,S,MU]=polyfit(dE,Y,2);
      [yy,dyy]=polyval(P,xx,S,MU);
      errorbar(app.YBPMAxes,dE,Y,dY,'b.','MarkerSize',10); hold(app.YBPMAxes,'on');
      plot(app.YBPMAxes,xx,yy+dyy,'k--',xx,yy-dyy,'k--'); plot(app.YBPMAxes,xx,yy,'k'); hold(app.YBPMAxes,'off');
      xlabel(app.YBPMAxes,'dE [MeV]'); ylabel(app.YBPMAxes,'Y [mm]') ;
      [q,dq]=noplot_polyfit(dE/E0,Y,dY,2);
      app.DYEditField.Value = q(2); app.DYErrEditField.Value = dq(2) ;
      app.D2YEditField.Value = q(3); app.D2YErrEditField.Value = dq(3) ;
      drawnow;
    end
    
    function PlotData1(app)
      %PLOTDATA0 used for F2_Orbit DispMeasMethod 1
      global BEAMLINE
      use=find(app.data{1,5});
      id=app.data{1,6};
      E0=BEAMLINE{id(app.ibpm)}.P*1000;
      app.BPMNameLabel.Text = "BPM = " + app.cnames(use(app.ibpm)) + " (" + app.names(use(app.ibpm)) + " )" ;
      dE = [app.data{1,10}] ; % Delta-E (MeV)
      X = cell2mat(app.data(:,8)') ; X=X(app.ibpm,:) ;
      Y = cell2mat(app.data(:,9)') ; Y=Y(app.ibpm,:) ;
      [P,S,MU]=polyfit(dE,X,2);
      xx=linspace(min(dE),max(dE),1000); [yy,dyy]=polyval(P,xx,S,MU);
      plot(app.XBPMAxes,dE,X,'b.','MarkerSize',10);
      hold(app.XBPMAxes,'on');
      plot(app.XBPMAxes,xx,yy+dyy,'k--',xx,yy-dyy,'k--');
      plot(app.XBPMAxes,xx,yy,'k');
      hold(app.XBPMAxes,'off');
      xlabel(app.XBPMAxes,'dE [MeV]'); ylabel(app.XBPMAxes,'X [mm]') ;
      [q,dq]=noplot_polyfit(dE/E0,X,0,2);
      app.DXEditField.Value = q(2); app.DXErrEditField.Value = dq(2) ;
      app.D2XEditField.Value = q(3); app.D2XErrEditField.Value = dq(3) ;
      [P,S,MU]=polyfit(dE,Y,2);
      [yy,dyy]=polyval(P,xx,S,MU);
      plot(app.YBPMAxes,dE,Y,'b.','MarkerSize',10); hold(app.YBPMAxes,'on');
      plot(app.YBPMAxes,xx,yy+dyy,'k--',xx,yy-dyy,'k--'); plot(app.YBPMAxes,xx,yy,'k'); hold(app.YBPMAxes,'off');
      xlabel(app.YBPMAxes,'dE [MeV]'); ylabel(app.YBPMAxes,'Y [mm]') ;
      [q,dq]=noplot_polyfit(dE/E0,Y,0,2);
      app.DYEditField.Value = q(2); app.DYErrEditField.Value = dq(2) ;
      app.D2YEditField.Value = q(3); app.D2YErrEditField.Value = dq(3) ;
      drawnow;
    end
    
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app)
      
    end

    % Button pushed function: PrevBPMButton
    function PrevBPMButtonPushed(app, event)
      app.ibpm=app.ibpm-1;
      if app.ibpm<=1
        app.ibpm=1;
        app.PrevBPMButton.Enable=false;
      else
        app.PrevBPMButton.Enable=true;
      end
      app.NextBPMButton.Enable=true;
      app.PlotData;
    end

    % Button pushed function: NextBPMButton
    function NextBPMButtonPushed(app, event)
      app.ibpm=app.ibpm+1;
      if app.ibpm>=app.nbpm
        app.ibpm=app.nbpm;
        app.NextBPMButton.Enable=false;
      else
        app.NextBPMButton.Enable=true;
      end
      app.PrevBPMButton.Enable=true;
      app.PlotData;
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.BPMViewerUIFigure = uifigure('Visible', 'off');
      app.BPMViewerUIFigure.Name = 'BPM Viewer';
      app.BPMResponsevsEnergyScanPanel = uipanel(app.BPMViewerUIFigure);
      app.BPMResponsevsEnergyScanPanel.Title = 'BPM Response vs. Energy Scan';
      app.GridLayout = uigridlayout(app.BPMResponsevsEnergyScanPanel);
      app.XBPMAxes = uiaxes(app.GridLayout);
      title(app.XBPMAxes, '')
      xlabel(app.XBPMAxes, 'dE [MeV]')
      ylabel(app.XBPMAxes, 'X [mm]')
      app.YBPMAxes = uiaxes(app.GridLayout);
      title(app.YBPMAxes, '')
      xlabel(app.YBPMAxes, 'dE [MeV]')
      ylabel(app.YBPMAxes, 'Y [mm]')
      app.DxmmEditFieldLabel = uilabel(app.BPMViewerUIFigure);
      app.DxmmEditFieldLabel.Text = 'Dx (mm)';
      app.DXEditField = uieditfield(app.BPMViewerUIFigure, 'numeric');
      app.DXEditField.Editable = 'off';
      app.D2xmm2EditFieldLabel = uilabel(app.BPMViewerUIFigure);
      app.D2xmm2EditFieldLabel.Text = 'D2x (mm^2)';
      app.D2XEditField = uieditfield(app.BPMViewerUIFigure, 'numeric');
      app.D2XEditField.Editable = 'off';
      app.DymmEditFieldLabel = uilabel(app.BPMViewerUIFigure);
      app.DymmEditFieldLabel.Text = 'Dy (mm)';
      app.DYEditField = uieditfield(app.BPMViewerUIFigure, 'numeric');
      app.DYEditField.Editable = 'off';
      app.D2ymm2EditFieldLabel = uilabel(app.BPMViewerUIFigure);
      app.D2ymm2EditFieldLabel.Text = 'D2y (mm^2)';
      app.D2YEditField = uieditfield(app.BPMViewerUIFigure, 'numeric');
      app.D2YEditField.Editable = 'off';
      app.Label = uilabel(app.BPMViewerUIFigure);
      app.Label.Text = '+/-';
      app.DXErrEditField = uieditfield(app.BPMViewerUIFigure, 'numeric');
      app.DXErrEditField.Editable = 'off';
      app.Label_3 = uilabel(app.BPMViewerUIFigure);
      app.Label_3.Text = '+/-';
      app.D2XErrEditField = uieditfield(app.BPMViewerUIFigure, 'numeric');
      app.D2XErrEditField.Editable = 'off';
      app.Label_4 = uilabel(app.BPMViewerUIFigure);
      app.Label_4.Text = '+/-';
      app.DYErrEditField = uieditfield(app.BPMViewerUIFigure, 'numeric');
      app.DYErrEditField.Editable = 'off';
      app.Label_6 = uilabel(app.BPMViewerUIFigure);
      app.Label_6.Text = '+/-';
      app.D2YErrEditField = uieditfield(app.BPMViewerUIFigure, 'numeric');
      app.D2YErrEditField.Editable = 'off';
      app.BPMNameLabel = uilabel(app.BPMViewerUIFigure);
      app.BPMNameLabel.Text = 'BPM = XXXXXX';
      app.PrevBPMButton = uibutton(app.BPMViewerUIFigure, 'push');
      app.PrevBPMButton.ButtonPushedFcn = createCallbackFcn(app, @PrevBPMButtonPushed, true);
      app.PrevBPMButton.Icon = 'larrow.svg';
      app.PrevBPMButton.Enable = 'off';
      app.PrevBPMButton.Text = '';
      app.NextBPMButton = uibutton(app.BPMViewerUIFigure, 'push');
      app.NextBPMButton.ButtonPushedFcn = createCallbackFcn(app, @NextBPMButtonPushed, true);
      app.NextBPMButton.Icon = 'rarrow.svg';
      app.NextBPMButton.Enable = 'off';
      app.NextBPMButton.Text = '';
      % Show the figure after all components are created
      app.BPMViewerUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = Escan_bpmViewer

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.BPMViewerUIFigure)

      % Execute the startup function
      runStartupFcn(app, @startupFcn)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.BPMViewerUIFigure)
    end
  end
end