classdef F2_OrbitBump < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    FACETIIOrbitBumpsUIFigure   matlab.ui.Figure
    RegionSelectPanel           matlab.ui.container.Panel
    GridLayout                  matlab.ui.container.GridLayout
    INJButton                   matlab.ui.control.StateButton
    L0Button                    matlab.ui.control.StateButton
    DL1Button                   matlab.ui.control.StateButton
    L1Button                    matlab.ui.control.StateButton
    BC11Button                  matlab.ui.control.StateButton
    L2Button                    matlab.ui.control.StateButton
    BC14Button                  matlab.ui.control.StateButton
    L3Button                    matlab.ui.control.StateButton
    BC20Button                  matlab.ui.control.StateButton
    FFSButton                   matlab.ui.control.StateButton
    SPECTButton                 matlab.ui.control.StateButton
    TargetElementPanel          matlab.ui.container.Panel
    ListBox                     matlab.ui.control.ListBox
    SelectBumpPlaneButtonGroup  matlab.ui.container.ButtonGroup
    HorizontalxButton           matlab.ui.control.ToggleButton
    VerticalyButton             matlab.ui.control.ToggleButton
    BPMSPanel                   matlab.ui.container.Panel
    ListBox_3                   matlab.ui.control.ListBox
    ZEditFieldLabel             matlab.ui.control.Label
    ZEditField                  matlab.ui.control.NumericEditField
    CorrectorsResponsePanel     matlab.ui.container.Panel
    ListBox_2                   matlab.ui.control.ListBox
    FudgeFactorsusePanel        matlab.ui.container.Panel
    EditField                   matlab.ui.control.NumericEditField
    EditField_2                 matlab.ui.control.NumericEditField
    EditField_3                 matlab.ui.control.NumericEditField
    FITButton                   matlab.ui.control.Button
    CheckBox                    matlab.ui.control.CheckBox
    CheckBox_2                  matlab.ui.control.CheckBox
    CheckBox_3                  matlab.ui.control.CheckBox
    OrbitPlotPanel              matlab.ui.container.Panel
    PlotAxes                    matlab.ui.control.UIAxes
    BumpControlPanel            matlab.ui.container.Panel
    SetmmEditFieldLabel         matlab.ui.control.Label
    BumpVal                     matlab.ui.control.NumericEditField
    BumpDecr                    matlab.ui.control.Button
    BumpChange                  matlab.ui.control.NumericEditField
    BumpIncr                    matlab.ui.control.Button
    CORReadbackmmLabel          matlab.ui.control.Label
    BumpRdb_cor                 matlab.ui.control.NumericEditField
    RangemmEditFieldLabel       matlab.ui.control.Label
    BumpRange                   matlab.ui.control.EditField
    BPMReadbackmmLabel          matlab.ui.control.Label
    BumpRdb_bpm                 matlab.ui.control.NumericEditField
    GOButton                    matlab.ui.control.Button
    OrbitPanel                  matlab.ui.container.Panel
    ReferenceEditFieldLabel     matlab.ui.control.Label
    ReferenceEditField          matlab.ui.control.EditField
    UpdateRefOrbitButton        matlab.ui.control.Button
    NAveEditFieldLabel          matlab.ui.control.Label
    NAveEditField               matlab.ui.control.NumericEditField
    UpdateButton                matlab.ui.control.StateButton
    CorrectorsPanel             matlab.ui.container.Panel
    GridLayout2                 matlab.ui.container.GridLayout
    BDESkGmLabel                matlab.ui.control.Label
    BACTkGmLabel                matlab.ui.control.Label
    KickmradLabel               matlab.ui.control.Label
    CoefLabel                   matlab.ui.control.Label
    EditField_4                 matlab.ui.control.NumericEditField
    EditField_6                 matlab.ui.control.NumericEditField
    EditField_7                 matlab.ui.control.NumericEditField
    EditField_8                 matlab.ui.control.NumericEditField
    EditField_10                matlab.ui.control.NumericEditField
    EditField_11                matlab.ui.control.NumericEditField
    EditField_12                matlab.ui.control.NumericEditField
    EditField_14                matlab.ui.control.NumericEditField
    EditField_15                matlab.ui.control.NumericEditField
    EditField_16                matlab.ui.control.NumericEditField
    EditField_17                matlab.ui.control.NumericEditField
    EditField_18                matlab.ui.control.NumericEditField
  end

  
  properties (Access = public)
    aobj % F2_OrbitBump object
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, LLM)
      if exist('LLM','var')
        app.aobj = F2_OrbitBumpApp(app,LLM);
      else
        app.aobj = F2_OrbitBumpApp(app) ;
      end
      app.INJButtonValueChanged;
    end

    % Value changed function: INJButton
    function INJButtonValueChanged(app, event)
      value = [app.INJButton.Value app.L0Button.Value app.DL1Button.Value app.L1Button.Value ...
        app.BC11Button.Value app.L2Button.Value app.BC14Button.Value app.L3Button.Value ...
        app.BC20Button.Value app.FFSButton.Value app.SPECTButton.Value] ;
%       ele1=find(value,1);
%       if isempty(ele1)
%         value(1)=true;
%         app.INJButton.Value=true;
%       end
%       ele2=find(~value,1)-1;
%       if isempty(ele2)
%         ele2=length(value);
%       elseif ele2<ele1
%         ele2=length(value);
%       end
%       value=false(1,length(value)); value(ele1:ele2)=true;
      app.INJButton.Value=value(1);
      app.L0Button.Value=value(2);
      app.DL1Button.Value=value(3);
      app.L1Button.Value=value(4);
      app.BC11Button.Value=value(5);
      app.L2Button.Value=value(6);
      app.BC14Button.Value=value(7);
      app.L3Button.Value=value(8);
      app.BC20Button.Value=value(9);
      app.FFSButton.Value=value(10);
      app.SPECTButton.Value=value(11);
      drawnow
      app.aobj.UseRegion = value ;
      app.aobj.FO.LM.ModelClasses="MONI";
      usereg=false(size(app.aobj.FO.usebpm));
      usereg(~app.aobj.FO.badbpms & ismember(app.aobj.FO.bpmid,app.aobj.FO.LM.ModelID)) = true ;
      app.ListBox_3.Items = app.aobj.FO.bpmnames(usereg) ;
      app.ListBox_3.ItemsData = app.aobj.FO.bpmid(usereg) ;
      app.ListBox_3.Value = app.aobj.FO.bpmid(obj.usebpm) ;
      app.ListBox.Items = app.aobj.LM.ModelNames ;
      app.ListBox.Value = app.aobj.targetName ;
      app.ZEditField.Value = app.aobj.LM.ModelZ(app.ListBox.Value) ;
      app.ListBox_2.Items = sprintf("%s / %.1f",app.aobj.CorModelNames,app.aobj.CorResp) ;
      app.ListBox_2.ItemsData = 1:length(app.ListBox_2.Items) ;
      app.ListBox_2.Value = app.ListBox_2.ItemsData(app.aobj.usecor) ;
    end

    % Selection changed function: SelectBumpPlaneButtonGroup
    function SelectBumpPlaneButtonGroupSelectionChanged(app, event)
      selectedButton = app.SelectBumpPlaneButtonGroup.SelectedObject;
      switch selectedButton
        case app.HorizontalxButton
          app.aobj.dim="x";
        case app.VerticalyButton
          app.aobj.dim="y";
      end
      app.INJButtonValueChanged();
    end

    % Value changed function: ListBox_3
    function ListBox_3ValueChanged(app, event)
      value = app.ListBox_3.Value;
      app.aobj.FO.usebpm=true(size(app.aobj.FO.usebpm));
      app.aobj.FO.usebpm(~ismember(app.aobj.FO.bpmid,value)) = false ;
      if ~isempty(app.aobj.FO.BPMS.modelID)
        app.aobj.FO.usebpm(~ismember(app.aobj.FO.bpmid,app.aobj.FO.BPMS.modelID))=false;
      end
    end

    % Value changed function: ListBox
    function ListBoxValueChanged(app, event)
      app.aobj.targetName = app.ListBox.Value ;
      app.ZEditField.Value = app.aobj.LM.ModelZ(app.ListBox.Value) ;
    end

    % Value changed function: ListBox_2
    function ListBox_2ValueChanged(app, event)
      newval = app.ListBox_2.Value;
      val = app.usecor ;
      val(1) = newval(1); val(3) = newval(end);
      app.usecor = val ;
      app.ListBox_2.Value = val ;
    end

    % Button pushed function: GOButton
    function GOButtonPushed(app, event)
      app.aobj.SetBump();
    end

    % Value changed function: NAveEditField
    function NAveEditFieldValueChanged(app, event)
      value = app.NAveEditField.Value;
      app.aobj.FO.npulse = value ;
    end

    % Value changed function: BumpVal
    function BumpValValueChanged(app, event)
      value = app.BumpVal.Value;
      app.aobj.BumpVal = value.*1e-3 ;
    end

    % Button pushed function: BumpIncr
    function BumpIncrPushed(app, event)
      app.BumpVal.Value = app.BumpVal.Value + app.BumpChange.Value ;
      app.BumpValValueChanged() ;
    end

    % Button pushed function: BumpDecr
    function BumpDecrPushed(app, event)
      app.BumpVal.Value = app.BumpVal.Value - app.BumpChange.Value ;
      app.BumpValValueChanged() ;
    end

    % Button pushed function: UpdateRefOrbitButton
    function UpdateRefOrbitButtonPushed(app, event)
      app.aobj.FO.StoreRef();
      app.aobj.FO.UseRefOrbit="local";
      app.ReferenceEditField.Value=datestr(now);
    end

    % Value changed function: UpdateButton
    function UpdateButtonValueChanged(app, event)
      value = app.UpdateButton.Value;
      if value
        app.aobj.StartTimer;
      else
        app.aobj.StopTimer;
      end
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.FACETIIOrbitBumpsUIFigure = uifigure('Visible', 'off');
      app.FACETIIOrbitBumpsUIFigure.Name = 'FACET-II Orbit Bumps';
      app.RegionSelectPanel = uipanel(app.FACETIIOrbitBumpsUIFigure);
      app.RegionSelectPanel.Title = 'Region Select';
      app.GridLayout = uigridlayout(app.RegionSelectPanel);
      app.INJButton = uibutton(app.GridLayout, 'state');
      app.INJButton.ValueChangedFcn = createCallbackFcn(app, @INJButtonValueChanged, true);
      app.INJButton.Text = 'INJ';
      app.INJButton.Value = true;
      app.L0Button = uibutton(app.GridLayout, 'state');
      app.L0Button.Text = 'L0';
      app.L0Button.Value = true;
      app.DL1Button = uibutton(app.GridLayout, 'state');
      app.DL1Button.Text = 'DL1';
      app.DL1Button.Value = true;
      app.L1Button = uibutton(app.GridLayout, 'state');
      app.L1Button.Text = 'L1';
      app.L1Button.Value = true;
      app.BC11Button = uibutton(app.GridLayout, 'state');
      app.BC11Button.Text = 'BC11';
      app.BC11Button.Value = true;
      app.L2Button = uibutton(app.GridLayout, 'state');
      app.L2Button.Text = 'L2';
      app.L2Button.Value = true;
      app.BC14Button = uibutton(app.GridLayout, 'state');
      app.BC14Button.Text = 'BC14';
      app.BC14Button.Value = true;
      app.L3Button = uibutton(app.GridLayout, 'state');
      app.L3Button.Text = 'L3';
      app.L3Button.Value = true;
      app.BC20Button = uibutton(app.GridLayout, 'state');
      app.BC20Button.Text = 'BC20';
      app.BC20Button.Value = true;
      app.FFSButton = uibutton(app.GridLayout, 'state');
      app.FFSButton.Text = 'FFS';
      app.FFSButton.Value = true;
      app.SPECTButton = uibutton(app.GridLayout, 'state');
      app.SPECTButton.Text = 'SPECT';
      app.SPECTButton.Value = true;
      app.TargetElementPanel = uipanel(app.FACETIIOrbitBumpsUIFigure);
      app.TargetElementPanel.Title = 'Target Element';
      app.ListBox = uilistbox(app.TargetElementPanel);
      app.ListBox.ItemsData = {'1', '2', '3', '4'};
      app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
      app.ListBox.Value = '1';
      app.SelectBumpPlaneButtonGroup = uibuttongroup(app.TargetElementPanel);
      app.SelectBumpPlaneButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectBumpPlaneButtonGroupSelectionChanged, true);
      app.SelectBumpPlaneButtonGroup.Title = 'Select Bump Plane';
      app.HorizontalxButton = uitogglebutton(app.SelectBumpPlaneButtonGroup);
      app.HorizontalxButton.Text = 'Horizontal (x)';
      app.HorizontalxButton.Value = true;
      app.VerticalyButton = uitogglebutton(app.SelectBumpPlaneButtonGroup);
      app.VerticalyButton.Text = 'Vertical (y)';
      app.BPMSPanel = uipanel(app.TargetElementPanel);
      app.BPMSPanel.Title = 'BPMS';
      app.ListBox_3 = uilistbox(app.BPMSPanel);
      app.ListBox_3.ItemsData = {'1', '2', '3', '4'};
      app.ListBox_3.Multiselect = 'on';
      app.ListBox_3.ValueChangedFcn = createCallbackFcn(app, @ListBox_3ValueChanged, true);
      app.ListBox_3.Value = {'1', '2', '3', '4'};
      app.ZEditFieldLabel = uilabel(app.TargetElementPanel);
      app.ZEditFieldLabel.Text = 'Z:';
      app.ZEditField = uieditfield(app.TargetElementPanel, 'numeric');
      app.CorrectorsResponsePanel = uipanel(app.FACETIIOrbitBumpsUIFigure);
      app.CorrectorsResponsePanel.Title = 'Correctors / Response';
      app.ListBox_2 = uilistbox(app.CorrectorsResponsePanel);
      app.ListBox_2.ItemsData = {'[1,2,3,4]'};
      app.ListBox_2.Multiselect = 'on';
      app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged, true);
      app.ListBox_2.Value = {'[1,2,3,4]'};
      app.FudgeFactorsusePanel = uipanel(app.CorrectorsResponsePanel);
      app.FudgeFactorsusePanel.Title = 'Fudge Factors / use';
      app.EditField = uieditfield(app.FudgeFactorsusePanel, 'numeric');
      app.EditField.Value = 1;
      app.EditField_2 = uieditfield(app.FudgeFactorsusePanel, 'numeric');
      app.EditField_2.Value = 1;
      app.EditField_3 = uieditfield(app.FudgeFactorsusePanel, 'numeric');
      app.EditField_3.Value = 1;
      app.FITButton = uibutton(app.FudgeFactorsusePanel, 'push');
      app.FITButton.Text = 'FIT';
      app.CheckBox = uicheckbox(app.FudgeFactorsusePanel);
      app.CheckBox.Text = '';
      app.CheckBox_2 = uicheckbox(app.FudgeFactorsusePanel);
      app.CheckBox_2.Text = '';
      app.CheckBox_3 = uicheckbox(app.FudgeFactorsusePanel);
      app.CheckBox_3.Text = '';
      app.CheckBox_3.Value = true;
      app.OrbitPlotPanel = uipanel(app.FACETIIOrbitBumpsUIFigure);
      app.OrbitPlotPanel.Title = 'Orbit Plot';
      app.PlotAxes = uiaxes(app.OrbitPlotPanel);
      title(app.PlotAxes, '')
      xlabel(app.PlotAxes, 'X')
      ylabel(app.PlotAxes, 'Y')
      app.BumpControlPanel = uipanel(app.FACETIIOrbitBumpsUIFigure);
      app.BumpControlPanel.Title = 'Bump Control';
      app.SetmmEditFieldLabel = uilabel(app.BumpControlPanel);
      app.SetmmEditFieldLabel.Text = 'Set (mm):';
      app.BumpVal = uieditfield(app.BumpControlPanel, 'numeric');
      app.BumpVal.ValueChangedFcn = createCallbackFcn(app, @BumpValValueChanged, true);
      app.BumpDecr = uibutton(app.BumpControlPanel, 'push');
      app.BumpDecr.ButtonPushedFcn = createCallbackFcn(app, @BumpDecrPushed, true);
      app.BumpDecr.Icon = 'larrow.svg';
      app.BumpDecr.Text = '';
      app.BumpChange = uieditfield(app.BumpControlPanel, 'numeric');
      app.BumpChange.Value = 0.1;
      app.BumpIncr = uibutton(app.BumpControlPanel, 'push');
      app.BumpIncr.ButtonPushedFcn = createCallbackFcn(app, @BumpIncrPushed, true);
      app.BumpIncr.Icon = 'rarrow.svg';
      app.BumpIncr.Text = '';
      app.CORReadbackmmLabel = uilabel(app.BumpControlPanel);
      app.CORReadbackmmLabel.Text = 'COR Readback (mm):';
      app.BumpRdb_cor = uieditfield(app.BumpControlPanel, 'numeric');
      app.BumpRdb_cor.Editable = 'off';
      app.RangemmEditFieldLabel = uilabel(app.BumpControlPanel);
      app.RangemmEditFieldLabel.Text = 'Range (mm):';
      app.BumpRange = uieditfield(app.BumpControlPanel, 'text');
      app.BumpRange.Enable = 'off';
      app.BPMReadbackmmLabel = uilabel(app.BumpControlPanel);
      app.BPMReadbackmmLabel.Text = 'BPM Readback (mm):';
      app.BumpRdb_bpm = uieditfield(app.BumpControlPanel, 'numeric');
      app.BumpRdb_bpm.Editable = 'off';
      app.GOButton = uibutton(app.BumpControlPanel, 'push');
      app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
      app.GOButton.Text = 'GO';
      app.OrbitPanel = uipanel(app.FACETIIOrbitBumpsUIFigure);
      app.OrbitPanel.Title = 'Orbit';
      app.ReferenceEditFieldLabel = uilabel(app.OrbitPanel);
      app.ReferenceEditFieldLabel.Text = 'Reference:';
      app.ReferenceEditField = uieditfield(app.OrbitPanel, 'text');
      app.ReferenceEditField.Value = 'none';
      app.UpdateRefOrbitButton = uibutton(app.OrbitPanel, 'push');
      app.UpdateRefOrbitButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateRefOrbitButtonPushed, true);
      app.UpdateRefOrbitButton.Text = 'Update Ref Orbit';
      app.NAveEditFieldLabel = uilabel(app.OrbitPanel);
      app.NAveEditFieldLabel.Text = 'N Ave:';
      app.NAveEditField = uieditfield(app.OrbitPanel, 'numeric');
      app.NAveEditField.ValueDisplayFormat = '%d';
      app.NAveEditField.ValueChangedFcn = createCallbackFcn(app, @NAveEditFieldValueChanged, true);
      app.NAveEditField.Value = 20;
      app.UpdateButton = uibutton(app.OrbitPanel, 'state');
      app.UpdateButton.ValueChangedFcn = createCallbackFcn(app, @UpdateButtonValueChanged, true);
      app.UpdateButton.Text = 'Update';
      app.UpdateButton.Value = true;
      app.CorrectorsPanel = uipanel(app.FACETIIOrbitBumpsUIFigure);
      app.CorrectorsPanel.Title = 'Correctors';
      app.GridLayout2 = uigridlayout(app.CorrectorsPanel);
      app.BDESkGmLabel = uilabel(app.GridLayout2);
      app.BDESkGmLabel.Text = 'BDES (kGm)';
      app.BACTkGmLabel = uilabel(app.GridLayout2);
      app.BACTkGmLabel.Text = 'BACT (kGm)';
      app.KickmradLabel = uilabel(app.GridLayout2);
      app.KickmradLabel.Text = 'Kick (mrad)';
      app.CoefLabel = uilabel(app.GridLayout2);
      app.CoefLabel.Text = 'Coef.';
      app.EditField_4 = uieditfield(app.GridLayout2, 'numeric');
      app.EditField_6 = uieditfield(app.GridLayout2, 'numeric');
      app.EditField_7 = uieditfield(app.GridLayout2, 'numeric');
      app.EditField_8 = uieditfield(app.GridLayout2, 'numeric');
      app.EditField_10 = uieditfield(app.GridLayout2, 'numeric');
      app.EditField_11 = uieditfield(app.GridLayout2, 'numeric');
      app.EditField_12 = uieditfield(app.GridLayout2, 'numeric');
      app.EditField_14 = uieditfield(app.GridLayout2, 'numeric');
      app.EditField_15 = uieditfield(app.GridLayout2, 'numeric');
      app.EditField_16 = uieditfield(app.GridLayout2, 'numeric');
      app.EditField_16.Editable = 'off';
      app.EditField_17 = uieditfield(app.GridLayout2, 'numeric');
      app.EditField_17.Editable = 'off';
      app.EditField_18 = uieditfield(app.GridLayout2, 'numeric');
      app.EditField_18.Editable = 'off';
      % Show the figure after all components are created
      app.FACETIIOrbitBumpsUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = F2_OrbitBump(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.FACETIIOrbitBumpsUIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.FACETIIOrbitBumpsUIFigure)
    end
  end
end