classdef F2_OrbitBump_correctors < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    BumpCorrectorsUIFigure  matlab.ui.Figure
    Corrector1Panel         matlab.ui.container.Panel
    Cor1Slider              matlab.ui.control.Slider
    Cor1                    matlab.ui.control.NumericEditField
    InitialValuePanel       matlab.ui.container.Panel
    Cor1Restore             matlab.ui.control.NumericEditField
    RestoreButton           matlab.ui.control.Button
    Corrector2Panel         matlab.ui.container.Panel
    Cor2Slider              matlab.ui.control.Slider
    Cor2                    matlab.ui.control.NumericEditField
    InitialValuePanel_2     matlab.ui.container.Panel
    Cor2Restore             matlab.ui.control.NumericEditField
    RestoreButton_2         matlab.ui.control.Button
    Corrector3Panel         matlab.ui.container.Panel
    Cor3Slider              matlab.ui.control.Slider
    Cor3                    matlab.ui.control.NumericEditField
    InitialValuePanel_3     matlab.ui.container.Panel
    Cor3Restore             matlab.ui.control.NumericEditField
    RestoreButton_3         matlab.ui.control.Button
  end

  
  properties (Access = private)
    cor1
    cor2
    cor3
  end
  
  properties (Access = public)
    obapp % F2_OrbitBump app object
  end
  
  methods (Access = public)
    
    function Update(app)
      [bAct, bDes, bMax] = control_magnetGet(app.cor1) ; app.Cor1Slider.Limits=[-bMax,bMax]; app.Cor1.Limits=[-bMax,bMax]; app.Cor1Slider.Value = bDes; app.Cor1.Value=bAct;
      app.Cor1Slider.MajorTicks=[-bMax,-bMax/2,0,bMax/2,bMax]; app.Cor1Slider.MinorTicks=[]; app.Cor1Slider.MajorTickLabels=cellstr(num2str(app.Cor1Slider.MajorTicks(:),1));
      [bAct, bDes, bMax] = control_magnetGet(app.cor2) ; app.Cor2Slider.Limits=[-bMax,bMax]; app.Cor2.Limits=[-bMax,bMax]; app.Cor2Slider.Value = bDes; app.Cor2.Value=bAct;
      app.Cor2Slider.MajorTicks=[-bMax,-bMax/2,0,bMax/2,bMax]; app.Cor2Slider.MinorTicks=[]; app.Cor2Slider.MajorTickLabels=cellstr(num2str(app.Cor2Slider.MajorTicks(:),1));
      [bAct, bDes, bMax] = control_magnetGet(app.cor3) ; app.Cor3Slider.Limits=[-bMax,bMax]; app.Cor3.Limits=[-bMax,bMax]; app.Cor3Slider.Value = bDes; app.Cor3.Value=bAct;
      app.Cor3Slider.MajorTicks=[-bMax,-bMax/2,0,bMax/2,bMax]; app.Cor3Slider.MinorTicks=[]; app.Cor3Slider.MajorTickLabels=cellstr(num2str(app.Cor3Slider.MajorTicks(:),1));
      drawnow;
    end
    
    function init(app,cor1,cor2,cor3)
      app.cor1=cor1;
      app.cor2=cor2;
      app.cor3=cor3;
      app.Update;
      app.Cor1Restore.Value = app.Cor1.Value;
      app.Cor2Restore.Value = app.Cor2.Value;
      app.Cor3Restore.Value = app.Cor3.Value;
      app.Corrector1Panel.Title = cor1 ;
      app.Corrector2Panel.Title = cor2 ;
      app.Corrector3Panel.Title = cor3 ;
      drawnow;
    end
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, cor1, cor2, cor3)
      app.init(cor1,cor2,cor3);
    end

    % Value changed function: Cor1Slider
    function Cor1SliderValueChanged(app, event)
      value = app.Cor1Slider.Value;
      app.Cor1.Value = value ;
      app.Cor1ValueChanged ;
    end

    % Value changed function: Cor1
    function Cor1ValueChanged(app, event)
      value = app.Cor1.Value;
      control_magnetSet(app.cor1,value);
      if ~isempty(app.obapp) && isprop(app.obapp,'aobj')
        app.obapp.aobj.CORS.ReadB() ; app.obapp.aobj.ProcCors();
      end
    end

    % Value changed function: Cor2Slider
    function Cor2SliderValueChanged(app, event)
      value = app.Cor2Slider.Value;
      app.Cor2.Value = value ;
      app.Cor2ValueChanged ;
    end

    % Value changed function: Cor2
    function Cor2ValueChanged(app, event)
      value = app.Cor2.Value;
      control_magnetSet(app.cor2,value);
      if ~isempty(app.obapp) && isprop(app.obapp,'aobj')
        app.obapp.aobj.CORS.ReadB() ; app.obapp.aobj.ProcCors();
      end
    end

    % Value changed function: Cor3Slider
    function Cor3SliderValueChanged(app, event)
      value = app.Cor3Slider.Value;
      app.Cor3.Value = value ;
      app.Cor3ValueChanged ;
    end

    % Value changed function: Cor3
    function Cor3ValueChanged(app, event)
      value = app.Cor3.Value;
      control_magnetSet(app.cor3,value);
      if ~isempty(app.obapp) && isprop(app.obapp,'aobj')
        app.obapp.aobj.CORS.ReadB() ; app.obapp.aobj.ProcCors();
      end
    end

    % Button pushed function: RestoreButton
    function RestoreButtonPushed(app, event)
      app.Cor1.Value = app.Cor1Restore.Value;
      app.Cor1ValueChanged ;
    end

    % Button pushed function: RestoreButton_2
    function RestoreButton_2Pushed(app, event)
      app.Cor2.Value = app.Cor2Restore.Value;
      app.Cor2ValueChanged ;
    end

    % Button pushed function: RestoreButton_3
    function RestoreButton_3Pushed(app, event)
      app.Cor3.Value = app.Cor3Restore.Value;
      app.Cor3ValueChanged ;
    end

    % Value changing function: Cor1Slider
    function Cor1SliderValueChanging(app, event)
      changingValue = event.Value;
      app.Cor1.Value = changingValue;
      drawnow;
    end

    % Value changing function: Cor2Slider
    function Cor2SliderValueChanging(app, event)
      changingValue = event.Value;
      app.Cor2.Value = changingValue;
      drawnow;
    end

    % Value changing function: Cor3Slider
    function Cor3SliderValueChanging(app, event)
      changingValue = event.Value;
      app.Cor3.Value = changingValue;
      drawnow;
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.BumpCorrectorsUIFigure = uifigure('Visible', 'off');
      app.BumpCorrectorsUIFigure.Name = 'Bump Correctors';
      app.Corrector1Panel = uipanel(app.BumpCorrectorsUIFigure);
      app.Corrector1Panel.Title = 'Corrector 1';
      app.Cor1Slider = uislider(app.Corrector1Panel);
      app.Cor1Slider.MajorTicks = [0 20 40 60 80 100];
      app.Cor1Slider.MajorTickLabels = {'0', '20', '40', '60', '80', '100'};
      app.Cor1Slider.ValueChangedFcn = createCallbackFcn(app, @Cor1SliderValueChanged, true);
      app.Cor1Slider.ValueChangingFcn = createCallbackFcn(app, @Cor1SliderValueChanging, true);
      app.Cor1Slider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
      app.Cor1 = uieditfield(app.Corrector1Panel, 'numeric');
      app.Cor1.ValueChangedFcn = createCallbackFcn(app, @Cor1ValueChanged, true);
      app.InitialValuePanel = uipanel(app.Corrector1Panel);
      app.InitialValuePanel.Title = 'Initial Value';
      app.Cor1Restore = uieditfield(app.InitialValuePanel, 'numeric');
      app.Cor1Restore.Editable = 'off';
      app.RestoreButton = uibutton(app.InitialValuePanel, 'push');
      app.RestoreButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreButtonPushed, true);
      app.RestoreButton.Text = 'Restore';
      app.Corrector2Panel = uipanel(app.BumpCorrectorsUIFigure);
      app.Corrector2Panel.Title = 'Corrector 2';
      app.Cor2Slider = uislider(app.Corrector2Panel);
      app.Cor2Slider.MajorTicks = [0 20 40 60 80 100];
      app.Cor2Slider.MajorTickLabels = {'0', '20', '40', '60', '80', '100'};
      app.Cor2Slider.ValueChangedFcn = createCallbackFcn(app, @Cor2SliderValueChanged, true);
      app.Cor2Slider.ValueChangingFcn = createCallbackFcn(app, @Cor2SliderValueChanging, true);
      app.Cor2Slider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
      app.Cor2 = uieditfield(app.Corrector2Panel, 'numeric');
      app.Cor2.ValueChangedFcn = createCallbackFcn(app, @Cor2ValueChanged, true);
      app.InitialValuePanel_2 = uipanel(app.Corrector2Panel);
      app.InitialValuePanel_2.Title = 'Initial Value';
      app.Cor2Restore = uieditfield(app.InitialValuePanel_2, 'numeric');
      app.Cor2Restore.Editable = 'off';
      app.RestoreButton_2 = uibutton(app.InitialValuePanel_2, 'push');
      app.RestoreButton_2.ButtonPushedFcn = createCallbackFcn(app, @RestoreButton_2Pushed, true);
      app.RestoreButton_2.Text = 'Restore';
      app.Corrector3Panel = uipanel(app.BumpCorrectorsUIFigure);
      app.Corrector3Panel.Title = 'Corrector 3';
      app.Cor3Slider = uislider(app.Corrector3Panel);
      app.Cor3Slider.MajorTicks = [0 20 40 60 80 100];
      app.Cor3Slider.MajorTickLabels = {'0', '20', '40', '60', '80', '100'};
      app.Cor3Slider.ValueChangedFcn = createCallbackFcn(app, @Cor3SliderValueChanged, true);
      app.Cor3Slider.ValueChangingFcn = createCallbackFcn(app, @Cor3SliderValueChanging, true);
      app.Cor3Slider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
      app.Cor3 = uieditfield(app.Corrector3Panel, 'numeric');
      app.Cor3.ValueChangedFcn = createCallbackFcn(app, @Cor3ValueChanged, true);
      app.InitialValuePanel_3 = uipanel(app.Corrector3Panel);
      app.InitialValuePanel_3.Title = 'Initial Value';
      app.Cor3Restore = uieditfield(app.InitialValuePanel_3, 'numeric');
      app.Cor3Restore.Editable = 'off';
      app.RestoreButton_3 = uibutton(app.InitialValuePanel_3, 'push');
      app.RestoreButton_3.ButtonPushedFcn = createCallbackFcn(app, @RestoreButton_3Pushed, true);
      app.RestoreButton_3.Text = 'Restore';
      % Show the figure after all components are created
      app.BumpCorrectorsUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = F2_OrbitBump_correctors(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.BumpCorrectorsUIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.BumpCorrectorsUIFigure)
    end
  end
end