classdef s20LaserRoomMultiProfmon < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        UIAxes_2               matlab.ui.control.UIAxes
        UIAxes_3               matlab.ui.control.UIAxes
        UIAxes_4               matlab.ui.control.UIAxes
        UIAxes_5               matlab.ui.control.UIAxes
        UIAxes_6               matlab.ui.control.UIAxes
        UIAxes_7               matlab.ui.control.UIAxes
        UIAxes_8               matlab.ui.control.UIAxes
        StartDisplayingButton  matlab.ui.control.Button
        PauseDisplayingButton  matlab.ui.control.Button
        PrinttoeLogButton      matlab.ui.control.Button
        ShowstatsButton        matlab.ui.control.Button
        AutoROICheckBox        matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        plotBool = 1 ;% Bool for starting or stopping the plotting
        autoROI = 0;
        cameraPVs = {'CAMR:LT20:0001','CAMR:LT20:0002','CAMR:LT20:0003','CAMR:LT20:0004',...
            'CAMR:LT20:0005','CAMR:LT20:0006','CAMR:LT20:0007','CAMR:LT20:0008'};
        AxesStorage;% Store all axes
        plotTitles = {'S20RegenOut','S20PulsePicker','S20PreampNear','S20PreampFar',...
            'S20PreampOut','S20MPANear','S20MPAFar','S20Launch'};
   
    end   

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartDisplayingButton
        function StartDisplayingButtonPushed(app, event)
            app.AxesStorage={app.UIAxes, app.UIAxes_2, app.UIAxes_3, app.UIAxes_4...
                , app.UIAxes_5, app.UIAxes_6, app.UIAxes_7, app.UIAxes_8};
            app.plotBool = 1;
            while app.plotBool
                
                for i =1:length(app.cameraPVs)
                    a = profmon_grab([app.cameraPVs{i}]);
                    if app.autoROI
                       [imgcrop,~] = setImgROI(a.img,4);
                       imagesc(app.AxesStorage{i},imgcrop);
                    else
                    imagesc(app.AxesStorage{i}, a.img);
                    end
                    axis(app.AxesStorage{i},'tight');
                    app.AxesStorage{i}.Colormap = jetvar;
                end      
                drawnow
                pause(0.3)
            end
            
        end

        % Button pushed function: PauseDisplayingButton
        function PauseDisplayingButtonPushed(app, event)
            app.plotBool = 0;
        end

        % Value changed function: AutoROICheckBox
        function AutoROICheckBoxValueChanged(app, event)
            value = app.AutoROICheckBox.Value;
            app.autoROI = value;
        end

        % Button pushed function: PrinttoeLogButton
        function PrinttoeLogButtonPushed(app, event)
            fh = figure(101);
            for i =1:length(app.cameraPVs)
                    a = profmon_grab([app.cameraPVs{i}]);
                    subplot(2,4,i)
                    if app.autoROI
                       [imgcrop,~] = setImgROI(a.img,4);
                       imagesc(imgcrop);
                    else
                    imagesc(a.img);
                    end
                   % axis(fh,'tight');
                    colormap  jetvar;
                    title(app.plotTitles{i})
            end
                drawnow
                print(fh, '-dpsc2', ['-P','physics-facetlog']);  
                close(fh)
                       
        end

        % Button pushed function: ShowstatsButton
        function ShowstatsButtonPushed(app, event)
            addpath('/home/fphysics/cemma/S10Laser/S10LaserWatchdog')
            statsTable
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.plotBool =0;
            delete(app)
            exit
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'S20RegenOut')
            xlabel(app.UIAxes, 'X [pix]')
            ylabel(app.UIAxes, 'Y [pix]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'S20PulsePicker')
            xlabel(app.UIAxes_2, 'X [pix]')
            ylabel(app.UIAxes_2, 'Y [pix]')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'S20PreampNear')
            xlabel(app.UIAxes_3, 'X [pix]')
            ylabel(app.UIAxes_3, 'Y [pix]')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'S20PreampFar')
            xlabel(app.UIAxes_4, 'X [pix]')
            ylabel(app.UIAxes_4, 'Y [pix]')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'S20PreampOut')
            xlabel(app.UIAxes_5, 'X [pix]')
            ylabel(app.UIAxes_5, 'Y [pix]')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'S20MPANear')
            xlabel(app.UIAxes_6, 'X [pix]')
            ylabel(app.UIAxes_6, 'Y [pix]')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_7 = uiaxes(app.UIFigure);
            title(app.UIAxes_7, 'S20MPAFar')
            xlabel(app.UIAxes_7, 'X [pix]')
            ylabel(app.UIAxes_7, 'Y [pix]')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_8 = uiaxes(app.UIFigure);
            title(app.UIAxes_8, 'S20Launch')
            xlabel(app.UIAxes_8, 'X [pix]')
            ylabel(app.UIAxes_8, 'Y [pix]')
            zlabel(app.UIAxes_8, 'Z')
            app.StartDisplayingButton = uibutton(app.UIFigure, 'push');
            app.StartDisplayingButton.ButtonPushedFcn = createCallbackFcn(app, @StartDisplayingButtonPushed, true);
            app.StartDisplayingButton.Text = 'Start Displaying';
            app.PauseDisplayingButton = uibutton(app.UIFigure, 'push');
            app.PauseDisplayingButton.ButtonPushedFcn = createCallbackFcn(app, @PauseDisplayingButtonPushed, true);
            app.PauseDisplayingButton.Text = 'Pause Displaying';
            app.PrinttoeLogButton = uibutton(app.UIFigure, 'push');
            app.PrinttoeLogButton.ButtonPushedFcn = createCallbackFcn(app, @PrinttoeLogButtonPushed, true);
            app.PrinttoeLogButton.Text = 'Print to eLog';
            app.ShowstatsButton = uibutton(app.UIFigure, 'push');
            app.ShowstatsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowstatsButtonPushed, true);
            app.ShowstatsButton.Text = 'Show stats';
            app.AutoROICheckBox = uicheckbox(app.UIFigure);
            app.AutoROICheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoROICheckBoxValueChanged, true);
            app.AutoROICheckBox.Text = 'Auto ROI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = s20LaserRoomMultiProfmon

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end