classdef F2_SimControls < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                      matlab.ui.Figure
    TabGroup                      matlab.ui.container.TabGroup
    LT10Tab                       matlab.ui.container.Tab
    MPSShutterSwitchLabel         matlab.ui.control.Label
    MPSShutterSwitch              matlab.ui.control.Switch
    TelescopeSwitchLabel          matlab.ui.control.Label
    TelescopeSwitch               matlab.ui.control.Switch
    MirrorunitsmmPanel            matlab.ui.container.Panel
    TabGroup3                     matlab.ui.container.TabGroup
    M2Tab                         matlab.ui.container.Tab
    HPOSEditFieldLabel            matlab.ui.control.Label
    HPOSEditField                 matlab.ui.control.NumericEditField
    Button                        matlab.ui.control.Button
    Button_2                      matlab.ui.control.Button
    HPOSEditFieldLabel_2          matlab.ui.control.Label
    VPOSEditField                 matlab.ui.control.NumericEditField
    Button_3                      matlab.ui.control.Button
    Button_4                      matlab.ui.control.Button
    EditField                     matlab.ui.control.NumericEditField
    EditField_2                   matlab.ui.control.NumericEditField
    HVELOEditFieldLabel           matlab.ui.control.Label
    HVELOEditField                matlab.ui.control.NumericEditField
    VVELOEditFieldLabel           matlab.ui.control.Label
    VVELOEditField                matlab.ui.control.NumericEditField
    HStop                         matlab.ui.control.Button
    VStop                         matlab.ui.control.Button
    HHome                         matlab.ui.control.Button
    VHome                         matlab.ui.control.Button
    HACCLEditFieldLabel           matlab.ui.control.Label
    HACCLEditField                matlab.ui.control.NumericEditField
    VACCLEditFieldLabel           matlab.ui.control.Label
    VACCLEditField                matlab.ui.control.NumericEditField
    mmsLabel                      matlab.ui.control.Label
    SecondsLabel_2                matlab.ui.control.Label
    M3Tab                         matlab.ui.container.Tab
    LaserEnergyuJEditFieldLabel   matlab.ui.control.Label
    LaserEnergyuJEditField        matlab.ui.control.NumericEditField
    LaserreprateHzEditFieldLabel  matlab.ui.control.Label
    LaserreprateHzEditField       matlab.ui.control.NumericEditField
    IN10Tab                       matlab.ui.container.Tab
    GunRFSwitchLabel              matlab.ui.control.Label
    GunRFSwitch                   matlab.ui.control.Switch
    FaradayCupSwitchLabel         matlab.ui.control.Label
    FaradayCupSwitch              matlab.ui.control.Switch
    CathodeQEEditFieldLabel       matlab.ui.control.Label
    CathodeQEEditField            matlab.ui.control.NumericEditField
  end

  
  properties (Access = public)
    pvs % Description
    pvlist
    homepos
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, arg1)
      % Motor home positions based on default Values in GUI
      app.homepos=[app.HPOSEditField.Value app.VPOSEditField.Value];
      % labca setup and formation of PV list
      %lcaSetSeverityWarnLevel(14) ;
      %lcaSetSeverityWarnLevel(4) ;
      context = PV.Initialize(PVtype.EPICS) ;
      pvl=[PV(context,'name',"gun_rfstate",'pvname',"KLYS:LI10:21:MOD:HVON_STATE",'guihan',app.GunRFSwitch,'pvlogic','~','mode',"rw"); % Gun rf on/off (10-2 modulator state)
        PV(context,'name',"laser_shutterCtrl",'pvname',"IOC:SYS1:MP01:MSHUTCTL",'guihan',app.MPSShutterSwitch,'monitor',true,'mode',"rw"); % Laser MPS shutter control
        PV(context,'name',"fcup_stat",'pvname',"FARC:IN10:241:PNEUMATIC",'monitor',true,'guihan',app.FaradayCupSwitch,'mode',"rw"); % Faraday cup in/out status
        PV(context,'name',"M2_posx",'pvname',"MIRR:LT10:770:M2_MOTR_H",'monitor',true,'guihan',app.HPOSEditField,'mode',"rw"); % X Position readback for laser based on motors [mm]
        PV(context,'name',"M2_posy",'pvname',"MIRR:LT10:770:M2_MOTR_V",'monitor',true,'guihan',app.VPOSEditField,'mode',"rw"); % Y Position readback for laser based on motors [mm]
        PV(context,'name',"laser_energy",'pvname',"LASR:LT10:930:PWR_SET",'monitor',true,'guihan',app.LaserEnergyuJEditField,'mode',"rw"); % Laser energy readout (uJ)
        PV(context,'name',"laser_telescope",'pvname',"LASR:LT10:100:TELE",'monitor',true,'guihan',app.TelescopeSwitch,'mode',"rw");
        PV(context,'name',"laser_reprate",'pvname',"LASR:LT10:REPRATE",'monitor',true,'guihan',app.LaserreprateHzEditField,'mode',"rw");
        PV(context,'name',"M2_velox",'pvname',"MIRR:LT10:770:M2_MOTR_H.VELO",'monitor',true,'guihan',app.HVELOEditField,'mode',"rw");
        PV(context,'name',"M2_veloy",'pvname',"MIRR:LT10:770:M2_MOTR_V.VELO",'monitor',true,'guihan',app.VVELOEditField,'mode',"rw");
        PV(context,'name',"M2_accx",'pvname',"MIRR:LT10:770:M2_MOTR_H.ACCL",'monitor',true,'guihan',app.HACCLEditField,'mode',"rw");
        PV(context,'name',"M2_accy",'pvname',"MIRR:LT10:770:M2_MOTR_V.ACCL",'monitor',true,'guihan',app.VACCLEditField,'mode',"rw");
        PV(context,'name',"M2_stopx",'pvname',"MIRR:LT10:770:M2_MOTR_H.STOP",'mode',"rw");
        PV(context,'name',"M2_stopy",'pvname',"MIRR:LT10:770:M2_MOTR_V.STOP",'mode',"rw");];
      app.pvs = struct(pvl) ;
      app.pvlist = pvl ;
      if ~exist('arg1','var')
        pset(pvl,'debug',0) ;
      else
        pset(pvl,'debug',arg1) ;
      end
      caget(pvl);
      run(pvl,false,0.02);
    end

    % Button pushed function: Button
    function ButtonPushed(app, event)
      app.HPOSEditField.Value = app.HPOSEditField.Value - app.EditField.Value ;
      app.HPOSEditField.ValueChangedFcn(app.HPOSEditField);
    end

    % Button pushed function: Button_2
    function Button_2Pushed(app, event)
      app.HPOSEditField.Value = app.HPOSEditField.Value + app.EditField.Value ;
      app.HPOSEditField.ValueChangedFcn(app.HPOSEditField);
    end

    % Button pushed function: Button_3
    function Button_3Pushed(app, event)
      app.VPOSEditField.Value = app.VPOSEditField.Value - app.EditField_2.Value ;
      app.VPOSEditField.ValueChangedFcn(app.VPOSEditField);
    end

    % Button pushed function: Button_4
    function Button_4Pushed(app, event)
      app.VPOSEditField.Value = app.VPOSEditField.Value + app.EditField_2.Value ;
      app.VPOSEditField.ValueChangedFcn(app.VPOSEditField);
    end

    % Button pushed function: HStop
    function HStopButtonPushed(app, event)
      caput(app.pvs.M2_stopx,1);
    end

    % Button pushed function: VStop
    function VStopButtonPushed(app, event)
      caput(app.pvs.M2_stopy,1);
    end

    % Button pushed function: HHome
    function HHomeButtonPushed(app, event)
      app.HVELOEditField.Value=4;
      app.HVELOEditField.ValueChangedFcn(app.HVELOEditField);
      app.HPOSEditField.Value=app.homepos(1);
      app.HPOSEditField.ValueChangedFcn(app.HPOSEditField);
    end

    % Button pushed function: VHome
    function VHomeButtonPushed(app, event)
      app.VVELOEditField.Value=4;
      app.VVELOEditField.ValueChangedFcn(app.VVELOEditField);
      app.VPOSEditField.Value=app.homepos(2);
      app.VPOSEditField.ValueChangedFcn(app.VPOSEditField);
    end

    % Close request function: UIFigure
    function UIFigureCloseRequest(app, event)
      stop(app.pvlist);
      Cleanup(app.pvlist);
      delete(app);
      exit
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
      app.TabGroup = uitabgroup(app.UIFigure);
      app.LT10Tab = uitab(app.TabGroup);
      app.LT10Tab.Title = 'LT10';
      app.MPSShutterSwitchLabel = uilabel(app.LT10Tab);
      app.MPSShutterSwitchLabel.Text = 'MPS Shutter';
      app.MPSShutterSwitch = uiswitch(app.LT10Tab, 'slider');
      app.MPSShutterSwitch.Items = {'Out', 'In'};
      app.MPSShutterSwitch.ItemsData = {'No', 'Yes'};
      app.MPSShutterSwitch.Value = 'Yes';
      app.TelescopeSwitchLabel = uilabel(app.LT10Tab);
      app.TelescopeSwitchLabel.Text = 'Telescope';
      app.TelescopeSwitch = uiswitch(app.LT10Tab, 'slider');
      app.TelescopeSwitch.Items = {'OUT', 'IN'};
      app.TelescopeSwitch.Value = 'OUT';
      app.MirrorunitsmmPanel = uipanel(app.LT10Tab);
      app.MirrorunitsmmPanel.Title = 'Mirror (units = mm)';
      app.TabGroup3 = uitabgroup(app.MirrorunitsmmPanel);
      app.M2Tab = uitab(app.TabGroup3);
      app.M2Tab.Title = 'M2';
      app.HPOSEditFieldLabel = uilabel(app.M2Tab);
      app.HPOSEditFieldLabel.Text = 'H POS';
      app.HPOSEditField = uieditfield(app.M2Tab, 'numeric');
      app.HPOSEditField.ValueDisplayFormat = '%3.3g';
      app.HPOSEditField.Value = 3.5;
      app.Button = uibutton(app.M2Tab, 'push');
      app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
      app.Button.Icon = 'larrow.svg';
      app.Button.Text = '';
      app.Button_2 = uibutton(app.M2Tab, 'push');
      app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
      app.Button_2.Icon = 'rarrow.svg';
      app.Button_2.Text = '';
      app.HPOSEditFieldLabel_2 = uilabel(app.M2Tab);
      app.HPOSEditFieldLabel_2.Text = 'V POS';
      app.VPOSEditField = uieditfield(app.M2Tab, 'numeric');
      app.VPOSEditField.ValueDisplayFormat = '%3.3g';
      app.VPOSEditField.Value = 2.5;
      app.Button_3 = uibutton(app.M2Tab, 'push');
      app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
      app.Button_3.Icon = 'larrow.svg';
      app.Button_3.Text = '';
      app.Button_4 = uibutton(app.M2Tab, 'push');
      app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
      app.Button_4.Icon = 'rarrow.svg';
      app.Button_4.Text = '';
      app.EditField = uieditfield(app.M2Tab, 'numeric');
      app.EditField.ValueDisplayFormat = '%3.3g';
      app.EditField.Value = 0.1;
      app.EditField_2 = uieditfield(app.M2Tab, 'numeric');
      app.EditField_2.ValueDisplayFormat = '%3.3g';
      app.EditField_2.Value = 0.1;
      app.HVELOEditFieldLabel = uilabel(app.M2Tab);
      app.HVELOEditFieldLabel.Text = 'H VELO';
      app.HVELOEditField = uieditfield(app.M2Tab, 'numeric');
      app.HVELOEditField.ValueDisplayFormat = '%3.3g';
      app.HVELOEditField.Value = 4;
      app.VVELOEditFieldLabel = uilabel(app.M2Tab);
      app.VVELOEditFieldLabel.Text = 'V VELO';
      app.VVELOEditField = uieditfield(app.M2Tab, 'numeric');
      app.VVELOEditField.ValueDisplayFormat = '%3.3g';
      app.VVELOEditField.Value = 4;
      app.HStop = uibutton(app.M2Tab, 'push');
      app.HStop.ButtonPushedFcn = createCallbackFcn(app, @HStopButtonPushed, true);
      app.HStop.Icon = 'stop.svg';
      app.HStop.Text = '';
      app.VStop = uibutton(app.M2Tab, 'push');
      app.VStop.ButtonPushedFcn = createCallbackFcn(app, @VStopButtonPushed, true);
      app.VStop.Icon = 'stop.svg';
      app.VStop.Text = '';
      app.HHome = uibutton(app.M2Tab, 'push');
      app.HHome.ButtonPushedFcn = createCallbackFcn(app, @HHomeButtonPushed, true);
      app.HHome.Icon = 'home.svg';
      app.HHome.Text = '';
      app.VHome = uibutton(app.M2Tab, 'push');
      app.VHome.ButtonPushedFcn = createCallbackFcn(app, @VHomeButtonPushed, true);
      app.VHome.Icon = 'home.svg';
      app.VHome.Text = '';
      app.HACCLEditFieldLabel = uilabel(app.M2Tab);
      app.HACCLEditFieldLabel.Text = 'H ACCL';
      app.HACCLEditField = uieditfield(app.M2Tab, 'numeric');
      app.HACCLEditField.ValueDisplayFormat = '%3.3g';
      app.HACCLEditField.Value = 20;
      app.VACCLEditFieldLabel = uilabel(app.M2Tab);
      app.VACCLEditFieldLabel.Text = 'V ACCL';
      app.VACCLEditField = uieditfield(app.M2Tab, 'numeric');
      app.VACCLEditField.ValueDisplayFormat = '%3.3g';
      app.VACCLEditField.Value = 20;
      app.mmsLabel = uilabel(app.M2Tab);
      app.mmsLabel.Text = 'mm/s';
      app.SecondsLabel_2 = uilabel(app.M2Tab);
      app.SecondsLabel_2.Text = 'Seconds';
      app.M3Tab = uitab(app.TabGroup3);
      app.M3Tab.Title = 'M3';
      app.LaserEnergyuJEditFieldLabel = uilabel(app.LT10Tab);
      app.LaserEnergyuJEditFieldLabel.Text = 'Laser Energy (uJ)';
      app.LaserEnergyuJEditField = uieditfield(app.LT10Tab, 'numeric');
      app.LaserEnergyuJEditField.Value = 10;
      app.LaserreprateHzEditFieldLabel = uilabel(app.LT10Tab);
      app.LaserreprateHzEditFieldLabel.Text = 'Laser rep rate (Hz)';
      app.LaserreprateHzEditField = uieditfield(app.LT10Tab, 'numeric');
      app.LaserreprateHzEditField.Value = 30;
      app.IN10Tab = uitab(app.TabGroup);
      app.IN10Tab.Title = 'IN10';
      app.GunRFSwitchLabel = uilabel(app.IN10Tab);
      app.GunRFSwitchLabel.Text = 'Gun RF';
      app.GunRFSwitch = uiswitch(app.IN10Tab, 'slider');
      app.GunRFSwitch.Items = {'OFF', 'ON'};
      app.GunRFSwitch.Value = 'OFF';
      app.FaradayCupSwitchLabel = uilabel(app.IN10Tab);
      app.FaradayCupSwitchLabel.Text = 'Faraday Cup';
      app.FaradayCupSwitch = uiswitch(app.IN10Tab, 'slider');
      app.FaradayCupSwitch.Items = {'OUT', 'IN'};
      app.FaradayCupSwitch.Value = 'OUT';
      app.CathodeQEEditFieldLabel = uilabel(app.IN10Tab);
      app.CathodeQEEditFieldLabel.Text = 'Cathode QE';
      app.CathodeQEEditField = uieditfield(app.IN10Tab, 'numeric');
      app.CathodeQEEditField.Value = 0.001;
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = F2_SimControls(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end