classdef F2_SliceBPMDispersion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NotchJawCollimatorControlPanel  matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        DTOTR                           matlab.ui.control.UIAxes
        DTOTRStop                       matlab.ui.control.Button
        DTOTRStart                      matlab.ui.control.Button
        BitdepthSpinnerLabel            matlab.ui.control.Label
        DTOTRBitDepth                   matlab.ui.control.Spinner
        SYAG                            matlab.ui.control.UIAxes
        SYAGStart                       matlab.ui.control.Button
        SYAGStop                        matlab.ui.control.Button
        BitdepthSpinnerLabel_2          matlab.ui.control.Label
        SYAGBitDepth                    matlab.ui.control.Spinner
        CollimatorControlsPanel         matlab.ui.container.Panel
        NotchSet                        matlab.ui.control.NumericEditField
        NotchControlLabel               matlab.ui.control.Label
        NotchRead                       matlab.ui.control.NumericEditField
        JawControlLabel                 matlab.ui.control.Label
        JawLeftSet                      matlab.ui.control.NumericEditField
        JawLeftRead                     matlab.ui.control.NumericEditField
        YawLabel                        matlab.ui.control.Label
        YawSet                          matlab.ui.control.NumericEditField
        YawRead                         matlab.ui.control.NumericEditField
        NotchHome                       matlab.ui.control.Button
        RetractAll                      matlab.ui.control.Button
        CalibrateNotchButton            matlab.ui.control.Button
        XLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        NotchYSet                       matlab.ui.control.NumericEditField
        NotchYRead                      matlab.ui.control.NumericEditField
        SettoLabel                      matlab.ui.control.Label
        ReadbackLabel                   matlab.ui.control.Label
        SettoLabel_2                    matlab.ui.control.Label
        ReadbackLabel_2                 matlab.ui.control.Label
        NotchLLSLamp                    matlab.ui.control.Lamp
        NotchHLSLamp                    matlab.ui.control.Lamp
        JawLLSLamp                      matlab.ui.control.Lamp
        JawHLSLamp                      matlab.ui.control.Lamp
        MoveCollimatorsTogetherPanel    matlab.ui.container.Panel
        StartPosLabel                   matlab.ui.control.Label
        StartPos                        matlab.ui.control.NumericEditField
        StartPosLabel_3                 matlab.ui.control.Label
        EndPos                          matlab.ui.control.NumericEditField
        StepsEditFieldLabel             matlab.ui.control.Label
        NumSteps                        matlab.ui.control.NumericEditField
        ScanStartButton                 matlab.ui.control.Button
        ABORTButton                     matlab.ui.control.Button
        ScaninProgressLabel             matlab.ui.control.Label
        ScanFinishedLabel               matlab.ui.control.Label
        ShotsBufferLabel                matlab.ui.control.Label
        ShotsperBuffer                  matlab.ui.control.NumericEditField
        DebugmodeCheckBox               matlab.ui.control.CheckBox
        ScanNotesLabel                  matlab.ui.control.Label
        ClearPlotButton                 matlab.ui.control.Button
        PlottingPanel                   matlab.ui.container.Panel
        ZLocationEditFieldLabel         matlab.ui.control.Label
        ZLocation                       matlab.ui.control.NumericEditField
        recalculateButton               matlab.ui.control.Button
        xPlot                           matlab.ui.control.UIAxes
        xpPlot                          matlab.ui.control.UIAxes
        yPlot                           matlab.ui.control.UIAxes
        ypPlot                          matlab.ui.control.UIAxes
        tmitPlot                        matlab.ui.control.UIAxes
        PrinttoelogButton               matlab.ui.control.Button
        FACETIIBPMSliceDispersionMeasurementGUILabel  matlab.ui.control.Label
        PVFilteringPanel                matlab.ui.container.Panel
        PVFilterList                    matlab.ui.control.TextArea
        PVListFormatPV_NAMEMINMAXLabel  matlab.ui.control.Label
        EnableFilteringCheckBox         matlab.ui.control.CheckBox
        LoadDefaultButton               matlab.ui.control.Button
        ClearListButton                 matlab.ui.control.Button
        ApplyfilterButton               matlab.ui.control.Button
        FilterStatusLabel               matlab.ui.control.Label
        StatusPanel                     matlab.ui.container.Panel
        SystemLog                       matlab.ui.control.TextArea
        ClearLogButton                  matlab.ui.control.Button
        DataLoadingandSavingPanel       matlab.ui.container.Panel
        SavePathEditFieldLabel          matlab.ui.control.Label
        SavePathEditField               matlab.ui.control.EditField
        CommentTextAreaLabel            matlab.ui.control.Label
        CommentTextArea                 matlab.ui.control.TextArea
        BrowseButton                    matlab.ui.control.Button
        SaveDataButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        xROI = [];  % creates property (x Region Of Interest) as an empty array
        yROI = [];  % creates property (y Region Of Interest) as an empty arra
        DTOTRtimer
        SYAGtimer 
        aobj; % object for support class
    end


    methods (Access = private)
        function startTimers(app,~)   % Starts SYAG and DTOTR timers
            
            if ~isvalid(app)
                return;
            end
            
            if isempty(app.DTOTRtimer) || ~isvalid(app.DTOTRtimer)
                app.DTOTRtimer = timer('ExecutionMode','fixedRate','Period',0.1,'TimerFcn',@(~,~) updateDTOTR(app));
            end
            if strcmp(app.DTOTRtimer.Running, 'off')
                start(app.DTOTRtimer);
            end
            if isempty(app.SYAGtimer) || ~isvalid(app.SYAGtimer)
                %app.SYAGtimer = timer('ExecutionMode','fixedRate','Period',0.1,'TimerFcn',@(~,~) updateSYAG(app), 'StopFcn',@(~,~)delete(timerfind('Name','SYAGtimer')));
                app.SYAGtimer = timer('ExecutionMode','fixedRate','Period',0.1,'TimerFcn',@(~,~) updateSYAG(app));
            end
            if strcmp(app.SYAGtimer.Running, 'off')
                start(app.SYAGtimer); %add start timer functions
            end
        end
        function updateDTOTR(app,~) %Grabs image and adjust Roi for DTOTR
            dtotr_data = profmon_grab('CAMR:LI20:107'); %grabs image 
            app.xROI = dtotr_data.roiX:dtotr_data.roiX+dtotr_data.roiXN-1; 
            app.yROI = dtotr_data.roiY:dtotr_data.roiY+dtotr_data.roiYN-1;  % Sets axis range for camera
            dtotr_img = (dtotr_data.img);
            imagesc(app.DTOTR, app.xROI, app.yROI, dtotr_img); %displays image in app.DTOTR axis
            axis(app.DTOTR, 'tight')
            drawnow;
        end
        function updateSYAG(app,~)  %Grabs image and adjust ROI for SYAG
            syag_data = profmon_grab('CAMR:LI20:100'); %grabs image from camera
            app.xROI = syag_data.roiX:syag_data.roiX+syag_data.roiXN-1; 
            app.yROI = syag_data.roiY:syag_data.roiY+syag_data.roiYN-1;  % Sets axis range for camera
            syag_img = (syag_data.img);
            imagesc(app.SYAG, app.xROI, app.yROI, syag_img); %displays image in app.SYAG axis
            axis(app.SYAG, 'tight')
            drawnow;
        end
     end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.aobj = F2_SliceBPMApp(app); % links to .m file
            app.startTimers(); %Camera timers      
        end

        % Button pushed function: DTOTRStart
        function DTOTRStartButtonPushed(app, event)
            if isempty(app.DTOTRtimer) || ~isvalid(app.DTOTRtimer)
               app.DTOTRtimer = timer('ExecutionMode','fixedRate','Period',0.1,'TimerFcn',@(~,~) updateDTOTR(app));
            % call DTOTR function every time
            end
            if strcmp(app.DTOTRtimer.Running,'off')
               start(app.DTOTRtimer)             % starts timer if it isnt already running
            end
        end

        % Button pushed function: DTOTRStop
        function DTOTRStopButtonPushed(app, event)
             if ~isempty(app.DTOTRtimer) && isvalid(app.DTOTRtimer) 
                stop(app.DTOTRtimer) %stop timer if it running
            end
        end

        % Button pushed function: SYAGStart
        function SYAGStartButtonPushed(app, event)
            if isempty(app.SYAGtimer) || ~isvalid(app.SYAGtimer)
               app.SYAGtimer = timer('ExecutionMode','fixedRate','Period',0.1,'TimerFcn',@(~,~) updateSYAG(app));
               %calls SYAG function for the follwing two timer if
               %statements
            end
            if strcmp(app.SYAGtimer.Running,'off')
               start(app.SYAGtimer) %starts timer for SYAG if not already on
            end
        end

        % Button pushed function: SYAGStop
        function SYAGStopButtonPushed(app, event)
            if ~isempty(app.SYAGtimer) && isvalid(app.SYAGtimer)
                stop(app.SYAGtimer) %stops timer of SYAG if not already off
            end
        end

        % Callback function
        function DTOTRColorIntensityValueChanged(app, event)
            value = app.DTOTRColorIntensity.Value;
            MaxPixel = 2^value-1;
            clim(app.DTOTR, [0 MaxPixel]);
        end

        % Callback function
        function SYAGColorIntensityValueChanged(app, event)
            value = app.SYAGColorIntensity.Value;
            MaxPixel = 2^value-1;
            clim(app.SYAG, [0 MaxPixel]);
        end

        % Button pushed function: ScanStartButton
        function ScanStartButtonPushed(app, event)
            app.aobj.scanCols();
        end

        % Button pushed function: ABORTButton
        function ABORTButtonPushed(app, event)
            app.aobj.abort();
        end

        % Button pushed function: NotchHome
        function NotchHomeButtonPushed(app, event)
            app.aobj.notchHome();
        end

        % Close request function: NotchJawCollimatorControlPanel
        function NotchJawCollimatorControlPanelCloseRequest(app, event)
            fprintf('Closing application...\n');
            if ~isempty(app.aobj) && isvalid(app.aobj)
                app.aobj.stopflag = true;
            end
            
            if ~isempty(app.DTOTRtimer) && isvalid(app.DTOTRtimer)
                if strcmp(app.DTOTRtimer.Running, 'on')
                    stop(app.DTOTRtimer); % stop timer if it's running
                    fprintf('  DTOTR timer stopped\n');
                end
                delete(app.DTOTRtimer);
                app.DTOTRtimer = [];
            end
            
            if ~isempty(app.SYAGtimer) && isvalid(app.SYAGtimer)
                if strcmp(app.SYAGtimer.Running, 'on')
                    stop(app.SYAGtimer); % stop timer if it's running
                    fprintf('  SYAG timer stopped\n');
                end
                delete(app.SYAGtimer);
                app.SYAGtimer = [];
            end
            % Cleanup EPICS PV connections (NEW)
            if ~isempty(app.aobj) && isvalid(app.aobj)
                try
                    app.aobj.cleanup();
                catch ME
                    warning('Error during cleanup: %s', ME.message);
                end
            end
            
            % Small pause to ensure cleanup completes
            pause(0.2);
            
            %deletes the app
            delete(app)
            fprintf('Application closed successfully\n');
        end

        % Button pushed function: RetractAll
        function RetractAllButtonPushed(app, event)
            app.aobj.retractCols();
        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            app.aobj.clearPlotsAndData();
        end

        % Button pushed function: recalculateButton
        function recalculateButtonPushed(app, event)
            app.aobj.recalculateAndPlot();
        end

        % Button pushed function: CalibrateNotchButton
        function CalibrateNotchButtonPushed(app, event)
            app.aobj.calibrateNotch();
        end

        % Value changed function: DebugmodeCheckBox
        function DebugmodeCheckBoxValueChanged(app, event)
            value = app.DebugmodeCheckBox.Value;
            app.aobj.debug_mode = value;
        end

        % Button pushed function: PrinttoelogButton
        function PrinttoelogButtonPushed(app, event)
            app.aobj.plotToElog();
        end

        % Value changed function: SYAGBitDepth
        function SYAGBitDepthValueChanged(app, event)
            value = app.SYAGBitDepth.Value;
            MaxPixel = 2^value - 1;
            caxis(app.SYAG, [0, MaxPixel]);
        end

        % Value changed function: DTOTRBitDepth
        function DTOTRBitDepthValueChanged(app, event)
            value = app.DTOTRBitDepth.Value;
            MaxPixel = 2^value - 1;
            caxis(app.DTOTR, [0, MaxPixel]);
        end

        % Button pushed function: LoadDefaultButton
        function LoadDefaultButtonPushed(app, event)
            app.aobj.loadDefaultFilters();
        end

        % Button pushed function: ClearListButton
        function ClearListButtonPushed(app, event)
            app.aobj.clearFilters();
        end

        % Button pushed function: ApplyfilterButton
        function ApplyfilterButtonPushed(app, event)
            app.aobj.recalculateAndPlot();
        end

        % Value changed function: EnableFilteringCheckBox
        function EnableFilteringCheckBoxValueChanged(app, event)
            app.aobj.updateFilterStatus();
        end

        % Button pushed function: ClearLogButton
        function ClearLogButtonPushed(app, event)
            app.SystemLog.Value = {''};
            pause(0.05);
            %app.aobj.logMessage('Log cleared');
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            app.aobj.saveData();
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.aobj.loadData();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NotchJawCollimatorControlPanel = uifigure('Visible', 'off');
            app.NotchJawCollimatorControlPanel.Name = 'Notch/Jaw Collimator Control Panel';
            app.NotchJawCollimatorControlPanel.CloseRequestFcn = createCallbackFcn(app, @NotchJawCollimatorControlPanelCloseRequest, true);
            app.Panel = uipanel(app.NotchJawCollimatorControlPanel);
            app.DTOTR = uiaxes(app.Panel);
            title(app.DTOTR, 'DTOTR')
            xlabel(app.DTOTR, 'X')
            ylabel(app.DTOTR, 'Y')
            zlabel(app.DTOTR, 'Z')
            app.DTOTRStop = uibutton(app.Panel, 'push');
            app.DTOTRStop.ButtonPushedFcn = createCallbackFcn(app, @DTOTRStopButtonPushed, true);
            app.DTOTRStop.Text = 'Stop';
            app.DTOTRStart = uibutton(app.Panel, 'push');
            app.DTOTRStart.ButtonPushedFcn = createCallbackFcn(app, @DTOTRStartButtonPushed, true);
            app.DTOTRStart.Text = 'Start';
            app.BitdepthSpinnerLabel = uilabel(app.Panel);
            app.BitdepthSpinnerLabel.Text = 'Bit depth';
            app.DTOTRBitDepth = uispinner(app.Panel);
            app.DTOTRBitDepth.Limits = [0 16];
            app.DTOTRBitDepth.ValueChangedFcn = createCallbackFcn(app, @DTOTRBitDepthValueChanged, true);
            app.DTOTRBitDepth.Value = 12;
            app.SYAG = uiaxes(app.Panel);
            title(app.SYAG, 'SYAG')
            xlabel(app.SYAG, 'X')
            ylabel(app.SYAG, 'Y')
            zlabel(app.SYAG, 'Z')
            app.SYAGStart = uibutton(app.Panel, 'push');
            app.SYAGStart.ButtonPushedFcn = createCallbackFcn(app, @SYAGStartButtonPushed, true);
            app.SYAGStart.Text = 'Start';
            app.SYAGStop = uibutton(app.Panel, 'push');
            app.SYAGStop.ButtonPushedFcn = createCallbackFcn(app, @SYAGStopButtonPushed, true);
            app.SYAGStop.Text = 'Stop';
            app.BitdepthSpinnerLabel_2 = uilabel(app.Panel);
            app.BitdepthSpinnerLabel_2.Text = 'Bit depth';
            app.SYAGBitDepth = uispinner(app.Panel);
            app.SYAGBitDepth.Limits = [0 16];
            app.SYAGBitDepth.ValueChangedFcn = createCallbackFcn(app, @SYAGBitDepthValueChanged, true);
            app.SYAGBitDepth.Value = 8;
            app.CollimatorControlsPanel = uipanel(app.NotchJawCollimatorControlPanel);
            app.CollimatorControlsPanel.Title = 'Collimator Controls';
            app.NotchSet = uieditfield(app.CollimatorControlsPanel, 'numeric');
            app.NotchSet.ValueDisplayFormat = '%0.0f';
            app.NotchControlLabel = uilabel(app.CollimatorControlsPanel);
            app.NotchControlLabel.Text = 'Notch Control';
            app.NotchRead = uieditfield(app.CollimatorControlsPanel, 'numeric');
            app.NotchRead.ValueDisplayFormat = '%0.0f';
            app.NotchRead.Editable = 'off';
            app.JawControlLabel = uilabel(app.CollimatorControlsPanel);
            app.JawControlLabel.Text = 'Jaw Control';
            app.JawLeftSet = uieditfield(app.CollimatorControlsPanel, 'numeric');
            app.JawLeftSet.ValueDisplayFormat = '%0.0f';
            app.JawLeftRead = uieditfield(app.CollimatorControlsPanel, 'numeric');
            app.JawLeftRead.ValueDisplayFormat = '%0.0f';
            app.JawLeftRead.Editable = 'off';
            app.YawLabel = uilabel(app.CollimatorControlsPanel);
            app.YawLabel.Text = 'Yaw:';
            app.YawSet = uieditfield(app.CollimatorControlsPanel, 'numeric');
            app.YawSet.ValueDisplayFormat = '%0.0f';
            app.YawRead = uieditfield(app.CollimatorControlsPanel, 'numeric');
            app.YawRead.ValueDisplayFormat = '%0.0f';
            app.YawRead.Editable = 'off';
            app.NotchHome = uibutton(app.CollimatorControlsPanel, 'push');
            app.NotchHome.ButtonPushedFcn = createCallbackFcn(app, @NotchHomeButtonPushed, true);
            app.NotchHome.Text = 'Home';
            app.RetractAll = uibutton(app.CollimatorControlsPanel, 'push');
            app.RetractAll.ButtonPushedFcn = createCallbackFcn(app, @RetractAllButtonPushed, true);
            app.RetractAll.Text = 'Retract All';
            app.CalibrateNotchButton = uibutton(app.CollimatorControlsPanel, 'push');
            app.CalibrateNotchButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateNotchButtonPushed, true);
            app.CalibrateNotchButton.Text = 'Calibrate Notch';
            app.XLabel = uilabel(app.CollimatorControlsPanel);
            app.XLabel.Text = 'X:';
            app.YLabel = uilabel(app.CollimatorControlsPanel);
            app.YLabel.Text = 'Y:';
            app.NotchYSet = uieditfield(app.CollimatorControlsPanel, 'numeric');
            app.NotchYRead = uieditfield(app.CollimatorControlsPanel, 'numeric');
            app.NotchYRead.Editable = 'off';
            app.SettoLabel = uilabel(app.CollimatorControlsPanel);
            app.SettoLabel.Text = 'Set to';
            app.ReadbackLabel = uilabel(app.CollimatorControlsPanel);
            app.ReadbackLabel.Text = 'Read back';
            app.SettoLabel_2 = uilabel(app.CollimatorControlsPanel);
            app.SettoLabel_2.Text = 'Set to';
            app.ReadbackLabel_2 = uilabel(app.CollimatorControlsPanel);
            app.ReadbackLabel_2.Text = 'Read back';
            app.NotchLLSLamp = uilamp(app.CollimatorControlsPanel);
            app.NotchLLSLamp.Visible = 'off';
            app.NotchHLSLamp = uilamp(app.CollimatorControlsPanel);
            app.NotchHLSLamp.Visible = 'off';
            app.JawLLSLamp = uilamp(app.CollimatorControlsPanel);
            app.JawLLSLamp.Visible = 'off';
            app.JawHLSLamp = uilamp(app.CollimatorControlsPanel);
            app.JawHLSLamp.Visible = 'off';
            app.MoveCollimatorsTogetherPanel = uipanel(app.NotchJawCollimatorControlPanel);
            app.MoveCollimatorsTogetherPanel.Title = 'Collimator Scan';
            app.StartPosLabel = uilabel(app.MoveCollimatorsTogetherPanel);
            app.StartPosLabel.Text = 'Start Position:';
            app.StartPos = uieditfield(app.MoveCollimatorsTogetherPanel, 'numeric');
            app.StartPos.Value = -1;
            app.StartPosLabel_3 = uilabel(app.MoveCollimatorsTogetherPanel);
            app.StartPosLabel_3.Text = 'End Position:';
            app.EndPos = uieditfield(app.MoveCollimatorsTogetherPanel, 'numeric');
            app.EndPos.Value = 0.28;
            app.StepsEditFieldLabel = uilabel(app.MoveCollimatorsTogetherPanel);
            app.StepsEditFieldLabel.Text = '# Steps';
            app.NumSteps = uieditfield(app.MoveCollimatorsTogetherPanel, 'numeric');
            app.NumSteps.Value = 5;
            app.ScanStartButton = uibutton(app.MoveCollimatorsTogetherPanel, 'push');
            app.ScanStartButton.ButtonPushedFcn = createCallbackFcn(app, @ScanStartButtonPushed, true);
            app.ScanStartButton.Text = 'Start';
            app.ABORTButton = uibutton(app.MoveCollimatorsTogetherPanel, 'push');
            app.ABORTButton.ButtonPushedFcn = createCallbackFcn(app, @ABORTButtonPushed, true);
            app.ABORTButton.Text = 'ABORT';
            app.ScaninProgressLabel = uilabel(app.MoveCollimatorsTogetherPanel);
            app.ScaninProgressLabel.Visible = 'off';
            app.ScaninProgressLabel.Text = '**Scan in Progress**';
            app.ScanFinishedLabel = uilabel(app.MoveCollimatorsTogetherPanel);
            app.ScanFinishedLabel.Visible = 'off';
            app.ScanFinishedLabel.Text = '**Scan Finished**';
            app.ShotsBufferLabel = uilabel(app.MoveCollimatorsTogetherPanel);
            app.ShotsBufferLabel.Text = 'Shots/Buffer:';
            app.ShotsperBuffer = uieditfield(app.MoveCollimatorsTogetherPanel, 'numeric');
            app.ShotsperBuffer.Value = 10;
            app.DebugmodeCheckBox = uicheckbox(app.MoveCollimatorsTogetherPanel);
            app.DebugmodeCheckBox.ValueChangedFcn = createCallbackFcn(app, @DebugmodeCheckBoxValueChanged, true);
            app.DebugmodeCheckBox.Text = 'Debug mode';
            app.ScanNotesLabel = uilabel(app.MoveCollimatorsTogetherPanel);
            app.ScanNotesLabel.Text = 'Set Start and End positions for the Jaw Collimator';
            app.ClearPlotButton = uibutton(app.MoveCollimatorsTogetherPanel, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            app.PlottingPanel = uipanel(app.NotchJawCollimatorControlPanel);
            app.PlottingPanel.Title = 'Plotting';
            app.ZLocationEditFieldLabel = uilabel(app.PlottingPanel);
            app.ZLocationEditFieldLabel.Text = 'Z Location:';
            app.ZLocation = uieditfield(app.PlottingPanel, 'numeric');
            app.ZLocation.ValueDisplayFormat = '%0.2f';
            app.ZLocation.Value = 1993.91;
            app.recalculateButton = uibutton(app.PlottingPanel, 'push');
            app.recalculateButton.ButtonPushedFcn = createCallbackFcn(app, @recalculateButtonPushed, true);
            app.recalculateButton.Text = 'recalculate';
            app.xPlot = uiaxes(app.PlottingPanel);
            title(app.xPlot, '')
            xlabel(app.xPlot, 'Slice Energy [GeV]')
            ylabel(app.xPlot, 'x-x_0 [µm]')
            zlabel(app.xPlot, 'Z')
            app.xpPlot = uiaxes(app.PlottingPanel);
            title(app.xpPlot, '')
            xlabel(app.xpPlot, 'Slice Energy [GeV]')
            ylabel(app.xpPlot, 'x''-x''_0 [µrad]')
            zlabel(app.xpPlot, 'Z')
            app.yPlot = uiaxes(app.PlottingPanel);
            title(app.yPlot, '')
            xlabel(app.yPlot, 'Slice Energy [GeV]')
            ylabel(app.yPlot, 'y-y_0 [µm]')
            zlabel(app.yPlot, 'Z')
            app.ypPlot = uiaxes(app.PlottingPanel);
            title(app.ypPlot, '')
            xlabel(app.ypPlot, 'Slice Energy [GeV]')
            ylabel(app.ypPlot, 'y''-y''_0 [µrad]')
            zlabel(app.ypPlot, 'Z')
            app.tmitPlot = uiaxes(app.PlottingPanel);
            title(app.tmitPlot, '')
            xlabel(app.tmitPlot, 'Slice Energy [GeV]')
            ylabel(app.tmitPlot, 'Slice Charge [nC]')
            zlabel(app.tmitPlot, 'Z')
            app.PrinttoelogButton = uibutton(app.PlottingPanel, 'push');
            app.PrinttoelogButton.ButtonPushedFcn = createCallbackFcn(app, @PrinttoelogButtonPushed, true);
            app.PrinttoelogButton.Text = 'Print to elog';
            app.FACETIIBPMSliceDispersionMeasurementGUILabel = uilabel(app.NotchJawCollimatorControlPanel);
            app.FACETIIBPMSliceDispersionMeasurementGUILabel.Text = 'FACET-II BPM Slice Dispersion Measurement GUI';
            app.PVFilteringPanel = uipanel(app.NotchJawCollimatorControlPanel);
            app.PVFilteringPanel.Title = 'PV Filtering';
            app.PVFilterList = uitextarea(app.PVFilteringPanel);
            app.PVListFormatPV_NAMEMINMAXLabel = uilabel(app.PVFilteringPanel);
            app.PVListFormatPV_NAMEMINMAXLabel.Text = 'PV List (Format: PV_NAME; MIN; MAX)';
            app.EnableFilteringCheckBox = uicheckbox(app.PVFilteringPanel);
            app.EnableFilteringCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableFilteringCheckBoxValueChanged, true);
            app.EnableFilteringCheckBox.Text = 'Enable Filtering';
            app.LoadDefaultButton = uibutton(app.PVFilteringPanel, 'push');
            app.LoadDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDefaultButtonPushed, true);
            app.LoadDefaultButton.Text = 'Load Default';
            app.ClearListButton = uibutton(app.PVFilteringPanel, 'push');
            app.ClearListButton.ButtonPushedFcn = createCallbackFcn(app, @ClearListButtonPushed, true);
            app.ClearListButton.Text = 'Clear List';
            app.ApplyfilterButton = uibutton(app.PVFilteringPanel, 'push');
            app.ApplyfilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyfilterButtonPushed, true);
            app.ApplyfilterButton.Text = 'Apply filter';
            app.FilterStatusLabel = uilabel(app.PVFilteringPanel);
            app.FilterStatusLabel.Text = 'Filter Status';
            app.StatusPanel = uipanel(app.NotchJawCollimatorControlPanel);
            app.StatusPanel.Title = 'Status';
            app.SystemLog = uitextarea(app.StatusPanel);
            app.SystemLog.Editable = 'off';
            app.ClearLogButton = uibutton(app.StatusPanel, 'push');
            app.ClearLogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLogButtonPushed, true);
            app.ClearLogButton.Text = 'Clear Log';
            app.DataLoadingandSavingPanel = uipanel(app.NotchJawCollimatorControlPanel);
            app.DataLoadingandSavingPanel.Title = 'Data Loading and Saving';
            app.SavePathEditFieldLabel = uilabel(app.DataLoadingandSavingPanel);
            app.SavePathEditFieldLabel.Text = 'Save Path';
            app.SavePathEditField = uieditfield(app.DataLoadingandSavingPanel, 'text');
            app.CommentTextAreaLabel = uilabel(app.DataLoadingandSavingPanel);
            app.CommentTextAreaLabel.Text = 'Comment';
            app.CommentTextArea = uitextarea(app.DataLoadingandSavingPanel);
            app.BrowseButton = uibutton(app.DataLoadingandSavingPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SaveDataButton = uibutton(app.DataLoadingandSavingPanel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            % Show the figure after all components are created
            app.NotchJawCollimatorControlPanel.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = F2_SliceBPMDispersion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NotchJawCollimatorControlPanel)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NotchJawCollimatorControlPanel)
        end
    end
end