classdef F2_TFeedback < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                       matlab.ui.Figure
    FeedbackDropDownLabel          matlab.ui.control.Label
    FeedbackDropDown               matlab.ui.control.DropDown
    CorrectorsTextAreaLabel        matlab.ui.control.Label
    CorrectorsTextArea             matlab.ui.control.TextArea
    TargetEditFieldLabel           matlab.ui.control.Label
    TargetEditField                matlab.ui.control.EditField
    OffsetsmmmradPanel             matlab.ui.container.Panel
    GridLayout                     matlab.ui.container.GridLayout
    XLabel                         matlab.ui.control.Label
    XPLabel                        matlab.ui.control.Label
    YLabel                         matlab.ui.control.Label
    YPLabel                        matlab.ui.control.Label
    PosX                           matlab.ui.control.NumericEditField
    PosXP                          matlab.ui.control.NumericEditField
    PosY                           matlab.ui.control.NumericEditField
    PosYP                          matlab.ui.control.NumericEditField
    PositionPlotsmmmradPanel       matlab.ui.container.Panel
    GridLayout2                    matlab.ui.container.GridLayout
    XLabel_2                       matlab.ui.control.Label
    XPLabel_2                      matlab.ui.control.Label
    YLabel_2                       matlab.ui.control.Label
    YPLabel_2                      matlab.ui.control.Label
    xpl                            matlab.ui.control.UIAxes
    xppl                           matlab.ui.control.UIAxes
    ypl                            matlab.ui.control.UIAxes
    yppl                           matlab.ui.control.UIAxes
    gainEditFieldLabel             matlab.ui.control.Label
    gainEditField                  matlab.ui.control.NumericEditField
    UseModel                       matlab.ui.control.CheckBox
    CorrectorPlotsBDESkGmPanel     matlab.ui.container.Panel
    GridLayout2_2                  matlab.ui.container.GridLayout
    X1Label                        matlab.ui.control.Label
    X2Label                        matlab.ui.control.Label
    Y1Label                        matlab.ui.control.Label
    Y2Label                        matlab.ui.control.Label
    x1pl                           matlab.ui.control.UIAxes
    x2pl                           matlab.ui.control.UIAxes
    y1pl                           matlab.ui.control.UIAxes
    y2pl                           matlab.ui.control.UIAxes
    UseMeas                        matlab.ui.control.CheckBox
    EditField_5                    matlab.ui.control.EditField
    MakeNewMeasurementButton       matlab.ui.control.Button
    PlottimescalessEditFieldLabel  matlab.ui.control.Label
    PlotTime                       matlab.ui.control.NumericEditField
    EnableCheckBox                 matlab.ui.control.CheckBox
    DataValidLampLabel             matlab.ui.control.Label
    DataValidLamp                  matlab.ui.control.Lamp
    MeasurementDateTimeLabel       matlab.ui.control.Label
  end

  
  properties (Access = public)
    aobj % F2_TFeedbackApp object passed at init
    calobj % calibration GUI handle
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, LLM)
       if exist("LLM","var")
         app.aobj = F2_TFeedbackApp(app,LLM) ;
       else
         app.aobj = F2_TFeedbackApp(app) ;
       end
       app.FeedbackDropDownValueChanged();
    end

    % Button pushed function: MakeNewMeasurementButton
    function MakeNewMeasurementButtonPushed(app, event)
      app.calobj = TFeedback_Calibrate(app.FeedbackDropDown.Value);
    end

    % Value changed function: FeedbackDropDown
    function FeedbackDropDownValueChanged(app, event)
      value = app.FeedbackDropDown.Value;
      ifb = find(app.aobj.fbname==value);
      cornames = app.aobj.fbcors(:,[(ifb-1)*2+1 (ifb-1)*2+2]) ;
      app.CorrectorsTextArea.Value = cornames(:) ;
      app.TargetEditField.Value = app.aobj.fbloc(ifb) ;
      drawnow;
    end

    % Value changed function: EnableCheckBox
    function EnableCheckBoxValueChanged(app, event)
      value = app.EnableCheckBox.Value;
      app.aobj.fbon(app.aobj.fbname==app.FeedbackDropDown.Value) = value ;
    end

    % Value changed function: gainEditField
    function gainEditFieldValueChanged(app, event)
      value = app.gainEditField.Value;
      app.aobj.gain(app.aobj.fbname==app.FeedbackDropDown.Value) = value ;
    end

    % Value changed function: UseModel
    function UseModelValueChanged(app, event)
      value = app.UseModel.Value;
      app.aobj.usemodel(app.aobj.fbname==app.FeedbackDropDown.Value) = value ;
      app.UseMeas.Value=~value;
    end

    % Value changed function: UseMeas
    function UseMeasValueChanged(app, event)
      value = app.UseMeas.Value;
      app.aobj.usemodel(app.aobj.fbname==app.FeedbackDropDown.Value) = ~value ;
      app.UseModel.Value=~value;
    end

    % Value changed function: PosX
    function PosXValueChanged(app, event)
      value = app.PosX.Value;
      ifb=find(app.aobj.fbname==app.FeedbackDropDown.Value);
      app.aobj.offs((ifb-1)*4+1)=value*1e-3;
    end

    % Value changed function: PosXP
    function PosXPValueChanged(app, event)
      value = app.PosXP.Value;
      ifb=find(app.aobj.fbname==app.FeedbackDropDown.Value);
      app.aobj.offs((ifb-1)*4+2)=value*1e-3;
    end

    % Value changed function: PosY
    function PosYValueChanged(app, event)
      value = app.PosY.Value;
      ifb=find(app.aobj.fbname==app.FeedbackDropDown.Value);
      app.aobj.offs((ifb-1)*4+3)=value*1e-3;
    end

    % Value changed function: PosYP
    function PosYPValueChanged(app, event)
      value = app.PosYP.Value;
      ifb=find(app.aobj.fbname==app.FeedbackDropDown.Value);
      app.aobj.offs((ifb-1)*4+4)=value*1e-3;
    end

    % Close request function: UIFigure
    function UIFigureCloseRequest(app, event)
      app.aobj.ltr.stop;
      delete(app)
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
      app.FeedbackDropDownLabel = uilabel(app.UIFigure);
      app.FeedbackDropDownLabel.Text = 'Feedback:';
      app.FeedbackDropDown = uidropdown(app.UIFigure);
      app.FeedbackDropDown.Items = {'DL10', 'BC11', 'BC14', 'BC20'};
      app.FeedbackDropDown.ValueChangedFcn = createCallbackFcn(app, @FeedbackDropDownValueChanged, true);
      app.FeedbackDropDown.Value = 'DL10';
      app.CorrectorsTextAreaLabel = uilabel(app.UIFigure);
      app.CorrectorsTextAreaLabel.Text = 'Correctors:';
      app.CorrectorsTextArea = uitextarea(app.UIFigure);
      app.CorrectorsTextArea.Editable = 'off';
      app.CorrectorsTextArea.Value = {'X1'; 'X2'; 'Y1'; 'Y2'};
      app.TargetEditFieldLabel = uilabel(app.UIFigure);
      app.TargetEditFieldLabel.Text = 'Target:';
      app.TargetEditField = uieditfield(app.UIFigure, 'text');
      app.TargetEditField.Editable = 'off';
      app.OffsetsmmmradPanel = uipanel(app.UIFigure);
      app.OffsetsmmmradPanel.Title = 'Offsets [mm, mrad]';
      app.GridLayout = uigridlayout(app.OffsetsmmmradPanel);
      app.XLabel = uilabel(app.GridLayout);
      app.XLabel.Text = 'X';
      app.XPLabel = uilabel(app.GridLayout);
      app.XPLabel.Text = 'XP';
      app.YLabel = uilabel(app.GridLayout);
      app.YLabel.Text = 'Y';
      app.YPLabel = uilabel(app.GridLayout);
      app.YPLabel.Text = 'YP';
      app.PosX = uieditfield(app.GridLayout, 'numeric');
      app.PosX.ValueChangedFcn = createCallbackFcn(app, @PosXValueChanged, true);
      app.PosXP = uieditfield(app.GridLayout, 'numeric');
      app.PosXP.ValueChangedFcn = createCallbackFcn(app, @PosXPValueChanged, true);
      app.PosY = uieditfield(app.GridLayout, 'numeric');
      app.PosY.ValueChangedFcn = createCallbackFcn(app, @PosYValueChanged, true);
      app.PosYP = uieditfield(app.GridLayout, 'numeric');
      app.PosYP.ValueChangedFcn = createCallbackFcn(app, @PosYPValueChanged, true);
      app.PositionPlotsmmmradPanel = uipanel(app.UIFigure);
      app.PositionPlotsmmmradPanel.Title = 'Position Plots [mm, mrad]';
      app.GridLayout2 = uigridlayout(app.PositionPlotsmmmradPanel);
      app.XLabel_2 = uilabel(app.GridLayout2);
      app.XLabel_2.Text = 'X';
      app.XPLabel_2 = uilabel(app.GridLayout2);
      app.XPLabel_2.Text = 'XP';
      app.YLabel_2 = uilabel(app.GridLayout2);
      app.YLabel_2.Text = 'Y';
      app.YPLabel_2 = uilabel(app.GridLayout2);
      app.YPLabel_2.Text = 'YP';
      app.xpl = uiaxes(app.GridLayout2);
      title(app.xpl, '')
      xlabel(app.xpl, 'time [s]')
      ylabel(app.xpl, '')
      app.xppl = uiaxes(app.GridLayout2);
      title(app.xppl, '')
      xlabel(app.xppl, 'time [s]')
      ylabel(app.xppl, '')
      app.ypl = uiaxes(app.GridLayout2);
      title(app.ypl, '')
      xlabel(app.ypl, 'time [s]')
      ylabel(app.ypl, '')
      app.yppl = uiaxes(app.GridLayout2);
      title(app.yppl, '')
      xlabel(app.yppl, 'time [s]')
      ylabel(app.yppl, '')
      app.gainEditFieldLabel = uilabel(app.UIFigure);
      app.gainEditFieldLabel.Text = 'gain:';
      app.gainEditField = uieditfield(app.UIFigure, 'numeric');
      app.gainEditField.Limits = [0 100];
      app.gainEditField.ValueChangedFcn = createCallbackFcn(app, @gainEditFieldValueChanged, true);
      app.gainEditField.Value = 1;
      app.UseModel = uicheckbox(app.UIFigure);
      app.UseModel.ValueChangedFcn = createCallbackFcn(app, @UseModelValueChanged, true);
      app.UseModel.Text = 'Use Model Response Matrix?';
      app.CorrectorPlotsBDESkGmPanel = uipanel(app.UIFigure);
      app.CorrectorPlotsBDESkGmPanel.Title = 'Corrector Plots [BDES, kG.m]';
      app.GridLayout2_2 = uigridlayout(app.CorrectorPlotsBDESkGmPanel);
      app.X1Label = uilabel(app.GridLayout2_2);
      app.X1Label.Text = 'X1';
      app.X2Label = uilabel(app.GridLayout2_2);
      app.X2Label.Text = 'X2';
      app.Y1Label = uilabel(app.GridLayout2_2);
      app.Y1Label.Text = 'Y1';
      app.Y2Label = uilabel(app.GridLayout2_2);
      app.Y2Label.Text = 'Y2';
      app.x1pl = uiaxes(app.GridLayout2_2);
      title(app.x1pl, '')
      xlabel(app.x1pl, 'time [s]')
      ylabel(app.x1pl, '')
      app.x2pl = uiaxes(app.GridLayout2_2);
      title(app.x2pl, '')
      xlabel(app.x2pl, 'time [s]')
      ylabel(app.x2pl, '')
      app.y1pl = uiaxes(app.GridLayout2_2);
      title(app.y1pl, '')
      xlabel(app.y1pl, 'time [s]')
      ylabel(app.y1pl, '')
      app.y2pl = uiaxes(app.GridLayout2_2);
      title(app.y2pl, '')
      xlabel(app.y2pl, 'time [s]')
      ylabel(app.y2pl, '')
      app.UseMeas = uicheckbox(app.UIFigure);
      app.UseMeas.ValueChangedFcn = createCallbackFcn(app, @UseMeasValueChanged, true);
      app.UseMeas.Text = 'Use Measured Response Matrix?';
      app.UseMeas.Value = true;
      app.EditField_5 = uieditfield(app.UIFigure, 'text');
      app.EditField_5.Editable = 'off';
      app.MakeNewMeasurementButton = uibutton(app.UIFigure, 'push');
      app.MakeNewMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @MakeNewMeasurementButtonPushed, true);
      app.MakeNewMeasurementButton.Text = 'Make New Measurement';
      app.PlottimescalessEditFieldLabel = uilabel(app.UIFigure);
      app.PlottimescalessEditFieldLabel.Text = 'Plot timescales [s]';
      app.PlotTime = uieditfield(app.UIFigure, 'numeric');
      app.PlotTime.Value = 300;
      app.EnableCheckBox = uicheckbox(app.UIFigure);
      app.EnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableCheckBoxValueChanged, true);
      app.EnableCheckBox.Text = 'Enable?';
      app.EnableCheckBox.Value = true;
      app.DataValidLampLabel = uilabel(app.UIFigure);
      app.DataValidLampLabel.Text = 'Data Valid?';
      app.DataValidLamp = uilamp(app.UIFigure);
      app.MeasurementDateTimeLabel = uilabel(app.UIFigure);
      app.MeasurementDateTimeLabel.Text = 'Measurement Date/Time';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = F2_TFeedback(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end