classdef TFeedback_Calibrate < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                     matlab.ui.Figure
    KickrangemradEditFieldLabel  matlab.ui.control.Label
    kickRange                    matlab.ui.control.NumericEditField
    NDatakickEditFieldLabel      matlab.ui.control.Label
    NDatakickEditField           matlab.ui.control.NumericEditField
    NkicksEditFieldLabel         matlab.ui.control.Label
    NkicksEditField              matlab.ui.control.NumericEditField
    FeedbackDropDownLabel        matlab.ui.control.Label
    FeedbackDropDown             matlab.ui.control.DropDown
    TargetEditFieldLabel         matlab.ui.control.Label
    TargetEditField              matlab.ui.control.EditField
    CorrectorDropDownLabel       matlab.ui.control.Label
    CorrectorDropDown            matlab.ui.control.DropDown
    TargetResp                   matlab.ui.container.Panel
    GridLayout                   matlab.ui.container.GridLayout
    xpl                          matlab.ui.control.UIAxes
    xppl                         matlab.ui.control.UIAxes
    ypl                          matlab.ui.control.UIAxes
    yppl                         matlab.ui.control.UIAxes
    StartCalibrationButton       matlab.ui.control.Button
    DisplayDropDownLabel         matlab.ui.control.Label
    DisplayDropDown              matlab.ui.control.DropDown
  end

  
  properties (Access = public)
    aobj % F2_TFeedbackApp object
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, fbname, aobj)
      app.aobj=aobj;
      app.FeedbackDropDownLabel.Value = fbname ;
      app.FeedbackDropDownValueChanged() ;
      
    end

    % Value changed function: FeedbackDropDown
    function FeedbackDropDownValueChanged(app, event)
      value = app.FeedbackDropDown.Value;
      ifb = find(app.aobj.fbname==value) ;
      app.TargetEditField.Value = app.aobj.fbloc(ifb) ;
      app.CorrectorDropDown.Items = [ app.aobj.fbcors(1,(ifb-1)*length(app.aobj.fbname)*2+1);
        app.aobj.fbcors(1,(ifb-1)*length(app.aobj.fbname)*2+2);
        app.aobj.fbcors(2,(ifb-1)*length(app.aobj.fbname)*2+1);
        app.aobj.fbcors(2,(ifb-1)*length(app.aobj.fbname)*2+2) ] ;
      app.CorrectorDropDown.Value = app.aobj.fbcors(1,(ifb-1)*length(app.aobj.fbname)*2+1) ;
    end

    % Value changed function: DisplayDropDown
    function DisplayDropDownValueChanged(app, event)
      global BEAMLINE
      value = app.DisplayDropDown.Value;
      if value==1
        app.kickRange.Label='Kick range [mrad]';
        app.TargetResp.Title = "Target Responses [X, X'] ; [Y, Y'] [mm, mrad]'" ;
        app.kickRange.Value = app.aobj.cc_kick*1e3 ;
      else
        app.kickRange.Label='Kick range [kG]';
        app.TargetResp.Title = "Target Responses [X, X'] ; [Y, Y'] [mm, kG]'" ;
        app.kickRange.Value = app.aobj.cc_kick * (physConsts.clight / (BEAMLINE{app.aobj.fbloc_ind(app.aobj.fbname==app.FeedbackDropDown.Value)}.P*1e9)) ;
      end
    end

    % Value changed function: kickRange
    function kickRangeValueChanged(app, event)
      global BEAMLINE
      value = app.kickRange.Value;
      if app.DisplayDropDown.Value==1
        app.aobj.cc_kick = value*1e-3;
      else
        app.aobj.cc_kick = value / (physConsts.clight / (BEAMLINE{app.aobj.fbloc_ind(app.aobj.fbname==app.FeedbackDropDown.Value)}.P*1e9)) ;
      end
    end

    % Value changed function: NDatakickEditField
    function NDatakickEditFieldValueChanged(app, event)
      value = app.NDatakickEditField.Value;
      app.aobj.cc_ndat(1) = value;
    end

    % Value changed function: NkicksEditField
    function NkicksEditFieldValueChanged(app, event)
      value = app.NkicksEditField.Value;
      app.aobj.cc_ndat(2) = value;
    end

    % Button pushed function: StartCalibrationButton
    function StartCalibrationButtonPushed(app, event)
      app.StartCalibrationButton.Enable=false;
      drawnow;
      try
        ifb = find(app.aobj.fbname==app.FeedbackDropDown.Value) ;
        corsel=app.CorrectorDropDown.Value;
        if corsel>2
          dim=2;
          corsel=corsel-2;
        else
          dim=1;
        end
        app.aobj.GetExpResp(ifb,corsel,dim); %#ok<FNDSB> 
      catch ME
        app.StartCalibrationButton.Enable=true;
        fprintf(2,ME.message);
        errordlg('Calibration Error',ME.message);
        drawnow;
      end
      app.StartCalibrationButton.Enable=true;
      drawnow;
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.KickrangemradEditFieldLabel = uilabel(app.UIFigure);
      app.KickrangemradEditFieldLabel.Text = 'Kick range [mrad]';
      app.kickRange = uieditfield(app.UIFigure, 'numeric');
      app.kickRange.Limits = [0 10];
      app.kickRange.ValueChangedFcn = createCallbackFcn(app, @kickRangeValueChanged, true);
      app.kickRange.Value = 0.1;
      app.NDatakickEditFieldLabel = uilabel(app.UIFigure);
      app.NDatakickEditFieldLabel.Text = 'N Data / kick';
      app.NDatakickEditField = uieditfield(app.UIFigure, 'numeric');
      app.NDatakickEditField.Limits = [1 1000];
      app.NDatakickEditField.ValueDisplayFormat = '%.0f';
      app.NDatakickEditField.ValueChangedFcn = createCallbackFcn(app, @NDatakickEditFieldValueChanged, true);
      app.NDatakickEditField.Value = 10;
      app.NkicksEditFieldLabel = uilabel(app.UIFigure);
      app.NkicksEditFieldLabel.Text = 'N kicks';
      app.NkicksEditField = uieditfield(app.UIFigure, 'numeric');
      app.NkicksEditField.Limits = [1 100];
      app.NkicksEditField.ValueDisplayFormat = '%.0f';
      app.NkicksEditField.ValueChangedFcn = createCallbackFcn(app, @NkicksEditFieldValueChanged, true);
      app.NkicksEditField.Value = 5;
      app.FeedbackDropDownLabel = uilabel(app.UIFigure);
      app.FeedbackDropDownLabel.Text = 'Feedback:';
      app.FeedbackDropDown = uidropdown(app.UIFigure);
      app.FeedbackDropDown.Items = {'DL10', 'BC11', 'BC14', 'BC20'};
      app.FeedbackDropDown.ValueChangedFcn = createCallbackFcn(app, @FeedbackDropDownValueChanged, true);
      app.FeedbackDropDown.Value = 'DL10';
      app.TargetEditFieldLabel = uilabel(app.UIFigure);
      app.TargetEditFieldLabel.Text = 'Target:';
      app.TargetEditField = uieditfield(app.UIFigure, 'text');
      app.TargetEditField.Editable = 'off';
      app.CorrectorDropDownLabel = uilabel(app.UIFigure);
      app.CorrectorDropDownLabel.Text = 'Corrector';
      app.CorrectorDropDown = uidropdown(app.UIFigure);
      app.CorrectorDropDown.Items = {'X1', 'X2', 'Y1', 'Y2'};
      app.CorrectorDropDown.ItemsData = {'1,2,3,4'};
      app.CorrectorDropDown.Value = '1,2,3,4';
      app.TargetResp = uipanel(app.UIFigure);
      app.TargetResp.Title = 'Target Responses [X, X''] ; [Y, Y''] [mm, mrad/kG]';
      app.GridLayout = uigridlayout(app.TargetResp);
      app.xpl = uiaxes(app.GridLayout);
      title(app.xpl, '')
      xlabel(app.xpl, 'Kick [mrad]')
      ylabel(app.xpl, '')
      app.xppl = uiaxes(app.GridLayout);
      title(app.xppl, '')
      xlabel(app.xppl, 'Kick [mrad]')
      ylabel(app.xppl, '')
      app.ypl = uiaxes(app.GridLayout);
      title(app.ypl, '')
      xlabel(app.ypl, 'Kick [mrad]')
      ylabel(app.ypl, '')
      app.yppl = uiaxes(app.GridLayout);
      title(app.yppl, '')
      xlabel(app.yppl, 'Kick [mrad]')
      ylabel(app.yppl, '')
      app.StartCalibrationButton = uibutton(app.UIFigure, 'push');
      app.StartCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @StartCalibrationButtonPushed, true);
      app.StartCalibrationButton.Text = 'Start Calibration';
      app.DisplayDropDownLabel = uilabel(app.UIFigure);
      app.DisplayDropDownLabel.Text = 'Display:';
      app.DisplayDropDown = uidropdown(app.UIFigure);
      app.DisplayDropDown.Items = {'Kick [mrad]', 'BDES [kG]'};
      app.DisplayDropDown.ItemsData = {'1,2'};
      app.DisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayDropDownValueChanged, true);
      app.DisplayDropDown.Value = '1,2';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = TFeedback_Calibrate(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end