classdef F2_Wirescan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        F2_WirescanUIFigure           matlab.ui.Figure
        DataMenu                      matlab.ui.container.Menu
        LoadMenu                      matlab.ui.container.Menu
        SaveAsMenu                    matlab.ui.container.Menu
        ExpertMenu                    matlab.ui.container.Menu
        EDMPanelMenu                  matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        MeasurementPanel              matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        StartScanButton               matlab.ui.control.Button
        AbortScanButton               matlab.ui.control.Button
        ButtonGroup                   matlab.ui.container.ButtonGroup
        XButton                       matlab.ui.control.RadioButton
        YButton                       matlab.ui.control.RadioButton
        UButton                       matlab.ui.control.RadioButton
        WIREDropDownLabel             matlab.ui.control.Label
        WIREDropDown                  matlab.ui.control.DropDown
        PMTDropDownLabel              matlab.ui.control.Label
        PMTDropDown                   matlab.ui.control.DropDown
        ProcessingPanel               matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        FitMethodDropDownLabel        matlab.ui.control.Label
        FitMethodDropDown             matlab.ui.control.DropDown
        WirediameterEditFieldLabel    matlab.ui.control.Label
        WirediameterEditField         matlab.ui.control.NumericEditField
        umLabel_5                     matlab.ui.control.Label
        chargeNormPanel               matlab.ui.container.Panel
        GridLayout6                   matlab.ui.container.GridLayout
        ChargeNormalizationCheckBox   matlab.ui.control.CheckBox
        TOROLabel                     matlab.ui.control.Label
        TORODropDown                  matlab.ui.control.DropDown
        jitterCorPanel                matlab.ui.container.Panel
        GridLayout7                   matlab.ui.container.GridLayout
        BPM1DropDown                  matlab.ui.control.DropDown
        BPM2DropDown                  matlab.ui.control.DropDown
        BPM1Label                     matlab.ui.control.Label
        BPM2Label                     matlab.ui.control.Label
        JitterCorrectionCheckBox      matlab.ui.control.CheckBox
        blenWinPanel                  matlab.ui.container.Panel
        GridLayout8                   matlab.ui.container.GridLayout
        BLENDropDown                  matlab.ui.control.DropDown
        BLENLabel                     matlab.ui.control.Label
        EditField_3                   matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        EditField_4                   matlab.ui.control.NumericEditField
        BunchLengthWindowingCheckBox  matlab.ui.control.CheckBox
        RightPanel                    matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        UIAxes                        matlab.ui.control.UIAxes
        ResultPanel                   matlab.ui.container.Panel
        GridLayout9                   matlab.ui.container.GridLayout
        TimestampEditFieldLabel       matlab.ui.control.Label
        ScanTimestamp                 matlab.ui.control.EditField
        FitWidthLabel                 matlab.ui.control.Label
        ScanWidth                     matlab.ui.control.NumericEditField
        FitCenterLabel                matlab.ui.control.Label
        ScanCenter                    matlab.ui.control.NumericEditField
        KurtosisLabel                 matlab.ui.control.Label
        ScanKurt                      matlab.ui.control.NumericEditField
        Label_5                       matlab.ui.control.Label
        ScanWidthError                matlab.ui.control.NumericEditField
        umLabel_3                     matlab.ui.control.Label
        umLabel_4                     matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        ScanCenterError               matlab.ui.control.NumericEditField
        SuccessLampLabel              matlab.ui.control.Label
        ScanSuccessLamp               matlab.ui.control.Lamp
        SkewnessLabel                 matlab.ui.control.Label
        ScanSkew                      matlab.ui.control.NumericEditField
        WireRangePanel                matlab.ui.container.Panel
        GridLayout10                  matlab.ui.container.GridLayout
        UnitsDropDownLabel            matlab.ui.control.Label
        UnitsDropDown                 matlab.ui.control.DropDown
        LowerlimitLabel               matlab.ui.control.Label
        UpperlimitLabel               matlab.ui.control.Label
        EditField                     matlab.ui.control.NumericEditField
        umLabel                       matlab.ui.control.Label
        EditField_2                   matlab.ui.control.NumericEditField
        umLabel_2                     matlab.ui.control.Label
        NptsLabel                     matlab.ui.control.Label
        PulsesEditField               matlab.ui.control.NumericEditField
        LogbookButton                 matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        aobj % F2_WirescanApp object
        UpdateObj % Object to notify of updated data
        UpdateMethod % Method to call when data is updated
        plane string
    end
    
    methods (Access = public)
        
        function RemoteStartScan(app)
            app.StartScanButtonPushed();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, LLM, iwire, dim)
            if exist('LLM','var') && ~isempty(LLM)
                if exist('iwire','var')
                    app.aobj = F2_WirescanApp(LLM,iwire,dim) ;
                else
                    app.aobj = F2_WirescanApp(LLM) ;
                end
            elseif exist('iwire','var')
                app.aobj = F2_WirescanApp([],iwire,dim) ;
            else
                app.aobj = F2_WirescanApp();
            end
            app.aobj.AttachGUI(app); % also updates app GUI fields
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.F2_WirescanUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {595, 595};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {281, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Menu selected function: EDMPanelMenu
        function EDMPanelMenuSelected(app, event)
            switch string(app.WIREDropDown.Value)
                case "IN10:561"
                    !edm -x -m "DEV=WIRE:IN10:561,MAD=WS10561,AREA=in10,RATEPV=EVNT:SYS1:1:INJECTRATE" /usr/local/facet/tools/edm/display/ws/wirescannerstart.edl &
                case "LI11:444"
                    !edm -x -m "DEV=WIRE:LI11:444,MAD=WS11444,AREA=li11,RATEPV=EVNT:SYS1:1:INJECTRATE" /usr/local/facet/tools/edm/display/ws/wirescannerstart.edl &
                case "LI11:614"
                    !edm -x -m "DEV=WIRE:LI11:614,MAD=WS11614,AREA=li11,RATEPV=EVNT:SYS1:1:INJECTRATE" /usr/local/facet/tools/edm/display/ws/wirescannerstart.edl &
                case "LI11:744"
                    !edm -x -m "DEV=WIRE:LI11:744,MAD=WS11744,AREA=li11,RATEPV=EVNT:SYS1:1:INJECTRATE" /usr/local/facet/tools/edm/display/ws/wirescannerstart.edl &
                case "LI12:214"
                    !edm -x -m "DEV=WIRE:LI12:214,MAD=WS12214,AREA=li12,RATEPV=EVNT:SYS1:1:INJECTRATE" /usr/local/facet/tools/edm/display/ws/wirescannerstart.edl &
                case "LI18:944"
                    !edm -x -m "DEV=WIRE:LI18:944,MAD=WS18944,AREA=li18,RATEPV=EVNT:SYS1:1:INJECTRATE" /usr/local/facet/tools/edm/display/ws/wirescannerstart.edl &
                case "LI19:144"
                    !edm -x -m "DEV=WIRE:LI19:144,MAD=WS19144,AREA=li19,RATEPV=EVNT:SYS1:1:INJECTRATE" /usr/local/facet/tools/edm/display/ws/wirescannerstart.edl &
                case "LI19:244"
                    !edm -x -m "DEV=WIRE:LI19:244,MAD=WS19244,AREA=li19,RATEPV=EVNT:SYS1:1:INJECTRATE" /usr/local/facet/tools/edm/display/ws/wirescannerstart.edl &
                case "LI19:344"
                    !edm -x -m "DEV=WIRE:LI19:344,MAD=WS19344,AREA=li19,RATEPV=EVNT:SYS1:1:INJECTRATE" /usr/local/facet/tools/edm/display/ws/wirescannerstart.edl &
                case "LI20:3179"
                    !edm -x -m "DEV=WIRE:LI20:3179,MAD=IPWS1,AREA=li20,RATEPV=EVNT:SYS1:1:INJECTRATE" /usr/local/facet/tools/edm/display/ws/wirescannerstart.edl &
                case "LI20:3206"
                    !edm -x -m "DEV=WIRE:LI20:3206,MAD=IPWS3,AREA=li20,RATEPV=EVNT:SYS1:1:INJECTRATE" /usr/local/facet/tools/edm/display/ws/wirescannerstart.edl &
      end
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
      [fn,pn]=uigetfile(F2C.datadir+"/F2_Wirescan*.mat", 'Pick WS data file');
      if isequal(fn,0)
        return
      end
      app.aobj.confload(fullfile(pn,fn));
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
      [fn,pn]=uiputfile(F2C.datadir, 'WS data file');
      if isequal(fn,0)
        return
      end
      app.aobj.confsave(fullfile(pn,fn));
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
      app.aobj.ResetData;
      cla(app.UIAxes); reset(app.UIAxes);
      selectedButton = app.ButtonGroup.SelectedObject;
      switch selectedButton
        case app.XButton
          app.aobj.plane="x";
          app.plane="x";
        case app.YButton
          app.aobj.plane="y";
          app.plane="y";
        case app.UButton
          app.aobj.plane="u";
          app.plane="u";
      end
      app.aobj.guiupdate;
        end

        % Value changed function: WIREDropDown
        function WIREDropDownValueChanged(app, event)
      app.aobj.ResetData;
      cla(app.UIAxes); reset(app.UIAxes);
      value = app.WIREDropDown.Value;
      app.aobj.wirename = string(value) ;
      app.aobj.guiupdate;
        end

        % Value changed function: PMTDropDown
        function PMTDropDownValueChanged(app, event)
      value = app.PMTDropDown.Value;
      app.aobj.pmtname = value ;
      app.aobj.guiupdate;
      app.aobj.ProcData;
        end

        % Button pushed function: StartScanButton
        function StartScanButtonPushed(app, event)
            app.ScanSuccessLamp.Color='r';
            app.aobj.ResetData;
            cla(app.UIAxes); reset(app.UIAxes); axis(app.UIAxes,'off');
            rectangle(app.UIAxes,'Position',[0,0.4,0/100,0.2],'facecolor','g');axis(app.UIAxes,[0 1 0 1]);
            title(app.UIAxes,'Scan Progress...');
            text(app.UIAxes,max([0 0/100-0.1]),0.5,sprintf('%.1f %%',0));
            drawnow
            try
                app.aobj.StartScan ;
            catch ME
                errordlg("Scan failed - see xterm window","Scan Failed");
                eDefRelease(app.aobj.edef);
                throw(ME);
            end
        end

        % Button pushed function: AbortScanButton
        function AbortScanButtonPushed(app, event)
      app.aobj.AbortScan=true ;
        end

        % Value changed function: JitterCorrectionCheckBox
        function JitterCorrectionCheckBoxValueChanged(app, event)
      value = app.JitterCorrectionCheckBox.Value;
      app.aobj.jittercor = value;
      app.aobj.guiupdate ;
      app.aobj.ProcData;
        end

        % Value changed function: BPM1DropDown
        function BPM1DropDownValueChanged(app, event)
      app.aobj.ResetData;
      cla(app.UIAxes); reset(app.UIAxes);
      value = "BPMS:"+app.BPM1DropDown.Value;
      isel = find(app.aobj.bpms==value);
      if ~isempty(isel)
        app.aobj.bpmsel(1)=isel;
      end
      app.aobj.guiupdate;
        end

        % Value changed function: ChargeNormalizationCheckBox
        function ChargeNormalizationCheckBoxValueChanged(app, event)
      value = app.ChargeNormalizationCheckBox.Value;
      app.aobj.chargenorm = value ;
      app.aobj.guiupdate;
      app.aobj.ProcData;
        end

        % Value changed function: TORODropDown
        function TORODropDownValueChanged(app, event)
      value = app.TORODropDown.Value;
      app.aobj.toroname = value ;
      app.aobj.guiupdate;
      app.aobj.ProcData;
        end

        % Value changed function: BunchLengthWindowingCheckBox
        function BunchLengthWindowingCheckBoxValueChanged(app, event)
      value = app.BunchLengthWindowingCheckBox.Value;
      app.aobj.blenwin = value ;
      app.aobj.guiupdate ;
      app.aobj.ProcData;
        end

        % Value changed function: BLENDropDown
        function BLENDropDownValueChanged(app, event)
      value = app.BLENDropDown.Value;
      app.aobj.blmname = value ;
      app.aobj.guiupdate;
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
      val1 = app.EditField_3.Value;
      val2 = app.EditField_4.Value;
      app.aobj.blenwin=[val1 val2];
      app.aobj.guiupdate;
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
      val1 = app.EditField_3.Value;
      val2 = app.EditField_4.Value;
      app.aobj.blenwin=[val1 val2];
      app.aobj.guiupdate;
        end

        % Value changed function: UnitsDropDown
        function UnitsDropDownValueChanged(app, event)
      app.aobj.guiupdate;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
      val1 = app.EditField.Value;
      val2 = app.EditField_2.Value;
      switch app.UnitsDropDown.Value
        case "Position"
          app.aobj.pos_range=[val1 val2].*1e-6;
        otherwise
          app.aobj.motor_range=[val1 val2].*1e-6;
      end
      app.aobj.guiupdate;
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
      val1 = app.EditField.Value;
      val2 = app.EditField_2.Value;
      switch app.UnitsDropDown.Value
        case "Position"
          app.aobj.pos_range=[val1 val2].*1e-6;
        otherwise
          app.aobj.motor_range=[val1 val2].*1e-6;
      end
      app.aobj.guiupdate;
        end

        % Value changed function: PulsesEditField
        function PulsesEditFieldValueChanged(app, event)
      value = app.PulsesEditField.Value;
      app.aobj.npulses = value ;
      app.aobj.guiupdate;
        end

        % Button pushed function: LogbookButton
        function LogbookButtonPushed(app, event)
            fh=figure;
            ahan=axes(fh);
            app.aobj.ProcData(ahan);
            
            ts_str = sprintf('scan completed at: %s', app.ScanTimestamp.Value);
            fit_str = sprintf('fit method: %s', app.aobj.fitmethod);
            ws_msmt_1 = sprintf('  sig%s = %.3f  +/- %.2f um\n  %s = %.3f +/- %.2f um', ...
                app.aobj.plane, app.ScanWidth.Value, app.ScanWidthError.Value, ...
                app.aobj.plane, app.ScanCenter.Value, app.ScanCenterError.Value);
            ws_msmt_2 = sprintf('  skew = %.3f \t kurt = %.3f\t', app.ScanSkew.Value, app.ScanKurt.Value);
            log_msg = sprintf('%s\n%s\n%s\n%s\n', ts_str, fit_str, ws_msmt_1, ws_msmt_2);
            
            util_printLog2020(fh, 'title',sprintf('%s Wire Scan (%c)',char(app.aobj.wirename),char(app.aobj.plane)),'author','F2_Wirescan.m','text',log_msg);
            delete(fh);
        end

        % Value changed function: FitMethodDropDown
        function FitMethodDropDownValueChanged(app, event)
      value = app.FitMethodDropDown.Value;
      switch string(value)
        case "Gaussian"
          app.aobj.fitmethod="gauss";
        case "Asymm Gaussian"
          app.aobj.fitmethod="agauss";
        case "Asymm Gaussian (2)"
          app.aobj.fitmethod="agauss2";
      end
      app.aobj.ProcData;
        end

        % Value changed function: BPM2DropDown
        function BPM2DropDownValueChanged(app, event)
      app.aobj.ResetData;
      cla(app.UIAxes); reset(app.UIAxes);
      value = "BPMS:"+app.BPM2DropDown.Value;
      isel = find(app.aobj.bpms==value);
      if ~isempty(isel)
        app.aobj.bpmsel(2)=isel;
      end
      app.aobj.guiupdate;
        end

        % Close request function: F2_WirescanUIFigure
        function F2_WirescanUIFigureCloseRequest(app, event)
      delete(app)
      
        end

        % Value changed function: WirediameterEditField
        function WirediameterEditFieldValueChanged(app, event)
      value = app.WirediameterEditField.Value;
      app.aobj.WireDiam = value ;
      app.aobj.guiupdate;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.F2_WirescanUIFigure = uifigure('Visible', 'off');
            app.F2_WirescanUIFigure.Name = 'F2_Wirescan';
            app.F2_WirescanUIFigure.CloseRequestFcn = createCallbackFcn(app, @F2_WirescanUIFigureCloseRequest, true);
            app.F2_WirescanUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.DataMenu = uimenu(app.F2_WirescanUIFigure);
            app.DataMenu.Text = 'Data';
            app.LoadMenu = uimenu(app.DataMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load...';
            app.SaveAsMenu = uimenu(app.DataMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'Save As...';
            app.ExpertMenu = uimenu(app.F2_WirescanUIFigure);
            app.ExpertMenu.Text = 'Expert';
            app.EDMPanelMenu = uimenu(app.ExpertMenu);
            app.EDMPanelMenu.MenuSelectedFcn = createCallbackFcn(app, @EDMPanelMenuSelected, true);
            app.EDMPanelMenu.Text = 'EDM Panel...';
            app.GridLayout = uigridlayout(app.F2_WirescanUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MeasurementPanel = uipanel(app.LeftPanel);
            app.MeasurementPanel.Title = 'Measurement';
            app.GridLayout5 = uigridlayout(app.MeasurementPanel);
            app.StartScanButton = uibutton(app.GridLayout5, 'push');
            app.StartScanButton.ButtonPushedFcn = createCallbackFcn(app, @StartScanButtonPushed, true);
            app.StartScanButton.Text = 'Start Scan';
            app.AbortScanButton = uibutton(app.GridLayout5, 'push');
            app.AbortScanButton.ButtonPushedFcn = createCallbackFcn(app, @AbortScanButtonPushed, true);
            app.AbortScanButton.Text = 'Abort Scan';
            app.ButtonGroup = uibuttongroup(app.GridLayout5);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.XButton = uiradiobutton(app.ButtonGroup);
            app.XButton.Text = 'X';
            app.XButton.Value = true;
            app.YButton = uiradiobutton(app.ButtonGroup);
            app.YButton.Text = 'Y';
            app.UButton = uiradiobutton(app.ButtonGroup);
            app.UButton.Text = 'U';
            app.WIREDropDownLabel = uilabel(app.GridLayout5);
            app.WIREDropDownLabel.Text = 'WIRE:';
            app.WIREDropDown = uidropdown(app.GridLayout5);
            app.WIREDropDown.Items = {'IN10:561', 'LI11:444', 'LI11:614', 'LI11:744', 'LI12:214', 'LI18:944', 'LI19:144', 'LI19:244', 'LI19:344'};
            app.WIREDropDown.ValueChangedFcn = createCallbackFcn(app, @WIREDropDownValueChanged, true);
            app.WIREDropDown.Value = 'IN10:561';
            app.PMTDropDownLabel = uilabel(app.GridLayout5);
            app.PMTDropDownLabel.Text = 'PMT:';
            app.PMTDropDown = uidropdown(app.GridLayout5);
            app.PMTDropDown.Items = {'LI19:144'};
            app.PMTDropDown.ValueChangedFcn = createCallbackFcn(app, @PMTDropDownValueChanged, true);
            app.PMTDropDown.Value = 'LI19:144';
            app.ProcessingPanel = uipanel(app.LeftPanel);
            app.ProcessingPanel.Title = 'Processing';
            app.GridLayout3 = uigridlayout(app.ProcessingPanel);
            app.FitMethodDropDownLabel = uilabel(app.GridLayout3);
            app.FitMethodDropDownLabel.Text = 'Fit Method:';
            app.FitMethodDropDown = uidropdown(app.GridLayout3);
            app.FitMethodDropDown.Items = {'Gaussian', 'Asymm Gaussian', 'Asymm Gaussian (2)'};
            app.FitMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @FitMethodDropDownValueChanged, true);
            app.FitMethodDropDown.Value = 'Asymm Gaussian (2)';
            app.WirediameterEditFieldLabel = uilabel(app.GridLayout3);
            app.WirediameterEditFieldLabel.Text = 'Wire diameter:';
            app.WirediameterEditField = uieditfield(app.GridLayout3, 'numeric');
            app.WirediameterEditField.ValueChangedFcn = createCallbackFcn(app, @WirediameterEditFieldValueChanged, true);
            app.WirediameterEditField.Value = 60;
            app.umLabel_5 = uilabel(app.GridLayout3);
            app.umLabel_5.Text = 'um';
            app.chargeNormPanel = uipanel(app.GridLayout3);
            app.GridLayout6 = uigridlayout(app.chargeNormPanel);
            app.ChargeNormalizationCheckBox = uicheckbox(app.GridLayout6);
            app.ChargeNormalizationCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChargeNormalizationCheckBoxValueChanged, true);
            app.ChargeNormalizationCheckBox.Text = 'Charge Normalization';
            app.TOROLabel = uilabel(app.GridLayout6);
            app.TOROLabel.Text = 'TORO:';
            app.TORODropDown = uidropdown(app.GridLayout6);
            app.TORODropDown.Items = {'IN10:431', 'IN10:591', 'LI11:360', 'LI14:890', 'LI20:1988', 'LI20:2040', 'LI20:2452', 'LI20:3163', 'LI20:3255'};
            app.TORODropDown.ValueChangedFcn = createCallbackFcn(app, @TORODropDownValueChanged, true);
            app.TORODropDown.Value = 'IN10:431';
            app.jitterCorPanel = uipanel(app.GridLayout3);
            app.GridLayout7 = uigridlayout(app.jitterCorPanel);
            app.BPM1DropDown = uidropdown(app.GridLayout7);
            app.BPM1DropDown.Items = {'---'};
            app.BPM1DropDown.ValueChangedFcn = createCallbackFcn(app, @BPM1DropDownValueChanged, true);
            app.BPM1DropDown.Value = '---';
            app.BPM2DropDown = uidropdown(app.GridLayout7);
            app.BPM2DropDown.Items = {'---'};
            app.BPM2DropDown.ValueChangedFcn = createCallbackFcn(app, @BPM2DropDownValueChanged, true);
            app.BPM2DropDown.Value = '---';
            app.BPM1Label = uilabel(app.GridLayout7);
            app.BPM1Label.Text = 'BPM1:';
            app.BPM2Label = uilabel(app.GridLayout7);
            app.BPM2Label.Text = 'BPM2:';
            app.JitterCorrectionCheckBox = uicheckbox(app.GridLayout7);
            app.JitterCorrectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @JitterCorrectionCheckBoxValueChanged, true);
            app.JitterCorrectionCheckBox.Text = 'Jitter Correction';
            app.blenWinPanel = uipanel(app.GridLayout3);
            app.GridLayout8 = uigridlayout(app.blenWinPanel);
            app.BLENDropDown = uidropdown(app.GridLayout8);
            app.BLENDropDown.Items = {'---'};
            app.BLENDropDown.ValueChangedFcn = createCallbackFcn(app, @BLENDropDownValueChanged, true);
            app.BLENDropDown.Value = '---';
            app.BLENLabel = uilabel(app.GridLayout8);
            app.BLENLabel.Text = 'BLEN:';
            app.EditField_3 = uieditfield(app.GridLayout8, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.Label = uilabel(app.GridLayout8);
            app.Label.Text = '---';
            app.EditField_4 = uieditfield(app.GridLayout8, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.BunchLengthWindowingCheckBox = uicheckbox(app.GridLayout8);
            app.BunchLengthWindowingCheckBox.ValueChangedFcn = createCallbackFcn(app, @BunchLengthWindowingCheckBoxValueChanged, true);
            app.BunchLengthWindowingCheckBox.Enable = 'off';
            app.BunchLengthWindowingCheckBox.Text = 'Bunch Length Windowing';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ResultPanel = uipanel(app.GridLayout2);
            app.ResultPanel.Title = 'Result';
            app.GridLayout9 = uigridlayout(app.ResultPanel);
            app.TimestampEditFieldLabel = uilabel(app.GridLayout9);
            app.TimestampEditFieldLabel.Text = 'Timestamp';
            app.ScanTimestamp = uieditfield(app.GridLayout9, 'text');
            app.ScanTimestamp.Editable = 'off';
            app.ScanTimestamp.Value = 'YYYY-MM-DD hh:mm:ss';
            app.FitWidthLabel = uilabel(app.GridLayout9);
            app.FitWidthLabel.Text = 'Fit Width:';
            app.ScanWidth = uieditfield(app.GridLayout9, 'numeric');
            app.ScanWidth.ValueDisplayFormat = '%.1f';
            app.ScanWidth.Editable = 'off';
            app.FitCenterLabel = uilabel(app.GridLayout9);
            app.FitCenterLabel.Text = 'Fit Center:';
            app.ScanCenter = uieditfield(app.GridLayout9, 'numeric');
            app.ScanCenter.ValueDisplayFormat = '%.1f';
            app.ScanCenter.Editable = 'off';
            app.KurtosisLabel = uilabel(app.GridLayout9);
            app.KurtosisLabel.Text = 'Kurtosis';
            app.ScanKurt = uieditfield(app.GridLayout9, 'numeric');
            app.ScanKurt.Editable = 'off';
            app.Label_5 = uilabel(app.GridLayout9);
            app.Label_5.Text = '+/-';
            app.ScanWidthError = uieditfield(app.GridLayout9, 'numeric');
            app.ScanWidthError.ValueDisplayFormat = '%.1f';
            app.ScanWidthError.Editable = 'off';
            app.umLabel_3 = uilabel(app.GridLayout9);
            app.umLabel_3.Text = 'um';
            app.umLabel_4 = uilabel(app.GridLayout9);
            app.umLabel_4.Text = 'um';
            app.Label_6 = uilabel(app.GridLayout9);
            app.Label_6.Text = '+/-';
            app.ScanCenterError = uieditfield(app.GridLayout9, 'numeric');
            app.ScanCenterError.ValueDisplayFormat = '%.1f';
            app.ScanCenterError.Editable = 'off';
            app.SuccessLampLabel = uilabel(app.GridLayout9);
            app.SuccessLampLabel.Text = 'Success';
            app.ScanSuccessLamp = uilamp(app.GridLayout9);
            app.SkewnessLabel = uilabel(app.GridLayout9);
            app.SkewnessLabel.Text = 'Skewness:';
            app.ScanSkew = uieditfield(app.GridLayout9, 'numeric');
            app.ScanSkew.Editable = 'off';
            app.WireRangePanel = uipanel(app.GridLayout2);
            app.WireRangePanel.Title = 'Wire Range';
            app.GridLayout10 = uigridlayout(app.WireRangePanel);
            app.UnitsDropDownLabel = uilabel(app.GridLayout10);
            app.UnitsDropDownLabel.Text = 'Units:';
            app.UnitsDropDown = uidropdown(app.GridLayout10);
            app.UnitsDropDown.Items = {'Motor', 'Position'};
            app.UnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitsDropDownValueChanged, true);
            app.UnitsDropDown.Value = 'Position';
            app.LowerlimitLabel = uilabel(app.GridLayout10);
            app.LowerlimitLabel.Text = 'Lower limit:';
            app.UpperlimitLabel = uilabel(app.GridLayout10);
            app.UpperlimitLabel.Text = 'Upper limit:';
            app.EditField = uieditfield(app.GridLayout10, 'numeric');
            app.EditField.ValueDisplayFormat = '%.0f';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.umLabel = uilabel(app.GridLayout10);
            app.umLabel.Text = 'um';
            app.EditField_2 = uieditfield(app.GridLayout10, 'numeric');
            app.EditField_2.ValueDisplayFormat = '%.0f';
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.umLabel_2 = uilabel(app.GridLayout10);
            app.umLabel_2.Text = 'um';
            app.NptsLabel = uilabel(app.GridLayout10);
            app.NptsLabel.Text = 'N pts:';
            app.PulsesEditField = uieditfield(app.GridLayout10, 'numeric');
            app.PulsesEditField.ValueChangedFcn = createCallbackFcn(app, @PulsesEditFieldValueChanged, true);
            app.PulsesEditField.Value = 100;
            app.LogbookButton = uibutton(app.GridLayout2, 'push');
            app.LogbookButton.ButtonPushedFcn = createCallbackFcn(app, @LogbookButtonPushed, true);
            app.LogbookButton.Icon = 'logbook.gif';
            app.LogbookButton.Text = 'Logbook';
            % Show the figure after all components are created
            app.F2_WirescanUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = F2_Wirescan(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.F2_WirescanUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.F2_WirescanUIFigure)
        end
    end
end