classdef F2_phasing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FACETIIRFPhaseScansUIFigure  matlab.ui.Figure
        banner                       matlab.ui.control.Label
        ax                           matlab.ui.control.UIAxes
        mainControlPanel             matlab.ui.container.Panel
        layoutControl                matlab.ui.container.GridLayout
        scanButton                   matlab.ui.control.Button
        abortButton                  matlab.ui.control.Button
        undoButton                   matlab.ui.control.Button
        applyButton                  matlab.ui.control.Button
        logbookButton                matlab.ui.control.Button
        helpButton                   matlab.ui.control.Button
        ScanConfigurationPanel       matlab.ui.container.Panel
        layoutConfig                 matlab.ui.container.GridLayout
        NstepsEditFieldLabel         matlab.ui.control.Label
        editNSteps                   matlab.ui.control.NumericEditField
        rangeEditFieldLabel          matlab.ui.control.Label
        editRange                    matlab.ui.control.NumericEditField
        KlysoffsetLabel              matlab.ui.control.Label
        editOffset                   matlab.ui.control.NumericEditField
        NsamplesEditFieldLabel       matlab.ui.control.Label
        editNSamples                 matlab.ui.control.NumericEditField
        selectZigzag                 matlab.ui.control.CheckBox
        SBSToffsetLabel              matlab.ui.control.Label
        editSBOffset                 matlab.ui.control.NumericEditField
        selectSim                    matlab.ui.control.CheckBox
        resultPanel                  matlab.ui.container.Panel
        layoutResult                 matlab.ui.container.GridLayout
        ControlPhaseLabel            matlab.ui.control.Label
        initPDES                     matlab.ui.control.NumericEditField
        ReadbackPhaseLabel           matlab.ui.control.Label
        initPACT                     matlab.ui.control.NumericEditField
        PhaseOffsetControlLabel      matlab.ui.control.Label
        initPOC                      matlab.ui.control.NumericEditField
        finalPDES                    matlab.ui.control.NumericEditField
        finalPACT                    matlab.ui.control.NumericEditField
        finalPOC                     matlab.ui.control.NumericEditField
        NewLabel                     matlab.ui.control.Label
        CurrentLabel                 matlab.ui.control.Label
        currentPDES                  matlab.ui.control.NumericEditField
        currentPACT                  matlab.ui.control.NumericEditField
        currentPOC                   matlab.ui.control.NumericEditField
        updateCurrentButton          matlab.ui.control.Button
        InitialLabel                 matlab.ui.control.Label
        Panel                        matlab.ui.container.Panel
        selectLinac                  matlab.ui.control.DropDown
        KlystronLabel                matlab.ui.control.Label
        selectSector                 matlab.ui.control.DropDown
        selectKlys                   matlab.ui.control.DropDown
        klysActiveLamp               matlab.ui.control.Lamp
        EEditFieldLabel              matlab.ui.control.Label
        beamEnergy                   matlab.ui.control.NumericEditField
        MeVLabel                     matlab.ui.control.Label
        QEditFieldLabel              matlab.ui.control.Label
        bunchCharge                  matlab.ui.control.NumericEditField
        pCLabel                      matlab.ui.control.Label
        specBPM                      matlab.ui.control.EditField
        EditFieldLabel               matlab.ui.control.Label
        bpmDispersion                matlab.ui.control.NumericEditField
        mmLabel                      matlab.ui.control.Label
        fEditFieldLabel              matlab.ui.control.Label
        beamRate                     matlab.ui.control.NumericEditField
        HzLabel                      matlab.ui.control.Label
        BPMLabel                     matlab.ui.control.Label
        Panel_2                      matlab.ui.container.Panel
        message                      matlab.ui.control.Label
        StatusLabel                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        linac_map % array mask mapping sector,klys pairs to linac numbers
        target % struct with current linac/sector/klys params
        
        S % holds F2_phasescan obj
        
        % flags for catching abort rquests
        ABORT_REQUEST = false;  
        SCAN_ABORTED = false;
        
        % flag for determining if a scan if finished
        SCAN_READY = false;
        successful_scan = false;
    end
    
    methods (Access = private)
        
        % disables all interactive components, called during callback
        % execution in hopes of protecting user from self
        function enable_controls(app, state)
            app.scanButton.Enable = state;
            app.applyButton.Enable = state;
            app.abortButton.Enable = state;
            app.undoButton.Enable = state;
            app.helpButton.Enable = state;
            app.logbookButton.Enable = state;
            app.selectLinac.Enable = state;
            app.selectSector.Enable = state;
            app.selectKlys.Enable = state;
            app.editRange.Enable = state;
            app.editNSteps.Enable = state;
            app.editNSamples.Enable = state;
            app.selectZigzag.Enable = state;
            app.selectSim.Enable = state;
            app.updateCurrentButton.Enable = state;
        end
 
        % associate each linac with an int labelling its linac region
        % L0: 10-3, 10-4
        % L1: 11-1, 11-2
        % L2: 11-4 - 14-8
        % L3: 15-1 - 19-6
        function construct_klys_map(app)
            % literal indexing wastes ~11*8 elements, but who cares lol
            %app.linac_map = zeros(20, 8);
            app.linac_map = NaN(20,8);
            app.linac_map(10,3:4) = 0;
            app.linac_map(11,1:2) = 1;
            app.linac_map(11,4:8) = 2;
            app.linac_map(12:14,:) = 2;
            app.linac_map(15:19,:) = 3;
            
            % 14-7, 15-2, and 19-7,8 do not exist, set back to NaN
            app.linac_map(14,7) = NaN;
            app.linac_map(15,2) = NaN;
            app.linac_map(19,7:8) = NaN;
        end

        % get klystron status to determine if station is on beam
        function update_klys_stat(app)
            app.klysActiveLamp.Color = [0.3 0.3 0.3];
            
            [act, ~,~,~,~,~] = control_klysStatGet(app.target.klys_str, 10);
            % TO DO: replace with manual inspection of HSTA (?)

            % enable scan button and set active light green for stations on
            % beam, otherwise leave scan button disabled and set the light
            % to black or red if the station is off-beam or MNT/TBR/ARU
            lamp_color = [0.3 0.3 0.3];
            app.SCAN_READY = false;
            
            if bitget(act, 1)
                lamp_color = [0.0 1.0 0.0];
                msg = 'Ready to scan %s.';
                app.SCAN_READY = true;
            elseif bitget(act, 2)
                msg = '%s is not triggering on accelerate time.';
            elseif bitget(act, 3)
                lamp_color = [1.0 0.0 0.0];
                msg = '%s is offline (OFF/MNT/TBR/ARU).';
            end
            
            app.klysActiveLamp.Color = lamp_color;
            app.message.Text = sprintf(msg, app.target.klys_str);
        end
        
        % get current klystron PDES, PHAS, GOLD & KPHR
        function update_klys_phase_params(app, initial)
            [PDES, PACT, offset] = app.S.get_phase_settings();
            if initial
                app.initPDES.Value = PDES;
                app.initPACT.Value = PACT;
                app.initPOC.Value = offset;
            else
                app.currentPDES.Value = PDES;
                app.currentPACT.Value = PACT;
                app.currentPOC.Value = offset;
            end
        end
        
        function label_phase_settings(app)
            % label phase setting tooltips
            [s_ctl, s_rbv, s_offs] = app.S.get_phase_setting_desc();
            app.ControlPhaseLabel.Tooltip = {s_ctl};
            app.ReadbackPhaseLabel.Tooltip = {s_rbv};
            app.PhaseOffsetControlLabel.Tooltip = {s_offs};
        end
        
        % helper to update chrge, rate energy in one line
        function update_operating_point(app)
            app.update_klys_phase_params(true);
            app.update_klys_phase_params(false);
            app.bunchCharge.Value   = app.S.beam.Q;
            app.beamRate.Value      = app.S.beam.f;
            app.beamEnergy.Value    = app.S.beam.E_design;
            app.specBPM.Value       = app.S.BPM;
            app.bpmDispersion.Value = app.S.eta;
        end
        
        % populate app.S.in struct from GUI
        function get_scan_inputs(app)
            app.S.in.dPhi = app.editRange.Value;
            app.S.in.N_steps = app.editNSteps.Value;
            app.S.in.N_samples = app.editNSamples.Value;
            app.S.in.zigzag = app.selectZigzag.Value;
            app.S.in.simulation = app.selectSim.Value;            
            app.S.compute_scan_range();
            app.S.init_msmt();
        end
        
        % generate final phase scan plot for logbook
        function fig = make_plot(app)
            fig = figure('position',[500,500,800,600]);
            axis = axes;
            axis.FontSize = 12;
            % hold(axis, "on");
            app.S.plot_phase_scan(axis, true);
            shg;
        end
        
        % main phase scan code for L2, L3 (SLC) klystrons
        function phase_scan(app)
            
            app.successful_scan = false;
            
            % verify that TD11 is in if scanning upstream of L2
            if app.target.linac < 2
                TD11_in = strcmp(lcaGetSmart('DUMP:LI11:390:TGT_STS'), 'IN');
                if ~TD11_in
                    app.message.Text = 'TD-11 is not inserted. Scan aborted.';
                    return
                end
            end
            
            % disable relevant longitudinal feedbacks before scanning
            % for L0, also turn off LLRF slow feedbacks
            app.S.disable_feedbacks()
            if app.target.linac == 0, app.S.set_L0_LLRF_phase_feedback(0); end
            
            % for L1, manually set SSSB on crest & fix energy
            if app.target.linac == 1
                app.S.set_L1_chirp(0.0);
                pause(1);
                app.S.L1_energy_correction();
            end
            
            hold(app.ax,"off");
            app.S.label_plot(app.ax);

            % main scan loop: set phase, take BPM data & plot
            for i = 1:app.S.in.N_steps
                app.S.I_STEP = i;
                
                step_str = sprintf('[%d/%d]', i, app.S.in.N_steps);
                
                if app.ABORT_REQUEST, app.SCAN_ABORTED = true; break; end

                app.message.Text = sprintf('%s Setting %s phase = %.1f deg ...', ...
                    step_str, app.target.klys_str, app.S.in.range(i) ...
                    );
                app.S.step_phase()
                
                if app.ABORT_REQUEST, app.SCAN_ABORTED = true; break; end
                
                app.message.Text = sprintf('%s Collecting BPM data ...', step_str);
                app.S.position_energy_msmt();
                
                if app.ABORT_REQUEST, app.SCAN_ABORTED = true; break; end
                
                app.S.plot_phase_scan(app.ax, false);
                
            end
            
            % quit here if the scan was aborted
            if app.SCAN_ABORTED, return; end
            
            % re-enable L0 LLRF phase FB
            if app.target.linac == 0, app.S.set_L0_LLRF_phase_feedback(1); end
            
            % (3) fit BPM data and calculate beam phase error and energy
            app.S.beam_phase_fit();
            
            app.successful_scan = true;
            app.S.end_time = datetime('now');
            app.S.end_time.Format = 'dd-MMM-uuuu HH:mm:ss';
            %fprintf('phi set  = %.1f deg\n', app.S.in.phi_set);
            %fprintf('phi act  = %.2f deg\n', err);
            %fprintf('phi meas = %.2f deg\n', app.S.fit.phi_meas);
            %fprintf('est. dE  = %.2f MeV\n', app.S.fit.E0);
            %fprintf('\n');
            %disp(app.S.fit);
            
            % (4) plot fit and scan result
            app.S.plot_phase_scan(app.ax, true);
            
            app.message.Text = 'Scan completed. Press "Apply" to correct phase error.';
            
            % (5) restore initial phase setting
            if ~app.S.in.simulation, app.S.reset_phase(); end
            
        end
 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.target = struct;
            
            app.target.linac = 1;
            app.target.sector = 11;
            app.target.klys = 1;
            app.target.klys_str = '11-1';
            
            % initialize a scan object on startup so things behave normally
            % TO DO: is this when to prompt user before old scan deletion ??
            app.S = F2_phasescan(app.target.linac, app.target.sector, app.target.klys);
            
            app.construct_klys_map();
            app.update_klys_stat();
            app.update_operating_point();
            app.label_phase_settings();
            app.get_scan_inputs();
            app.editOffset.Value = -1 * app.S.in.phi_set;
            
            % re-label plot axes
            app.S.label_plot(app.ax);
            
            disableDefaultInteractivity(app.ax);
        end

        % Value changed function: selectLinac
        function selectLinacValueChanged(app, event)
            app.target.linac = uint8(str2double(app.selectLinac.Value(end)));
        
            % update list of sectors, default target: 1st sector
            [~,s] = find(app.linac_map' == app.target.linac);
            app.selectSector.Items = string(unique(s));
            app.selectSector.Value = string(s(1));
            app.selectSectorValueChanged(event);
            
            % modify default scan params for L1, disable gold/offset box
            if app.target.linac == 0
                app.editRange.Value = 20;
                app.editNSteps.Value = 15;
            elseif app.target.linac == 1
                app.editRange.Value = 20;
                app.editNSteps.Value = 15;
            else
                app.editRange.Value = 60;
                app.editNSteps.Value = 9;
            end
        end

        % Value changed function: selectSector
        function selectSectorValueChanged(app, event)
            app.target.sector = uint8(str2double(app.selectSector.Value));
            
            % update klys list, default target: 1st klys
            [k,s] = find(app.linac_map' == app.target.linac);
            available_klys = [];
            for i = 1:size(k)
                if s(i) == app.target.sector, available_klys(end+1) = k(i); end
            end
            app.selectKlys.Items = string(available_klys);
            app.selectKlys.Value = string(available_klys(1));
            app.selectKlysValueChanged(event);
            
            % for L2-3 subbooster PDES, populate sbst offset accordingly
            app.editSBOffset.Value = 0;
            app.editSBOffset.Enable = false;
            app.SBSToffsetLabel.Enable = false;
            if app.target.linac >= 2 
                app.editSBOffset.Enable = true;
                app.SBSToffsetLabel.Enable = true;
                app.editSBOffset.Value = app.S.in.sbst_offset;
            end
            
        end

        % Value changed function: selectKlys
        function selectKlysValueChanged(app, event)
            app.enable_controls(false); 
            app.message.Text = 'Checking klystron status ...'; drawnow;

            app.target.klys = uint8(str2double(app.selectKlys.Value));
            app.target.klys_str = sprintf('%d-%d',app.target.sector,app.target.klys);
            app.scanButton.Text = sprintf('Scan %s', app.target.klys_str);
            
            % once user selects a klystron check if it is on beam time
            % and grab it's current phase settings
            app.update_klys_stat();
            app.update_klys_phase_params(true);
 
            % initialize scan object in case there isn't one already held
            % TO DO: is this when to prompt user before old scan deletion ??
            app.S = F2_phasescan(app.target.linac, app.target.sector, app.target.klys);
            
            app.editOffset.Value = app.S.in.klys_offset;
            
            % label phase setting tooltips
            [s_ctl, s_rbv, s_offs] = app.S.get_phase_setting_desc();
            app.ControlPhaseLabel.Tooltip = {s_ctl};
            app.ReadbackPhaseLabel.Tooltip = {s_rbv};
            app.PhaseOffsetControlLabel.Tooltip = {s_offs};
            
            app.update_operating_point();
            
            app.S.label_plot(app.ax);
            
            app.enable_controls(true);
            
            % disable undo/gold/logbook buttons
            app.scanButton.Enable = app.SCAN_READY;
            app.abortButton.Enable = false;
            app.undoButton.Enable = false;
            app.applyButton.Enable = false;
            app.logbookButton.Enable = false;
            
        end

        % Button pushed function: scanButton
        function scanButtonPushed(app, event)
            app.enable_controls(false); drawnow;
            app.abortButton.Enable = true;
            app.helpButton.Enable = true;
            app.scanButton.Text = 'Scanning...';

            app.S = F2_phasescan(app.target.linac, app.target.sector, app.target.klys);
            app.get_scan_inputs();
            
            % run the scan!
            app.phase_scan();

            % if the scan was aborted revert phase settings
            if app.SCAN_ABORTED
                app.message.Text = 'Restoring initial phase settings ...';
                app.S.revert_phase_settings();
                app.update_klys_phase_params(false);
                app.ABORT_REQUEST = false;
                app.SCAN_ABORTED = false;
                app.message.Text = sprintf('%s scan aborted.', app.target.klys_str);
            end
            
            % write proposed phase settings to "new" boxes
            if app.successful_scan
                app.finalPDES.Value = app.S.out.PDES;
                app.finalPACT.Value = app.S.out.PACT;
                app.finalPOC.Value = app.S.out.POC;
            end
            
            app.enable_controls(true);
            app.scanButton.Enable = true;
            app.scanButton.Text = sprintf('Scan %s', app.target.klys_str);
            app.abortButton.Enable = false;
            app.undoButton.Enable = true;
            app.applyButton.Enable = app.successful_scan;
            app.logbookButton.Enable = app.successful_scan;
        end

        % Button pushed function: applyButton
        function applyButtonPushed(app, event)
            app.message.Text = 'Applying phase correction ...';
            app.enable_controls(false);
            if abs(app.S.fit.phi_meas) > 20.0
                resp = questdlg('Large (>20deg) phase change. Set measured zero phase?');
                if ~strcmp(resp, 'Yes')
                    app.message.Text = 'Phase correction declined.';
                    app.enable_controls(true);
                    app.abortButton.Enable = false;
                    return
                end
            end
            
            app.S.apply_phase_correction();
            
            app.update_klys_phase_params(false);
            app.enable_controls(true);
            app.abortButton.Enable = false;
            app.message.Text = 'Phase correction applied.';
        end

        % Button pushed function: logbookButton
        function logbookButtonPushed(app, event)
            fig = app.make_plot();
            opts.title = sprintf('Phase Scan: K%s', app.target.klys_str);
            opts.author = 'FACET-II Phase Scan GUI';
            util_printLog(fig, opts);
            app.message.Text = 'Sent to fphysics elog.';
        end

        % Button pushed function: abortButton
        function abortButtonPushed(app, event)
            app.ABORT_REQUEST = true;
            app.message.Text = 'SCAN ABORT REQUESTED. Waiting for interrupt...';
        end

        % Button pushed function: undoButton
        function undoButtonPushed(app, event)
            app.message.Text = 'Restoring initial phase settings ...';
            app.S.revert_phase_settings();
            app.update_klys_phase_params(false);
            app.message.Text = sprintf('%s phase settings restored.', app.target.klys_str);
        end

        % Button pushed function: helpButton
        function helpButtonPushed(app, event)
            prev_msg = app.message.Text;
            app.message.Text = 'Opening MCC wiki ...';
            system("firefox https://aosd.slac.stanford.edu/wiki/index.php/FACET-II_Phase_Scan_GUI &");
            pause(3)
            app.message.Text = prev_msg;
        end

        % Callback function
        function updateInitButtonPushed(app, event)
            app.update_klys_phase_params(true);
        end

        % Button pushed function: updateCurrentButton
        function updateCurrentButtonPushed(app, event)
            app.update_klys_phase_params(false);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FACETIIRFPhaseScansUIFigure = uifigure('Visible', 'off');
            app.FACETIIRFPhaseScansUIFigure.Name = 'FACET-II RF Phase Scans';
            app.banner = uilabel(app.FACETIIRFPhaseScansUIFigure);
            app.banner.Text = {''; ''};
            app.ax = uiaxes(app.FACETIIRFPhaseScansUIFigure);
            title(app.ax, 'Title')
            xlabel(app.ax, '\phi')
            ylabel(app.ax, {'\Delta x (BPMS:LI11:333:X)'; ''})
            app.mainControlPanel = uipanel(app.FACETIIRFPhaseScansUIFigure);
            app.layoutControl = uigridlayout(app.mainControlPanel);
            app.scanButton = uibutton(app.layoutControl, 'push');
            app.scanButton.ButtonPushedFcn = createCallbackFcn(app, @scanButtonPushed, true);
            app.scanButton.Text = 'Scan 11-1';
            app.abortButton = uibutton(app.layoutControl, 'push');
            app.abortButton.ButtonPushedFcn = createCallbackFcn(app, @abortButtonPushed, true);
            app.abortButton.Enable = 'off';
            app.abortButton.Text = 'Abort';
            app.undoButton = uibutton(app.layoutControl, 'push');
            app.undoButton.ButtonPushedFcn = createCallbackFcn(app, @undoButtonPushed, true);
            app.undoButton.Enable = 'off';
            app.undoButton.Text = 'Undo';
            app.applyButton = uibutton(app.layoutControl, 'push');
            app.applyButton.ButtonPushedFcn = createCallbackFcn(app, @applyButtonPushed, true);
            app.applyButton.Enable = 'off';
            app.applyButton.Text = 'Apply';
            app.logbookButton = uibutton(app.layoutControl, 'push');
            app.logbookButton.ButtonPushedFcn = createCallbackFcn(app, @logbookButtonPushed, true);
            app.logbookButton.Enable = 'off';
            app.logbookButton.Text = 'Logbook';
            app.helpButton = uibutton(app.layoutControl, 'push');
            app.helpButton.ButtonPushedFcn = createCallbackFcn(app, @helpButtonPushed, true);
            app.helpButton.Text = 'Help';
            app.ScanConfigurationPanel = uipanel(app.FACETIIRFPhaseScansUIFigure);
            app.ScanConfigurationPanel.Title = 'Scan Configuration';
            app.layoutConfig = uigridlayout(app.ScanConfigurationPanel);
            app.NstepsEditFieldLabel = uilabel(app.layoutConfig);
            app.NstepsEditFieldLabel.Text = 'N steps';
            app.editNSteps = uieditfield(app.layoutConfig, 'numeric');
            app.editNSteps.Value = 15;
            app.rangeEditFieldLabel = uilabel(app.layoutConfig);
            app.rangeEditFieldLabel.Text = '+/- range';
            app.editRange = uieditfield(app.layoutConfig, 'numeric');
            app.editRange.Value = 20;
            app.KlysoffsetLabel = uilabel(app.layoutConfig);
            app.KlysoffsetLabel.Text = 'Klys offset';
            app.editOffset = uieditfield(app.layoutConfig, 'numeric');
            app.editOffset.Editable = 'off';
            app.NsamplesEditFieldLabel = uilabel(app.layoutConfig);
            app.NsamplesEditFieldLabel.Text = 'N samples';
            app.editNSamples = uieditfield(app.layoutConfig, 'numeric');
            app.editNSamples.Value = 20;
            app.selectZigzag = uicheckbox(app.layoutConfig);
            app.selectZigzag.Text = 'Zigzag';
            app.SBSToffsetLabel = uilabel(app.layoutConfig);
            app.SBSToffsetLabel.Enable = 'off';
            app.SBSToffsetLabel.Text = 'SBST offset';
            app.editSBOffset = uieditfield(app.layoutConfig, 'numeric');
            app.editSBOffset.Editable = 'off';
            app.editSBOffset.Enable = 'off';
            app.selectSim = uicheckbox(app.layoutConfig);
            app.selectSim.Text = 'Simulated scan';
            app.selectSim.Value = true;
            app.resultPanel = uipanel(app.FACETIIRFPhaseScansUIFigure);
            app.resultPanel.Title = 'Phase Control Settings';
            app.layoutResult = uigridlayout(app.resultPanel);
            app.ControlPhaseLabel = uilabel(app.layoutResult);
            app.ControlPhaseLabel.Tooltip = {'PVNAME HERE'};
            app.ControlPhaseLabel.Text = 'Control Phase';
            app.initPDES = uieditfield(app.layoutResult, 'numeric');
            app.initPDES.ValueDisplayFormat = '%.1f';
            app.initPDES.Editable = 'off';
            app.ReadbackPhaseLabel = uilabel(app.layoutResult);
            app.ReadbackPhaseLabel.Tooltip = {'PVNAME HERE'; ''};
            app.ReadbackPhaseLabel.Text = 'Readback Phase';
            app.initPACT = uieditfield(app.layoutResult, 'numeric');
            app.initPACT.ValueDisplayFormat = '%.1f';
            app.initPACT.Editable = 'off';
            app.PhaseOffsetControlLabel = uilabel(app.layoutResult);
            app.PhaseOffsetControlLabel.Tooltip = {'PVNAME HERE'; ''};
            app.PhaseOffsetControlLabel.Text = 'Phase Offset Control';
            app.initPOC = uieditfield(app.layoutResult, 'numeric');
            app.initPOC.ValueDisplayFormat = '%.1f';
            app.initPOC.Editable = 'off';
            app.finalPDES = uieditfield(app.layoutResult, 'numeric');
            app.finalPDES.ValueDisplayFormat = '%.1f';
            app.finalPDES.Editable = 'off';
            app.finalPACT = uieditfield(app.layoutResult, 'numeric');
            app.finalPACT.ValueDisplayFormat = '%.1f';
            app.finalPACT.Editable = 'off';
            app.finalPOC = uieditfield(app.layoutResult, 'numeric');
            app.finalPOC.ValueDisplayFormat = '%.1f';
            app.finalPOC.Editable = 'off';
            app.NewLabel = uilabel(app.layoutResult);
            app.NewLabel.Text = 'New';
            app.CurrentLabel = uilabel(app.layoutResult);
            app.CurrentLabel.Text = 'Current';
            app.currentPDES = uieditfield(app.layoutResult, 'numeric');
            app.currentPDES.ValueDisplayFormat = '%.1f';
            app.currentPDES.Editable = 'off';
            app.currentPACT = uieditfield(app.layoutResult, 'numeric');
            app.currentPACT.ValueDisplayFormat = '%.1f';
            app.currentPACT.Editable = 'off';
            app.currentPOC = uieditfield(app.layoutResult, 'numeric');
            app.currentPOC.ValueDisplayFormat = '%.1f';
            app.currentPOC.Editable = 'off';
            app.updateCurrentButton = uibutton(app.layoutResult, 'push');
            app.updateCurrentButton.ButtonPushedFcn = createCallbackFcn(app, @updateCurrentButtonPushed, true);
            app.updateCurrentButton.Text = 'Update';
            app.InitialLabel = uilabel(app.layoutResult);
            app.InitialLabel.Text = 'Initial';
            app.Panel = uipanel(app.FACETIIRFPhaseScansUIFigure);
            app.selectLinac = uidropdown(app.Panel);
            app.selectLinac.Items = {'L0', 'L1', 'L2', 'L3'};
            app.selectLinac.ValueChangedFcn = createCallbackFcn(app, @selectLinacValueChanged, true);
            app.selectLinac.Value = 'L1';
            app.KlystronLabel = uilabel(app.Panel);
            app.KlystronLabel.Text = {'Klystron: '; ''};
            app.selectSector = uidropdown(app.Panel);
            app.selectSector.Items = {'11'};
            app.selectSector.ValueChangedFcn = createCallbackFcn(app, @selectSectorValueChanged, true);
            app.selectSector.Value = '11';
            app.selectKlys = uidropdown(app.Panel);
            app.selectKlys.Items = {'1', '2'};
            app.selectKlys.ValueChangedFcn = createCallbackFcn(app, @selectKlysValueChanged, true);
            app.selectKlys.Value = '1';
            app.klysActiveLamp = uilamp(app.Panel);
            app.EEditFieldLabel = uilabel(app.Panel);
            app.EEditFieldLabel.Text = 'E =';
            app.beamEnergy = uieditfield(app.Panel, 'numeric');
            app.beamEnergy.ValueDisplayFormat = '%.0f';
            app.beamEnergy.Editable = 'off';
            app.beamEnergy.Value = 10000;
            app.MeVLabel = uilabel(app.Panel);
            app.MeVLabel.Text = 'MeV';
            app.QEditFieldLabel = uilabel(app.Panel);
            app.QEditFieldLabel.Text = 'Q =';
            app.bunchCharge = uieditfield(app.Panel, 'numeric');
            app.bunchCharge.ValueDisplayFormat = '%.0f';
            app.bunchCharge.Editable = 'off';
            app.bunchCharge.Value = 2000;
            app.pCLabel = uilabel(app.Panel);
            app.pCLabel.Text = 'pC';
            app.specBPM = uieditfield(app.Panel, 'text');
            app.specBPM.Editable = 'off';
            app.specBPM.Value = 'BPMS:LI11:333:X';
            app.EditFieldLabel = uilabel(app.Panel);
            app.EditFieldLabel.Text = 'η =';
            app.bpmDispersion = uieditfield(app.Panel, 'numeric');
            app.bpmDispersion.ValueDisplayFormat = '%.0f';
            app.bpmDispersion.Editable = 'off';
            app.bpmDispersion.Value = 1000;
            app.mmLabel = uilabel(app.Panel);
            app.mmLabel.Text = 'mm';
            app.fEditFieldLabel = uilabel(app.Panel);
            app.fEditFieldLabel.Text = 'f =';
            app.beamRate = uieditfield(app.Panel, 'numeric');
            app.beamRate.ValueDisplayFormat = '%.0f';
            app.beamRate.Editable = 'off';
            app.beamRate.Value = 30;
            app.HzLabel = uilabel(app.Panel);
            app.HzLabel.Text = 'Hz';
            app.BPMLabel = uilabel(app.Panel);
            app.BPMLabel.Text = 'BPM:';
            app.Panel_2 = uipanel(app.FACETIIRFPhaseScansUIFigure);
            app.message = uilabel(app.Panel_2);
            app.message.Text = 'If you can read this, GUI initialization did not complete...';
            app.StatusLabel = uilabel(app.Panel_2);
            app.StatusLabel.Text = 'Status:';
            % Show the figure after all components are created
            app.FACETIIRFPhaseScansUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = F2_phasing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FACETIIRFPhaseScansUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FACETIIRFPhaseScansUIFigure)
        end
    end
end