classdef ElementChooser < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    ElementChooserUIFigure  matlab.ui.Figure
    UITable                 matlab.ui.control.Table
    OKButton                matlab.ui.control.Button
    CANCELButton            matlab.ui.control.Button
  end

  
  properties (Access = public)
    LM % Description
    tableind = nan
  end
  
  methods (Access = public)
    
    function results = GetChoice(app)
      uiwait(app.ElementChooserUIFigure);
      results = app.tableind(:,1);
      delete(app);
    end
  end
  
  methods (Access = private)
    
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, LM)
      if exist('LM','var')
        app.LM=LM;
      else
        app.LM=LucretiaModel;
      end
      id=1:length(app.LM.ModelID);
      tab=table(id(:),cellstr(num2str(app.LM.ModelZ(:))),app.LM.ModelID(:),app.LM.ModelNames(:),app.LM.ControlNames(:));
      app.UITable.Data = tab ;
      drawnow
    end

    % Button pushed function: OKButton
    function OKButtonPushed(app, event)
      uiresume(app.ElementChooserUIFigure);
    end

    % Button pushed function: CANCELButton
    function CANCELButtonPushed(app, event)
      app.tableind=nan;
      uiresume(app.ElementChooserUIFigure);
    end

    % Close request function: ElementChooserUIFigure
    function ElementChooserUIFigureCloseRequest(app, event)
      delete(app)
      
    end

    % Cell selection callback: UITable
    function UITableCellSelection(app, event)
      app.tableind = event.Indices;
      
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.ElementChooserUIFigure = uifigure('Visible', 'off');
      app.ElementChooserUIFigure.Name = 'Element Chooser';
      app.ElementChooserUIFigure.CloseRequestFcn = createCallbackFcn(app, @ElementChooserUIFigureCloseRequest, true);
      app.UITable = uitable(app.ElementChooserUIFigure);
      app.UITable.ColumnName = {'ID'; 'Z [m]'; 'BEAMLINE Index'; 'Model Name'; 'Control Name'};
      app.UITable.RowName = {};
      app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
      app.OKButton = uibutton(app.ElementChooserUIFigure, 'push');
      app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
      app.OKButton.Text = 'OK';
      app.CANCELButton = uibutton(app.ElementChooserUIFigure, 'push');
      app.CANCELButton.ButtonPushedFcn = createCallbackFcn(app, @CANCELButtonPushed, true);
      app.CANCELButton.Text = 'CANCEL';
      % Show the figure after all components are created
      app.ElementChooserUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = ElementChooser(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.ElementChooserUIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.ElementChooserUIFigure)
    end
  end
end