classdef Multiknob < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    MultiknobUIFigure          matlab.ui.Figure
    GridLayout                 matlab.ui.container.GridLayout
    LeftPanel                  matlab.ui.container.Panel
    SetpointEditFieldLabel     matlab.ui.control.Label
    SetpointEditField          matlab.ui.control.NumericEditField
    Slider                     matlab.ui.control.Slider
    Switch_2                   matlab.ui.control.Switch
    ReadbackDESEditFieldLabel  matlab.ui.control.Label
    ReadbackDESEditField       matlab.ui.control.NumericEditField
    EditField                  matlab.ui.control.NumericEditField
    EditField_2                matlab.ui.control.NumericEditField
    ResetButton                matlab.ui.control.Button
    TrimAllButton              matlab.ui.control.Button
    ReadbackACTEditFieldLabel  matlab.ui.control.Label
    ReadbackACTEditField       matlab.ui.control.NumericEditField
    RightPanel                 matlab.ui.container.Panel
    UITable                    matlab.ui.control.Table
  end

  % Properties that correspond to apps with auto-reflow
  properties (Access = private)
    onePanelWidth = 576;
  end

  
  properties (Access = private)
    MagNames string % Magnet Control Names
    Scales % Multiknob scale factors
    LM % Lucretia Model object
    LM_ind % Index of Magnames into LM
    WriteAction string {mustBeMember(WriteAction,["TRIM","PERTURB"])} = "TRIM"
  end
  
  methods (Access = private)
    
    function GetMagData(app)
      if isempty(app.MagNames)
        return
      end
      [bact,bdes] = control_magnetGet(cellstr(app.MagNames(:))) ;
      app.UITable.Data{:,4:5} = [bdes(:) bact(:)] ;
      app.SetReadback("set") ;
      
    end
    
    function SetReadback(app,cmd)
      B0 = app.UITable.Data{:,3} ;
      sca = app.UITable.Data{:,6} ;
      bdes = app.UITable.Data{:,4} ;
      bact = app.UITable.Data{:,5} ;
      readbackDES = (bdes(:) - B0(:)) ./ sca ;
      readbackACT = (bact(:) - B0(:)) ./ sca ;
      app.ReadbackDESEditField.Value = mean(readbackDES) ;
      app.ReadbackACTEditField.Value = mean(readbackACT) ;
      vdes = app.SetpointEditField.Value ;
      vact = app.ReadbackDESEditField.Value ;
      vcact = app.ReadbackACTEditField.Value ;
      switch cmd
        case "setting"
          errcol = [1.00,1.00,0.07] ;
        case "set"
          errcol = [0.85,0.33,0.10] ;
      end
      if abs(vdes-vact) > 0.001 && abs(vact-vdes)/abs(vdes) > 0.01
        app.ReadbackDESEditField.BackgroundColor=errcol ;
      else
        app.ReadbackDESEditField.BackgroundColor=[0.94,0.94,0.94] ;
      end
      if abs(vdes-vcact) > 0.1 && abs(vcact-vdes)/abs(vdes) > 0.1
        app.ReadbackACTEditField.BackgroundColor=errcol ;
      else
        app.ReadbackACTEditField.BackgroundColor=[0.94,0.94,0.94] ;
      end
      drawnow
    end
    function UpdateTable(app,knobval)
      B0 = app.UITable.Data{:,3} ;
      sca = app.UITable.Data{:,6} ;
      vals = B0(:) + sca(:) .* knobval ;
      use = app.UITable.Data{:,7} ;
      app.UITable.Data{:,4} = vals(use) ;
    end
    
    function SetMagData(app,knobval)
      if isempty(app.MagNames)
        return
      end
      app.ReadbackDESEditField.BackgroundColor='black' ;
      app.ReadbackACTEditField.BackgroundColor='black' ;
      drawnow;
      B0 = app.UITable.Data{:,3} ;
      sca = app.UITable.Data{:,6} ;
      vals = B0(:) + sca(:) .* knobval ;
      use = app.UITable.Data{:,7} ;
      mname=app.MagNames(use);
      try
        control_magnetSet(cellstr(mname(:)),vals(use),'action',char(app.WriteAction)) ;
      catch ME
        app.GetMagData ;
        fprintf('Magnets Set Failed.\n');
        throw(ME);
      end
      app.GetMagData ;
      fprintf('Magnets Set.\n');
    end

  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, MagControlNames, Scale)
      global BEAMLINE
      if ~exist('MagControlNames','var') || ~exist('Scale','var') || length(MagControlNames)~=length(Scale)
        error('Must provide list of control names and Scales of same length')
      end
      app.LM = LucretiaModel("/usr/local/facet/tools/facet2-lattice/Lucretia/models/FACET2e/FACET2e.mat") ;
      Names = string(MagControlNames) ;
      app.LM_ind = find(ismember(app.LM.ControlNames,Names));
      if length(app.LM_ind)~=length(Names)
        error('Some or all of magnet names not found in model')
      end
      app.MagNames=Names(:);
      Z = arrayfun(@(x) BEAMLINE{x}.Coordi(3),app.LM.ModelUniqueID(app.LM_ind)) ;
      n=length(Z);
      tab = table(Names(:),string(Z(:)),zeros(n,1),zeros(n,1),zeros(n,1),zeros(n,1),true(n,1)) ;
      tab.Properties.VariableNames=["Name";"Z";"BDES_INIT";"BDES";"BACT";"SCALE";"USE"];
      app.UITable.Data = tab ;
      app.UITable.Data{:,3} = app.UITable.Data{:,4} ; % Set BDES_INIT
      app.UITable.Data{:,6} = Scale(:) ;
      [bact,bdes] = control_magnetGet(cellstr(app.MagNames(:))) ;
      app.UITable.Data{:,3:5} = [bdes(:) bdes(:) bact(:)] ;
      app.UITable.ColumnName=tab.Properties.VariableNames;
      app.UITable.ColumnSortable = true ;
      app.UITable.ColumnEditable=[false false false false false true true];
      app.UITable.ColumnSortable=false;
    end

    % Value changed function: SetpointEditField
    function SetpointEditFieldValueChanged(app, event)
      value = app.SetpointEditField.Value;
      app.Slider.Value = value ;
      app.SetMagData(value) ;
    end

    % Value changing function: Slider
    function SliderValueChanging(app, event)
      changingValue = event.Value;
      app.SetpointEditField.Value = changingValue ;
      UpdateTable(app,changingValue);
    end

    % Value changed function: Slider
    function SliderValueChanged(app, event)
      value = app.Slider.Value;
      app.SetpointEditField.Value = value ;
      app.SetMagData(value) ;
    end

    % Button pushed function: TrimAllButton
    function TrimAllButtonPushed(app, event)
      vals = app.UITable.Data{:,4} ;
      app.ReadbackDESEditField.BackgroundColor='black' ;
      app.ReadbackACTEditField.BackgroundColor='black' ;
      drawnow;
      try
        control_magnetSet(cellstr(app.MagNames(:)),vals(:),'action','TRIM') ;
      catch ME
        fprintf('Magnets failed to trim\n');
        app.GetMagData;
        throw(ME);
      end
      app.GetMagData;
    end

    % Button pushed function: ResetButton
    function ResetButtonPushed(app, event)
      vals = app.UITable.Data{:,3} ;
      app.ReadbackDESEditField.BackgroundColor='black' ;
      app.ReadbackACTEditField.BackgroundColor='black' ;
      drawnow;
      try
        control_magnetSet(cellstr(app.MagNames(:)),vals(:),'action','TRIM') ;
      catch ME
        fprintf('Magnets failed to trim\n');
        app.GetMagData;
        throw(ME);
      end
      app.GetMagData;
      app.SetpointEditField.Value=0;
      app.Slider.Value=0;
    end

    % Value changed function: EditField
    function EditFieldValueChanged(app, event)
      value = app.EditField.Value;
      app.Slider.Limits(1) = value ;
      app.SetpointEditField.Limits(1) = value ;
    end

    % Value changed function: EditField_2
    function EditField_2ValueChanged(app, event)
      value = app.EditField_2.Value;
      app.Slider.Limits(2) = value ;
      app.SetpointEditField.Limits(2) = value ;
    end

    % Value changed function: Switch_2
    function Switch_2ValueChanged(app, event)
      value = app.Switch_2.Value;
      app.WriteAction = upper(string(value)) ;
    end

    % Changes arrangement of the app based on UIFigure width
    function updateAppLayout(app, event)
            currentFigureWidth = app.MultiknobUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {362, 362};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.MultiknobUIFigure = uifigure('Visible', 'off');
      app.MultiknobUIFigure.Name = 'Multiknob';
      app.MultiknobUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
      app.GridLayout = uigridlayout(app.MultiknobUIFigure);
      app.LeftPanel = uipanel(app.GridLayout);
      app.SetpointEditFieldLabel = uilabel(app.LeftPanel);
      app.SetpointEditFieldLabel.Text = 'Setpoint';
      app.SetpointEditField = uieditfield(app.LeftPanel, 'numeric');
      app.SetpointEditField.Limits = [0 1];
      app.SetpointEditField.ValueChangedFcn = createCallbackFcn(app, @SetpointEditFieldValueChanged, true);
      app.Slider = uislider(app.LeftPanel);
      app.Slider.Limits = [0 1];
      app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
      app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
      app.Switch_2 = uiswitch(app.LeftPanel, 'slider');
      app.Switch_2.Items = {'Trim', 'Perturb'};
      app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
      app.Switch_2.Value = 'Trim';
      app.ReadbackDESEditFieldLabel = uilabel(app.LeftPanel);
      app.ReadbackDESEditFieldLabel.Text = 'Readback (DES)';
      app.ReadbackDESEditField = uieditfield(app.LeftPanel, 'numeric');
      app.ReadbackDESEditField.Editable = 'off';
      app.EditField = uieditfield(app.LeftPanel, 'numeric');
      app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
      app.EditField_2 = uieditfield(app.LeftPanel, 'numeric');
      app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
      app.EditField_2.Value = 1;
      app.ResetButton = uibutton(app.LeftPanel, 'push');
      app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
      app.ResetButton.Text = 'Reset';
      app.TrimAllButton = uibutton(app.LeftPanel, 'push');
      app.TrimAllButton.ButtonPushedFcn = createCallbackFcn(app, @TrimAllButtonPushed, true);
      app.TrimAllButton.Text = 'Trim All';
      app.ReadbackACTEditFieldLabel = uilabel(app.LeftPanel);
      app.ReadbackACTEditFieldLabel.Text = 'Readback (ACT)';
      app.ReadbackACTEditField = uieditfield(app.LeftPanel, 'numeric');
      app.ReadbackACTEditField.Editable = 'off';
      app.RightPanel = uipanel(app.GridLayout);
      app.UITable = uitable(app.RightPanel);
      app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
      app.UITable.RowName = {};
      % Show the figure after all components are created
      app.MultiknobUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = Multiknob(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.MultiknobUIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.MultiknobUIFigure)
    end
  end
end