classdef Password < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    PasswordUIFigure  matlab.ui.Figure
    PasswordHTML      matlab.ui.control.HTML
    EnterPasswordandpressenterLabel  matlab.ui.control.Label
    PasswordLabel     matlab.ui.control.Label
  end

  
  properties (Access = private)
    PasswordEntered % Description
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Data changed function: PasswordHTML
    function PasswordHTMLDataChanged(app, event)
      % Generate password with : echo "fixedforever" | openssl aes-256-cbc -a -salt -pass pass:somepassword
      password = app.PasswordHTML.Data;
      [~,spass]=system(sprintf('echo "U2FsdGVkX19s9rbZLfNp+wN+RwfUtyMC1WzwCaNnPNc=" | openssl aes-256-cbc -d -a -pass pass:%s',password));
      if string(strip(spass)) == "fixedforever"
        app.PasswordLabel.Text = "OK" ;
        drawnow;
        pause(1);
        delete(app);
      else
        app.PasswordLabel.Text = "Incorrect" ;
      end
    end

    % Close request function: PasswordUIFigure
    function PasswordUIFigureCloseRequest(app, event)
      delete(app)
      
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.PasswordUIFigure = uifigure('Visible', 'off');
      app.PasswordUIFigure.Name = 'Password';
      app.PasswordUIFigure.CloseRequestFcn = createCallbackFcn(app, @PasswordUIFigureCloseRequest, true);
      app.PasswordHTML = uihtml(app.PasswordUIFigure);
      app.PasswordHTML.HTMLSource = 'passwordEdit.html';
      app.PasswordHTML.DataChangedFcn = createCallbackFcn(app, @PasswordHTMLDataChanged, true);
      app.EnterPasswordandpressenterLabel = uilabel(app.PasswordUIFigure);
      app.EnterPasswordandpressenterLabel.Text = 'Enter Password and press enter...';
      app.PasswordLabel = uilabel(app.PasswordUIFigure);
      app.PasswordLabel.Text = '';
      % Show the figure after all components are created
      app.PasswordUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = Password

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.PasswordUIFigure)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.PasswordUIFigure)
    end
  end
end