classdef colormapselect < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure   matlab.ui.Figure
    Image      matlab.ui.control.Image
    Button     matlab.ui.control.Button
    Button_2   matlab.ui.control.Button
    Button_3   matlab.ui.control.Button
    Button_4   matlab.ui.control.Button
    Button_5   matlab.ui.control.Button
    Button_6   matlab.ui.control.Button
    Button_7   matlab.ui.control.Button
    Button_8   matlab.ui.control.Button
    Button_9   matlab.ui.control.Button
    Button_10  matlab.ui.control.Button
    Button_11  matlab.ui.control.Button
    Button_12  matlab.ui.control.Button
    Button_13  matlab.ui.control.Button
    Button_14  matlab.ui.control.Button
    Button_15  matlab.ui.control.Button
    Button_16  matlab.ui.control.Button
    Button_17  matlab.ui.control.Button
    Button_18  matlab.ui.control.Button
  end

  
  properties (Access = public)
    cprop % property of calling object to set with colormap choice
    cobj % calling object
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, cobj, cprop)
      if exist('cobj','var')
        app.cobj = cobj ;
        app.cprop=cprop;
      end
    end

    % Close request function: UIFigure
    function UIFigureCloseRequest(app, event)
      delete(app)
      app.cobj.(app.cprop) = app.cobj.(app.cprop) ;
    end

    % Button pushed function: Button, Button_10, Button_11, 
    % Button_12, Button_13, Button_14, Button_15, Button_16, 
    % Button_17, Button_18, Button_2, Button_3, Button_4, Button_5, 
    % Button_6, Button_7, Button_8, Button_9
    function ButtonPushed(app, event)
      app.cobj.(app.cprop) = string(event.Source.Tooltip) ;
      delete(app);
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
      app.Image = uiimage(app.UIFigure);
      app.Image.ImageSource = 'cmapselect.png';
      app.Button = uibutton(app.UIFigure, 'push');
      app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
      app.Button.Tag = 'parula';
      app.Button.Icon = 'checkmark.svg';
      app.Button.Tooltip = {'parula'};
      app.Button.Text = '';
      app.Button_2 = uibutton(app.UIFigure, 'push');
      app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
      app.Button_2.Tag = 'turbo';
      app.Button_2.Icon = 'checkmark.svg';
      app.Button_2.Tooltip = {'turbo'};
      app.Button_2.Text = '';
      app.Button_3 = uibutton(app.UIFigure, 'push');
      app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
      app.Button_3.Icon = 'checkmark.svg';
      app.Button_3.Tooltip = {'hsv'};
      app.Button_3.Text = '';
      app.Button_4 = uibutton(app.UIFigure, 'push');
      app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
      app.Button_4.Icon = 'checkmark.svg';
      app.Button_4.Tooltip = {'hot'};
      app.Button_4.Text = '';
      app.Button_5 = uibutton(app.UIFigure, 'push');
      app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
      app.Button_5.Icon = 'checkmark.svg';
      app.Button_5.Tooltip = {'cool'};
      app.Button_5.Text = '';
      app.Button_6 = uibutton(app.UIFigure, 'push');
      app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
      app.Button_6.Icon = 'checkmark.svg';
      app.Button_6.Tooltip = {'spring'};
      app.Button_6.Text = '';
      app.Button_7 = uibutton(app.UIFigure, 'push');
      app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
      app.Button_7.Icon = 'checkmark.svg';
      app.Button_7.Tooltip = {'summer'};
      app.Button_7.Text = '';
      app.Button_8 = uibutton(app.UIFigure, 'push');
      app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
      app.Button_8.Icon = 'checkmark.svg';
      app.Button_8.Tooltip = {'autumn'};
      app.Button_8.Text = '';
      app.Button_9 = uibutton(app.UIFigure, 'push');
      app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
      app.Button_9.Icon = 'checkmark.svg';
      app.Button_9.Tooltip = {'winter'};
      app.Button_9.Text = '';
      app.Button_10 = uibutton(app.UIFigure, 'push');
      app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
      app.Button_10.Icon = 'checkmark.svg';
      app.Button_10.Tooltip = {'gray'};
      app.Button_10.Text = '';
      app.Button_11 = uibutton(app.UIFigure, 'push');
      app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
      app.Button_11.Icon = 'checkmark.svg';
      app.Button_11.Tooltip = {'bone'};
      app.Button_11.Text = '';
      app.Button_12 = uibutton(app.UIFigure, 'push');
      app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
      app.Button_12.Icon = 'checkmark.svg';
      app.Button_12.Tooltip = {'copper'};
      app.Button_12.Text = '';
      app.Button_13 = uibutton(app.UIFigure, 'push');
      app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
      app.Button_13.Icon = 'checkmark.svg';
      app.Button_13.Tooltip = {'pink'};
      app.Button_13.Text = '';
      app.Button_14 = uibutton(app.UIFigure, 'push');
      app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
      app.Button_14.Icon = 'checkmark.svg';
      app.Button_14.Tooltip = {'jet'};
      app.Button_14.Text = '';
      app.Button_15 = uibutton(app.UIFigure, 'push');
      app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
      app.Button_15.Icon = 'checkmark.svg';
      app.Button_15.Tooltip = {'lines'};
      app.Button_15.Text = '';
      app.Button_16 = uibutton(app.UIFigure, 'push');
      app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
      app.Button_16.Icon = 'checkmark.svg';
      app.Button_16.Tooltip = {'colorcube'};
      app.Button_16.Text = '';
      app.Button_17 = uibutton(app.UIFigure, 'push');
      app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
      app.Button_17.Icon = 'checkmark.svg';
      app.Button_17.Tooltip = {'prism'};
      app.Button_17.Text = '';
      app.Button_18 = uibutton(app.UIFigure, 'push');
      app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
      app.Button_18.Icon = 'checkmark.svg';
      app.Button_18.Tooltip = {'flag'};
      app.Button_18.Text = '';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = colormapselect(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end