classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        UIAxes              matlab.ui.control.UIAxes
        PV1GaugeLabel       matlab.ui.control.Label
        PV1Gauge            matlab.ui.control.Gauge
        PV2GaugeLabel       matlab.ui.control.Label
        PV2Gauge            matlab.ui.control.Gauge
        TravisTestGUILabel  matlab.ui.control.Label
        PVDropDownLabel     matlab.ui.control.Label
        PVDropDown          matlab.ui.control.DropDown
        CloseAppButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        aobj % GUI Support Objectt
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.aobj = AppSupportTemplate(app);
        end

        % Button pushed function: CloseAppButton
        function CloseAppButtonPushed(app, event)
            app.UIFigureCloseRequest()
        end

        % Value changed function: PVDropDown
        function PVDropDownValueChanged(app, event)
            value = app.PVDropDown.Value;
            
            switch value
                case 'PV 1'
                    app.aobj.setPlotOptTo1();
                case 'PV 2'
                    app.aobj.setPlotOptTo2();
                    
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                app.aobj.clearPV();
            catch 
                delete(app);
            end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'matlab PVs over time'; ''})
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, 'PV')
            app.PV1GaugeLabel = uilabel(app.UIFigure);
            app.PV1GaugeLabel.Text = 'PV 1';
            app.PV1Gauge = uigauge(app.UIFigure, 'circular');
            app.PV1Gauge.Limits = [0 1];
            app.PV2GaugeLabel = uilabel(app.UIFigure);
            app.PV2GaugeLabel.Text = 'PV 2';
            app.PV2Gauge = uigauge(app.UIFigure, 'circular');
            app.PV2Gauge.Limits = [-1 1];
            app.TravisTestGUILabel = uilabel(app.UIFigure);
            app.TravisTestGUILabel.Text = 'Travis Test GUI';
            app.PVDropDownLabel = uilabel(app.UIFigure);
            app.PVDropDownLabel.Text = 'PV';
            app.PVDropDown = uidropdown(app.UIFigure);
            app.PVDropDown.Items = {'PV 1', 'PV 2', ''};
            app.PVDropDown.ValueChangedFcn = createCallbackFcn(app, @PVDropDownValueChanged, true);
            app.PVDropDown.Value = 'PV 1';
            app.CloseAppButton = uibutton(app.UIFigure, 'push');
            app.CloseAppButton.ButtonPushedFcn = createCallbackFcn(app, @CloseAppButtonPushed, true);
            app.CloseAppButton.Text = 'Close App';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end