classdef F2_GunE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SolenoidCorrectorPanel  matlab.ui.container.Panel
        SwitchLabel             matlab.ui.control.Label
        Switch                  matlab.ui.control.ToggleSwitch
        dIAEditFieldLabel       matlab.ui.control.Label
        dIAEditField            matlab.ui.control.NumericEditField
        ScreenPositionsPanel    matlab.ui.container.Panel
        dxmmEditFieldLabel      matlab.ui.control.Label
        dxmmEditField           matlab.ui.control.NumericEditField
        dymmEditFieldLabel      matlab.ui.control.Label
        dymmEditField           matlab.ui.control.NumericEditField
        MainSolenoidPanel       matlab.ui.container.Panel
        CurrentAEditFieldLabel  matlab.ui.control.Label
        CurrentAEditField       matlab.ui.control.NumericEditField
        ConstrainFitPanel       matlab.ui.container.Panel
        EnableCheckBox          matlab.ui.control.CheckBox
        EminMeVEditFieldLabel   matlab.ui.control.Label
        EminMeVEditField        matlab.ui.control.NumericEditField
        EmaxMeVEditFieldLabel   matlab.ui.control.Label
        EmaxMeVEditField        matlab.ui.control.NumericEditField
        UIAxes                  matlab.ui.control.UIAxes
        EnergyFitMeVPanel       matlab.ui.container.Panel
        ClosestLabel            matlab.ui.control.Label
        ClosestEditField        matlab.ui.control.NumericEditField
        UseREditFieldLabel      matlab.ui.control.Label
        UseREditField           matlab.ui.control.NumericEditField
        UseThetaEditFieldLabel  matlab.ui.control.Label
        UseThetaEditField       matlab.ui.control.NumericEditField
        PlotControlsPanel       matlab.ui.container.Panel
        RotateButton            matlab.ui.control.Button
        ZoomButton              matlab.ui.control.Button
        PanButton               matlab.ui.control.Button
    end


    properties (Access = private)
        FD % F2GunDiagnostics object containing lookup data and methods
    end

    methods (Access = private)
        
        % Do the lookup table fit using parameters in app
        function dofit(app)
            try
                app.ClosestEditField.Enable=true;
                app.UseREditField.Enable=true;
                app.UseThetaEditField.Enable=true;
                dx = app.dxmmEditField.Value ;
                dy = app.dymmEditField.Value ;
                corI = app.dIAEditField.Value ;
                solI = app.CurrentAEditField.Value ;
                if app.Switch.Value == "XC10121"
                    ctype='x';
                else
                    ctype='y';
                end
                if app.EnableCheckBox.Value
                    emin = app.EminMeVEditField.Value ;
                    emax = app.EmaxMeVEditField.Value ;
                    [E1,E2,E3]=app.FD.Ecalc(dx,dy,corI,solI,ctype,emin,emax);
                else
                    [E1,E2,E3]=app.FD.Ecalc(dx,dy,corI,solI,ctype);
                end
                app.ClosestEditField.Value = E1 ;
                app.UseREditField.Value = E2 ;
                app.UseThetaEditField.Value = E3 ;
            catch
                cla(app.UIAxes);
                app.ClosestEditField.Value=0;
                app.UseREditField.Value=0;
                app.UseThetaEditField.Value=0;
                app.ClosestEditField.Enable=false;
                app.UseREditField.Enable=false;
                app.UseThetaEditField.Enable=false;
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ludatafile)
          if ~exist('ludatafile','var')
            ludatafile=which('solcaldata.mat');
          end
          if ~exist(ludatafile,'file')
            error('Data file does not exist')
          end
          % Initialize diagnostics object with provided lookup data file
          app.FD=F2GunDiagnostics('apps/solcaldata'); app.FD.verbose=2; app.FD.axhan = app.UIAxes ;
          app.FD.Ecalplot(app.CurrentAEditField.Value);
          
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.dofit;
            
        end

        % Value changed function: dIAEditField
        function dIAEditFieldValueChanged(app, event)
            app.dofit;
            
        end

        % Value changed function: dxmmEditField
        function dxmmEditFieldValueChanged(app, event)
            app.dofit;
        end

        % Value changed function: dymmEditField
        function dymmEditFieldValueChanged(app, event)
            app.dofit;
        end

        % Value changed function: CurrentAEditField
        function CurrentAEditFieldValueChanged(app, event)
            app.dofit;
        end

        % Value changed function: EnableCheckBox
        function EnableCheckBoxValueChanged(app, event)
            app.dofit;
        end

        % Value changed function: EminMeVEditField
        function EminMeVEditFieldValueChanged(app, event)
            app.dofit;
        end

        % Value changed function: EmaxMeVEditField
        function EmaxMeVEditFieldValueChanged(app, event)
            app.dofit;
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            rotate3d(app.UIAxes,'on');
        end

        % Button pushed function: ZoomButton
        function ZoomButtonPushed(app, event)
            zoom(app.UIAxes,'on');
        end

        % Callback function
        function ResetZoomButtonPushed(app, event)
            zoom(app.UIAxes,'out');
        end

        % Button pushed function: PanButton
        function PanButtonPushed(app, event)
            pan(app.UIAxes,'on');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SolenoidCorrectorPanel = uipanel(app.UIFigure);
            app.SolenoidCorrectorPanel.Title = 'Solenoid Corrector';
            app.SwitchLabel = uilabel(app.SolenoidCorrectorPanel);
            app.SwitchLabel.Text = '';
            app.Switch = uiswitch(app.SolenoidCorrectorPanel, 'toggle');
            app.Switch.Items = {'XC10121', 'YC10122'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'XC10121';
            app.dIAEditFieldLabel = uilabel(app.SolenoidCorrectorPanel);
            app.dIAEditFieldLabel.Text = 'dI (A)';
            app.dIAEditField = uieditfield(app.SolenoidCorrectorPanel, 'numeric');
            app.dIAEditField.Limits = [0 100];
            app.dIAEditField.ValueChangedFcn = createCallbackFcn(app, @dIAEditFieldValueChanged, true);
            app.dIAEditField.Value = 1;
            app.ScreenPositionsPanel = uipanel(app.UIFigure);
            app.ScreenPositionsPanel.Title = 'Screen Positions';
            app.dxmmEditFieldLabel = uilabel(app.ScreenPositionsPanel);
            app.dxmmEditFieldLabel.Text = 'dx (mm)';
            app.dxmmEditField = uieditfield(app.ScreenPositionsPanel, 'numeric');
            app.dxmmEditField.Limits = [0 1000];
            app.dxmmEditField.ValueChangedFcn = createCallbackFcn(app, @dxmmEditFieldValueChanged, true);
            app.dxmmEditField.Value = 1.01748;
            app.dymmEditFieldLabel = uilabel(app.ScreenPositionsPanel);
            app.dymmEditFieldLabel.Text = 'dy (mm)';
            app.dymmEditField = uieditfield(app.ScreenPositionsPanel, 'numeric');
            app.dymmEditField.Limits = [0 1000];
            app.dymmEditField.ValueChangedFcn = createCallbackFcn(app, @dymmEditFieldValueChanged, true);
            app.dymmEditField.Value = 0.859221;
            app.MainSolenoidPanel = uipanel(app.UIFigure);
            app.MainSolenoidPanel.Title = 'Main Solenoid';
            app.CurrentAEditFieldLabel = uilabel(app.MainSolenoidPanel);
            app.CurrentAEditFieldLabel.Text = 'Current (A)';
            app.CurrentAEditField = uieditfield(app.MainSolenoidPanel, 'numeric');
            app.CurrentAEditField.Limits = [130 170];
            app.CurrentAEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentAEditFieldValueChanged, true);
            app.CurrentAEditField.Value = 160;
            app.ConstrainFitPanel = uipanel(app.UIFigure);
            app.ConstrainFitPanel.Title = 'Constrain Fit ?';
            app.EnableCheckBox = uicheckbox(app.ConstrainFitPanel);
            app.EnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableCheckBoxValueChanged, true);
            app.EnableCheckBox.Text = 'Enable';
            app.EminMeVEditFieldLabel = uilabel(app.ConstrainFitPanel);
            app.EminMeVEditFieldLabel.Text = 'Emin (MeV)';
            app.EminMeVEditField = uieditfield(app.ConstrainFitPanel, 'numeric');
            app.EminMeVEditField.Limits = [0.1 6.5];
            app.EminMeVEditField.ValueChangedFcn = createCallbackFcn(app, @EminMeVEditFieldValueChanged, true);
            app.EminMeVEditField.Value = 3.5;
            app.EmaxMeVEditFieldLabel = uilabel(app.ConstrainFitPanel);
            app.EmaxMeVEditFieldLabel.Text = 'Emax (MeV)';
            app.EmaxMeVEditField = uieditfield(app.ConstrainFitPanel, 'numeric');
            app.EmaxMeVEditField.Limits = [0.5 7];
            app.EmaxMeVEditField.ValueChangedFcn = createCallbackFcn(app, @EmaxMeVEditFieldValueChanged, true);
            app.EmaxMeVEditField.Value = 6.5;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'FIt To Lookup Table')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.EnergyFitMeVPanel = uipanel(app.UIFigure);
            app.EnergyFitMeVPanel.Title = 'Energy Fit (MeV)';
            app.ClosestLabel = uilabel(app.EnergyFitMeVPanel);
            app.ClosestLabel.Text = 'Closest';
            app.ClosestEditField = uieditfield(app.EnergyFitMeVPanel, 'numeric');
            app.ClosestEditField.Editable = 'off';
            app.UseREditFieldLabel = uilabel(app.EnergyFitMeVPanel);
            app.UseREditFieldLabel.Text = 'Use R';
            app.UseREditField = uieditfield(app.EnergyFitMeVPanel, 'numeric');
            app.UseREditField.Editable = 'off';
            app.UseThetaEditFieldLabel = uilabel(app.EnergyFitMeVPanel);
            app.UseThetaEditFieldLabel.Text = 'Use Theta';
            app.UseThetaEditField = uieditfield(app.EnergyFitMeVPanel, 'numeric');
            app.UseThetaEditField.Editable = 'off';
            app.PlotControlsPanel = uipanel(app.UIFigure);
            app.PlotControlsPanel.Title = 'Plot Controls';
            app.RotateButton = uibutton(app.PlotControlsPanel, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.ZoomButton = uibutton(app.PlotControlsPanel, 'push');
            app.ZoomButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomButtonPushed, true);
            app.ZoomButton.Text = 'Zoom';
            app.PanButton = uibutton(app.PlotControlsPanel, 'push');
            app.PanButton.ButtonPushedFcn = createCallbackFcn(app, @PanButtonPushed, true);
            app.PanButton.Text = 'Pan';
        end
    end

    methods (Access = public)

        % Construct app
        function app = F2_GunE(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end